classdef DeleteResultsMessage_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        CancelButton  matlab.ui.control.Button
        OKbutton      matlab.ui.control.Button
        TextArea      matlab.ui.control.TextArea
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function DeleteResultsMessage_OpeningFcn(app)
            
        end

        % Button pushed function: CancelButton
        function CancelButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            
            DeleteResults=0;
            
            cd (ProjectPath)
            save(ProjectName,'DeleteResults','-append')
            cd (MainDirectory)
            closereq
        end

        % Button pushed function: OKbutton
        function OKbutton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            
            DeleteResults=1;
            
            cd (ProjectPath)
            save(ProjectName,'DeleteResults','-append')
            cd (MainDirectory)
            closereq
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'DeleteResults')
            cd (MainDirectory)
            
            if DeleteResults==0
                DeleteResults=0;
            
                cd (ProjectPath)
                save(ProjectName,'DeleteResults','-append')
                cd (MainDirectory)
            end
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Alert: Result Deletion';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.uipanel1 = uipanel(app.figure1);
            app.CancelButton = uibutton(app.uipanel1, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButton_Callback, true);
            app.CancelButton.Text = 'Cancel';
            app.OKbutton = uibutton(app.uipanel1, 'push');
            app.OKbutton.ButtonPushedFcn = createCallbackFcn(app, @OKbutton_Callback, true);
            app.OKbutton.Text = 'OK';
            app.TextArea = uitextarea(app.uipanel1);
            app.TextArea.Value = {'NOTE: the existing results will be deleted!'};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DeleteResultsMessage_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @DeleteResultsMessage_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end