classdef IIDAP_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure0         matlab.ui.Figure
        axes10          matlab.ui.control.UIAxes
        DefinePanel     matlab.ui.container.Panel
        status3b        matlab.ui.control.Label
        status2b        matlab.ui.control.Label
        status1b        matlab.ui.control.Label
        SDoFButton      matlab.ui.control.Button
        ModelButton     matlab.ui.control.Button
        GMButton        matlab.ui.control.Button
        status1         matlab.ui.control.Label
        status2         matlab.ui.control.Label
        status3         matlab.ui.control.Label
        status4b        matlab.ui.control.Label
        AnalysisButton  matlab.ui.control.Button
        status4         matlab.ui.control.Label
        Image1          matlab.ui.control.Image
        Image2          matlab.ui.control.Image
        Image3          matlab.ui.control.Image
        Image4          matlab.ui.control.Image
        PlotGMButton    matlab.ui.control.Button
        ProgressText    matlab.ui.control.EditField
        uipanel5        matlab.ui.container.Panel
        RunButton       matlab.ui.control.Button
        ResultsButton   matlab.ui.control.Button
        SaveButton      matlab.ui.control.Button
        Image_5         matlab.ui.control.Image
        Image_6         matlab.ui.control.Image
        Image_7         matlab.ui.control.Image
        uipanel7        matlab.ui.container.Panel
        StartButton     matlab.ui.control.Button
        OpenButton      matlab.ui.control.Button
        SummaryButton   matlab.ui.control.Button
        text27          matlab.ui.control.Label
        SaveAsButton    matlab.ui.control.Button
        Image0          matlab.ui.control.Image
        text25          matlab.ui.control.Label
        HelpButton      matlab.ui.control.Button
        AboutButton     matlab.ui.control.Button
        Image5          matlab.ui.control.Image
        Image5_2        matlab.ui.control.Image
        ResetButton     matlab.ui.control.Button
    end


    methods (Access = private)
        function BLINK_TEXT(app, YourString, pauseTime, nTimes)
            app.ProgressText.FontColor=[0 1 0];
            app.ProgressText.Value=YourString;
            drawnow();
            
            for i=1:nTimes
                app.ProgressText.Value='';
                drawnow();
                pause(pauseTime);
                app.ProgressText.Value=YourString;
                drawnow();
                pause(pauseTime);
            end
        end
        
        function Show_Summary(app)
            clc
            [SummaryText]=Get_Project_Summary;
            Project_Summary_App(SummaryText)
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function IIDAP_OpeningFcn(app)
            app.Image5.Visible='off';
            global MainDirectory
            clc;
            MainDirectory=pwd;
            app.StartButton.Enable='on';
            app.OpenButton.Enable='on';
            app.SaveAsButton.Enable='off';
            app.SummaryButton.Enable='off';
            
            app.SDoFButton.Enable='off';
            app.ModelButton.Enable='off';
            app.GMButton.Enable='off';
            app.AnalysisButton.Enable='off';
            app.RunButton.Enable='off';
            app.ResultsButton.Enable='off';
            app.SaveButton.Enable='off';
            app.PlotGMButton.Enable='off';
            
            app.status1.BackgroundColor=[0.7 0.7 0.7];
            app.status2.BackgroundColor=[0.7 0.7 0.7];
            app.status3.BackgroundColor=[0.7 0.7 0.7];
            app.status4.BackgroundColor=[0.7 0.7 0.7];
            app.status1b.BackgroundColor=[0.7 0.7 0.7];
            app.status2b.BackgroundColor=[0.7 0.7 0.7];
            app.status3b.BackgroundColor=[0.7 0.7 0.7];
            app.status4b.BackgroundColor=[0.7 0.7 0.7];
            
            app.axes10.cla
            app.axes10.YTick=[];
            app.axes10.XTick=[];
            
            app.text27.Text=['Project: ---'];
            
            BLINK_TEXT (app,'COPYRIGHT: A. ELKADY and D. LIGNOS (2018)', 0.2, 3)

        end

        % Button pushed function: AboutButton
        function AboutButton_Callback(app, event)
            About_App
        end

        % Button pushed function: AnalysisButton
        function AnalysisButton_Callback(app, event)
            app.Image5.Visible='on';
            Module_AnalysisType_App(app)
        end

        % Button pushed function: GMButton
        function GMButton_Callback(app, event)
            app.Image5.Visible='on';
            Module_GroundMotion_App(app)
        end

        % Button pushed function: HelpButton
        function HelpButton_Callback(app, event)
            global MainDirectory
            cd (MainDirectory)
            filex='IIDAP Manual.pdf';
            
            if ispc
                winopen(filex)
            end
            
            if ismac
                system(['open ' filex ]);
                web('https://www.youtube.com/playlist?list=PLz_XdUL-6Y_lqvBanzTFuFNB9K3xDzXAi','-browser')
            end
        end

        % Button pushed function: ModelButton
        function ModelButton_Callback(app, event)
            app.Image5.Visible='on';
            Module_SystemModel_App(app)
        end

        % Button pushed function: OpenButton
        function OpenButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            
            % Get current directory, the user-specified project name, and selected Units
            MainDirectory=pwd;
            [ProjectName,ProjectPath] = uigetfile('*.mat','Select the Project .mat file');
            
            cd(MainDirectory)
            
            % Get current directory and user-specified project name
            if exist(ProjectPath) ==0
                d=errordlg('Project does not exist in current directory!','Error','modal');
                waitfor(d);
            else
                cd (ProjectPath)
                save(ProjectName,'MainDirectory','ProjectPath','ProjectName','-append');
                load(ProjectName,'SDoFstatus','PDeltastatus','T','SystemModelstatus','SystemModel_Option','GMstatus','GM_Option','Wave_name','AnalysisTypestatus','Analysis_Option','Runstatus');
                cd (MainDirectory)
                app.SDoFButton.Enable='on';
            
                if SDoFstatus==1
                    app.status1b.BackgroundColor='g';
                    app.SDoFButton.Enable='on';
                    app.ModelButton.Enable='on';
                    if PDeltastatus~=1
                        app.status1.BackgroundColor='g';app.status1.FontColor='k';app.status1.Text=['SDoF: T=',num2str(round(T*100)/100),' sec (no PDelta)'];
                    else
                        app.status1.BackgroundColor='g';app.status1.FontColor='k';app.status1.Text=['SDoF: T=',num2str(round(T*100)/100),' sec (with PDelta)'];
                    end
                end
                if SystemModelstatus==1
                    if SystemModel_Option==1
                        app.status2.BackgroundColor='g';app.status2.FontColor='k';app.status2.Text='System Model: Linear';
                    elseif SystemModel_Option==2
                        app.status2.BackgroundColor='g';app.status2.FontColor='k';app.status2.Text='System Model: Bi-Linear';
                    elseif SystemModel_Option==3
                        app.status2.BackgroundColor='g';app.status2.FontColor='k';app.status2.Text='System Model: IMK Bilinear';
                    elseif SystemModel_Option==4
                        app.status2.BackgroundColor='g';app.status2.FontColor='k';app.status2.Text='System Model: IMK Pinching';
                    elseif SystemModel_Option==5
                        app.status2.BackgroundColor='g';app.status2.FontColor='k';app.status2.Text='System Model: IMK P-Oriented';
                    elseif SystemModel_Option==6
                        app.status2.BackgroundColor='g';app.status2.FontColor='k';app.status2.Text='System Model: Self-Centering';
                    end
                    app.status2b.BackgroundColor='g';
                    app.GMButton.Enable='on';
                end
                if GMstatus==1
                    if GM_Option==1
                        app.status3.BackgroundColor='g';app.status3.FontColor='k';app.status3.Text='Ground Motion: Single GM';
                    elseif GM_Option==2
                        app.status3.BackgroundColor='g';app.status3.FontColor='k';app.status3.Text='Ground Motion: Multiple GMs';
                    elseif GM_Option==3
                        app.status3.BackgroundColor='g';app.status3.FontColor='k';app.status3.Text=['Ground Motion: ',Wave_name];
                    end
                    app.status3b.BackgroundColor='g';
                    app.AnalysisButton.Enable='on';
                    app.PlotGMButton.Enable='on';
                end
                if AnalysisTypestatus==1
                    if Analysis_Option==1
                        app.status4.BackgroundColor='g';app.status4.FontColor='k';app.status4.Text='Analysis Type: RHA';
                    elseif Analysis_Option==2
                        app.status4.BackgroundColor='g';app.status4.FontColor='k';app.status4.Text='Analysis Type: IDA';
                    elseif Analysis_Option==3
                        app.status4.BackgroundColor='g';app.status4.FontColor='k';app.status4.Text='Analysis Type: RSA';
                    elseif Analysis_Option==4
                        app.status4.BackgroundColor='g';app.status4.FontColor='k';app.status4.Text='Analysis Type: CSA';
                    end
                    app.status4b.BackgroundColor='g';
                    app.RunButton.Enable='on';
                end
                if Runstatus==1
                    app.ResultsButton.Enable='on';
                    app.SaveButton.Enable='on';
                end
                DeleteResults=0;
                cd (ProjectPath)
                save(ProjectName,'DeleteResults','-append')
                cd (MainDirectory)
            end
            app.SaveAsButton.Enable='on';
            app.SummaryButton.Enable='on';
            app.ProgressText.Value='COPYRIGHT: A. ELKADY and D. LIGNOS (2018)';
            app.ProgressText.FontColor='g';
            xx=ProjectName;
            xx=xx(1:end-4);
            app.text27.Text=['Project: ',xx];
        end

        % Button pushed function: PlotGMButton
        function PlotGMButton_Callback(app, event)
            Module_GroundMotion_Plot_App
        end

        % Button pushed function: ResultsButton
        function ResultsButton_Callback(app, event)
            app.Image5.Visible='on';
            
            global MainDirectory ProjectPath ProjectName
            cd (ProjectPath)
            load(ProjectName,'Analysis_Option')
            cd (MainDirectory)
            
            if Analysis_Option==1
                Module_Results_RHA_Options_App(app)
            elseif Analysis_Option==2
                Module_Results_IDA_Options_App(app)
            elseif Analysis_Option==3
                Module_Results_RSA_Options_App(app)
            elseif Analysis_Option==4
                Module_Results_CSA_Options_App(app)
            end
        end

        % Button pushed function: RunButton
        function RunButton_Callback(app, event)
            app.Image5.Visible='on';
            global MainDirectory ProjectPath ProjectName
            
            fclose all;
            
            waitfor(IntegratorMessage_App(app));
            
            try
                tic;
                if app.RunButton.UserData==1
                    cd (ProjectPath)
                    load(ProjectName,'PDeltastatus','Backbone','BackboneNoPD','BackbonePD','Analysis_Option','Runstatus','Quick_RSA') ;
                    cd (MainDirectory)
                    clc
            
                    Runstatus=0;
            
                    clear Backbone
                    if PDeltastatus==1
                        Backbone=BackbonePD;
                    else
                        Backbone=BackboneNoPD;
                    end
                    cd (ProjectPath)
                    save(ProjectName,'Backbone','-append');
                    pause(0.2)
                    cd (MainDirectory)
            
                    if Analysis_Option==1
                        Run_RHA_App(app)
                        Runstatus=1;
                    end
                    if Analysis_Option==2
                        Run_IDA_App(app)
                        Runstatus=1;
                    end
                    if Analysis_Option==3
                        if Quick_RSA==1
                            Run_RSA_EL_AAonly_App(app)
                        else
                            Run_RSA_App(app)
                        end
                        Runstatus=1;
                    end
                    if Analysis_Option==4
                        Run_CSA_App(app)
                        Runstatus=1;
                    end
            
                    if Runstatus==1
                        app.ResultsButton.Enable='on';
                        app.SaveButton.Enable='on';
                    end
            
                    cd (ProjectPath)
                    save(ProjectName,'Runstatus','-append')
                    pause(0.5);
                    cd (MainDirectory)
            
                    % Progress Bar
                    app.ProgressText.Value=['ANALYSIS DONE - Elapsed Time: ', num2str(round(toc*10/60)/10),' minutes'];
                    app.ProgressText.FontColor='g';
                    app.axes10.Position=[5 7 382 31];
                    app.axes10.BackgroundColor='g';
                    drawnow
                    app.Image5.Visible='off';
                else
            
                end
            
            catch ME
               ErrorMessage_App
               app.ProgressText.Value='Oops, problem! - Error file was created';
               app.ProgressText.FontColor='r';
               cd (ProjectPath)
               fid = fopen('Error.txt', 'wt');
               fprintf(fid, '%s\n', ME.identifier);
               fprintf(fid, '%s\n', ME.message);
               fprintf(fid, '%s\n', ME.stack.name);
               fprintf(fid, '%d\n', ME.stack.line);
               cd (MainDirectory)
               fclose(fid);
               app.Image5.Visible='off';
            end
        end

        % Button pushed function: SDoFButton
        function SDoFButton_Callback(app, event)
            app.Image5.Visible='on';
            Module_SDoF_App(app)
        end

        % Button pushed function: SaveAsButton
        function SaveAsButton_Callback(app, event)
            app.Image5.Visible='on';
            global MainDirectory ProjectPath ProjectName MainDirectory2 ProjectPath2 ProjectName2
            clc;
            
            cd (ProjectPath)
            load (ProjectName)
            cd (MainDirectory)
            
            clear MainDirectory2 ProjectPath2 ProjectName2
            
            MainDirectory2=pwd;
            % Get current directory, the user-specified project name, and selected Units
            [ProjectName2, ProjectPath2] = uiputfile('*.mat','Save current project as');
            
            cd (ProjectPath2)
            save(ProjectName2, '-regexp', '^(?!(event|app)$).')
            cd (MainDirectory2)
            
            MainDirectory=MainDirectory2;
            ProjectName=ProjectName2;
            ProjectPath=ProjectPath2;
            
            cd (ProjectPath)
            save(ProjectName,'MainDirectory','ProjectName','ProjectPath','-append')
            cd (MainDirectory)
            
            xx=ProjectName;
            xx=xx(1:end-4);
            app.text27.Text=['Project: ',xx];
            app.Image5.Visible='off';
        end

        % Button pushed function: SaveButton
        function SaveButton_Callback(app, event)
            app.Image5.Visible='on';
            Save_Results_App(app)
            app.Image5.Visible='off';
        end

        % Button pushed function: StartButton
        function StartButton_Callback(app, event)
            app.Image5.Visible='on';
            global MainDirectory ProjectName ProjectPath
            clc;
            
            % Get current directory and user-specified project name
            MainDirectory=pwd;
            [ProjectName, ProjectPath] = uiputfile('*.mat','Select the workspace direcotry and specify project name');
            
            if isempty(ProjectName) || ischar(ProjectName)==0
                return
            else
                app.Image5.Visible='on';
                waitfor(UnitMessage_App);
                app.Image5.Visible='off';
                % Create porject directory and save the initializing project data using ProjectData.mat file
                SDoFstatus=0;
                SystemModelstatus=0;
                GMstatus=0;
                AnalysisTypestatus=0;
                Runstatus=0;
                PDeltastatus=0;
                Hazardstatus=0;
                PDeltastatus=0;
                Integratorstatus=0;
                Integrator_Option=2;
                gamma=0.50;
                beta=0.25;
                AddTime=0;
                SaAVG_status=0;
                DeleteResults=0;
                cd (ProjectPath)
                save(ProjectName,'SaAVG_status','SDoFstatus','GMstatus','PDeltastatus','Hazardstatus','AnalysisTypestatus','SystemModelstatus','Runstatus','DeleteResults','AddTime','Integratorstatus','Integrator_Option','gamma','beta','-append')
                pause(0.5)
                cd (MainDirectory)
            end
            
            xx=ProjectName;
            xx=xx(1:end-4);
            app.text27.Text=['Project: ',xx];
            
            app.SaveAsButton.Enable='on';
            app.SummaryButton.Enable='on';
            app.ModelButton.Enable='off';
            app.GMButton.Enable='off';
            app.AnalysisButton.Enable='off';
            app.RunButton.Enable='off';
            app.ResultsButton.Enable='off';
            app.SaveButton.Enable='off';
            app.PlotGMButton.Enable='off';
            
            app.status2.BackgroundColor=[0.7 0.7 0.7];
            app.status3.BackgroundColor=[0.7 0.7 0.7];
            app.status4.BackgroundColor=[0.7 0.7 0.7];
            
            app.status2b.BackgroundColor=[0.7 0.7 0.7];
            app.status3b.BackgroundColor=[0.7 0.7 0.7];
            app.status4b.BackgroundColor=[0.7 0.7 0.7];
            
            app.SDoFButton.Enable='on';
            app.status1.BackgroundColor='r';
            app.status1b.BackgroundColor='r';
            app.ProgressText.Value='DEFINE THE 4 MAIN COMPONENTS OF THE PROJECT';
            app.ProgressText.FontColor='g';
            
            app.Image5.Visible='off';
        end

        % Button pushed function: SummaryButton
        function SummaryButton_Callback(app, event)
            Show_Summary(app)
        end

        % Close request function: figure0
        function figure0_CloseRequestFcn(app, event)
            app.ProgressText.Value='EXITING - PLEASE WAIT';
            app.ProgressText.FontColor='g';
            drawnow
            close all
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            IIDAP_OpeningFcn(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure0 = uifigure('Visible', 'off');
            app.figure0.Name = 'IIDAP';
            app.figure0.CloseRequestFcn = createCallbackFcn(app, @figure0_CloseRequestFcn, true);
            app.axes10 = uiaxes(app.figure0);
            app.axes10.XAxisLocation = 'top';
            app.axes10.XTick = [];
            app.axes10.YTick = [];
            app.axes10.ZTick = [];
            app.DefinePanel = uipanel(app.figure0);
            app.status3b = uilabel(app.DefinePanel);
            app.status3b.Text = '';
            app.status2b = uilabel(app.DefinePanel);
            app.status2b.Text = '';
            app.status1b = uilabel(app.DefinePanel);
            app.status1b.Text = '';
            app.SDoFButton = uibutton(app.DefinePanel, 'push');
            app.SDoFButton.ButtonPushedFcn = createCallbackFcn(app, @SDoFButton_Callback, true);
            app.SDoFButton.Text = 'SDoF';
            app.ModelButton = uibutton(app.DefinePanel, 'push');
            app.ModelButton.ButtonPushedFcn = createCallbackFcn(app, @ModelButton_Callback, true);
            app.ModelButton.Text = 'System Model';
            app.GMButton = uibutton(app.DefinePanel, 'push');
            app.GMButton.ButtonPushedFcn = createCallbackFcn(app, @GMButton_Callback, true);
            app.GMButton.Text = 'Ground Motion';
            app.status1 = uilabel(app.DefinePanel);
            app.status1.Text = 'SDoF is not defined';
            app.status2 = uilabel(app.DefinePanel);
            app.status2.Text = 'System Model is not defined';
            app.status3 = uilabel(app.DefinePanel);
            app.status3.Text = 'Ground Motion is not defined';
            app.status4b = uilabel(app.DefinePanel);
            app.status4b.Text = '';
            app.AnalysisButton = uibutton(app.DefinePanel, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButton_Callback, true);
            app.AnalysisButton.Text = 'Analysis Type';
            app.status4 = uilabel(app.DefinePanel);
            app.status4.Text = 'Analysis Type is not defined';
            app.Image1 = uiimage(app.DefinePanel);
            app.Image1.ImageSource = 'SDoFaxes1figure.png';
            app.Image2 = uiimage(app.DefinePanel);
            app.Image2.ImageSource = 'SMaxes2figure.png';
            app.Image3 = uiimage(app.DefinePanel);
            app.Image3.ImageSource = 'GMaxes3figure.png';
            app.Image4 = uiimage(app.DefinePanel);
            app.Image4.ImageSource = 'Analysisaxes9figure.png';
            app.PlotGMButton = uibutton(app.DefinePanel, 'push');
            app.PlotGMButton.ButtonPushedFcn = createCallbackFcn(app, @PlotGMButton_Callback, true);
            app.PlotGMButton.Tooltip = 'Inspect the defined ground motion(s)';
            app.PlotGMButton.Text = 'scope';
            app.ProgressText = uieditfield(app.figure0, 'text');
            app.ProgressText.Value = 'COPYRIGHT: A. ELKADY and D. LIGNOS - 2018';
            app.uipanel5 = uipanel(app.figure0);
            app.RunButton = uibutton(app.uipanel5, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButton_Callback, true);
            app.RunButton.Text = 'Run Analysis';
            app.ResultsButton = uibutton(app.uipanel5, 'push');
            app.ResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsButton_Callback, true);
            app.ResultsButton.Text = 'View Results';
            app.SaveButton = uibutton(app.uipanel5, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButton_Callback, true);
            app.SaveButton.Text = 'Save Results';
            app.Image_5 = uiimage(app.uipanel5);
            app.Image_5.ImageSource = 'Run.png';
            app.Image_6 = uiimage(app.uipanel5);
            app.Image_6.ImageSource = 'View.png';
            app.Image_7 = uiimage(app.uipanel5);
            app.Image_7.ImageSource = 'Save.png';
            app.uipanel7 = uipanel(app.figure0);
            app.StartButton = uibutton(app.uipanel7, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButton_Callback, true);
            app.StartButton.Tooltip = 'Start a new project';
            app.StartButton.Text = 'New';
            app.OpenButton = uibutton(app.uipanel7, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButton_Callback, true);
            app.OpenButton.Tooltip = 'Open an existing project';
            app.OpenButton.Text = 'Open';
            app.SummaryButton = uibutton(app.uipanel7, 'push');
            app.SummaryButton.ButtonPushedFcn = createCallbackFcn(app, @SummaryButton_Callback, true);
            app.SummaryButton.Tooltip = {'Show summary of the defined project parameters'};
            app.SummaryButton.Text = 'SCOPE';
            app.text27 = uilabel(app.uipanel7);
            app.text27.Text = 'Current Project: --';
            app.SaveAsButton = uibutton(app.uipanel7, 'push');
            app.SaveAsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAsButton_Callback, true);
            app.SaveAsButton.Tooltip = 'Save current project under a different name';
            app.SaveAsButton.Text = 'Save as';
            app.Image0 = uiimage(app.figure0);
            app.Image0.ImageSource = 'Banner.png';
            app.text25 = uilabel(app.figure0);
            app.text25.Text = 'v1.3.0';
            app.HelpButton = uibutton(app.figure0, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButton_Callback, true);
            app.HelpButton.Tooltip = {'Click to open manual'};
            app.HelpButton.Text = 'help';
            app.AboutButton = uibutton(app.figure0, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButton_Callback, true);
            app.AboutButton.Tooltip = {'Abour IIDAP'};
            app.AboutButton.Text = 'about';
            app.Image5 = uiimage(app.figure0);
            app.Image5.Enable = 'off';
            app.Image5.ImageSource = 'Transparent.png';
            app.Image5_2 = uiimage(app.figure0);
            app.Image5_2.Enable = 'off';
            app.Image5_2.ImageSource = 'Transparent.png';
            app.ResetButton = uibutton(app.figure0, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Tooltip = {'Click to reset'};
            app.ResetButton.Text = 'R';
            % Show the figure after all components are created
            app.figure0.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = IIDAP_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure0)

            % Execute the startup function
            runStartupFcn(app, @IIDAP_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure0)
        end
    end
end