classdef Module_AnalysisType_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel1        matlab.ui.container.Panel
        SubmitButton    matlab.ui.control.Button
        ButtonGroup     matlab.ui.container.ButtonGroup
        RHAradiobutton  matlab.ui.control.RadioButton
        IDAradiobutton  matlab.ui.control.RadioButton
        RSAradiobutton  matlab.ui.control.RadioButton
        CSAradiobutton  matlab.ui.control.RadioButton
        text4           matlab.ui.control.Label
        text15          matlab.ui.control.Label
        text14          matlab.ui.control.Label
        text20          matlab.ui.control.Label
        text17          matlab.ui.control.Label
        text16          matlab.ui.control.Label
        text18          matlab.ui.control.Label
        text19          matlab.ui.control.Label
        text7           matlab.ui.control.Label
        Image           matlab.ui.control.Image
        Image_2         matlab.ui.control.Image
        Image_3         matlab.ui.control.Image
        Image_4         matlab.ui.control.Image
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_AnalysisType_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            Pos_Main=app.MainApp.figure0.Position;
            Pos_Secd=app.figure1.Position;
            app.figure1.Position=[Pos_Main(1) Pos_Main(2) Pos_Secd(3) Pos_Secd(4)];
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'AnalysisTypestatus','Analysis_Option')
            cd (MainDirectory)
            
            if AnalysisTypestatus==1
                if Analysis_Option==1
                    app.RHAradiobutton.Value=1;
                elseif Analysis_Option==2
                    app.IDAradiobutton.Value=1;
                elseif Analysis_Option==3
                    app.RSAradiobutton.Value=1;
                elseif Analysis_Option==4
                    app.CSAradiobutton.Value=1;
                end
            else
                app.RHAradiobutton.Value=1;
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            app.MainApp.Image5.Visible='on';
            if app.RHAradiobutton.Value==1
                    AnalysisType='RHA';
                    Module_AnalysisType_RHA_App(app.MainApp)
                elseif app.IDAradiobutton.Value==1
                    AnalysisType='IDA';
                    Module_AnalysisType_IDA_App(app.MainApp)
                elseif app.RSAradiobutton.Value==1
                    AnalysisType='RSA';
                    Module_AnalysisType_RSA_App(app.MainApp)
                elseif app.CSAradiobutton.Value==1
                    AnalysisType='CSA';
                    Module_AnalysisType_CSA_App(app.MainApp)
            end
            
            closereq
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Analysis Type Options';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Choose Analysis Type';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Next';
            app.ButtonGroup = uibuttongroup(app.uipanel1);
            app.RHAradiobutton = uiradiobutton(app.ButtonGroup);
            app.RHAradiobutton.Text = 'Response History Analysis (RHA)';
            app.RHAradiobutton.Value = true;
            app.IDAradiobutton = uiradiobutton(app.ButtonGroup);
            app.IDAradiobutton.Text = 'Incremental Dynamic Analysis (IDA)';
            app.RSAradiobutton = uiradiobutton(app.ButtonGroup);
            app.RSAradiobutton.Text = 'Response Spectrum Analysis (RSA)';
            app.CSAradiobutton = uiradiobutton(app.ButtonGroup);
            app.CSAradiobutton.Enable = 'off';
            app.CSAradiobutton.Text = 'Collapse Spectrum Analysis (CSA)';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Run dynamic response-history analysis';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Text = 'Run response-history analysis for a set';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Text = 'of ground motions through collapse. Get';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Text = 'the mean annual frequency of collapse';
            app.text17 = uilabel(app.uipanel1);
            app.text17.Text = 'Get the response-spectrum analysis for a';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Text = 'range of periods at a single scale factor';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Enable = 'off';
            app.text18.Text = 'and the mean annual frequency of collapse';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Enable = 'off';
            app.text19.Text = 'Get the collapse capacity spectrum ';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Text = 'at a single GM scale factor';
            app.Image = uiimage(app.uipanel1);
            app.Image.ImageSource = 'Dynamic.png';
            app.Image_2 = uiimage(app.uipanel1);
            app.Image_2.ImageSource = 'IDA.png';
            app.Image_3 = uiimage(app.uipanel1);
            app.Image_3.ImageSource = 'Spectrum.png';
            app.Image_4 = uiimage(app.uipanel1);
            app.Image_4.ImageSource = 'CHA.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_AnalysisType_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_AnalysisType_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end