classdef Module_AnalysisType_CSA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel1        matlab.ui.container.Panel
        uipanel2        matlab.ui.container.Panel
        text12          matlab.ui.control.Label
        text13          matlab.ui.control.Label
        text14          matlab.ui.control.Label
        text33          matlab.ui.control.Label
        RSAoptionpopup  matlab.ui.control.DropDown
        edit8           matlab.ui.control.NumericEditField
        edit9           matlab.ui.control.NumericEditField
        edit10          matlab.ui.control.NumericEditField
        SubmitButton    matlab.ui.control.Button
        uipanel3        matlab.ui.container.Panel
        text19          matlab.ui.control.Label
        text20          matlab.ui.control.Label
        text22          matlab.ui.control.Label
        edit1           matlab.ui.control.NumericEditField
        edit3           matlab.ui.control.NumericEditField
        edit4           matlab.ui.control.NumericEditField
        uipanel6        matlab.ui.container.Panel
        checkbox1       matlab.ui.control.CheckBox
        text23          matlab.ui.control.Label
        text26          matlab.ui.control.Label
        text28          matlab.ui.control.Label
        edit30          matlab.ui.control.NumericEditField
        edit31          matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp % Description
        TipWindow
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_AnalysisType_CSA_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
                        
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'AnalysisTypestatus','Analysis_Option','To','Tincr','Tend','RSA_Option','Units','SystemModel_Option','delta_SystemFail','delta_SystemFail_pos','delta_SystemFail_neg','Sa_incr','Ulimit','Alimit','SaAVG_status','SaAVG_T1','SaAVG_T2','T')
            cd (MainDirectory)
            
            if Units==1
                app.text20.Text='Displacement limit [m]';
            else
                app.text20.Text='Displacement limit [in]';
            end
            
            if AnalysisTypestatus==1 && Analysis_Option==4
                app.edit8.Value=To;
                app.edit9.Value=Tincr;
                app.edit10.Value=Tend;
            
                app.edit1.Value=Sa_incr;
                app.edit3.Value=Ulimit;
                app.edit4.Value=Alimit;
            
                if RSA_Option==1
                    app.RSAoptionpopup.Value='1';
                elseif RSA_Option==2
                    app.RSAoptionpopup.Value='2';
                elseif RSA_Option==3
                    app.RSAoptionpopup.Value='3';
                elseif RSA_Option==4
                    app.RSAoptionpopup.Value='4';
                end
            else
                if SystemModel_Option==1 || SystemModel_Option==2
                    app.edit3.Value=delta_SystemFail;
                else
                    app.edit3.Value=min(delta_SystemFail_pos,delta_SystemFail_neg);
                end
            end
            
            if SaAVG_status==0
                app.checkbox1.Value=0;
                checkbox1_Callback(app)
            else
                app.checkbox1.Value=1;
                app.edit30.Enable='on';
                app.edit31.Enable='on';
                app.edit30.Value=SaAVG_T1/T;
                app.edit31.Value=SaAVG_T2/T;
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc
            cd (ProjectPath)
            load(ProjectName,'T','nGM')
            cd (MainDirectory)
            
            Sa_tol=0.01;
            Sa_incr=app.edit1.Value;
            Ulimit=app.edit3.Value;
            Alimit=app.edit4.Value;
            
            if app.checkbox1.Value==1
                SaAVG_status=1;
                SaAVG_cT1=app.edit30.Value;
                SaAVG_cT2=app.edit31.Value;
                SaAVG_T1=SaAVG_cT1*T;
                SaAVG_T2=SaAVG_cT2*T;
            else
                SaAVG_status=0;
                SaAVG_T1=1;
                SaAVG_T2=1;
                SaAVG_cT1=1;
                SaAVG_cT2=1;
            end
            
            To=app.edit8.Value;
            Tincr=app.edit9.Value;
            Tend=app.edit10.Value;
            
            %% INPUT VALUES CHECKS BEFORE SUBMISSION
            if isnan(Sa_incr) || isnan(Sa_tol) || isnan(Ulimit) || isnan(Alimit) || isnan(To) || isnan(Tincr) || isnan(Tend)
                ed = errordlg('Some paramters are not defined. Please define all the required paramters.','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            if Sa_incr<0 || Alimit<0
                ed = errordlg('IDA increment size, Sa tolerance, and Acceleration limit must be POSITIVE VALUES','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            if Sa_incr<0.01
                ed = errordlg('IDA increment size is too small. A step size larger than 0.05g in recommended','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            if To<0 || Tincr<0 || Tend<0
                ed = errordlg('All period values must be POSITIVE VALUES','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            if To>Tend
                ed = errordlg('End Period must be LARGER THAN the Start Period','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            if app.checkbox1.Value==1
            if SaAVG_T2<SaAVG_T1
                ed = errordlg('Period Range Error: Second period should be larger than the First Period!','Error', 'modal');
                uiwait(ed);
                return;
            end
            end
            
            %%
            
            if app.RSAoptionpopup.Value=='1'
                RSA_Option=1;
            elseif app.RSAoptionpopup.Value=='2'
                RSA_Option=2;
            elseif app.RSAoptionpopup.Value=='3'
                RSA_Option=3;
            elseif app.RSAoptionpopup.Value=='4'
                RSA_Option=4;
            end
            
            AnalysisTypestatus=1;
            Analysis_Option=4;
                        
            str{1}='Saving your IDA paramters.';
            str{2}='Please wait...';
            str{3}='This may take ~30 sec!';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
            drawnow
            
            cd (ProjectPath)
            save (ProjectName,'AnalysisTypestatus','Analysis_Option','RSA_Option','To','Tincr','Tend','Sa_incr','Sa_tol','Ulimit','Alimit','SaAVG_status','SaAVG_T1','SaAVG_T2','SaAVG_cT1','SaAVG_cT2','-append')
            pause(0.5)
            cd (MainDirectory)
            
            delete(app.TipWindow)

            if nGM>=5
                waitfor(HazardMessage_App(app.MainApp));
            end
            
            app.MainApp.status4.BackgroundColor='g';
            app.MainApp.status4.FontColor='k';
            app.MainApp.status4.Text='Analysis Type: CSA';
            app.MainApp.status4b.BackgroundColor='g';
            app.MainApp.RunButton.Enable='on';
            app.MainApp.ResultsButton.Enable='off';
            app.MainApp.SaveButton.Enable='off';
            app.MainApp.ProgressText.Value='READY TO RUN ANALYSIS';
            app.MainApp.ProgressText.FontColor='g';
            
            app.MainApp.Image5.Visible='off';
            closereq
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'T','SaAVG_status')
            cd (MainDirectory)
            
            if app.checkbox1.Value==1 && SaAVG_status~=1
                app.edit30.Enable='on';
                app.edit31.Enable='on';
                app.edit30.Value=1;
                % assign values for period range based on recommendations by Adam & Jager (2012)
                if T<=0.25
                    app.edit31.Value=T/T;
                elseif T>0.25 && T <=0.5
                    app.edit31.Value=(T+0.2)/T;
                elseif T>0.5 && T <=0.7
                    app.edit31.Value=(T+0.4)/T;
                elseif T>0.7 && T <=1.5
                    app.edit31.Value=(T+0.8)/T;
                elseif T>1.5
                    app.edit31.Value=(T+1.2)/T;
                end
            elseif app.checkbox1.Value==1 && SaAVG_status==1
                app.edit30.Enable='on';
                app.edit31.Enable='on';
            else
                app.edit30.Enable='off';
                app.edit31.Enable='off';
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Analysis Type: CSA';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = 'RSA Period Range';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Text = 'Start period [sec]';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Text = 'Increment period [sec]';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Text = 'End period [sec]';
            app.text33 = uilabel(app.uipanel2);
            app.text33.Text = 'Analysis method';
            app.RSAoptionpopup = uidropdown(app.uipanel2);
            app.RSAoptionpopup.Items = {'Vary Mass', 'Vary Stiffness but maintain Fy', 'Vary Stiffness but maintain dy'};
            app.RSAoptionpopup.ItemsData = {'1', '2', '3'};
            app.RSAoptionpopup.Value = '1';
            app.edit8 = uieditfield(app.uipanel2, 'numeric');
            app.edit8.Limits = [0 Inf];
            app.edit8.ValueDisplayFormat = '%.3f';
            app.edit8.Value = 0.1;
            app.edit9 = uieditfield(app.uipanel2, 'numeric');
            app.edit9.Limits = [0 Inf];
            app.edit9.ValueDisplayFormat = '%.3f';
            app.edit9.Value = 0.1;
            app.edit10 = uieditfield(app.uipanel2, 'numeric');
            app.edit10.Limits = [0 Inf];
            app.edit10.ValueDisplayFormat = '%.3f';
            app.edit10.Value = 3;
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.uipanel3 = uipanel(app.uipanel1);
            app.uipanel3.Title = 'IDA Parameters';
            app.text19 = uilabel(app.uipanel3);
            app.text19.Text = 'Incremental step [g]';
            app.text20 = uilabel(app.uipanel3);
            app.text20.Tooltip = 'Displacement limit at which collapse occurs';
            app.text20.Text = 'Displacement limit [m]';
            app.text22 = uilabel(app.uipanel3);
            app.text22.Tooltip = 'Acceleration limit at which collapse occurs';
            app.text22.Text = 'Acceleration limit [g]';
            app.edit1 = uieditfield(app.uipanel3, 'numeric');
            app.edit1.Limits = [0 Inf];
            app.edit1.ValueDisplayFormat = '%.3f';
            app.edit1.Value = 0.1;
            app.edit3 = uieditfield(app.uipanel3, 'numeric');
            app.edit3.Limits = [0 Inf];
            app.edit3.ValueDisplayFormat = '%.3f';
            app.edit3.Value = 1;
            app.edit4 = uieditfield(app.uipanel3, 'numeric');
            app.edit4.Limits = [0 Inf];
            app.edit4.ValueDisplayFormat = '%.3f';
            app.edit4.Value = 5;
            app.uipanel6 = uipanel(app.uipanel1);
            app.checkbox1 = uicheckbox(app.uipanel6);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'Optional: IM = Sa,ᴀᴠɢ';
            app.text23 = uilabel(app.uipanel6);
            app.text23.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text23.Text = 'Range:';
            app.text26 = uilabel(app.uipanel6);
            app.text26.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text26.Text = 'to';
            app.text28 = uilabel(app.uipanel6);
            app.text28.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text28.Text = 'T';
            app.edit30 = uieditfield(app.uipanel6, 'numeric');
            app.edit30.Limits = [0 Inf];
            app.edit30.ValueDisplayFormat = '%.1f';
            app.edit30.Value = 0.1;
            app.edit31 = uieditfield(app.uipanel6, 'numeric');
            app.edit31.Limits = [0 Inf];
            app.edit31.ValueDisplayFormat = '%.1f';
            app.edit31.Value = 3;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_AnalysisType_CSA_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_AnalysisType_CSA_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end