classdef Module_AnalysisType_IDA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        SubmitButton  matlab.ui.control.Button
        uipanel2      matlab.ui.container.Panel
        text12        matlab.ui.control.Label
        text13        matlab.ui.control.Label
        text20        matlab.ui.control.Label
        edit1         matlab.ui.control.NumericEditField
        edit3         matlab.ui.control.NumericEditField
        edit4         matlab.ui.control.NumericEditField
        uipanel4      matlab.ui.container.Panel
        text23        matlab.ui.control.Label
        text26        matlab.ui.control.Label
        text28        matlab.ui.control.Label
        checkbox1     matlab.ui.control.CheckBox
        edit16        matlab.ui.control.NumericEditField
        edit17        matlab.ui.control.NumericEditField
        text21        matlab.ui.control.Label
        edit18        matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp % Description
        TipWindow
    end
    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_AnalysisType_IDA_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Units','AnalysisTypestatus','Analysis_Option','SystemModel_Option','delta_SystemFail','delta_SystemFail_pos','delta_SystemFail_neg','Sa_incr','Ulimit','Alimit','T','SaAVG_status','SaAVG_T1','SaAVG_T2')
            cd (MainDirectory)
            
            if Units==1
                app.text13.Text='Displacement limit [m]';
            else
                app.text13.Text='Displacement limit [in]';
            end
            
            if AnalysisTypestatus==1 && Analysis_Option==2
                app.edit1.Value=Sa_incr;
                app.edit3.Value=Ulimit;
                app.edit4.Value=Alimit;
            
            else
                if SystemModel_Option==1 || SystemModel_Option==2
                    app.edit3.Value=delta_SystemFail;
                else
                    app.edit3.Value=min(delta_SystemFail_pos,delta_SystemFail_neg);
                end
            end
            
            if SaAVG_status==0
                app.checkbox1.Value=0;
                checkbox1_Callback(app)
            else
                app.checkbox1.Value=1;
                app.edit16.Enable='on';
                app.edit17.Enable='on';
                app.edit16.Value=SaAVG_T1/T;
                app.edit17.Value=SaAVG_T2/T;
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Runstatus','Analysis_Option')
            cd (MainDirectory)
            
            if Runstatus==1
                waitfor(DeleteResultsMessage_App);
            end
            
            cd (ProjectPath)
            load(ProjectName,'DeleteResults')
            cd (MainDirectory)
            
            if Runstatus==1 && DeleteResults==0
                return;
            elseif Runstatus==1 && DeleteResults==1
                if Analysis_Option==1
                    rmvar(ProjectPath,ProjectName, 'RESULTS')
                elseif Analysis_Option==2
                    rmvar(ProjectPath,ProjectName, 'RESULTS','IDA')
                elseif Analysis_Option==3
                    rmvar(ProjectPath,ProjectName, 'RESULTS','RSA')
                elseif Analysis_Option==4
            
                end
                Runstatus=0;
                DeleteResults=1;
            
                cd (ProjectPath)
                save(ProjectName,'Runstatus','DeleteResults','-append')
                cd (MainDirectory)
            
                app.MainApp.ResultsButton.Enable='off';
                app.MainApp.SaveButton.Enable='off';
            end
            
            cd (ProjectPath)
            load(ProjectName,'AnalysisTypestatus','Analysis_Option','nGM','T')
            pause(0.5)
            cd (MainDirectory)
            
            Sa_incr=app.edit1.Value;
            Sa_tol=0.01;
            Ulimit=app.edit3.Value;
            Alimit=app.edit4.Value;
            
            if app.checkbox1.Value==1
                SaAVG_status=1;
                SaAVG_cT1=app.edit16.Value;
                SaAVG_cT2=app.edit17.Value;
                SaAVG_T1=SaAVG_cT1*T;
                SaAVG_T2=SaAVG_cT2*T;
                SaAVG_sample=app.edit18.Value;
            else
                SaAVG_status=0;
                SaAVG_T1=1;
                SaAVG_T2=1;
                SaAVG_cT1=1;
                SaAVG_cT2=1;
                SaAVG_sample=1;
            end
            
            %% INPUT VALUES CHECKS BEFORE SUBMISSION
            if isnan(Sa_incr) || isnan(Ulimit) || isnan(Alimit)
                ed = errordlg('Some paramters are not defined. Please define all the required paramters.','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            if Sa_incr<0 || Alimit<0
                ed = errordlg('IDA increment size, Sa tolerance, and Acceleration limit must be POSITIVE VALUES','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            if Sa_incr<=0.01
                ed = errordlg('IDA increment size is too small. A step size larger than the default 0.01g tolerenace in recommended','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            if app.checkbox1.Value==1
            if SaAVG_T2<SaAVG_T1
                ed = errordlg('Period Range Error: Second period should be larger than the First Period!','Error', 'modal');
                waitfor(ed);
                return;
            end
            end
            %%
            
            AnalysisTypestatus=1;
            Analysis_Option=2;
            
            str{1}='Saving your IDA paramters.';
            str{2}='Please wait...';
            str{3}='This may take ~30 sec!';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;%text1 is the value tag for the Static Text in GUI1
            drawnow
            
            cd (ProjectPath)
            pause(1.0)
            save (ProjectName,'AnalysisTypestatus','Analysis_Option','Sa_incr','Sa_tol','Ulimit','Alimit','SaAVG_status','SaAVG_cT1','SaAVG_cT2','SaAVG_T1','SaAVG_T2','SaAVG_sample','-append')
            pause(1.0)
            cd (MainDirectory)
            
            delete(app.TipWindow)
            
            pause(0.5)
            if nGM>=5
                waitfor(HazardMessage_App(app.MainApp));
            end
            
            %a=findall(groot, 'Name', 'IIDAP'); %myguide1 is the file name for guide1
            app.MainApp.status4.BackgroundColor='g';
            app.MainApp.status4.FontColor='k';
            app.MainApp.status4.Text='Analysis Type: IDA';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.status4b.BackgroundColor='g';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.RunButton.Enable='on';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.ResultsButton.Enable='off';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.SaveButton.Enable='off';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.ProgressText.Value='READY TO RUN ANALYSIS';
            app.MainApp.ProgressText.FontColor='g';
            
            app.MainApp.Image5.Visible='off';
            closereq
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'T','SaAVG_status')
            cd (MainDirectory)
            
            if app.checkbox1.Value==1 && SaAVG_status~=1
                app.edit16.Enable='on';
                app.edit17.Enable='on';
                app.edit16.Value=1;
                % assign values for period range based on recommendations by Adam & Jager (2012)
                if T<=0.25
                    app.edit17.Value=T/T;
                elseif T>0.25 && T <=0.5
                    app.edit17.Value=(T+0.2)/T;
                elseif T>0.5 && T <=0.7
                    app.edit17.Value=(T+0.4)/T;
                elseif T>0.7 && T <=1.5
                    app.edit17.Value=(T+0.8)/T;
                elseif T>1.5
                    app.edit17.Value=(T+1.2)/T;
                end
            elseif app.checkbox1.Value==1 && SaAVG_status==1
                app.edit16.Enable='on';
                app.edit17.Enable='on';
            else
                app.edit16.Enable='off';
                app.edit17.Enable='off';
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Value changed function: edit16
        function edit16ValueChanged(app, event)
            app.edit17.Limits=[app.edit16.Value inf];
        end

        % Value changed function: edit17
        function edit17ValueChanged(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Analysis Type: IDA';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.uipanel2 = uipanel(app.uipanel1);
            app.uipanel2.Title = 'IDA Parameters';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text12.Text = 'Incremental step [g]';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Tooltip = 'Displacement limit at which collapse occurs';
            app.text13.Text = 'Displacement limit [m]';
            app.text20 = uilabel(app.uipanel2);
            app.text20.Tooltip = 'Acceleration limit at which collapse occurs';
            app.text20.Text = 'Acceleration limit [g]';
            app.edit1 = uieditfield(app.uipanel2, 'numeric');
            app.edit1.Limits = [0.02 Inf];
            app.edit1.Value = 0.1;
            app.edit3 = uieditfield(app.uipanel2, 'numeric');
            app.edit3.Limits = [0.01 Inf];
            app.edit3.Value = 0.1;
            app.edit4 = uieditfield(app.uipanel2, 'numeric');
            app.edit4.Limits = [0.1 Inf];
            app.edit4.Value = 5;
            app.uipanel4 = uipanel(app.uipanel1);
            app.text23 = uilabel(app.uipanel4);
            app.text23.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text23.Text = 'Range:';
            app.text26 = uilabel(app.uipanel4);
            app.text26.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text26.Text = 'to';
            app.text28 = uilabel(app.uipanel4);
            app.text28.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text28.Text = 'T';
            app.checkbox1 = uicheckbox(app.uipanel4);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'Optional: IM = Sa,ᴀᴠɢ';
            app.edit16 = uieditfield(app.uipanel4, 'numeric');
            app.edit16.Limits = [0 Inf];
            app.edit16.ValueDisplayFormat = '%.1f';
            app.edit16.ValueChangedFcn = createCallbackFcn(app, @edit16ValueChanged, true);
            app.edit16.Value = 0.1;
            app.edit17 = uieditfield(app.uipanel4, 'numeric');
            app.edit17.Limits = [0 Inf];
            app.edit17.ValueDisplayFormat = '%.1f';
            app.edit17.ValueChangedFcn = createCallbackFcn(app, @edit17ValueChanged, true);
            app.edit17.Value = 3;
            app.text21 = uilabel(app.uipanel4);
            app.text21.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text21.Text = '#sampling periods';
            app.edit18 = uieditfield(app.uipanel4, 'numeric');
            app.edit18.Limits = [1 100];
            app.edit18.ValueDisplayFormat = '%.0f';
            app.edit18.Tooltip = {'a larger number will result in longer computation'};
            app.edit18.Value = 20;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_AnalysisType_IDA_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_AnalysisType_IDA_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end