classdef Module_AnalysisType_RHA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                    matlab.ui.Figure
        uipanel2                   matlab.ui.container.Panel
        uipanel3                   matlab.ui.container.Panel
        text21                     matlab.ui.control.Label
        text24                     matlab.ui.control.Label
        checkbox1                  matlab.ui.control.CheckBox
        edit16                     matlab.ui.control.NumericEditField
        edit17                     matlab.ui.control.NumericEditField
        text22                     matlab.ui.control.Label
        text21_2                   matlab.ui.control.Label
        edit18                     matlab.ui.control.NumericEditField
        ButtonGroup                matlab.ui.container.ButtonGroup
        ConstantScaleFactorButton  matlab.ui.control.RadioButton
        BasedonTargetIMButton      matlab.ui.control.RadioButton
        edit1                      matlab.ui.control.NumericEditField
        edit2                      matlab.ui.control.NumericEditField
        text20                     matlab.ui.control.Label
        SubmitButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_AnalysisType_RHA_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'AnalysisTypestatus','Analysis_Option','RHA_Option','SF','TargetSA','SaAVG_status','SaAVG_T1','SaAVG_T2','T')
            cd (MainDirectory)
            
            if AnalysisTypestatus==1 && Analysis_Option==1
                if RHA_Option==1
                    app.ConstantScaleFactorButton.Value=1;
                    app.edit1.Value=SF;
                elseif RHA_Option==2
                    app.BasedonTargetIMButton.Value=1;
                    app.edit2.Value=TargetSA;
                end
            else
                app.ConstantScaleFactorButton.Value=1;
                app.edit1.Value=SF;
            end
            ButtonGroupSelectionChanged(app)
            
            if SaAVG_status==0
                app.checkbox1.Value=0;
                checkbox1_Callback(app)
            else
                app.checkbox1.Value=1;
                app.edit2.Value=TargetSA;
                app.edit16.Enable='on';
                app.edit17.Enable='on';
                app.edit16.Value=SaAVG_T1/T;
                app.edit17.Value=SaAVG_T2/T;
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Runstatus','Analysis_Option','T')
            cd (MainDirectory)
            
            if Runstatus==1
                waitfor(DeleteResultsMessage_App);
            end
            
            cd (ProjectPath)
            load(ProjectName,'DeleteResults')
            cd (MainDirectory)
            
            if Runstatus==1 && DeleteResults==0
                return;
            elseif Runstatus==1 && DeleteResults==1
                if Analysis_Option==1
                    rmvar(ProjectPath,ProjectName, 'RESULTS')
                elseif Analysis_Option==2
                    rmvar(ProjectPath,ProjectName, 'RESULTS','IDA')
                elseif Analysis_Option==3
                    rmvar(ProjectPath,ProjectName, 'RESULTS','RSA')
                elseif Analysis_Option==4
            
                end
                Runstatus=0;
                DeleteResults=1;
            
                cd (ProjectPath)
                save(ProjectName,'Runstatus','DeleteResults','-append')
                cd (MainDirectory)
            
                app.MainApp.ResultsButton.Enable='off';
                app.MainApp.SaveButton.Enable='off';
            end
           
            AnalysisTypestatus=1;
            Analysis_Option=1;
            AnalysisType='Dynamic';
            
            %%
            
            if app.ConstantScaleFactorButton.Value==1
                RHA_Option=1;
                SF=app.edit1.Value;
            
                if SF<0 || isnan(SF)
                    ed = errordlg('The scale factor must be POSITIVE VALUE','Error', 'modal');
                    waitfor(ed);
                    return;
                end
            
                cd (ProjectPath)
                save (ProjectName,'AnalysisTypestatus','Analysis_Option','AnalysisType','RHA_Option','SF','-append')
                pause (0.5)
                cd (MainDirectory)
            end
            
            if app.BasedonTargetIMButton.Value==1
                RHA_Option=2;
                TargetSA=app.edit2.Value;
            
                if app.checkbox1.Value==1
                    SaAVG_status=1;
                    SaAVG_cT1=app.edit16.Value;
                    SaAVG_cT2=app.edit17.Value;
                    SaAVG_sample=app.edit18.Value;
                    SaAVG_T1=SaAVG_cT1*T;
                    SaAVG_T2=SaAVG_cT2*T;
                else
                    SaAVG_status=0;
                    SaAVG_T1=1;
                    SaAVG_T2=1;
                    SaAVG_cT1=1;
                    SaAVG_cT2=1;
                    SaAVG_sample=1;
                end
            
                if TargetSA<0 || isnan(TargetSA)
                    ed = errordlg('The target spectral acceleration must be POSITIVE VALUE','Error', 'modal');
                    waitfor(ed);
                    return;
                end
            
                if app.checkbox1.Value==1
                if SaAVG_T2<SaAVG_T1
                    ed = errordlg('Period Range Error: Second period should be larger than the First Period!','Error', 'modal');
                    waitfor(ed);
                    return;
                end
                end
            
                cd (ProjectPath)
                pause (0.5)
                save (ProjectName,'AnalysisTypestatus','Analysis_Option','AnalysisType','RHA_Option','TargetSA','SaAVG_status','SaAVG_cT1','SaAVG_cT2','SaAVG_T1','SaAVG_T2','SaAVG_sample','-append')
                pause (0.5)
                cd (MainDirectory)
            
            end

            app.MainApp.status4.BackgroundColor='g';
            app.MainApp.status4.FontColor='k';
            app.MainApp.status4.Text='Analysis Type: RHA';
            app.MainApp.status4b.BackgroundColor='g';
            app.MainApp.RunButton.Enable='on';
            app.MainApp.ResultsButton.Enable='off';
            app.MainApp.SaveButton.Enable='off';
            app.MainApp.ProgressText.Value='READY TO RUN ANALYSIS';
            app.MainApp.ProgressText.FontColor='g';
            
            app.MainApp.Image5.Visible='off';
            closereq
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'T','SaAVG_status')
            cd (MainDirectory)
            if app.ConstantScaleFactorButton.Value==1
                app.checkbox1.Value=0;
                app.edit16.Enable='off';
                app.edit17.Enable='off';
                app.edit18.Enable='off';
            else
                if app.checkbox1.Value==1 && SaAVG_status~=1
                    app.edit16.Enable='on';
                    app.edit17.Enable='on';
                    app.edit18.Enable='on';
                    app.edit16.Value=1;
                    % assign values for period range based on recommendations by Adam & Jager (2012)
                    if T<=0.25
                        app.edit17.Value=T/T;
                    elseif T>0.25 && T <=0.5
                        app.edit17.Value=(T+0.2)/T;
                    elseif T>0.5 && T <=0.7
                        app.edit17.Value=(T+0.4)/T;
                    elseif T>0.7 && T <=1.5
                        app.edit17.Value=(T+0.8)/T;
                    elseif T>1.5
                        app.edit17.Value=(T+1.2)/T;
                    end
                elseif app.checkbox1.Value==1 && SaAVG_status==1
                    app.edit16.Enable='on';
                    app.edit17.Enable='on';
                    app.edit18.Enable='on';
                else
                    app.edit16.Enable='off';
                    app.edit17.Enable='off';
                    app.edit18.Enable='off';                    
                end
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.ConstantScaleFactorButton.Value==1
                app.edit1.Enable='on';
                app.edit2.Enable='off';
                app.checkbox1.Value=0;
                checkbox1_Callback(app)
            else
                app.edit1.Enable='off';
                app.edit2.Enable='on';                
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)            
        end

        % Value changed function: edit16
        function edit16ValueChanged(app, event)
            xx = app.edit16.Value;
            app.edit17.Limits=[xx inf];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Analysis Type: RHA';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Ground Motion Scaling';
            app.uipanel3 = uipanel(app.uipanel2);
            app.text21 = uilabel(app.uipanel3);
            app.text21.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text21.Text = 'Range:';
            app.text24 = uilabel(app.uipanel3);
            app.text24.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text24.Text = 'T';
            app.checkbox1 = uicheckbox(app.uipanel3);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'Optional: IM=Sa,ᴀᴠɢ';
            app.edit16 = uieditfield(app.uipanel3, 'numeric');
            app.edit16.Limits = [0 Inf];
            app.edit16.ValueDisplayFormat = '%5.2f';
            app.edit16.ValueChangedFcn = createCallbackFcn(app, @edit16ValueChanged, true);
            app.edit16.Value = 0.1;
            app.edit17 = uieditfield(app.uipanel3, 'numeric');
            app.edit17.Limits = [0 Inf];
            app.edit17.ValueDisplayFormat = '%5.2f';
            app.edit17.Value = 3;
            app.text22 = uilabel(app.uipanel3);
            app.text22.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text22.Text = 'to';
            app.text21_2 = uilabel(app.uipanel3);
            app.text21_2.Tooltip = 'Step for IDA. Recommended value is 0.1g';
            app.text21_2.Text = '#sampling periods';
            app.edit18 = uieditfield(app.uipanel3, 'numeric');
            app.edit18.Limits = [1 100];
            app.edit18.ValueDisplayFormat = '%.0f';
            app.edit18.Tooltip = {'a larger number will result in longer computation'};
            app.edit18.Value = 20;
            app.ButtonGroup = uibuttongroup(app.uipanel2);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.ConstantScaleFactorButton = uiradiobutton(app.ButtonGroup);
            app.ConstantScaleFactorButton.Text = 'Constant Scale Factor';
            app.ConstantScaleFactorButton.Value = true;
            app.BasedonTargetIMButton = uiradiobutton(app.ButtonGroup);
            app.BasedonTargetIMButton.Text = 'Based on Target IM';
            app.edit1 = uieditfield(app.ButtonGroup, 'numeric');
            app.edit1.Limits = [0 Inf];
            app.edit1.ValueDisplayFormat = '%.3f';
            app.edit1.Value = 1;
            app.edit2 = uieditfield(app.ButtonGroup, 'numeric');
            app.edit2.Limits = [0 Inf];
            app.edit2.ValueDisplayFormat = '%.3f';
            app.edit2.Value = 1;
            app.text20 = uilabel(app.uipanel2);
            app.text20.Text = '[g]';
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_AnalysisType_RHA_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_AnalysisType_RHA_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end