classdef Module_AnalysisType_RSA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel2        matlab.ui.container.Panel
        text19          matlab.ui.control.Label
        text14          matlab.ui.control.Label
        text13          matlab.ui.control.Label
        text12          matlab.ui.control.Label
        RSAoptionpopup  matlab.ui.control.DropDown
        radiobutton4    matlab.ui.control.CheckBox
        edit1           matlab.ui.control.NumericEditField
        edit2           matlab.ui.control.NumericEditField
        edit3           matlab.ui.control.NumericEditField
        SubmitButton    matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_AnalysisType_RSA_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Runstatus','Analysis_Option')
            cd (MainDirectory)
            
            if Runstatus==1
                waitfor(DeleteResultsMessage_App);
            end
            
            cd (ProjectPath)
            load(ProjectName,'DeleteResults')
            cd (MainDirectory)
            
            if Runstatus==1 && DeleteResults==0
                return;
            elseif Runstatus==1 && DeleteResults==1
                if Analysis_Option==1
                    rmvar(ProjectPath,ProjectName, 'RESULTS')
                elseif Analysis_Option==2
                    rmvar(ProjectPath,ProjectName, 'RESULTS','IDA')
                elseif Analysis_Option==3
                    rmvar(ProjectPath,ProjectName, 'RESULTS','RSA')
                elseif Analysis_Option==4
            
                end
                Runstatus=0;
                DeleteResults=1;
            
                cd (ProjectPath)
                save(ProjectName,'Runstatus','DeleteResults','-append')
                cd (MainDirectory)
            
                app.MainApp.ResultsButton.Enable='off';
                app.MainApp.SaveButton.Enable='off';
            end
            
            cd (ProjectPath)
            load(ProjectName,'AnalysisTypestatus','Analysis_Option','To','Tincr','Tend','RSA_Option')
            cd (MainDirectory)
            
            if AnalysisTypestatus==1 && Analysis_Option==3
                app.edit1.Value=To;
                app.edit2.Value=Tincr;
                app.edit3.Value=Tend;
                if RSA_Option==1
                    app.RSAoptionpopup.Value='1';
                elseif RSA_Option==2
                    app.RSAoptionpopup.Value='2';
                elseif RSA_Option==3
                    app.RSAoptionpopup.Value='3';
                end
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc
            
            if  app.radiobutton4.Value==1
                To=0;
                Tincr=0.05;
                Tend=5;
                Quick_RSA=1;
            
                RSA_Option=1;
                AnalysisTypestatus=1;
                Analysis_Option=3;
            
                cd (ProjectPath)
                save (ProjectName,'AnalysisTypestatus','Analysis_Option','RSA_Option','To','Tincr','Tend','Quick_RSA','-append')
                pause (0.5)
                cd (MainDirectory)
            
            else
            
            
            To=app.edit1.Value;
            Tincr=app.edit2.Value;
            Tend=app.edit3.Value;
            
            %% INPUT VALUES CHECKS BEFORE SUBMISSION
            if isnan(To) || isnan(Tincr) || isnan(Tend)
                ed = errordlg('Some paramters are not defined. Please define all the required paramters.','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            if To<0 || Tincr<0 || Tend<0
                ed = errordlg('All period values must be POSITIVE VALUES','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            if To>Tend
                ed = errordlg('End Period must be LARGER THAN the Start Period','Error', 'modal');
                waitfor(ed);
                return;
            end
            %%
            
            if     app.RSAoptionpopup.Value=='1'
                RSA_Option=1;
            elseif app.RSAoptionpopup.Value=='2'
                RSA_Option=2;
            elseif app.RSAoptionpopup.Value=='3'
                RSA_Option=3;
            end
            
            AnalysisTypestatus=1;
            Analysis_Option=3;
            Quick_RSA=0;
            
            cd (ProjectPath)
            save (ProjectName,'AnalysisTypestatus','Analysis_Option','RSA_Option','To','Tincr','Tend','Quick_RSA','-append')
            pause (0.5)
            cd (MainDirectory)
            end
            
            
            %a=findall(groot, 'Name', 'IIDAP'); %myguide1 is the file name for guide1
            app.MainApp.status4.BackgroundColor='g';
            app.MainApp.status4.FontColor='k';
            app.MainApp.status4.Text='Analysis Type: RSA';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.status4b.BackgroundColor='g';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.RunButton.Enable='on';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.ResultsButton.Enable='off';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.SaveButton.Enable='off';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.ProgressText.Value='READY TO RUN ANALYSIS';
            app.MainApp.ProgressText.FontColor='g';
            
            app.MainApp.Image5.Visible='off';
            closereq
        end

        % Value changed function: radiobutton4
        function radiobutton4_Callback(app, event)
            if get(app.radiobutton4,'Value')==1
                app.RSAoptionpopup.Enable='off';
                app.edit1.Enable='off';
                app.edit2.Enable='off';
                app.edit3.Enable='off';
            else
                app.RSAoptionpopup.Enable='on';
                app.edit1.Enable='on';
                app.edit2.Enable='on';
                app.edit3.Enable='on';
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Analysis Type: RSA';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel2 = uipanel(app.figure1);
            app.uipanel2.Title = 'Spectrum Period Range';
            app.text19 = uilabel(app.uipanel2);
            app.text19.Text = 'Analysis method';
            app.text14 = uilabel(app.uipanel2);
            app.text14.Text = 'End period [sec]';
            app.text13 = uilabel(app.uipanel2);
            app.text13.Text = 'Increment period [sec]';
            app.text12 = uilabel(app.uipanel2);
            app.text12.Text = 'Start period [sec]';
            app.RSAoptionpopup = uidropdown(app.uipanel2);
            app.RSAoptionpopup.Items = {'Vary Mass', 'Vary Ke & maintain Fy', 'Vary Ke & maintain dy'};
            app.RSAoptionpopup.ItemsData = {'1', '2', '3'};
            app.RSAoptionpopup.Value = '1';
            app.radiobutton4 = uicheckbox(app.uipanel2);
            app.radiobutton4.ValueChangedFcn = createCallbackFcn(app, @radiobutton4_Callback, true);
            app.radiobutton4.Text = 'Run a Quick Elastic RSA';
            app.edit1 = uieditfield(app.uipanel2, 'numeric');
            app.edit1.Limits = [0 Inf];
            app.edit1.Value = 0.01;
            app.edit2 = uieditfield(app.uipanel2, 'numeric');
            app.edit2.Limits = [0 Inf];
            app.edit2.Value = 0.1;
            app.edit3 = uieditfield(app.uipanel2, 'numeric');
            app.edit3.Limits = [0 Inf];
            app.edit3.Value = 5;
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_AnalysisType_RSA_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_AnalysisType_RSA_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end