classdef Module_GroundMotion_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                matlab.ui.Figure
        ButtonGroup            matlab.ui.container.ButtonGroup
        SingleRecordButton     matlab.ui.control.RadioButton
        MultipleRecordsButton  matlab.ui.control.RadioButton
        HarmonicWaveButton     matlab.ui.control.RadioButton
        TipIcon3               matlab.ui.control.Button
        uipanel4               matlab.ui.container.Panel
        popupmenu2             matlab.ui.control.DropDown
        text12                 matlab.ui.control.Label
        text13                 matlab.ui.control.Label
        text14                 matlab.ui.control.Label
        text15                 matlab.ui.control.Label
        edit4                  matlab.ui.control.NumericEditField
        edit5                  matlab.ui.control.NumericEditField
        edit6                  matlab.ui.control.NumericEditField
        TipIcon2               matlab.ui.control.Button
        uipanel3               matlab.ui.container.Panel
        text2                  matlab.ui.control.Label
        BrowseGMFolderButton   matlab.ui.control.Button
        text9                  matlab.ui.control.Label
        text21                 matlab.ui.control.Label
        SFEdit2                matlab.ui.control.NumericEditField
        Unitpopup2             matlab.ui.control.DropDown
        TipIcon1               matlab.ui.control.Button
        uipanel2               matlab.ui.container.Panel
        BrowseFileButton       matlab.ui.control.Button
        text7                  matlab.ui.control.Label
        text8                  matlab.ui.control.Label
        text11                 matlab.ui.control.Label
        text20                 matlab.ui.control.Label
        SFEdit                 matlab.ui.control.NumericEditField
        edit7                  matlab.ui.control.NumericEditField
        Unitpopup1             matlab.ui.control.DropDown
        uipanel5               matlab.ui.container.Panel
        checkbox1              matlab.ui.control.CheckBox
        TipIcon4               matlab.ui.control.Button
        edit8                  matlab.ui.control.NumericEditField
        SubmitButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
        TipWindow % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_GroundMotion_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;    
        
            Pos_Main=app.MainApp.figure0.Position;
            Pos_Secd=app.figure1.Position;
            
            app.figure1.Position=[Pos_Main(1) Pos_Main(2) Pos_Secd(3) Pos_Secd(4)];
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'GMstatus','GM_Option','GM','SF','Wave_name','Twave','AddTime','Units')
            cd (MainDirectory)
            
            if GMstatus==1
                if GM_Option==1
                    app.SFEdit.Value=SF;
                    app.SingleRecordButton.Value=1;
                    app.edit7.Enable='on';
                    app.edit7.Value=GM.dt;
                elseif GM_Option==2
                    app.SFEdit2.Value=SF;
                    app.MultipleRecordsButton.Value=1;
                elseif GM_Option==3
                    if strcmp(Wave_name,'Sine Wave')
                        app.popupmenu2.Value='1';
                    elseif strcmp(Wave_name,'Cosine Wave')
                        app.popupmenu2.Value='2';
                    elseif strcmp(Wave_name,'Triangular Wave')
                        app.popupmenu2.Value='3';
                    elseif strcmp(Wave_name,'Rectangular Wave')
                        app.popupmenu2.Value='4';
                    end
                    app.edit4.Value=GM.pga;
                    app.edit5.Value=Twave;
                    app.edit6.Value=GM.duration;
                    app.HarmonicWaveButton.Value=1;
                end
            else
                app.SingleRecordButton.Value=1;
            end
            
            ButtonGroupSelectionChanged(app)
            
            
            if AddTime==0
                app.checkbox1.Value=0;
                app.edit8.Value=0;
                checkbox1_Callback(app)
            else
                app.checkbox1.Value=1;
                app.edit8.Value=AddTime;
                checkbox1_Callback(app)
            end
            
            if Units==1
                xx{1}='g';
                xx{2}='m/sec2';
                xx{3}='mm/sec2';
                app.Unitpopup1.Items=xx;
                app.Unitpopup2.Items=xx;
            else
                xx{1}='g';
                xx{2}='in/sec2';
                xx{3}='ft/sec2';
                app.Unitpopup1.Items=xx;
                app.Unitpopup2.Items=xx;
            end

        end

        % Button pushed function: BrowseFileButton
        function BrowseFileButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc
            cd (ProjectPath)
            load (ProjectName,'GMfilePath','GMfilename')
            cd (MainDirectory)
            clear GMfilePath GMfilename
% %             if app.SingleRecordButton.Value == 1
                [GMfilename GMfilePath]= uigetfile({'*.*'},'Select the Ground Motion File');
                z=[GMfilePath,GMfilename];
                data=importdata(z);
                if size(data,2) ==1 ||  size(data,2) >2
                    app.edit7.Enable='on';
                    app.edit7.Value=000;
                    edit7ValueChanged(app)
                elseif size(data,2) ==2
                    app.edit7.Enable='off';
                    app.edit7.Value=data(2,1)-data(1,1);
                end
                cd (ProjectPath)
                pause(0.1)
                save (ProjectName,'GMfilename','GMfilePath','-append')
                pause(0.5)
                cd (MainDirectory)
            
                [GMx, errormsg, inputtype]=CheckAndGetGMinfo(GMfilePath, GMfilename);
            
                % Check if acceleration data are in "g" units
            %     if max(abs(GMx.GA))>3
            %         warndlg('The acceleration data do not seem to be in units of "g" ---> Before Submiting, Either: A) Specify a scale factor equal to 1/g OR B) Re-import the data in units of "g"','Data Input Warning!', 'modal');
            %     end
            
%             else
%                 ed = errordlg('Option 1 is not Selected','Error', 'modal');
%                 waitfor(ed);
%                 return
%             end
        end

        % Button pushed function: BrowseGMFolderButton
        function BrowseGMFolderButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc
            cd (ProjectPath)
            load (ProjectName,'GMFolderPath')
            cd (MainDirectory)
            clear GMFolderPath
%             if app.MultipleRecordsButton.Value == 1
                GMFolderPath= uigetdir;
                cd (ProjectPath)
                save (ProjectName,'GMFolderPath','-append')
                cd (MainDirectory)
%             else
%                 ed = errordlg('Option 2 is not Selected','Error', 'modal');
%                 waitfor(ed);
%                 return
%             end
        end

        % Callback function
        function SFEdit2_Callback(app, event)
            nGM=app.SFEdit2.Value;
            if     nGM<0 || mod(nGM,1)~=0
               app.SFEdit2.FontColor='r';
            else
               app.SFEdit2.FontColor='k';
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            
            
            cd (ProjectPath)
            load(ProjectName,'Runstatus','Analysis_Option')
            cd (MainDirectory)
            
            if Runstatus==1
                waitfor(DeleteResultsMessage_App);
            end
            
            cd (ProjectPath)
            load(ProjectName,'DeleteResults')
            cd (MainDirectory)
            
            if Runstatus==1 && DeleteResults==0
                return;
            elseif Runstatus==1 && DeleteResults==1
                if Analysis_Option==1
                    rmvar(ProjectPath,ProjectName, 'RESULTS')
                elseif Analysis_Option==2
                    rmvar(ProjectPath,ProjectName, 'RESULTS','IDA')
                elseif Analysis_Option==3
                    rmvar(ProjectPath,ProjectName, 'RESULTS','RSA')
                elseif Analysis_Option==4
            
                end
                Runstatus=0;
                DeleteResults=1;
            
                cd (ProjectPath)
                save(ProjectName,'Runstatus','DeleteResults','-append')
                cd (MainDirectory)
            
                app.MainApp.ResultsButton.Enable='off';
                app.MainApp.SaveButton.Enable='off';
            end
            
            
            cd (ProjectPath)
            pause(0.1)
            load (ProjectName,'GMfilePath','GMfilename','GMFolderPath','GM','GMstatus','GM_Option','SF','nGM','Wave_name','Twave','AddTime','g','Units','AnalysisTypestatus')
            pause(0.1)
            cd (MainDirectory)
            
            str{1}='Importing ground motion data.';
            str{2}='Please wait...';
            str{3}='This may take about ~30sec!';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
            drawnow
            
            GMstatus=0;
            clear GM
            
            if Units==1
                if str2double(app.Unitpopup1.Value) == 1
                    AccUnit=g;
                elseif str2double(app.Unitpopup1.Value) == 2
                    AccUnit=1;
                else
                    AccUnit=1/1000;
                end
            else
                if str2double(app.Unitpopup1.Value) == 1
                    AccUnit=g;
                elseif str2double(app.Unitpopup1.Value) == 2
                    AccUnit=1;
                else
                    AccUnit=12;
                end
            end
            
               %% Single ground motion option
            if app.SingleRecordButton.Value == 1
            
                GM_Option=1;
            
                if exist('GMfilename') ==0
                    d=errordlg('Please browse for the ground motion file first!','Error','modal');
                    waitfor(d);
                    delete(app.TipWindow)
                    return
                end
                [GMx, errormsg, inputtype]=CheckAndGetGMinfo(GMfilePath, GMfilename);
            
                 nGM=1;
                % If the GM file containes TWO columns of data (i.e., Time & Acceleration)
                if inputtype ==1
            
                    SF=app.SFEdit.Value;
                    if isnan(SF)
                        ed = errordlg('Please specify the ground motion scale factor.','Error', 'modal');
                        waitfor(ed);
                        delete(app.TipWindow)
                        return;
                    end
                    if max(abs(GMx.GA))>1.0
                        ed = errordlg('The Acceleration Values are larger than 1g.','Error', 'modal');
                        waitfor(ed);
                    end
                    evalc(['GM.GA',num2str(1),'=GMx.GA * AccUnit * SF']);
                    evalc(['GM.Time',num2str(1),'=GMx.Time']);
                    GM.npoints(1)=GMx.npoints(1);
                    GM.dt(1)=GMx.dt(1);
                    GM.duration(1)=GMx.duration(1);
                    GM.pga(1)=GMx.pga(1);
                    GM.name(1)=GMx.name(1);
            
                    if errormsg==1
                        ed = errordlg('Time step is not constant','Error', 'modal');
                        waitfor(ed);
                        delete(app.TipWindow)
                        return
                    else
                        GMstatus=1;
                        cd (ProjectPath)
                        pause(0.1)
                        save (ProjectName,'GMfilePath','GM','GMstatus','GM_Option','SF','nGM','-append')
                        pause(0.5)
                        cd (MainDirectory)
                    end
            
                % If the GM file containes One column of data (i.e., Acceleration)
                elseif inputtype ==2
            
                    SF=app.SFEdit.Value;
                    dt=app.edit7.Value;
            
                    if isnan(SF) || isnan(dt) || dt==0.0
                        ed=errordlg('Please specify the ground motion time step!','WindowStyle', 'modal');
                        waitfor(ed);
                        delete(app.TipWindow)
                        return
                    else
                        evalc(['GM.GA',num2str(1),'=GMx.GA * AccUnit *SF']);
                        GM.npoints(1)=GMx.npoints;
                        GM.dt(1)=dt;
                        GM.duration(1)=(GMx.npoints(1)-1)*dt;
                        GM.pga(1)=GMx.pga(1);
                        GM.name(1)=GMx.name(1);
                        evalc(['GM.Time',num2str(1),'=(dt:dt:GM.duration(1)+dt)','''',]);
            
                        GMstatus=1;
                        cd (ProjectPath)
                        save (ProjectName,'GMfilePath','GM','GMstatus','GM_Option','SF','nGM','-append')
                        pause(0.5)
                        cd (MainDirectory)
                    end
            
                end
            
                   %% Multiple ground motions option
            elseif app.MultipleRecordsButton.Value == 1
            
                GM_Option=2;
            
                if exist('GMFolderPath') ==0
                    d=errordlg('Please browse for the ground motion folder first!','Error','modal');
                    waitfor(d);
                    delete(app.TipWindow)
                    return
                end
            
                % Get into the GM folder and get the GM files names
                cd (GMFolderPath)
                % If the file "GM Data.txt" exits
                if exist ('GM Data.txt')~=0
                     delimiter = char(9); %tab delimeter
                     fid = fopen('GM Data.txt','rt');
                     tLines = fgets(fid);
                     numCols = numel(strfind(tLines,delimiter)) + 1;
                     fclose(fid);
                    % if the file has 2 columns
                    if numCols == 2
                        inputtype = 2;
                    else
                        inputtype =1;
                    end
                else
                    d=errordlg('The file "GM Data.txt" is not found in the selected folder!','Error','modal');
                    waitfor(d);
                    delete(app.TipWindow)
                    return
                end
                GM_Data=importdata('GM Data.txt'); % Read the file data
                nGM=size(GM_Data.data,1);
            
                cd (MainDirectory)
            
                if inputtype ==1
                        GMsTimeStep=GM_Data.data;
                        GMfilenames=char(GM_Data.textdata);
                    for i=1:nGM
                        GMfilename=GMfilenames(i,:);
                        [GMx, errormsg, inputtype]=CheckAndGetGMinfo(GMFolderPath, GMfilenames);
            
                        SF=app.SFEdit2.Value;
                        if isnan(SF)
                            ed = errordlg('Please specify the ground motion scale factor.','Error', 'modal');
                            waitfor(ed);
                            delete(app.TipWindow)
                            return;
                        end
                        evalc(['GM.GA',num2str(i),'=GMx.GA * AccUnit *SF']);
                        evalc(['GM.Time',num2str(i),'=GMx.Time']);
                        GM.npoints(i,1)=GMx.npoints(1);
                        GM.dt(i,1)=GMx.dt(1);
                        GM.duration(i,1)=GMx.duration(1);
                        GM.pga(i,1)=GMx.pga(1);
                        GM.name(i,1)={GMx.name(1)};
            
                        if errormsg==1
                            ed = errordlg(['Time step of GM: ',GMfilenames ,' is not constant'],'Error', 'modal');
                            waitfor(ed);
                            delete(app.TipWindow)
                            return
                        else
                            GMstatus=1;
                        end
            
                        clear GMx
                    end
            
                elseif inputtype ==2
                        GMsTimeStep=GM_Data.data;
                        GMfilenames=char(GM_Data.textdata);
                    for i=1:nGM
                        GMfilename=GMfilenames(i,:);
                        [GMx, errormsg, inputtype]=CheckAndGetGMinfo(GMFolderPath, GMfilename);
                        SF=app.SFEdit2.Value;
                        if isnan(SF)
                            ed = errordlg('Please specify the ground motion scale factor.','Error', 'modal');
                            waitfor(ed);
                            delete(app.TipWindow)
                            return;
                        end
                        evalc(['GM.GA',num2str(i),'=GMx.GA * AccUnit * SF']);
                        GM.npoints(i,1)=GMx.npoints(1);
                        GM.dt(i,1)=GMsTimeStep(i,1);
                        GM.duration(i,1)=(GMx.npoints(1)-1)*GMsTimeStep(i,1);
                        GM.pga(i,1)=GMx.pga(1);
                        GM.name(i,1)={GMx.name(1)};
                        evalc(['GM.Time',num2str(i),'=(GM.dt(i,1):GM.dt(i,1):GM.duration(i,1)+GM.dt(i,1))','''',]);
            
                        clear GMx
                    end
            
                    GMstatus=1;
            
                end
            
                if GMstatus==1
                    cd (ProjectPath)
                    pause(0.5)
                    save (ProjectName,'GMFolderPath','GM','GMstatus','GM_Option','GMfilenames','SF','nGM','-append')
                    pause(0.5)
                    cd (MainDirectory)
                end
            
                %% Analytical wave option
            elseif app.HarmonicWaveButton.Value == 1
            
                GM_Option=3;
                SF=1;
                nGM=1;
                Amplitude=app.edit4.Value;
                Twave=app.edit5.Value;
                Duration=app.edit6.Value;
                Freq=1/Twave;
            
                if isnan(Amplitude) || isnan(Twave) || isnan(Duration)
                    ed = errordlg('Please specify the ground motion scale factor.','Error', 'modal');
                    waitfor(ed);
                    delete(app.TipWindow)
                    return;
                end
            
                if app.popupmenu2.Value=='1'
                    Wave_Option=1;
                    Wave_name='Sine Wave';
                    GM.name={'Sine Wave'};
                    XX=ceil(Twave/0.01);
                    count=1;
                    for t=0.0:Twave/XX:Duration
                        GM.GA1(count,1)=Amplitude*sin(2*3.1415923654*Freq*t) * g;
                        count=count+1;
                    end
            
                    GM.npoints=size(GM.GA1,1);
                    GM.dt=Twave/XX;
                    GM.duration=Duration;
                    GM.pga=Amplitude;
                    GM.Time1=(0.0:Twave/XX:Duration)';
            
                elseif app.popupmenu2.Value=='2'
                    Wave_Option=2;
                    Wave_name='Cosine Wave';
                    GM.name={'Cosine Wave'};
                    XX=ceil(Twave/0.01);
                    count=1;
                    for t=0.0:Twave/XX:Duration
                        GM.GA1(count,1)=Amplitude*cos(2*3.1415923654*Freq*t) * g;
                        count=count+1;
                    end
            
                    GM.npoints=size(GM.GA1,1);
                    GM.dt=Twave/XX;
                    GM.duration=Duration;
                    GM.pga=Amplitude;
                    GM.Time1=(0.0:Twave/XX:Duration)';
            
                elseif app.popupmenu2.Value=='3'
                    Wave_Option=3;
                    Wave_name='Triangular Wave';
                    GM.name={'Triangular Wave'};
                    XX=ceil(Twave/0.01);
                    count=1;
                    for t=0.0:Twave/4:Duration
                        GM.GA1(count,1)=Amplitude*sin(2*3.1415923654*Freq*t) * g;
                        count=count+1;
                    end
                    GM.Time1=(0.0:Twave/4:Duration)';
            
                    counterX=1;
                    for i=Twave/XX:Twave/XX:Duration
                        GM.GA1x(counterX,1)=interp1( GM.Time1, GM.GA1,i);
                        GM.Time1x(counterX,1)=i;
                        counterX=counterX+1;
                    end
            
                    GM.GA1=GM.GA1x;
                    GM.Time1=GM.Time1x;
            
                    GM.npoints=size(GM.GA1,1);
                    GM.dt=Twave/XX;
                    GM.duration=Duration;
                    GM.pga=Amplitude;
            
                elseif app.popupmenu2.Value=='4'
                    Wave_Option=4;
                    Wave_name='Rectangular Wave';
                    GM.name={'Rectangular Wave'};
                    GM.GA1(1,1)= 0.0 * g;
                    GM.Time1(1,1)= 0.0;
                    XX=ceil(Twave/0.01);
                    count=1;
                    n=1;
                    for t=0.0:Twave:Duration
                        GM.GA1(count+1,1)= Amplitude * g;
                        GM.GA1(count+2,1)= Amplitude * g;
                        GM.GA1(count+3,1)= -Amplitude * g;
                        GM.GA1(count+4,1)= -Amplitude * g;
            
                        GM.Time1(count+1,1)= (n-1)   * Twave+0.001;
                        GM.Time1(count+2,1)= (n-0.5) * Twave-0.001;
                        GM.Time1(count+3,1)= (n-0.5) * Twave+0.001;
                        GM.Time1(count+4,1)= n * Twave-0.001;
            
                        count=count+4;
                        n=n+1;
                    end
            
                    for i=1:size(GM.GA1,1)
                        if GM.Time1(i,1)>Duration
                            index=i;
                            break;
                        end
                    end
            
                    GM.GA1(index:end,:)=[];
                    GM.Time1(index:end,:)=[];
            
                    counterX=1;
                    for i=Twave/XX:Twave/XX:Duration
                        GM.GA1x(counterX,1)=interp1( GM.Time1, GM.GA1,i);
                        GM.Time1x(counterX,1)=i;
                        counterX=counterX+1;
                    end
            
                    GM.GA1=GM.GA1x;
                    GM.Time1=GM.Time1x;
            
                    GM.npoints=size(GM.GA1,1);
                    GM.dt=Twave/XX;
                    GM.duration=Duration;
                    GM.pga=Amplitude;
            
                end
            
                GMstatus=1;
            
                cd (ProjectPath)
                save (ProjectName,'GM','GMstatus','GM_Option','SF','nGM','Wave_name','Twave','-append')
                pause(0.5)
                cd (MainDirectory)
            
            end
            
            %% ADD FREE VIBRATION TIME
            if app.checkbox1.Value==1
                AddTime=app.edit8.Value;
                if isnan(AddTime)
                    ed = errordlg('Please specify the additional free vibration time.','Error', 'modal');
                    waitfor(ed);
                    delete(app.TipWindow)
                    return;
                end
            else
                AddTime=0;
            end
            
            if AddTime ~= 1
                for i=1:nGM
                    dt=GM.dt(i,1);
                    Duration=GM.duration(i,1);
                    npoints=GM.npoints(i,1);
                    count=0;
                    for j=dt:dt:AddTime
                        count=count+1;
                        evalc(['GM.GA',num2str(i),'(npoints+count,1)=0.0']);
                        evalc(['GM.Time',num2str(i),'(npoints+count,1)=Duration+j']);
                    end
                    GM.npoints(i,1)=GM.npoints(i,1)+count;
                    GM.duration(i,1)=GM.duration(i,1)+AddTime;
                end
            
                cd (ProjectPath)
                pause(1)
                save (ProjectName,'GM','AddTime','-append')
                pause(1)
                cd (MainDirectory)
            end
            %%
            
            delete(app.TipWindow)
            
            if GMstatus==1
                if GM_Option == 1
                    app.MainApp.status3.BackgroundColor='g';
                    app.MainApp.status3.FontColor='k';
                    app.MainApp.status3.Text='Ground Motion: Single GM';
                elseif GM_Option == 2
                    app.MainApp.status3.BackgroundColor='g';
                    app.MainApp.status3.FontColor='k';
                    app.MainApp.status3.Text='Ground Motion: Multiple GMs';
                elseif GM_Option == 3
                    app.MainApp.status3.BackgroundColor='g';
                    app.MainApp.status3.FontColor='k';
                    app.MainApp.status3.Text=['Ground Motion: ',Wave_name];
                end
                app.MainApp.status3b.BackgroundColor='g';
                app.MainApp.AnalysisButton.Enable='on';
                if AnalysisTypestatus~=1
                    app.MainApp.status4.BackgroundColor='r';
                    app.MainApp.status4b.BackgroundColor='r';
                end
            
                dt_user=min(GM.dt);
                cd (ProjectPath)
                pause(0.5)
                save (ProjectName,'dt_user','-append')
                pause(0.5)
                cd (MainDirectory)
            
                app.MainApp.PlotGMButton.Enable='on';
                app.MainApp.Image5.Visible='off';
                closereq
            end
        end

        % Button pushed function: TipIcon1
        function TipIcon1_Callback(app, event)
            str{1}='1. Browse for GM text file';
            str{2}='2. Specify the GM scale factor';
            str{3}='3. Specify GM time step only if the time column is not provided in the impoted GM file';
            str{4}='4. Specify the units of the imported acceleration data';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Button pushed function: TipIcon2
        function TipIcon2_Callback(app, event)
            str{1}='1. Browse for folder containing GMs files';
            str{2}='2. Specify the GM scale factor';
            str{3}='3. Specify the units of the imported acceleration data';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Button pushed function: TipIcon3
        function TipIcon3_Callback(app, event)
            str{1}='1. Select the wave shape';
            str{2}='2. Specify the wave acceleration amplitude';
            str{3}='3. Specify the wave period';
            str{4}='4. Specify the wave total duration';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Button pushed function: TipIcon4
        function TipIcon4_Callback(app, event)
            str{1}='Specify additonal free vibration time following the main ground motion/wave';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            if app.checkbox1.Value==1
                app.edit8.Enable='on';
            else
                app.edit8.Enable='off';
                app.edit8.Value=0;
            end
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            %selectedButton = app.ButtonGroup.SelectedObject;
            if app.SingleRecordButton.Value==1
                app.SFEdit.Enable='on';
                app.edit7.Enable='on';
                app.SFEdit2.Enable='off';
                app.BrowseFileButton.Enable='on';
                app.BrowseGMFolderButton.Enable='off';
                app.popupmenu2.Enable='off';
                app.edit4.Enable='off';
                app.edit5.Enable='off';
                app.edit6.Enable='off';
                app.Unitpopup2.Enable='off';
                app.Unitpopup1.Enable='on';
            elseif app.MultipleRecordsButton.Value==1
                app.SFEdit.Enable='off';
                app.edit7.Enable='off';
                app.SFEdit2.Enable='on';
                app.BrowseFileButton.Enable='off';
                app.BrowseGMFolderButton.Enable='on';
                app.popupmenu2.Enable='off';
                app.edit4.Enable='off';
                app.edit5.Enable='off';
                app.edit6.Enable='off';
                app.Unitpopup2.Enable='on';
                app.Unitpopup1.Enable='off';
            else
                app.SFEdit.Enable='off';
                app.edit7.Enable='off';
                app.SFEdit2.Enable='off';
                app.BrowseFileButton.Enable='off';
                app.BrowseGMFolderButton.Enable='off';
                app.popupmenu2.Enable='on';
                app.edit4.Enable='on';
                app.edit5.Enable='on';
                app.edit6.Enable='on';
                app.Unitpopup2.Enable='off';
                app.Unitpopup1.Enable='off';
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app.TipWindow)
            delete(app)
        end

        % Callback function
        function edit7ValueChanged(app, event)
            if app.edit7.Value==0
                app.edit7.FontColor='r';
                app.edit7.Tooltip='Specify GM time step';
            else
                app.edit7.FontColor='k';
                app.edit7.Tooltip='';                
            end
            
        end

        % Value changed function: edit7
        function edit7ValueChanged2(app, event)
            if app.edit7.Value==0
                app.edit7.FontColor='r';
                app.edit7.Tooltip='Specify GM time step';
            else
                app.edit7.FontColor='k';
                app.edit7.Tooltip='';                
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Ground Motion Module';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.ButtonGroup = uibuttongroup(app.figure1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.SingleRecordButton = uiradiobutton(app.ButtonGroup);
            app.SingleRecordButton.Text = 'Single Record';
            app.SingleRecordButton.Value = true;
            app.MultipleRecordsButton = uiradiobutton(app.ButtonGroup);
            app.MultipleRecordsButton.Text = 'Multiple Records';
            app.HarmonicWaveButton = uiradiobutton(app.ButtonGroup);
            app.HarmonicWaveButton.Text = 'Harmonic Wave';
            app.TipIcon3 = uibutton(app.ButtonGroup, 'push');
            app.TipIcon3.ButtonPushedFcn = createCallbackFcn(app, @TipIcon3_Callback, true);
            app.TipIcon3.Text = '?';
            app.uipanel4 = uipanel(app.ButtonGroup);
            app.popupmenu2 = uidropdown(app.uipanel4);
            app.popupmenu2.Items = {'Sine', 'Cosine', 'Triangular', 'Rectangular'};
            app.popupmenu2.ItemsData = {'1', '2', '3', '4'};
            app.popupmenu2.Value = '1';
            app.text12 = uilabel(app.uipanel4);
            app.text12.Text = 'Period [sec]';
            app.text13 = uilabel(app.uipanel4);
            app.text13.Text = 'Amplitude [g]';
            app.text14 = uilabel(app.uipanel4);
            app.text14.Text = 'Duration [sec]';
            app.text15 = uilabel(app.uipanel4);
            app.text15.Text = 'Wave shape';
            app.edit4 = uieditfield(app.uipanel4, 'numeric');
            app.edit4.Value = 0.5;
            app.edit5 = uieditfield(app.uipanel4, 'numeric');
            app.edit5.Limits = [0 Inf];
            app.edit5.Value = 1;
            app.edit6 = uieditfield(app.uipanel4, 'numeric');
            app.edit6.Limits = [0 Inf];
            app.edit6.Value = 10;
            app.TipIcon2 = uibutton(app.ButtonGroup, 'push');
            app.TipIcon2.ButtonPushedFcn = createCallbackFcn(app, @TipIcon2_Callback, true);
            app.TipIcon2.Text = '?';
            app.uipanel3 = uipanel(app.ButtonGroup);
            app.text2 = uilabel(app.uipanel3);
            app.text2.Text = 'Scale factor';
            app.BrowseGMFolderButton = uibutton(app.uipanel3, 'push');
            app.BrowseGMFolderButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseGMFolderButton_Callback, true);
            app.BrowseGMFolderButton.Tooltip = 'Browse for the ground motion suite data folder';
            app.BrowseGMFolderButton.Text = 'Browse';
            app.text9 = uilabel(app.uipanel3);
            app.text9.Text = 'Select folder';
            app.text21 = uilabel(app.uipanel3);
            app.text21.Text = 'Imported units';
            app.SFEdit2 = uieditfield(app.uipanel3, 'numeric');
            app.SFEdit2.Value = 1;
            app.Unitpopup2 = uidropdown(app.uipanel3);
            app.Unitpopup2.Items = {'g', 'm/sec2'};
            app.Unitpopup2.ItemsData = {'1', '2', '3'};
            app.Unitpopup2.Value = '1';
            app.TipIcon1 = uibutton(app.ButtonGroup, 'push');
            app.TipIcon1.ButtonPushedFcn = createCallbackFcn(app, @TipIcon1_Callback, true);
            app.TipIcon1.Text = '?';
            app.uipanel2 = uipanel(app.ButtonGroup);
            app.BrowseFileButton = uibutton(app.uipanel2, 'push');
            app.BrowseFileButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseFileButton_Callback, true);
            app.BrowseFileButton.Tooltip = 'Browse for the ground motion data file';
            app.BrowseFileButton.Text = 'Browse';
            app.text7 = uilabel(app.uipanel2);
            app.text7.Text = 'Scale factor';
            app.text8 = uilabel(app.uipanel2);
            app.text8.Text = 'Select file';
            app.text11 = uilabel(app.uipanel2);
            app.text11.Text = 'Time step [sec]';
            app.text20 = uilabel(app.uipanel2);
            app.text20.Text = 'Imported units';
            app.SFEdit = uieditfield(app.uipanel2, 'numeric');
            app.SFEdit.Value = 1;
            app.edit7 = uieditfield(app.uipanel2, 'numeric');
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7ValueChanged2, true);
            app.Unitpopup1 = uidropdown(app.uipanel2);
            app.Unitpopup1.Items = {'g', 'm/sec2'};
            app.Unitpopup1.ItemsData = {'1', '2', '3'};
            app.Unitpopup1.Value = '1';
            app.uipanel5 = uipanel(app.figure1);
            app.uipanel5.Title = 'Optional: Free Vibration';
            app.checkbox1 = uicheckbox(app.uipanel5);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'Additional Time [sec]';
            app.TipIcon4 = uibutton(app.uipanel5, 'push');
            app.TipIcon4.ButtonPushedFcn = createCallbackFcn(app, @TipIcon4_Callback, true);
            app.TipIcon4.Text = '?';
            app.edit8 = uieditfield(app.uipanel5, 'numeric');
            app.edit8.Limits = [0 Inf];
            app.edit8.Value = 10;
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_GroundMotion_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_GroundMotion_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end