classdef Module_GroundMotion_Plot_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        uipanel1         matlab.ui.container.Panel
        axes1            matlab.ui.control.UIAxes
        text24           matlab.ui.control.Label
        text23           matlab.ui.control.Label
        GMpopup          matlab.ui.control.DropDown
        NextButton       matlab.ui.control.Button
        PreviousButton   matlab.ui.control.Button
        popupmenu6       matlab.ui.control.DropDown
        Next2Button      matlab.ui.control.Button
        Previous2Button  matlab.ui.control.Button
        text25           matlab.ui.control.Label
        Savebutton       matlab.ui.control.Button
        text26           matlab.ui.control.Label
        edit7            matlab.ui.control.NumericEditField
        edit8            matlab.ui.control.NumericEditField
        TipIcon1         matlab.ui.control.Button
    end

    
    properties (Access = private)
        Property % Description
        TipWindow
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_GroundMotion_Plot_OpeningFcn(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'GM_Option','nGM','GM','Wave_name')
            cd (MainDirectory)
            
            if GM_Option==3
                X{1,1}=Wave_name;
            else
                if nGM==1
                    zz=GM.name{1};
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz={zz(1:indexz-1)}; end
                    X(1,1)=zz;
                else
                    zz=char(GM.name{1,1});
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz=zz(1:indexz-1); end
                    X{1,1}=zz;
                    clear z
                    for i=1:nGM
                        zz=char(GM.name{i,1});
                        indexz=strfind(zz,'.');
                        if isempty(zz); else; zz=zz(1:indexz-1); end
                        X{i,1}=zz;
                        clear zz
                    end
                end
            end
            app.GMpopup.Items=X;
            app.GMpopup.ItemsData=1:nGM;
            app.GMpopup.UserData=nGM;
            
            for GM_No=1:nGM
                evalc(['GA=GM.GA',num2str(GM_No)]);
                MaxPGA(GM_No,1)=max(abs(GA));
            end
            app.axes1.UserData=max(MaxPGA);
            
            app.GMpopup.Value=1;
            GMpopup_Callback(app)
              
        end

        % Button pushed function: Next2Button
        function Next2Button_Callback(app, event)
            Current_Choice=str2double(app.popupmenu6.Value);
            if Current_Choice<8
                Current_Choice=Current_Choice+1;
                app.popupmenu6.Value=num2str(Current_Choice);
                GMpopup_Callback(app)
            else
                Current_Choice=1;
                app.popupmenu6.Value=num2str(Current_Choice);
                GMpopup_Callback(app)
            end
        end

        % Button pushed function: NextButton
        function NextButton_Callback(app, event)
            nGM=app.GMpopup.UserData;
            Current_GM_No=app.GMpopup.Value;
            if Current_GM_No<nGM
                Next_GM_No=Current_GM_No+1;
                app.GMpopup.Value=Next_GM_No;
                GMpopup_Callback(app)
            end
        end

        % Button pushed function: Previous2Button
        function Previous2Button_Callback(app, event)
            Current_Choice=str2double(app.popupmenu6.Value);
            if Current_Choice>1
                Current_Choice=Current_Choice-1;
                app.popupmenu6.Value=num2str(Current_Choice);
                GMpopup_Callback(app)
            else
                Current_Choice=8;
                app.popupmenu6.Value=num2str(Current_Choice);
                GMpopup_Callback(app)
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButton_Callback(app, event)
            nGM=app.GMpopup.UserData;
            Current_GM_No=app.GMpopup.Value;
            if Current_GM_No>1
                Previous_GM_No=Current_GM_No-1;
                app.GMpopup.Value=Previous_GM_No;
                GMpopup_Callback(app)
            end
        end

        % Button pushed function: Savebutton
        function Savebutton_Callback(app, event)
            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'GM_Option','nGM','GM','Wave_name','AddTime','g','Units')
            cd (MainDirectory)
            MaxPGA=app.axes1.UserData;
            GM_No=app.GMpopup.Value;
            Selection=str2double(app.popupmenu6.Value);
            
            clear VGx UGx Tx GAx
            
            evalc(['Tx=','GM.Time',num2str(GM_No)]);
            evalc(['GAx=','GM.GA',num2str(GM_No)]);
            
            VGx(1,1)=0;
            UGx(1,1)=0;
            for i=2:size(GAx,1)
                VGx(i,1)=VGx(i-1,1)+0.5*(GAx(i,1)+GAx(i-1,1))*(Tx(i,1)-Tx(i-1,1));
                UGx(i,1)=UGx(i-1,1)+0.5*(VGx(i,1)+VGx(i-1,1))*(Tx(i,1)-Tx(i-1,1));
            end
            
            evalc(['GMTime=','GM.Time',num2str(GM_No)]);
            evalc(['Duration=','GM.Time',num2str(GM_No),'(end,1)']);
            MainGMTime=Duration-AddTime;
            for i=1:size(GMTime,1)
                if GMTime(i,1)>=MainGMTime
                    idexMainGM=i;
                    break
                end
            end
            
            evalc(['T=','GM.Time',num2str(GM_No),'(1:idexMainGM,1)']);
            evalc(['GA=','GM.GA',num2str(GM_No),'(1:idexMainGM,1)']);
            evalc(['GV=','VGx(1:idexMainGM,1)']);
            evalc(['GU=','UGx(1:idexMainGM,1)']);
            
            if Selection==1 
            
                X=T;
                Y=GA;
            
            elseif Selection==2
            
                X=T;
                Y=GV;
            
            elseif Selection==3
            
                X=T;
                Y=GU;
            
            elseif Selection==4
            
                Fs=1/(T(3,1)-T(2,1));
                L=length(T);
                Y=fft(GA/g);
                P2 = abs(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
                f = Fs*(0:(L/2))/L;
                X=f;
                Y=P1;
            
            elseif Selection==5
            
                threshold= app.edit7.Value/100;
                dGA=diff(GA);
                dGA_squared=dGA.^2;
                dT=diff(T);
                Sum=0;
                for i=1:size(dT,1)
                    product=pi/g*dGA_squared(i,1)*dT(i,1);
                    Sum=Sum+product;
                    AI(i+1,1)=Sum;
                end
                X=T;
                Y=AI*100/AI(end,1);
                
            elseif Selection==6 
            
                X=T;
                Y=GA;
                
            elseif Selection==7

                [T, FIV]=Get_FIV3(app);
                X=T;
                Y=FIV;
                    
             elseif Selection==8
                [Period, MaxAA, SAavg]=Get_SAavg(app);
                X=Period;
                Y=MaxAA/g;
            end
            
            cd (FilePath)
            fileX = fopen(FileName,'wt');
            fprintf(fileX,'%s\t%s','X-Data','Y-Data');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','-------------------------------');
            fprintf(fileX,'\n');
            for i=1:size(X,1)
              fprintf(fileX,'%f\t%f\t', X(i,1), Y(i,1));
              fprintf(fileX,'\n');
            end
            fclose (fileX);
            cd (MainDirectory)
        end

        % Value changed function: edit7
        function edit7_Callback(app, event)
            if str2double(app.popupmenu6.Value)==5
                Get_Ds(app);
            elseif str2double(app.popupmenu6.Value)==7
                Get_FIV3(app);
            elseif str2double(app.popupmenu6.Value)==8
                Get_SAavg(app);
            end
        end

        % Value changed function: edit8
        function edit8_Callback(app, event)
            if str2double(app.popupmenu6.Value)==7
                app.edit7.Limits=[0.001 1];
                Get_FIV3(app);
            elseif str2double(app.popupmenu6.Value)==8
                app.edit7.Limits=[app.edit8.Value+0.001 10];
                Get_SAavg(app);
            end
        end

        % Value changed function: GMpopup
        function GMpopup_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'GM_Option','nGM','GM','Wave_name','AddTime','g','Units')
            cd (MainDirectory)

            app.axes1.cla
            app.axes1.FontName = 'Times';
            app.axes1.FontSize = 17;
            
            MaxPGA=app.axes1.UserData;
            GM_No=app.GMpopup.Value;
            Selection=str2double(app.popupmenu6.Value);

            app.text26.Tooltip='';
            app.text25.Tooltip='';
            
            clear VGx UGx Tx GAx
            
            evalc(['Tx=','GM.Time',num2str(GM_No)]);
            evalc(['GAx=','GM.GA',num2str(GM_No)]);
            
            VGx(1,1)=0;
            UGx(1,1)=0;
            for i=2:size(GAx,1)
                VGx(i,1)=VGx(i-1,1)+0.5*(GAx(i,1)+GAx(i-1,1))*(Tx(i,1)-Tx(i-1,1));
                UGx(i,1)=UGx(i-1,1)+0.5*(VGx(i,1)+VGx(i-1,1))*(Tx(i,1)-Tx(i-1,1));
            end
            
            evalc(['GMTime=','GM.Time',num2str(GM_No)]);
            evalc(['Duration=','GM.Time',num2str(GM_No),'(end,1)']);
            MainGMTime=Duration-AddTime;
            for i=1:size(GMTime,1)
                if GMTime(i,1)>=MainGMTime
                    idexMainGM=i;
                    break
                end
            end
            
            evalc(['T=','GM.Time',num2str(GM_No),'(1:idexMainGM,1)']);
            evalc(['GA=','GM.GA',num2str(GM_No),'(1:idexMainGM,1)']);
            evalc(['GV=','VGx(1:idexMainGM,1)']);
            evalc(['GU=','UGx(1:idexMainGM,1)']);
            dt=GM.dt(GM_No,1);
    
            if Selection==1
            
                app.edit7.Visible='off';
                app.text25.Visible='off';
                app.edit8.Visible='off';
                app.text26.Visible='off';
                app.TipIcon1.Visible='off';
            
                %% Plot the ground acceleration history
                plot(app.axes1,T,GA/g,'-r','linewidth',1.5)
                app.axes1.XLabel.String='Time [sec]';
                app.axes1.YLabel.String='Acceleration [g]';
                app.axes1.XLabel.FontName='Times';
                app.axes1.YLabel.FontName='Times';
            
                %% Plot Origin Lines
                plot(app.axes1,[0 MainGMTime*2],[0 0],'-k')
                app.axes1.YLim=[-max(abs(GA))*1.3/g max(abs(GA))*1.3/g];
                app.axes1.XLim=[0 MainGMTime*1.1];
            
                %% Add label at pga
                [MaxGA, IndexMaxGA]=max(abs(GA));
                plot(app.axes1,T(IndexMaxGA,1),GA(IndexMaxGA,1)/g,'-ok','linewidth',1,'Markerfacecolor','g')
                text(app.axes1,T(IndexMaxGA,1),GA(IndexMaxGA,1)*1.15/g,[' ',num2str(round(MaxGA*100/g)/100),' g'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            
                text(app.axes1,0.02*T(end,1),-max(GA(IndexMaxGA,1))*1.1/g,['dt=',num2str(dt),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            
                if AddTime~=0
                    plot(app.axes1,T(end,1),GA(end,1)/g,'-ok','linewidth',1,'Markerfacecolor','r')
                    text(app.axes1,T(end,1),abs(GA(IndexMaxGA,1)/g/5),['+',num2str(AddTime),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                end


            elseif Selection==2
            
                app.edit7.Visible='off';
                app.text25.Visible='off';
                app.edit8.Visible='off';
                app.text26.Visible='off';
                app.TipIcon1.Visible='off';
            
                %% Plot the ground acceleration history
                plot(app.axes1,T,GV,'-r','linewidth',1.5)
                app.axes1.XLabel.String='Time [sec]';
                if Units==1; app.axes1.YLabel.String='Velocity [m/sec]'; end
                if Units==2; app.axes1.YLabel.String='Velocity [in/sec]'; end
                
                %% Plot Origin Lines
                plot(app.axes1,[0 MainGMTime*2],[0 0],'-k')
                app.axes1.YLim=[-max(abs(GV))*1.3 max(abs(GV))*1.3];
                app.axes1.XLim=[0 MainGMTime*1.1];
            
                %% Add label at pva
                [MaxGV, IndexMaxGV]=max(abs(GV));
                plot(app.axes1,T(IndexMaxGV,1),GV(IndexMaxGV,1),'-ok','linewidth',1,'Markerfacecolor','g')
                if Units==1;     text(app.axes1,T(IndexMaxGV,1),GV(IndexMaxGV,1)*1.15,[' ',num2str(round(MaxGV*100)/100),' m/sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold'); end
                if Units==2;     text(app.axes1,T(IndexMaxGV,1),GV(IndexMaxGV,1)*1.15,[' ',num2str(round(MaxGV*100)/100),' in/sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold'); end
                text(app.axes1,0.02*T(end,1),-max(GV(IndexMaxGV,1))*1.1,['dt=',num2str(dt),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            
                if AddTime~=0
                    plot(app.axes1,T(end,1),GV(end,1),'-ok','linewidth',1,'Markerfacecolor','r')
                    text(app.axes1,T(end,1),abs(GV(IndexMaxGV,1)/5),['+',num2str(AddTime),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                end
            
            elseif Selection==3
            
                app.edit7.Visible='off';
                app.text25.Visible='off';
                app.edit8.Visible='off';
                app.text26.Visible='off';
                app.TipIcon1.Visible='off';
            
                %% Plot the ground acceleration history
                plot(app.axes1,T,GU,'-r','linewidth',1.5)
                app.axes1.XLabel.String='Time [sec]';
                if Units==1; app.axes1.YLabel.String='Displacement [m]'; end
                if Units==2; app.axes1.YLabel.String='Displacement [in]'; end
                
                %% Plot Origin Lines
                plot(app.axes1,[0 MainGMTime*2],[0 0],'-k')
                app.axes1.YLim=[-max(abs(GU))*1.3 max(abs(GU))*1.3];
                app.axes1.XLim=[0 MainGMTime*1.1];
            
                %% Add label at pga
                [MaxGU, IndexMaxGU]=max(abs(GU));
                plot(app.axes1,T(IndexMaxGU,1),GU(IndexMaxGU,1),'-ok','linewidth',1,'Markerfacecolor','g')
                if Units==1;     text(app.axes1,T(IndexMaxGU,1),GU(IndexMaxGU,1)*1.15,[' ',num2str(round(MaxGU*100)/100),' m'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold'); end
                if Units==2;     text(app.axes1,T(IndexMaxGU,1),GU(IndexMaxGU,1)*1.15,[' ',num2str(round(MaxGU*100)/100),' in'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold'); end
                text(app.axes1,0.02*T(end,1),-max(GU(IndexMaxGU,1))*1.1,['dt=',num2str(dt),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            
                if AddTime~=0
                    plot(app.axes1,T(end,1),GU(end,1),'-ok','linewidth',1,'Markerfacecolor','r')
                    text(app.axes1,T(end,1),abs(GU(IndexMaxGU,1)/5),['+',num2str(AddTime),'sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                end
            
            % Plot FFT
            elseif Selection==4
            
                app.edit7.Visible='off';
                app.text25.Visible='off';
                app.edit8.Visible='off';
                app.text26.Visible='off';
                app.TipIcon1.Visible='off';
            
                app.axes1.XLabel.String='Frequency [Hz]';
                app.axes1.YLabel.String='FFT Amplitude';
                
                Fs=1/(T(3,1)-T(2,1));
                L=length(T);
                Y=fft(GA/g);
                P2 = abs(Y/L);
                P1 = P2(1:L/2+1);
                P1(2:end-1) = 2*P1(2:end-1);
            
                f = Fs*(0:(L/2))/L;
                plot(app.axes1,f,P1,'-r','linewidth',1.5)
                app.axes1.YLim=[0 max(P1)*1.2];
                app.axes1.XLim=[0 20];
            
                %% Add label at pga
                [MaxGA, IndexMaxGA]=max(abs(P1));
                plot(app.axes1,f(IndexMaxGA),P1(IndexMaxGA),'-ok','linewidth',1,'Markerfacecolor','g')
                text(app.axes1,f(IndexMaxGA),P1(IndexMaxGA)*1.10,[' ',num2str(round(f(IndexMaxGA)*100)/100),' Hz'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                text(app.axes1,f(IndexMaxGA),P1(IndexMaxGA)*1.,[' ',num2str((round((1/f(IndexMaxGA))*100)/100)),' sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            
                % Plot Arias Intensity
            elseif Selection==5
            
                app.edit7.Visible='on';
                app.text25.Visible='on';
                app.edit8.Visible='off';
                app.text26.Visible='off';
                app.edit7.Limits=[0 50];
                app.edit7.Value=10;
                app.text25.Text='Threshold [%]';
                app.TipIcon1.Visible='off';

                Get_Ds (app)
            
            elseif Selection==6
            
                app.edit7.Visible='off';
                app.edit8.Visible='off';
                app.text25.Visible='off';
                app.text26.Visible='off';
                app.TipIcon1.Visible='off';
            
                % Get Maximum Incremental Velocity
                dT=T(2,1)-T(1,1);
                ZEROindx(1,1)=1;
                for i=2:size(GA,1)-1
                    if GA(i,1)*GA(i+1,1)<=0
                        ZEROindx(end+1,1)=i;
                    end
                end
            
                for i=1:size(ZEROindx,1)-1
                    Sum=0;
                    for j=ZEROindx(i,1):ZEROindx(i+1,1)-1
                        Sum=Sum+abs(0.5*(GA(j,1)+GA(j+1,1))*dT);
                    end
                    IV(i,1)=Sum;
                end
            
                        % Getting the effective duration region to set the Xlim
                        threshold=0.05;
                        dGA=diff(GA);
                        dGA_squared=dGA.^2;
                        dT=diff(T);
                        Sum=0;
                        for i=1:size(dT,1)
                            product=pi/g*dGA_squared(i,1)*dT(i,1);
                            Sum=Sum+product;
                            AI(i+1,1)=Sum;
                        end
            
                        for i=2:size(AI,1)
                            if AI(i-1,1)/AI(end,1)<=threshold && AI(i,1)/AI(end,1)>=threshold
                                indexAI_5=i;
                                T_at_AI_5=T(i,1);
                            end
                            if AI(i-1,1)/AI(end,1)<=(1-threshold) && AI(i,1)/AI(end,1)>=(1-threshold)
                                indexAI_95=i;
                                T_at_AI_95=T(i,1);
                            end
                        end
            
                [MaxIV, Index]=max(abs(IV));
                IndexMaxIV1=ZEROindx(Index,1);
                IndexMaxIV2=ZEROindx(Index+1,1);
                PulseDuration=T(IndexMaxIV2,1)-T(IndexMaxIV1,1);
            
                %% Plot the ground acceleration history
                plot(app.axes1,T,GA/g,'-r','linewidth',1.5)
                app.axes1.XLabel.String='Time [sec]';
                app.axes1.YLabel.String='Acceleration [g]';
                
                %% Plot Origin Lines
                plot(app.axes1,[0 MainGMTime*2],[0 0],'-k')
                app.axes1.YLim=[-max(abs(GA))*1.3/g max(abs(GA))*1.3/g];
                app.axes1.XLim=[T_at_AI_5*0.85 T_at_AI_95*1.15];
                app.axes1.XLim=[0 MainGMTime*1.1];
            
                plot(app.axes1,[T(IndexMaxIV1,1) T(IndexMaxIV1,1)],[-max(abs(GA))*1.3/g max(abs(GA))*1.3/g],'-k')
                plot(app.axes1,[T(IndexMaxIV2,1) T(IndexMaxIV2,1)],[-max(abs(GA))*1.3/g max(abs(GA))*1.3/g],'-k')
            
                X= T(IndexMaxIV1:IndexMaxIV2,1);
                Y=GA(IndexMaxIV1:IndexMaxIV2,1)/g;
                fill(app.axes1,X,Y,'y');
                if Units==1
                    text(app.axes1,T(IndexMaxIV2,1),max(abs(GA))*1.05/g,['IV=',num2str(round(MaxIV*100)/100),' m/sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                else
                    text(app.axes1,T(IndexMaxIV2,1),max(abs(GA))*1.05/g,['IV=',num2str(round(MaxIV*100)/100),' in/sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')                   
                end
            
            
            elseif Selection==7
            
                app.edit7.Visible='on';
                app.edit8.Visible='on';
                app.text25.Visible='on';
                app.text26.Visible='on';
                app.text25.Text='beta'; %Cut-off Frequency [Hz]
                app.text26.Text='alpha'; %Time Segment [sec]'
                app.edit7.Limits=[0.001 1];
                app.edit8.Limits=[0.001 1];
                app.edit7.Value=0.8;
                app.edit8.Value=0.65;
                app.TipIcon1.Visible='on';
                edit8_Callback(app);
            
            elseif Selection==8
            
                app.edit7.Visible='on';
                app.edit8.Visible='on';
                app.text25.Visible='on';
                app.text26.Visible='on';
                app.text25.Text='End period [sec]';
                app.text26.Text='Start period [sec]';
                app.edit8.Limits=[0.001 10];
                app.edit7.Limits=[0.001 10];
                app.edit8.Value=1;
                app.edit7.Value=2;
                app.TipIcon1.Visible='off';
                edit8_Callback(app);
            end
        end

        % Value changed function: popupmenu6
        function popupmenu6_Callback(app, event)
            GMpopup_Callback(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            fclose all;
            delete(app)
        end

        % Button pushed function: TipIcon1
        function TipIcon1ButtonPushed(app, event)
            str{1}='For FIV3 computation, refer to this publication:';
            str{2}='';
            str{3}='Dávalos, H. and E. Miranda "Filtered Incremental Velocity: A Novel Approach in Intensity Measures for Seismic Collapse Estimation." Earthquake Engineering & Structural Dynamics. https://doi.org/10.1002/eqe.3205';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Ground Motion Plot';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Ground Motion Inspector';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.XLim = [-0.110161604692016 0.110161604692016];
            app.axes1.YLim = [-3.17482251867287 3.17482251867287];
            app.text24 = uilabel(app.uipanel1);
            app.text24.Text = 'Plot Type:';
            app.text23 = uilabel(app.uipanel1);
            app.text23.Text = 'Ground Motion:';
            app.GMpopup = uidropdown(app.uipanel1);
            app.GMpopup.Items = {'1'};
            app.GMpopup.ItemsData = {'1'};
            app.GMpopup.ValueChangedFcn = createCallbackFcn(app, @GMpopup_Callback, true);
            app.GMpopup.Value = '1';
            app.NextButton = uibutton(app.uipanel1, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButton_Callback, true);
            app.NextButton.Tooltip = 'Next Ground Motion';
            app.NextButton.Text = '>';
            app.PreviousButton = uibutton(app.uipanel1, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_Callback, true);
            app.PreviousButton.Tooltip = 'Preious Ground Motion';
            app.PreviousButton.Text = '<';
            app.popupmenu6 = uidropdown(app.uipanel1);
            app.popupmenu6.Items = {'Acceleration History', 'Velocity History', 'Displacement History', 'Fourier Transform (Frequency Domain)', 'Significant Duration (Ds)', 'Incremental Velocity', 'Filtered Incremental Velocity 3', 'SAavg'};
            app.popupmenu6.ItemsData = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.popupmenu6.ValueChangedFcn = createCallbackFcn(app, @popupmenu6_Callback, true);
            app.popupmenu6.Value = '1';
            app.Next2Button = uibutton(app.uipanel1, 'push');
            app.Next2Button.ButtonPushedFcn = createCallbackFcn(app, @Next2Button_Callback, true);
            app.Next2Button.Tooltip = 'Next Plot';
            app.Next2Button.Text = '>';
            app.Previous2Button = uibutton(app.uipanel1, 'push');
            app.Previous2Button.ButtonPushedFcn = createCallbackFcn(app, @Previous2Button_Callback, true);
            app.Previous2Button.Tooltip = 'Previous Plot';
            app.Previous2Button.Text = '<';
            app.text25 = uilabel(app.uipanel1);
            app.text25.Text = 'Threshold [%]';
            app.Savebutton = uibutton(app.uipanel1, 'push');
            app.Savebutton.ButtonPushedFcn = createCallbackFcn(app, @Savebutton_Callback, true);
            app.Savebutton.Text = 'save plot data';
            app.text26 = uilabel(app.uipanel1);
            app.text26.Text = 'Threshold [%]';
            app.edit7 = uieditfield(app.uipanel1, 'numeric');
            app.edit7.Limits = [0 Inf];
            app.edit7.ValueDisplayFormat = '%5.2f';
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit8 = uieditfield(app.uipanel1, 'numeric');
            app.edit8.Limits = [0 Inf];
            app.edit8.ValueDisplayFormat = '%5.2f';
            app.edit8.ValueChangedFcn = createCallbackFcn(app, @edit8_Callback, true);
            app.TipIcon1 = uibutton(app.uipanel1, 'push');
            app.TipIcon1.ButtonPushedFcn = createCallbackFcn(app, @TipIcon1ButtonPushed, true);
            app.TipIcon1.Text = '?';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_GroundMotion_Plot_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_GroundMotion_Plot_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end