classdef Module_Hazard_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        ButtonGroup       matlab.ui.container.ButtonGroup
        HazardRadio1      matlab.ui.control.RadioButton
        HazardRadio2      matlab.ui.control.RadioButton
        BrowseButton      matlab.ui.control.Button
        HazardPopup1      matlab.ui.control.DropDown
        text2             matlab.ui.control.Label
        text3             matlab.ui.control.Label
        text4             matlab.ui.control.Label
        text5             matlab.ui.control.Label
        TipIcon2          matlab.ui.control.Button
        text7             matlab.ui.control.Label
        BrowseMapsbutton  matlab.ui.control.Button
        TipIcon1          matlab.ui.control.Button
        edit1             matlab.ui.control.NumericEditField
        edit2             matlab.ui.control.NumericEditField
        edit3             matlab.ui.control.NumericEditField
        SubmitButton      matlab.ui.control.Button
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Hazard_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Hazardstatus','Hazard_Option','Latitude','Longitude','T_hazard','Vs30_CaseNo','T')
            cd (MainDirectory)
            
            if Hazardstatus==1 && Hazard_Option ==1
                app.HazardRadio1.Value=1;
                app.edit1.Value=Latitude;
                app.edit2.Value=Longitude;
                app.edit3.Value=T_hazard;
                app.HazardPopup1.Value=Vs30_CaseNo;
            elseif Hazardstatus==1 && Hazard_Option ==2
                app.HazardRadio2.Value=1;
            else
                app.HazardRadio1.Value=1;
                app.edit3.Value=T;
            end
            
            Sa_start=0.001;
            dSa=0.001;
            Sa_end=5.0;
            
            cd (ProjectPath)
            pause(0.2)
            save(ProjectName,'Sa_start','dSa','Sa_end','-append')
            pause(0.2)
            cd (MainDirectory)

        end

        % Button pushed function: BrowseButton
        function BrowseButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            if app.HazardRadio2.Value == 1
                [HazardFilename HazardFilePath]= uigetfile({'*.txt'},'File Selector');
                cd (ProjectPath)
                save(ProjectName,'HazardFilename','HazardFilePath','-append')
                pause(0.5)
                cd (MainDirectory)
            else
                ed = errordlg('User-defined Input is not Selected','Error', 'modal');
                uiwait(ed);
                return
            end
        end

        % Button pushed function: BrowseMapsbutton
        function BrowseMapsbutton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            
            if app.HazardRadio1.Value == 1
                HazardFolderPath= uigetdir;
                cd (ProjectPath)
                pause(0.1)
                save (ProjectName,'HazardFolderPath','-append')
                pause(0.5)
                cd (MainDirectory)
            else
                ed = errordlg('Option 1 is not Selected','Error', 'modal');
                uiwait(ed);
                return
            end
        end

        % Callback function
        function HazardEdit1_Callback(app, event)
            Latitude=app.edit1.Value;
            if     Latitude>=24.6 && Latitude<=50
               app.edit1.FontColor='k';
            else
               app.edit1.FontColor='r';
            end
        end

        % Callback function
        function HazardEdit2_Callback(app, event)
            Longitude=app.edit2.Value;
            if     Longitude>=-125 && Longitude<=-100
               app.edit2.FontColor='k';
            else
               app.edit2.FontColor='r';
            end
        end

        % Callback function
        function edit3_Callback(app, event)
            if app.edit3.Value>0 && app.edit3.Value<10
               app.edit3.FontColor='k';
            else
               app.edit3.FontColor='r';
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc
            
            Vs30_CaseNo =app.HazardPopup1.Value;
            switch Vs30_CaseNo
                case 1
                    Vs30  = 180;
                case 2
                    Vs30  = 259;
                case 3
                    Vs30  = 360;
                case 4
                    Vs30  = 537;
                case 5
                    Vs30  = 760;
            end
            
            if app.HazardRadio1.Value==1
                Latitude=app.edit1.Value;
                Longitude=app.edit2.Value;
                T_hazard=app.edit3.Value;
                cd (ProjectPath)
                load(ProjectName,'HazardFolderPath','SaAVG_status')
                cd (MainDirectory)
            
                %% INPUT DATA CHECKS
                if SaAVG_status ==1
                    ed = warndlg('Note that the extracted Hazard Curve will be based on SA at the specified period herein and not SA_avg!','Error', 'modal');
                    uiwait(ed);
                end
            
                if  T_hazard<0.0 || T_hazard>5.0 ||Latitude<24.6 || Latitude>50 || Longitude<-125 || Longitude>-100
                    ed = errordlg('Check your input. The period or the coordinates are outside the acceptable range','Error', 'modal');
                    uiwait(ed);
                    return
                end
            
                if isnan(T_hazard) ||isnan(Latitude) || isnan(Longitude)
                    ed = errordlg('Some paramters are not defined. Please define all the required paramters.','Error', 'modal');
                    uiwait(ed);
                    return
                end
                %%
                str{1}='Extracting Hazard Data...';
                str{2}='Please Wait';
                app.TipWindow=Tip_App;
                app.TipWindow.textbox.Text=str;%text1 is the value tag for the Static Text in GUI1
                drawnow
            
                [HazardCurveData]=Get_HazardCurve_USGSMaps(HazardFolderPath,Latitude,Longitude,T_hazard,Vs30);
            
                Hazardstatus=1;
                Hazard_Option=1;
            
                cd (MainDirectory)
                cd (ProjectPath)
                pause(0.2)
                save(ProjectName,'Latitude','Longitude','T_hazard','Vs30','Hazard_Option','Vs30_CaseNo','-append')
                pause(1.0)
                cd (MainDirectory)
            end
            
            if app.HazardRadio2.Value == 1
                clc
                cd (ProjectPath)
                load(ProjectName,'HazardFilename','HazardFilePath')
                cd (MainDirectory)
                if ischar(HazardFilename)
                    HazardCurveData=importdata(strcat(HazardFilePath,HazardFilename));
                    Hazardstatus=1;
                    Hazard_Option=2;
                    for i=1:size(HazardCurveData,1)
                        for j=1:size(HazardCurveData,2)
                            if HazardCurveData(i,j)==0
                                ed = errordlg('**The Sa and Annual Fequency of Exceedance values MUST NOT BE EQUAL TO ZERO**','Error', 'modal');
                                uiwait(ed);
                                return
                            end
                        end
                    end
                    cd (ProjectPath)
                    pause(0.2)
                    save(ProjectName,'Hazard_Option','-append');
                    pause(1.0)
                    cd (MainDirectory)
                end
            end
            
            % Fitting a 4th order polynomial
            SA=HazardCurveData(:,1);
            MAFE=HazardCurveData(:,2);
            COEFF=polyfit(log(SA),log(MAFE),4);
            
            if app.HazardRadio1.Value == 1
                app.TipWindow.textbox.Text='Done...Hazard Curve was Extracted and Fitted';
                drawnow
                delete(app.TipWindow)
            end
            
            cd (ProjectPath)
            pause(0.2)
            save(ProjectName,'COEFF','HazardCurveData','Hazardstatus','-append');
            pause(1.0)
            cd (MainDirectory)
            
            app.MainApp.Image5.Visible='off';
            closereq;
        end

        % Button pushed function: TipIcon1
        function TipIcon1_Callback(app, event)
            str{1}='This option extracts the hazard curve from the USGS Western US hazard maps.';
            str{2}='Linear interpolation is conducted based the specified building corrdinates, period and soil condition.';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Button pushed function: TipIcon2
        function TipIcon2_Callback(app, event)
            str{1}='Import hazard curve data from a file.';
            str{2}='The file should have .txt extension with two columns of data: Sa in [g] units (1st column) and Annual Fequency of Exceedance (2nd column).';
            str{3}='**The Sa and Annual Fequency of Exceedance values MUST NOT BE EQUAL TO ZERO**';
            app.TipWindow=Tip_App;
            app.TipWindow.textbox.Value=str;
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            selectedButton = app.ButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Seismic Hazard';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.ButtonGroup = uibuttongroup(app.figure1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.HazardRadio1 = uiradiobutton(app.ButtonGroup);
            app.HazardRadio1.Text = 'USGS Hazard Curve';
            app.HazardRadio1.Value = true;
            app.HazardRadio2 = uiradiobutton(app.ButtonGroup);
            app.HazardRadio2.Text = 'User-defined Hazard Curve';
            app.BrowseButton = uibutton(app.ButtonGroup, 'push');
            app.BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @BrowseButton_Callback, true);
            app.BrowseButton.Tooltip = 'Browse for a text file containing seismic hazard data.';
            app.BrowseButton.Text = 'Browse';
            app.HazardPopup1 = uidropdown(app.ButtonGroup);
            app.HazardPopup1.Items = {'180', '259', '360', '537', '760'};
            app.HazardPopup1.Tooltip = 'Shear wave velocity in the upper 30m soil layer (i.e., site condition)';
            app.HazardPopup1.Value = '180';
            app.text2 = uilabel(app.ButtonGroup);
            app.text2.Text = 'Latitude';
            app.text3 = uilabel(app.ButtonGroup);
            app.text3.Text = 'Longitude';
            app.text4 = uilabel(app.ButtonGroup);
            app.text4.Text = 'T [sec]';
            app.text5 = uilabel(app.ButtonGroup);
            app.text5.Tooltip = 'Shear wave velocity in the upper 30m soil layer (i.e., site condition)';
            app.text5.Text = 'Vs30 [m/sec]';
            app.TipIcon2 = uibutton(app.ButtonGroup, 'push');
            app.TipIcon2.ButtonPushedFcn = createCallbackFcn(app, @TipIcon2_Callback, true);
            app.TipIcon2.Text = '?';
            app.text7 = uilabel(app.ButtonGroup);
            app.text7.Tooltip = 'Browse for "USGS Hazard MAT Data" folder (Downloadable with IIDAP)';
            app.text7.Text = 'Hazard Maps Data Folder';
            app.BrowseMapsbutton = uibutton(app.ButtonGroup, 'push');
            app.BrowseMapsbutton.ButtonPushedFcn = createCallbackFcn(app, @BrowseMapsbutton_Callback, true);
            app.BrowseMapsbutton.Tooltip = 'Browse for "USGS Hazard MAT Data" folder (Provided with IIDAP)';
            app.BrowseMapsbutton.Text = 'Browse';
            app.TipIcon1 = uibutton(app.ButtonGroup, 'push');
            app.TipIcon1.ButtonPushedFcn = createCallbackFcn(app, @TipIcon1_Callback, true);
            app.TipIcon1.Text = '?';
            app.edit1 = uieditfield(app.ButtonGroup, 'numeric');
            app.edit1.Limits = [24.6 50];
            app.edit1.ValueDisplayFormat = '%.3f';
            app.edit1.Value = 33.996;
            app.edit2 = uieditfield(app.ButtonGroup, 'numeric');
            app.edit2.Limits = [-125 -100];
            app.edit2.ValueDisplayFormat = '%.3f';
            app.edit2.Value = -118.162;
            app.edit3 = uieditfield(app.ButtonGroup, 'numeric');
            app.edit3.Limits = [0 Inf];
            app.edit3.ValueDisplayFormat = '%.3f';
            app.edit3.Value = 1;
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Hazard_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Hazard_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end