classdef Module_Integrator_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        axes4         matlab.ui.control.UIAxes
        SubmitButton  matlab.ui.control.Button
        edit6         matlab.ui.control.EditField
        edit7         matlab.ui.control.EditField
        text18        matlab.ui.control.Label
        edit8         matlab.ui.control.EditField
        text19        matlab.ui.control.Label
        text20        matlab.ui.control.Label
        edit9         matlab.ui.control.EditField
        text21        matlab.ui.control.Label
        edit10        matlab.ui.control.EditField
    end

    
    methods (Access = private)
        function [dt_critical_GM, dt_critical_Integrator, dt_critical] = Check_dt_critical(app, handles, T, GM)
            dt_critical_GM=min(GM.dt);
            gamma=str2double(get(app.edit6,'String'));
            beta=str2double(get(app.edit7,'String'));
            
            if get(app.RFradiobutton,'Value')==1
                    dt_critical_Integrator = dt_critical_GM;
            end
            
            if get(app.CDradiobutton,'Value')==1
                    dt_critical_Integrator = round(T*100/10.0)/100;
            end
            
            if get(app.NMradiobutton,'Value')==1
                if and(beta >= gamma/2, beta >= 1/4) % Average Acceleration Method
                    dt_critical_Integrator = 'infinity';
                elseif and(beta == 0, gamma == 1/2)  % Central Difference Method
                    dt_critical_Integrator = T/3.141593;
                elseif and((abs(beta-1/6) <= 0.008),gamma == 1/2) % Linear Acceleration Method
                    dt_critical_Integrator = round(sqrt(3)*T*100/3.141593)/100;
                else
                    dt_critical_Integrator = 'unkown';
                end
            end
            
            set(app.edit9,'String',dt_critical_Integrator)
            set(app.edit10,'String',dt_critical_GM)
            if strcmp(dt_critical_Integrator,'infinity')
               dt_critical_IntegratorX= dt_critical_GM;
            elseif strcmp(dt_critical_Integrator,'unkown')
               dt_critical_IntegratorX= dt_critical_GM;
            else
               dt_critical_IntegratorX=dt_critical_Integrator;
            end
            
            dt_critical=min(dt_critical_GM,dt_critical_IntegratorX);
            set(app.edit8,'String',dt_critical)
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Integrator_OpeningFcn(app)
%             global MainDirectory ProjectPath ProjectName
%             clc;
%             cd (ProjectPath)
%             load (ProjectName,'SystemModel_Option','Integrator_Option','dt_user')
%             cd (MainDirectory)
%             
%             axes(app.axes4);
%             imshow ('NMsymbols.png')
%             axis image
%             
%             set(app.NMradiobutton,'Value',1);
%             NMradiobutton_Callback(hObject, eventdata, handles)
%             
%             if SystemModel_Option~=1
%                 set(app.NMradiobutton,'Enable','on');
%             else
%                 set(app.NMradiobutton,'Enable','off');
%             end
%             
%             if Integratorstatus==1
%                    if Integrator_Option==1
%                      set(app.NMradiobutton,'Value',1);
%                       NMradiobutton_Callback(hObject, eventdata, handles)
%                    elseif Integrator_Option==2
%                      set(app.CDradiobutton,'Value',1);
%                       CDradiobutton_Callback(hObject, eventdata, handles)
%                    elseif Integrator_Option==3
%                      set(app.RFradiobutton,'Value',1);
%                       RFradiobutton_Callback(hObject, eventdata, handles)
%                    end
%                 set(app.edit8,'String',dt_user)
%             end
%             
%             app.output = hObject;
%             guidata(hObject, handles);
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load (ProjectName,'T','GM','gamma','beta')
            cd (MainDirectory)
            
            dt_user=str2double(get(app.edit8,'String'));
            
            if get(app.RFradiobutton,'Value')==1
                Integrator_Option=3;
            end
            
            if get(app.CDradiobutton,'Value')==1
                Integrator_Option=1;
            end
            
            if get(app.NMradiobutton,'Value')==1
                Integrator_Option=2;
                gamma=str2double(get(app.edit6,'String'));
                beta=str2double(get(app.edit7,'String'));
            end
            
            [dt_critical_GM,dt_critical_Integrator,dt_critical]=Check_dt_critical (handles,T,GM);
            
            %% INPUT VALUES CHECKS BEFORE SUBMISSION
            if isnan(dt_user) || isnan(gamma) || isnan(beta)
                ed = errordlg('Some paramters are not defined. Please define all the required paramters.','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            %%
            if dt_user > dt_critical
                ed = errordlg('WARNING: The specified time step is larger than the critical time step!','Error', 'modal');
                uiwait(ed);
            end
            
            Integratorstatus=1;
            
            cd (ProjectPath)
            save(ProjectName,'Integratorstatus','Integrator_Option','gamma','beta','dt_user','dt_critical_GM','dt_critical_Integrator','-append')
            pause(0.5)
            cd (MainDirectory)
            
            close
        end

        % Value changed function: edit6
        function edit6_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load (ProjectName,'T','GM')
            cd (MainDirectory)
            [dt_critical_GM,dt_critical_Integrator,dt_critical]=Check_dt_critical (handles,T,GM);
        end

        % Value changed function: edit7
        function edit7_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load (ProjectName,'T','GM')
            cd (MainDirectory)
            [dt_critical_GM,dt_critical_Integrator,dt_critical]=Check_dt_critical (handles,T,GM);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Integrator Type';
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Choose Integerator';
            app.axes4 = uiaxes(app.uipanel1);
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.edit6 = uieditfield(app.uipanel1, 'text');
            app.edit6.ValueChangedFcn = createCallbackFcn(app, @edit6_Callback, true);
            app.edit6.Value = '0.5';
            app.edit7 = uieditfield(app.uipanel1, 'text');
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit7.Value = '0.25';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Tooltip = 'Time step to be used by the integrator.';
            app.text18.Text = 'Time step [sec]';
            app.edit8 = uieditfield(app.uipanel1, 'text');
            app.edit8.Tooltip = 'Time step to be used by the integrator.';
            app.edit8.Value = '0.5';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Text = 'Critical time step [sec]';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Tooltip = 'Critical time step for stability based on Integrator type';
            app.text20.Text = 'Inegrator';
            app.edit9 = uieditfield(app.uipanel1, 'text');
            app.edit9.Enable = 'off';
            app.edit9.Tooltip = 'Critical time step for stability based on Integrator type';
            app.edit9.Value = '0.5';
            app.text21 = uilabel(app.uipanel1);
            app.text21.Tooltip = 'Critical time step based on input ground motion(s)';
            app.text21.Text = 'GM';
            app.edit10 = uieditfield(app.uipanel1, 'text');
            app.edit10.Enable = 'off';
            app.edit10.Tooltip = 'Critical time step based on input ground motion(s)';
            app.edit10.Value = '0.5';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Integrator_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Integrator_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end