classdef Module_Results_AnimationF_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        uipanel1    matlab.ui.container.Panel
        axes1       matlab.ui.control.UIAxes
        text23      matlab.ui.control.Label
        PlayButton  matlab.ui.control.Button
        text24      matlab.ui.control.Label
        text25      matlab.ui.control.Label
        text26      matlab.ui.control.Label
        slider1     matlab.ui.control.Slider
        GMpopup     matlab.ui.control.DropDown
    end

    
    methods (Access = private)
        function Animate(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName)
            cd (MainDirectory)
            
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            t_pause=0.01;
            
            if Units==1
                app.axes1.XLabel.String='Displacement [m]';
                app.axes1.YLabel.String='Force [kN]';
            else
                app.axes1.XLabel.String='Displacement [in]';
                app.axes1.YLabel.String='Force [kip]';
            end
            app.axes1.XLim=[-max(abs(U_anime))*1.5 max(abs(U_anime))*1.5];
            app.axes1.YLim=[-max(abs(F_anime))*1.15 max(abs(F_anime))*1.15];
            
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
            
            start=app.GMpopup.UserData;
            
            for i=start:1:size(U_anime,1)
            
                h7=plot(app.axes1,U_anime(1:i,1),F_anime(1:i,1),'-k','linewidth',1.5);
                h8=plot(app.axes1,U_anime(i,1),F_anime(i,1), '-ko','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',5);
                h9=text(app.axes1,-max(abs(U_anime))*1.45,max(abs(F_anime))*1.05,['Time=',num2str(round(T_anime(i,1)*100)/100),'/',num2str(T_anime(end,1)),' sec'], 'fontname', 'Courier', 'fontsize',14);
            
                 % Plot the red mass ball
                if i>=Collapse_Index_anime && Collapse_Index_anime~=0
                    h12=plot(app.axes1,U_anime(i,1),F_anime(i,1), '-ko','MarkerEdgeColor','k','MarkerFaceColor',[0.7 0.7 0.7],'MarkerSize',5);
                    h6=text(app.axes1,-max(abs(U_anime))*1.45,max(abs(F_anime))*0.90,'COLLAPSE', 'fontname', 'Courier', 'fontsize',14, 'color','r','fontweight','bold');
                else
                    h12=plot(app.axes1,U_anime(i,1),F_anime(i,1), '-ko','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',5);
                    h6=text(app.axes1,-max(abs(U_anime))*1.45,max(abs(F_anime))*0.90,'', 'fontname', 'Courier', 'fontsize',14);
                end
                h10=plot(app.axes1,[0.0 0.0],[-max(abs(F_anime))*2.0 max(abs(F_anime))*2.0], '-k','linewidth',1);
                h11=plot(app.axes1,[-max(abs(U_anime))*2.0 max(abs(U_anime))*2.0],[0.0 0.0], '-k','linewidth',1);
            
                if app.PlayButton.UserData==2
                    break;
                end
            
                t_pause=1-app.slider1.Value;
            
                pause(t_pause)
            
                delete(h6);
                delete(h7);
                delete(h8);
                delete(h9);
                delete(h10);
                delete(h11);
                delete(h12);
            end
            
                if app.PlayButton.UserData==2
                    app.GMpopup.UserData=i;
                    app.PlayButton.Text='Play';
                    app.PlayButton.UserData=2;
                else
            
                if i>=Collapse_Index_anime && Collapse_Index_anime~=0
                    h12=plot(app.axes1,U_anime(end,1),F_anime(end,1), '-ko','MarkerEdgeColor','k','MarkerFaceColor',[0.7 0.7 0.7],'MarkerSize',5);
                    h6=text(app.axes1,-max(abs(U_anime))*1.45,max(abs(F_anime))*0.90,'COLLAPSE', 'fontname', 'Courier', 'fontsize',14, 'color','r','fontweight','bold');
                else
                    h12=plot(app.axes1,U_anime(end,1),F_anime(end,1), '-ko','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',5);
                    h6=text(app.axes1,-max(abs(U_anime))*1.45,max(abs(F_anime))*0.90,'', 'fontname', 'Courier', 'fontsize',14);
                end
                h9=text(app.axes1,-max(abs(U_anime))*1.45,max(abs(F_anime))*1.05,['Time=',num2str(round(T_anime(end,1)*100)/100),'/',num2str(T_anime(end,1)),' sec'], 'fontname', 'Courier', 'fontsize',14);
                plot(app.axes1,[0.0 0.0],[-max(abs(F_anime))*2.0 max(abs(F_anime))*2.0], '-k','linewidth',1);
                plot(app.axes1,[-max(abs(U_anime))*2.0 max(abs(U_anime))*2.0],[0.0 0.0], '-k','linewidth',1);
            
                plot(app.axes1,U_anime(:,1),F_anime(:,1),'-k','linewidth',1.5);
                plot(app.axes1,U_anime(end,1),F_anime(end,1), '-ko','MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',5);
            
                app.GMpopup.UserData=1;
                app.PlayButton.Text='Play';
                app.PlayButton.UserData=2;
            end
        end
 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_AnimationF_OpeningFcn(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName)
            cd (MainDirectory)
            
            app.PlayButton.UserData=2;
            app.GMpopup.UserData=1;
            app.slider1.Value=0.9;
            
            if GM_Option==3
                X{1,1}=Wave_name;
            else
                if nGM==1
                    zz=GM.name{1};
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz={zz(1:indexz-1)}; end
                    X(1,1)=zz;
                else
                    zz=char(GM.name{1,1});
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz=zz(1:indexz-1); end
                    X{1,1}=zz;
                    clear z
                    for i=1:nGM
                        zz=char(GM.name{i,1});
                        indexz=strfind(zz,'.');
                        if isempty(zz); else; zz=zz(1:indexz-1); end
                        X{i,1}=zz;
                        clear zz
                    end
                end
            end
            
            app.GMpopup.Items=X;
            app.GMpopup.ItemsData=1:nGM;
            app.GMpopup.Value=1;
            GMpopup_Callback(app)

        end

        % Button pushed function: PlayButton
        function PlayButton_Callback(app, event)
            if app.PlayButton.UserData==1
                app.PlayButton.Text='Play';
                app.PlayButton.UserData=2;
            elseif app.PlayButton.UserData==2
                app.PlayButton.Text='Pause';
                app.PlayButton.UserData=1;
                Animate(app)
                i=app.GMpopup.UserData+1;
                app.GMpopup.UserData=i;
            end
        end

        % Close request function: figure1
        function figure1_CloseRequestFcn(app, event)
            pause(1)
            delete(app);
        end

        % Value changed function: GMpopup
        function GMpopup_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName)
            cd (MainDirectory)

            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            GM_No=app.GMpopup.Value;
            app.PlayButton.UserData=2;
            
            evalc(['T_anime=','RESULTS.T',num2str(GM_No)]);
            evalc(['U_anime=','RESULTS.U',num2str(GM_No)]);
            evalc(['F_anime=','RESULTS.F',num2str(GM_No)]);
            evalc(['Collapse_Index_anime=','RESULTS.Collapse_Index',num2str(GM_No)]);
            
            t_pause=0.01;
            
            cd (ProjectPath)
            save(ProjectName,'T_anime','U_anime','F_anime','Collapse_Index_anime','-append')
            pause(0.5)
            cd (MainDirectory)
            
            if Units==1
                app.axes1.XLabel.String='Displacement [m]';
                app.axes1.YLabel.String='Force [kN]';
            else
                app.axes1.XLabel.String='Displacement [in]';
                app.axes1.YLabel.String='Force [kip]';
            end
            app.axes1.XLim=[-max(abs(U_anime))*1.5 max(abs(U_anime))*1.5];
            app.axes1.YLim=[-max(abs(F_anime))*1.15 max(abs(F_anime))*1.15];
            
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
            
            % Plot Origin Lines
            plot(app.axes1,[-max(abs(U_anime))*2.0 max(abs(U_anime))*2.0],[0.0 0.0], '-k','linewidth',1);
            plot(app.axes1,[0.0 0.0],[-max(abs(F_anime))*2.0 max(abs(F_anime))*2.0], '-k','linewidth',1);
            plot(app.axes1,0.0,0.0, '-ro','linewidth',3,'MarkerEdgeColor','k','MarkerFaceColor','r','MarkerSize',5);
            text(app.axes1,-max(abs(U_anime))*1.45,max(abs(F_anime))*1.05,['Time=',num2str(T_anime(1,1)),'/',num2str(T_anime(end,1)),' sec'], 'fontname', 'Courier', 'fontsize',14);
            
            app.GMpopup.UserData=1;
            app.PlayButton.Text='Play';
            app.PlayButton.UserData=2;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Results: Dynamic Animation F';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1_CloseRequestFcn, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Force-Displacement Response Animation';
            app.axes1 = uiaxes(app.uipanel1);
            app.text23 = uilabel(app.uipanel1);
            app.text23.Text = 'Ground Motion:';
            app.PlayButton = uibutton(app.uipanel1, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButton_Callback, true);
            app.PlayButton.Text = 'Pause';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Tooltip = 'Control animation speed';
            app.text24.Text = 'Speed:';
            app.text25 = uilabel(app.uipanel1);
            app.text25.Tooltip = 'Control animation speed';
            app.text25.Text = '-';
            app.text26 = uilabel(app.uipanel1);
            app.text26.Tooltip = 'Control animation speed';
            app.text26.Text = '+';
            app.slider1 = uislider(app.uipanel1);
            app.slider1.Limits = [0 0.99];
            app.slider1.MajorTicks = [];
            app.slider1.MinorTicks = [];
            app.slider1.Tooltip = 'Control animation speed. Faster animation speeds may not shows properly on slower computers.';
            app.GMpopup = uidropdown(app.uipanel1);
            app.GMpopup.Items = {};
            app.GMpopup.ValueChangedFcn = createCallbackFcn(app, @GMpopup_Callback, true);
            app.GMpopup.Value = {};
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_AnimationF_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_AnimationF_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end