classdef Module_Results_CSA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        uipanel1           matlab.ui.container.Panel
        axes1              matlab.ui.control.UIAxes
        text18             matlab.ui.control.Label
        GMpopup            matlab.ui.control.DropDown
        text19             matlab.ui.control.Label
        SpectrumTypepopup  matlab.ui.control.DropDown
        text33             matlab.ui.control.Label
        text34             matlab.ui.control.Label
        edit1              matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        MainApp % Description
    end
    
    methods (Access = private)
        function Plot_Curve(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'g','CSA','To','Tend','RSA_Option')
            cd (MainDirectory)

            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            nYears=app.edit1.Value;
            GM_No=app.GMpopup.Value-1;
            SpectrumType=app.SpectrumTypepopup.Value;
            
            period=CSA.Period;
            if GM_No==0
                if SpectrumType==1
                    Yaxis=CSA.SaCPS(:,:)/g;
                    app.axes1.YLabel.String= 'Sa_{collapse} [g]';
%                 elseif SpectrumType==2
%                     Yaxis=CSA.CC(:,:);
%                     app.axes1.YLabel.String= 'Sa_{collapse}/(F_y/W)';
                else%if SpectrumType==2 || SpectrumType==3
                    Plot_MAFC(app)
                end
            else
                if SpectrumType==1
                    Yaxis=CSA.SaCPS(:,GM_No)/g;
                    app.axes1.YLabel.String= 'Sa_{collapse} [g]';
%                 elseif SpectrumType==2
%                     Yaxis=CSA.CC(:,GM_No);
%                     app.axes1.YLabel.String= 'Sa_{collapse}/(F_y/W)';
                else%if SpectrumType==2 || SpectrumType==3
                    Plot_MAFC(app)
                end
            end
            
            if SpectrumType==1% ||  SpectrumType==2
                Yaxis(~isfinite(Yaxis))=0;% replace Inf values with zeros
                plot(app.axes1,period,Yaxis,'-r','linewidth',1)
                app.axes1.XLabel.String= 'Period [sec]';
                app.axes1.XLim=[To Tend];
                app.axes1.YLim=[0 max(max(abs(Yaxis)))*1.3];
            end
            app.axes1.XLabel.FontName= 'Times';
            app.axes1.YLabel.FontName= 'Times';
        end
        
        function Plot_MAFC(app)
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','g','CSA','IDA','zeta','Hazardstatus','COEFF','RESULTS','SaAVG_status','SaAVG_T1','SaAVG_T2','To','Tincr','Tend')
            cd (MainDirectory)
            
            nYears=app.edit1.Value;

            GM_No=app.GMpopup.Value-1;
            SpectrumType=app.SpectrumTypepopup.Value;
            period=CSA.Period;
            
            %% CALCULATE THE MAFC AT EACH PERIOD
            countT=0;
            for i=To:Tincr:Tend
            
                countT=countT+1;
            
                if GM_No==0
                    SaCPS=RESULTS.SaCPS(:,countT)/g;
                    SaCPS=sort(SaCPS, 1);
                    Parameters=lognfit(SaCPS);
                    MedianCPS=Parameters(1);
                    SigmaCPS=Parameters(2);
                else
                    SaCPS=RESULTS.SaCPS(GM_No,countT)/g;
                    MedianCPS=SaCPS;
                    SigmaCPS=0.4;
                end
            
                MAFC(countT,1)=0.0;
                dSa=0.001;
                for Sa=0.001:dSa:max(SaCPS)+2.0
                    f=COEFF(1)*log(Sa)^4+COEFF(2)*log(Sa)^3+COEFF(3)*log(Sa)^2+COEFF(4)*log(Sa)^1+COEFF(5);
                    f1=COEFF(1)*log(Sa+dSa)^4+COEFF(2)*log(Sa+dSa)^3+COEFF(3)*log(Sa+dSa)^2+COEFF(4)*log(Sa+dSa)^1+COEFF(5);
                    Slope= (exp(f)-exp(f1))/dSa;
                    Probability_Fragility = logncdf(Sa,MedianCPS,SigmaCPS);
                    MAFC(countT,1)=MAFC(countT,1)+Probability_Fragility*(Slope)*dSa;
                end
                Pc50(countT,1)=1-exp(-MAFC(countT,1)*nYears);
                Pc50(countT,1)=round(Pc50(countT,1)*10000)/10000;
            
            end
            
            if SpectrumType==2
                plot(app.axes1,period,MAFC,'-r','linewidth',1)
                app.axes1.XLabel.String= 'Period [sec]';
                app.axes1.YLabel.String= '\lambda_c [per annum]';
                app.axes1.XLim=[To Tend];
                app.axes1.YLim=[0 max(MAFC)*1.3];
            elseif SpectrumType==3
                plot(app.axes1,period,Pc50*100,'-r','linewidth',1)
                app.axes1.XLabel.String= 'Period [sec]';
                app.axes1.YLabel.String= ['P_c|',num2str(nYears),' years'];
                app.axes1.XLim=[To Tend];
                app.axes1.YLim=[0 max(Pc50)*100*1.3];
            end
            app.axes1.XLabel.FontName= 'Times';
            app.axes1.YLabel.FontName= 'Times';
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_CSA_OpeningFcn(app)
        
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'GM_Option','Wave_name','nGM','GM','Hazardstatus')
            cd (MainDirectory)
            
            if GM_Option==3
                X{1,1}=Wave_name;
            else
                if nGM==1
                    X(1,1)={'All'};
                    zz=GM.name{1};
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz={zz(1:indexz-1)}; end
                    X(2,1)=zz;
                else
                    X{1,1}='All';
                    for i=1:nGM
                        zz=char(GM.name{i,1});
                        indexz=strfind(zz,'.');
                        if isempty(zz); else; zz=zz(1:indexz-1); end
                        X{i+1,1}=zz;
                        clear zz
                    end
                end
            end
            app.GMpopup.Items=X;
            app.GMpopup.ItemsData=1:nGM+1;
            app.GMpopup.Value=2;
            
            clear X
            if Hazardstatus==1 %&& nGM>=5
                X{1,1}='Collapse Capacity Spectrum';
                %X{2,1}='Norm. Collapse Capacity Spectrum';
                X{2,1}='Mean Annual Frequency of Collapse Spectrum';
                X{3,1}='Probability of Collapse Spectrum';
                XX=1:3;
            else
                X{1,1}='Collapse Capacity Spectrum';
                X{2,1}='Norm. Collapse Capacity Spectrum';
                XX=1:2;
            end
            app.SpectrumTypepopup.Items=X;
            app.SpectrumTypepopup.ItemsData=XX;
            app.SpectrumTypepopup.Value=1;
            Plot_Curve(app)

        end

        % Value changed function: GMpopup
        function GMpopup_Callback(app, event)
            SpectrumTypepopup_Callback(app)
        end

        % Value changed function: SpectrumTypepopup
        function SpectrumTypepopup_Callback(app, event)
            if app.SpectrumTypepopup.Value==1 
                app.edit1.Enable='off';
                Plot_Curve(app)
            else
                app.edit1.Enable='on';
                Plot_MAFC(app)
            end

        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Results: CSA';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Collapse Spectrum Plot';
            app.axes1 = uiaxes(app.uipanel1);
            app.text18 = uilabel(app.uipanel1);
            app.text18.Text = 'Ground Motion:';
            app.GMpopup = uidropdown(app.uipanel1);
            app.GMpopup.Items = {};
            app.GMpopup.ValueChangedFcn = createCallbackFcn(app, @GMpopup_Callback, true);
            app.GMpopup.Value = {};
            app.text19 = uilabel(app.uipanel1);
            app.text19.Text = 'Spectrum Type:';
            app.SpectrumTypepopup = uidropdown(app.uipanel1);
            app.SpectrumTypepopup.Items = {'Collapse Capacity Spectrum', 'Normalized Collapse Capacity Spectrum'};
            app.SpectrumTypepopup.ValueChangedFcn = createCallbackFcn(app, @SpectrumTypepopup_Callback, true);
            app.SpectrumTypepopup.Value = 'Collapse Capacity Spectrum';
            app.text33 = uilabel(app.uipanel1);
            app.text33.Text = 'Life span';
            app.text34 = uilabel(app.uipanel1);
            app.text34.Text = 'years';
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.Limits = [0 Inf];
            app.edit1.Value = 50;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_CSA_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_CSA_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end