classdef Module_Results_CSA_IDAcurve_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1    matlab.ui.Figure
        uipanel1   matlab.ui.container.Panel
        axes1      matlab.ui.control.UIAxes
        text23     matlab.ui.control.Label
        GMpopup    matlab.ui.control.DropDown
        checkbox1  matlab.ui.control.CheckBox
        text24     matlab.ui.control.Label
        slider1    matlab.ui.control.Slider
        edit1      matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        function [Median16_Sa, Median50_Sa, Median84_Sa, D_Vector] = Get_Median_Collapse_Fragility(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','IDA','g')
            cd (MainDirectory)
            
            for i=1:nGM
                evalc(['Xaxes=IDA.U',num2str(i)]);
                MaxU(i,1)=max(Xaxes);
            end
            
                counter = 1;
            for D = 0.0:max(MaxU)/100:max(MaxU)
            
                for GM_No=1:nGM     % Loop for GMs
                    evalc(['Xaxes=IDA.U',num2str(GM_No)]);
                    evalc(['Yaxes=IDA.Sa',num2str(GM_No),'/g']);
                    Sa_Interp (GM_No,1)=interp1(Xaxes,Yaxes,D);
                end
            
                Sa_Interp=sort(Sa_Interp);
                Median16_Sa(counter,1)=(Sa_Interp(ceil(0.16*nGM),1)+Sa_Interp(floor(0.16*nGM),1))/2;
                Median50_Sa(counter,1)=median(Sa_Interp);
                Median84_Sa(counter,1)=(Sa_Interp(ceil(0.84*nGM),1)+Sa_Interp(floor(0.84*nGM),1))/2;
                D_Vector (counter,1)=D;
            
                counter = counter + 1;
            end
            
                Median16_Sa(counter,1)=Median16_Sa(end,1);
                Median50_Sa(counter,1)=Median50_Sa(end,1);
                Median84_Sa(counter,1)=Median84_Sa(end,1);
                Median16_Sa(~isfinite(Median16_Sa))=max(Median16_Sa);
                Median50_Sa(~isfinite(Median50_Sa))=max(Median50_Sa);
                Median84_Sa(~isfinite(Median84_Sa))=max(Median84_Sa);
            
                D_Vector (counter,1)=max(MaxU);
        end
        
        function Plot_Curve(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'IDA','RSA','g','nGM','zeta','Units','SaAVG_status','SaAVG_T1','SaAVG_T2')
            cd (MainDirectory)
            
            if app.checkbox1.Value==1
               NormVal=app.edit1.Value;
            else
               NormVal=1;
            end
            
            GM_No=app.GMpopup.Value-1;
            indxT=app.slider1.Value;
            Period=RSA.Period(indxT);
            
            for i=1:nGM
                evalc(['Xaxes=IDA.U',num2str(i),num2str(indxT)]);
                evalc(['Yaxes=IDA.Sa',num2str(i),num2str(indxT),'/g']);
                MaxU(i,1)=max(Xaxes);
                MaxSA(i,1)=max(Yaxes);
            end
            
            % clear axes
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            if GM_No==0 && nGM>5
                    plot (app.axes1,[0],[0],'-ok','Color',[0.5 0.5 0.5],'MarkerSize',3);
                    plot (app.axes1,[0],[0],'-r','linewidth',2);
                    plot (app.axes1,[0],[0],'--r','linewidth',2);
            end
            
            if GM_No==0
                for i=1:nGM
                    evalc(['Xaxes=IDA.U',num2str(i),num2str(indxT),'/NormVal']);
                    evalc(['Yaxes=IDA.Sa',num2str(i),num2str(indxT),'/g']);
                    plot (app.axes1,Xaxes,Yaxes,'-ok','Color',[0.5 0.5 0.5],'MarkerSize',3);
                    plot (app.axes1,Xaxes(end-1,1),Yaxes(end-1,1), '-ok','MarkerFaceColor','r','MarkerSize',4);
                end
            
                app.axes1.XLim=[0 max(MaxU)/NormVal];
                app.axes1.YLim=[0 round((max(MaxSA)+0.1)*10)/10];
            
                if nGM>5
                    [Median16_Sa, Median50_Sa, Median84_Sa, D_Vector]=Get_Median_Collapse_Fragility (app);
                    plot (app.axes1,D_Vector/NormVal,Median16_Sa,'--r','linewidth',2);
                    plot (app.axes1,D_Vector/NormVal,Median50_Sa,'-r','linewidth',2);
                    plot (app.axes1,D_Vector/NormVal,Median84_Sa,'--r','linewidth',2);
                end
            else
                evalc(['Xaxes=IDA.U',num2str(GM_No),num2str(indxT),'/NormVal']);
                evalc(['Yaxes=IDA.Sa',num2str(GM_No),num2str(indxT),'/g']);
                plot (app.axes1,Xaxes,Yaxes, '-ok','MarkerEdgeColor','k','MarkerSize',3,'Color',[0.5 0.5 0.5]);
                plot (app.axes1,Xaxes(end-1,1),Yaxes(end-1,1), '-ok','MarkerFaceColor','r','MarkerSize',4);
                app.axes1.XLim=[0 max(MaxU)/NormVal];
                app.axes1.YLim=[0 round((max(Yaxes)+0.1)*10)/10];
            end
            
            if app.checkbox1.Value==1
               app.axes1.XLabel.String='Drift ratio [rad]';
            else
                if Units ==1
                    app.axes1.XLabel.String='Displacement, \delta [m]';
                else
                    app.axes1.XLabel.String='Displacement, \delta [in]';
                end
            end
            
            if SaAVG_status==0
                app.axes1.YLabel.String=['Sa (T_1, ',num2str(zeta*100), '%) [g]'];
            else
                app.axes1.YLabel.String=['Sa_{avg} (\DeltaT=',num2str(SaAVG_T2-SaAVG_T1),'s , ',num2str(zeta*100), '%) [g]'];
            end
            
            text(app.axes1,0.01*max(MaxU)/NormVal,0.95*round((max(Yaxes)+0.1)*10)/10,['Period= ',num2str(Period),' sec'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_CSA_IDAcurve_OpeningFcn(app)
            app.axes1.cla;
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'GM_Option','Wave_name','nGM','GM','RSA')
            cd (MainDirectory)
            
            if GM_Option==3
                X{1,1}=Wave_name;
            else
                if nGM==1
                    X(1,1)={'All'};
                    zz=GM.name{1};
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz={zz(1:indexz-1)}; end
                    X(2,1)=zz;
                else
                    X{1,1}='All';
                    for i=1:nGM
                        zz=char(GM.name{i,1});
                        indexz=strfind(zz,'.');
                        if isempty(zz); else; zz=zz(1:indexz-1); end
                        X{i+1,1}=zz;
                        clear zz
                    end
                end
            end
            app.GMpopup.Items=X;
            app.GMpopup.ItemsData=1:nGM+1;
            app.GMpopup.Value=2;
            
            Xaxes=RSA.Period;
            N_Increments= size(Xaxes,1);

            app.slider1.Limits=[1 N_Increments];
            app.slider1.Value=round(N_Increments/2);
            app.slider1.UserData=N_Increments;
            app.slider1.MajorTicks=[1:min(N_Increments/5,10):N_Increments];
            count=0;
            for i=1:min(N_Increments/5,10):N_Increments
                count=count+1;
                xx{count}=num2str(RSA.Period(i));
            end
            app.slider1.MajorTickLabels=xx;
            
            app.GMpopup.Items=X;
            app.GMpopup.ItemsData=1:nGM+1;
            app.GMpopup.Value=2;
            GMpopup_Callback(app)
            app.checkbox1.Value=0;
            checkbox1_Callback(app)
            Plot_Curve(app)

        end

        % Value changed function: GMpopup
        function GMpopup_Callback(app, event)
            Plot_Curve(app)
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Units','H')
            cd (MainDirectory)
            
            if app.checkbox1.Value==1
               app.edit1.Enable='on';
               if Units ==1
                    app.edit1.Value=H;
               else
                    app.edit1.Value=H;
               end
            else
               app.edit1.Enable='off';
               app.edit1.Value=0;
            end
            Plot_Curve(app)
        end

        % Callback function
        function edit1_Callback(app, event)
            Plot_Curve(app)
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            pause(0.5)
            X = round(app.slider1.Value);
            app.slider1.Value=X;
            app.axes1.cla;
            Plot_Curve(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'IDA Curve at Period Ti';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'IDA Curves';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.XLim = [-0.110161604692016 0.110161604692016];
            app.axes1.YLim = [-3.17482251867287 3.17482251867287];
            app.text23 = uilabel(app.uipanel1);
            app.text23.Text = 'Ground Motion:';
            app.GMpopup = uidropdown(app.uipanel1);
            app.GMpopup.Items = {'1'};
            app.GMpopup.ValueChangedFcn = createCallbackFcn(app, @GMpopup_Callback, true);
            app.GMpopup.Value = '1';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'noramlize x-axis by';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Text = 'RSA Period:';
            app.slider1 = uislider(app.uipanel1);
            app.slider1.Limits = [0 1];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.ValueDisplayFormat = '%5.2f';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_CSA_IDAcurve_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_CSA_IDAcurve_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end