classdef Module_Results_CSA_Options_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        SubmitButton  matlab.ui.control.Button
        checkbox1     matlab.ui.control.CheckBox
        checkbox2     matlab.ui.control.CheckBox
        checkbox3     matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_CSA_Options_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            clc;
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            
            if app.checkbox1.Value==1
                Module_Results_CSA_App
            end
            
            if app.checkbox2.Value==1
                Module_Results_RSA_FD_App
            end
            
            if app.checkbox3.Value==1
                Module_Results_CSA_IDAcurve_App
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Result: CSA Plot Options';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Select Results to Plot';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'View';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.Text = ' CSA Plots';
            app.checkbox2 = uicheckbox(app.uipanel1);
            app.checkbox2.Text = ' Force-Disp. Response at Ti';
            app.checkbox3 = uicheckbox(app.uipanel1);
            app.checkbox3.Text = ' IDA Curve at Ti';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_CSA_Options_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Results_CSA_Options_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end