classdef Module_Results_CollapseFragilityCurve_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        uipanel1    matlab.ui.container.Panel
        axes1       matlab.ui.control.UIAxes
        saveButton  matlab.ui.control.Button
        text33      matlab.ui.control.Label
        text34      matlab.ui.control.Label
        edit8       matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        function Plot_CollapseFragility(app)
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','g','zeta','Hazardstatus','SaAVG_status','SaAVG_T1','SaAVG_T2','ProbabilityEmp','ProbabilityFit','SAxx','SaCPS','MAFc','MedianCPS','SigmaCPS','T')
            cd (MainDirectory)
            
            scatter (app.axes1,SaCPS,ProbabilityEmp, 'ko','MarkerEdgeColor','k','MarkerFaceColor',[0.6 0.6 0.6],'SizeData',26);
            plot(app.axes1,SAxx,ProbabilityFit,'-r','linewidth',2)
            if SaAVG_status==0
                app.axes1.XLabel.String=['Sa (T_1, ',num2str(zeta*100), '%) [g]'];
            else
                app.axes1.XLabel.String= ['Sa_{avg} (T=',num2str(round(SaAVG_T1*T*10)/10),'s-',num2str(round(SaAVG_T2*T*10)/10),'s, ',num2str(zeta*100), '%) [g]'];
            end
            app.axes1.YLabel.String='Probability of Collapse';
            app.axes1.XLim=[0 round((max(SaCPS)+0.1)*10)/10];
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
            
            text(app.axes1,0.03,0.95,['Median: ',num2str(round(exp(MedianCPS)*1000)/1000),'g'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            text(app.axes1,0.03,0.88,['Dispersion: ',num2str(round(SigmaCPS*1000)/1000)], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            
            %% Calculate the mean annual frequency of collapse, MAFc
            if Hazardstatus==1
                nyears=app.edit8.Value;
            
                Pc=1-exp(-MAFc*nyears);
                Pc=round(Pc*10000)/10000;
            
                text(app.axes1,0.03,0.81,['MAFC: ',num2str(round(MAFc*10000*100)/100),'x10^-^4/year'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                text(app.axes1,0.03,0.74,['Pc: ',num2str(Pc*100),'% in ',num2str(nyears),' years'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            
            else
                text(app.axes1,0.03,0.81,['MAFC: --'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                text(app.axes1,0.03,0.74,['Pc: --'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_CollapseFragilityCurve_OpeningFcn(app)
            Plot_CollapseFragility(app)
        end

        % Value changed function: edit8
        function edit8_Callback(app, event)
            Plot_CollapseFragility(app)
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)
            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','g','zeta','Hazardstatus','SaAVG_status','SaAVG_T1','SaAVG_T2','ProbabilityEmp','ProbabilityFit','SAxx','SaCPS','MAFc','MedianCPS','SigmaCPS')
            cd (MainDirectory)
            
            cd (FilePath)
            fileX = fopen(FileName,'wt');
            
            SAxx=SAxx';
            ProbabilityFit=ProbabilityFit';
            
            fprintf(fileX,'%s%f%s\t%s%f','Median Sa=',round(exp(MedianCPS)*1000)/1000,'g','Dispersion=', SigmaCPS);
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','-------------------------------');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','Empirical Distribution');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','-------------------------------');
            fprintf(fileX,'\n');
            a=['Sa(T1, ',num2str(zeta*100),'%) [g]']; b ='P|Collapse';
            fprintf(fileX,'%s\t%s\t',a,b);
            fprintf(fileX,'\n');
            for i=1:size(SaCPS,1)
              fprintf(fileX,'%f\t%f\t', SaCPS(i,1), ProbabilityEmp(i,1));
              fprintf(fileX,'\n');
            end
            fprintf(fileX,'%s\t','-------------------------------');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','Fitted Distribution');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','-------------------------------');
            fprintf(fileX,'\n');
            a=['Sa(T1, ',num2str(zeta*100),'%) [g]']; b ='P|Collapse';
            fprintf(fileX,'%s\t%s\t',a,b);
            fprintf(fileX,'\n');
            for i=1:size(SAxx,1)
              fprintf(fileX,'%f\t%f\t', SAxx(i,1), ProbabilityFit(i,1));
              fprintf(fileX,'\n');
            end
            
            if Hazardstatus==1
                nyears=app.edit8.Value;
                Pc=1-exp(-MAFc*nyears);
                Pc=round(Pc*10000)/10000;
            
                fprintf(fileX,'\n');
                fprintf(fileX,'%s\t','Mean Annual Frequency of Collapse');
                fprintf(fileX,'\n');
                fprintf(fileX,'%s\t','-------------------------------');
                fprintf(fileX,'\n');
                fprintf(fileX,'\n');
                fprintf(fileX,'%s%5.2f%s\t%s%5.2f%s%3.0f%s','MAFC=',(round(MAFc*10000*100)/100),'x10^-4/year','Dispersion=', Pc*100,'% in ',nyears,' years');
                fprintf(fileX,'\n');
            end

            cd (MainDirectory)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            fclose all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Results: IDA - Multiple GMs';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Collapse Fragility Curve';
            app.axes1 = uiaxes(app.uipanel1);
            app.saveButton = uibutton(app.uipanel1, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Text = 'save plot data';
            app.text33 = uilabel(app.uipanel1);
            app.text33.Text = 'Life span';
            app.text34 = uilabel(app.uipanel1);
            app.text34.Text = 'years';
            app.edit8 = uieditfield(app.uipanel1, 'numeric');
            app.edit8.Limits = [0 Inf];
            app.edit8.ValueChangedFcn = createCallbackFcn(app, @edit8_Callback, true);
            app.edit8.Value = 50;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_CollapseFragilityCurve_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_CollapseFragilityCurve_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end