classdef Module_Results_DispFragility_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        uipanel1    matlab.ui.container.Panel
        axes1       matlab.ui.control.UIAxes
        slider1     matlab.ui.control.Slider
        text23      matlab.ui.control.Label
        text24      matlab.ui.control.Label
        saveButton  matlab.ui.control.Button
        edit1       matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        function [MaxU, MaxSA] = Plot_IDA_Curves(app, nGM, IDA, g, Units, zeta)
            Umax=app.slider1.UserData;
            for i=1:nGM
                evalc(['Xaxes=IDA.U',num2str(i)]);
                evalc(['Yaxes=IDA.Sa',num2str(i),'/g']);
                MaxU(i,1)=max(Xaxes);
                MaxSA(i,1)=max(Yaxes);
            end
            TargetSa=app.slider1.Value;
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_DispFragility_OpeningFcn(app)
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','IDA','g','Units','zeta')
            cd (MainDirectory)
            
            [MaxU, MaxSA]=Plot_IDA_Curves (app, nGM, IDA,g, Units,zeta);
            
            MAXMaxSA=max(MaxSA);
            app.slider1.Limits=[0.0 round(MAXMaxSA*10)/10];
            app.slider1.MajorTicks=0.0:ceil(MAXMaxSA*10/5)/10:ceil(MAXMaxSA);
            app.slider1.Value=round(MAXMaxSA*10/2)/10;
            app.edit1.Value=round(MAXMaxSA*10/2)/10;  
            
            %% PLOT FRAGILITY CURVE AT TARGET SA
            % For each GM, Get the displacements at the target Sa
            TargetSa=app.edit1.Value;
            count=0;
            for i=1:nGM
                if  evalc(['max(IDA.Sa',num2str(i),'(1:end-1,1))<=TargetSa*g'])
                    count=count+1;
                    evalc(['U_at_targetSA(count,1)=interp1(IDA.Sa',num2str(i),'(1:end-1,1),','IDA.U',num2str(i),'(1:end-1,1)',',','TargetSa*g)']);
                else
                end
            end
            
            
            if count>=3
                U_at_targetSA=sort(U_at_targetSA, 1);
                Parameters=lognfit(U_at_targetSA);
                MedianU=Parameters(1);
                SigmaU=Parameters(2);
                if max(U_at_targetSA)==min(U_at_targetSA)
                    MedianU=MedianU;
                    SigmaU=0.001;
                end
            end
            
            Probability=zeros(count,1);
            for i=1:count
                Probability(i,1)=i/count;
            end
            
            scatter(app.axes1,U_at_targetSA,Probability, 'ko','MarkerEdgeColor','k','MarkerFaceColor',[0.6 0.6 0.6],'SizeData',26);
            if count>=3
                Urange = (0.0:max(MaxU)/nGM/100:max(MaxU)*1.1);
                Probability = logncdf(Urange,MedianU,SigmaU);
                plot(app.axes1,Urange,Probability,'-r','linewidth',2)
            else
            end
            
            if Units ==1; app.axes1.XLabel.String='Displacement, \delta [m]';else; app.axes1.XLabel.String='Displacement, \delta [in]'; end
            app.axes1.YLabel.String=['P[\delta|Sa=',num2str(floor(TargetSa*100)/100),'g]'];
            app.axes1.YLim=[0 1.0];
            app.axes1.XLim=[0 max(MaxU)];
            app.slider1.UserData=max(MaxU);
            slider1_Callback(app)

        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','IDA','g')
            cd (MainDirectory)
            
            TargetSa=app.edit1.Value;
            
            for i=1:nGM
                evalc(['Yaxes=IDA.Sa',num2str(i),'/g']);
                MaxSA(i,1)=max(Yaxes);
            end
            
            if TargetSa>max(MaxSA)-0.001
                app.edit1.FontColor='r';
                TargetSa=max(MaxSA)-0.001;
                app.edit1.Value=TargetSa;
                return
            else
               app.edit1.FontColor='k';
            end
            
            if TargetSa <0.001
                app.edit1.FontColor='r';
                TargetSa=0.001;
                app.edit1.Value=TargetSa;
                return
            else
               app.edit1.FontColor='k';
            end
            
            
            app.slider1.Value=TargetSa;
            slider1_Callback(app)
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)
            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','g','zeta','Hazardstatus','SaAVG_status','SaAVG_T1','SaAVG_T2','ProbabilityEmp','ProbabilityFit','SAxx','SaCPS','MAFc','MedianCPS','SigmaCPS')
            cd (MainDirectory)
            
            cd (FilePath)
            fileX = fopen(FileName,'wt');
            
            TargetSa=app.slider1.Value;
            app.edit1.Value=TargetSa;
            Umax=app.slider1.UserData;
            
            % For each GM, Get the displacements at the target Sa
            count=0;
            for i=1:nGM
                 evalc(['maxsa=max(IDA.Sa',num2str(i),'(1:end-1,1))']);
                if  maxsa>TargetSa*g
                    count=count+1;
                    evalc(['U_at_targetSA(count,1)=interp1(IDA.Sa',num2str(i),'(1:end-1,1),','IDA.U',num2str(i),'(1:end-1,1)',',','TargetSa*g)']);
                else
                end
            end
            
            % Get the paramters of the fitted lognormal distribution to the displacements at the target Sa
            U_at_targetSA=sort(U_at_targetSA, 1);
            Parameters=lognfit(U_at_targetSA);
            if count>=3
                MedianU=Parameters(1);
                SigmaU=Parameters(2);
                if max(U_at_targetSA)==min(U_at_targetSA)
                    MedianU=MedianU;
                    SigmaU=0.001;
                end
            end
            
            % Get the empirical probability distribution of the displacements at the
            % target Sa and Plot the scatter
            ProbabilityEmp=zeros(count,1);
            for i=1:count
                ProbabilityEmp(i,1)=i/count;
            end
            for i=1:nGM
                evalc(['Xaxes=IDA.U',num2str(i)]);
                evalc(['Yaxes=IDA.Sa',num2str(i),'/g']);
                MaxU(i,1)=max(Xaxes);
                MaxSA(i,1)=max(Yaxes);
            end
            
            if count>=3
                MaxU_atSa=max(U_at_targetSA);
                Urange = (0.0:MaxU_atSa/nGM/100:MaxU_atSa*1.5);
                ProbabilityFit = logncdf(Urange,MedianU,SigmaU);
            end
            
            if Units==1; fprintf(fileX,'%s%f%s\t%s%f','Median drift=',num2str(round(exp(MedianU)*1000)/1000),'m','Dispersion=', SigmaU); end
            if Units==2; fprintf(fileX,'%s%f%s\t%s%f','Median drift=',num2str(round(exp(MedianU)*1000)/1000),'in','Dispersion=', SigmaU); end
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','-------------------------------');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','Empirical Distribution');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','-------------------------------');
            fprintf(fileX,'\n');
            if Units==1; a=['Drift [m]']; end
            if Units==2; a=['Drift [in]']; end
            b =['P|Sa=',num2str(TargetSa),'g'];
            fprintf(fileX,'%s\t%s\t',a,b);
            fprintf(fileX,'\n');
            for i=1:size(U_at_targetSA,1)
              fprintf(fileX,'%f\t%f\t', U_at_targetSA(i,1), ProbabilityEmp(i,1));
              fprintf(fileX,'\n');
            end
            if count>=3
                fprintf(fileX,'%s\t','-------------------------------');
                fprintf(fileX,'\n');
                fprintf(fileX,'%s\t','Fitted Distribution');
                fprintf(fileX,'\n');
                fprintf(fileX,'%s\t','-------------------------------');
                fprintf(fileX,'\n');
                if Units==1; a=['Drift [m]']; end
                if Units==2; a=['Drift [in]']; end
                b =['P|Sa=',num2str(TargetSa),'g'];
                fprintf(fileX,'%s\t%s\t',a,b);
                fprintf(fileX,'\n');
                for i=1:size(Urange,1)
                  fprintf(fileX,'%f\t%f\t', Urange(i,1), ProbabilityFit(i,1));
                  fprintf(fileX,'\n');
                end
            end
            cd (MainDirectory)
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            clc
            global MainDirectory ProjectPath ProjectName
            cd (ProjectPath)
            load (ProjectName,'IDA','nGM','g','Units','zeta')
            cd (MainDirectory)
            
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            TargetSa=app.slider1.Value;
            app.edit1.Value=TargetSa;
            Umax=app.slider1.UserData;
            
            %% PLOT FRAGILITY CURVE AT TARGET SA
            % For each GM, Get the displacements at the target Sa
            count=0;
            for i=1:nGM
                 evalc(['maxsa=max(IDA.Sa',num2str(i),'(1:end-1,1))']);
                if  maxsa>TargetSa*g
                    count=count+1;
                    evalc(['U_at_targetSA(count,1)=interp1(IDA.Sa',num2str(i),'(1:end-1,1),','IDA.U',num2str(i),'(1:end-1,1)',',','TargetSa*g)']);
                else
                end
            end
            
            % Get the paramters of the fitted lognormal distribution to the displacements at the target Sa
            U_at_targetSA=sort(U_at_targetSA, 1);
            Parameters=lognfit(U_at_targetSA);
            if count>=3
                MedianU=Parameters(1);
                SigmaU=Parameters(2);
                if max(U_at_targetSA)==min(U_at_targetSA)
                    MedianU=MedianU;
                    SigmaU=0.001;
                end
            end
            
            % Get the empirical probability distribution of the displacements at the
            % target Sa and Plot the scatter
            Probability=zeros(count,1);
            for i=1:count
                Probability(i,1)=i/count;
            end
            for i=1:nGM
                evalc(['Xaxes=IDA.U',num2str(i)]);
                evalc(['Yaxes=IDA.Sa',num2str(i),'/g']);
                MaxU(i,1)=max(Xaxes);
                MaxSA(i,1)=max(Yaxes);
            end
            scatter(app.axes1,U_at_targetSA,Probability, 'ko','MarkerEdgeColor','k','MarkerFaceColor',[0.6 0.6 0.6],'SizeData',26);
            
            if count>=3
                MaxU_atSa=max(U_at_targetSA);
                Urange = (0.0:MaxU_atSa/nGM/100:MaxU_atSa*1.5);
                Probability = logncdf(Urange,MedianU,SigmaU);
                plot(app.axes1,Urange,Probability,'-r','linewidth',2)
                if Units==1;text(app.axes1,0.0,0.88,['Median: ',num2str(round(exp(MedianU)*1000)/1000),'m'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold'); end
                if Units==2;text(app.axes1,0.0,0.88,['Median: ',num2str(round(exp(MedianU)*1000)/1000),'in'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold'); end
                text(app.axes1,0.0,0.81,['Dispersion: ',num2str(round(SigmaU*1000)/1000)], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                legend(app.axes1,'Empircal CDF','Lognormal CDF');
                app.axes1.Legend.Location='southeast';
                app.axes1.Legend.FontSize=10;
                app.axes1.Legend.FontWeight='b';
            else
                    text(app.axes1,0.0,0.88,['Median: NaN'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                    text(app.axes1,0.0,0.81,['Dispersion: NaN'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            end
            
            if Units ==1; app.axes1.XLabel.String='Displacement, \delta [m]';else; app.axes1.XLabel.String='Displacement, \delta [in]'; end
            app.axes1.YLabel.String=['P[\delta|Sa=',num2str(floor(TargetSa*100)/100),'g]'];
            app.axes1.YLim=[0 1.0];
            app.axes1.XLim=[0 MaxU_atSa*1.5];
            text(app.axes1,0.0, 0.95,['#GM= ',num2str(count),'/',num2str(nGM)], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            
            [MaxU, MaxSA]=Plot_IDA_Curves (app, nGM, IDA,g, Units,zeta);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            fclose all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Results: IDA - Displacement Fragility';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Displacement Fragility at a Given Sa';
            app.axes1 = uiaxes(app.uipanel1);
            app.slider1 = uislider(app.uipanel1);
            app.slider1.Limits = [0 5];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Value = 0.5;
            app.text23 = uilabel(app.uipanel1);
            app.text23.Text = '[g]';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Text = 'Target Sa:';
            app.saveButton = uibutton(app.uipanel1, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Text = 'save plot data';
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.Limits = [0 Inf];
            app.edit1.ValueDisplayFormat = '%5.2f';
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_DispFragility_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_DispFragility_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end