classdef Module_Results_DispLimitStateFragility_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        uipanel1    matlab.ui.container.Panel
        axes1       matlab.ui.control.UIAxes
        slider1     matlab.ui.control.Slider
        text23      matlab.ui.control.Label
        text24      matlab.ui.control.Label
        saveButton  matlab.ui.control.Button
        edit1       matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        function [TargetDisp, SA_at_targetDisp, EmpProbability, FitProbability, MedianSA, SigmaSA, unitname, SArange] = Plot_DispFragility(app)
            clc
            global MainDirectory ProjectPath ProjectName
            cd (ProjectPath)
            load (ProjectName,'IDA','nGM','g','Units','zeta','SaAVG_status','SaAVG_T1','SaAVG_T2','T')
            cd (MainDirectory)
            
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            TargetDisp=app.slider1.Value;
            if TargetDisp==0; TargetSa=0.001; end
            app.edit1.Value=round(TargetDisp*10)/10;
            Umax=app.slider1.UserData;
            
            if Units==1
                unitname='m';
            else
                unitname='in';
            end
            
            %% PLOT FRAGILITY CURVE AT TARGET SA
            % For each GM, Get the displacements at the target Sa
            for i=1:nGM
                evalc(['MaxU=max(IDA.U',num2str(i),'(1:end-1,1))']);
                if TargetDisp>=MaxU
                    evalc(['SA_at_targetDisp(i,1)=max(IDA.Sa',num2str(i),'(1:end-1,1))/g']);
                else
                    evalc(['SA_at_targetDisp(i,1)=interp1(IDA.U',num2str(i),'(1:end-1,1),','IDA.Sa',num2str(i),'(1:end-1,1),','TargetDisp)/g']);
                end
            end
            
            % Get the paramters of the fitted lognormal distribution to the displacements at the target Sa
            SA_at_targetDisp=sort(SA_at_targetDisp, 1);
            Parameters=lognfit(SA_at_targetDisp);
            MedianSA=Parameters(1);
            SigmaSA=Parameters(2);
            if max(SA_at_targetDisp)==min(SA_at_targetDisp)
                MedianSA=MedianSA;
                SigmaSA=0.001;
            end
            
            
            % Get the empirical probability distribution of the displacements at the
            % target Sa and Plot the scatter
            EmpProbability=zeros(nGM,1);
            for i=1:nGM
                EmpProbability(i,1)=i/nGM;
            end
            for i=1:nGM
                evalc(['Yaxes=IDA.Sa',num2str(i),'/g']);
                MaxSA(i,1)=max(Yaxes);
            end
            
            scatter (app.axes1,SA_at_targetDisp,EmpProbability, 'ko','MarkerEdgeColor','k','MarkerFaceColor',[0.6 0.6 0.6],'SizeData',26);
            MaxSA_atU=max(SA_at_targetDisp);
            SArange = (0.0:MaxSA_atU/nGM/100:MaxSA_atU*1.5);
            FitProbability = logncdf(SArange,MedianSA,SigmaSA);
            plot(app.axes1,SArange,FitProbability,'-r','linewidth',2)
            text(app.axes1,0.0,0.95,['Median: ',num2str(round(exp(MedianSA)*1000)/1000),'g'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold');
            text(app.axes1,0.0,0.88,['Dispersion: ',num2str(round(SigmaSA*1000)/1000)], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            legend(app.axes1,'Empircal CDF','Lognormal CDF');
            app.axes1.Legend.Location='northeast';
            app.axes1.Legend.FontName='Courier';
            app.axes1.Legend.FontSize=10;
            app.axes1.Legend.FontWeight='bold';
                
            if SaAVG_status==0
                app.axes1.XLabel.String=['Sa (T_1, ',num2str(zeta*100), '%) [g]'];
            else
                app.axes1.XLabel.String= ['Sa_{avg} (T=',num2str(round(SaAVG_T1*T*10)/10),'s-',num2str(round(SaAVG_T2*T*10)/10),'s, ',num2str(zeta*100), '%) [g]'];
            end
            app.axes1.YLabel.String=['P(\delta>=',num2str(round(TargetDisp*10)/10),unitname,'|Sa)'];
            app.axes1.YLim=[0 1.0];
            app.axes1.XLim=[0 MaxSA_atU*1.5];
            
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
        end
        
        function [MaxU, MaxSA] = Plot_IDA_Curves(app, nGM, IDA, g, Units, zeta)
            Umax=app.slider1.UserData;
            for i=1:nGM
                evalc(['Xaxes=IDA.U',num2str(i)]);
                evalc(['Yaxes=IDA.Sa',num2str(i),'/g']);
                MaxU(i,1)=max(Xaxes);
                MaxSA(i,1)=max(Yaxes);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_DispLimitStateFragility_OpeningFcn(app)
            app.axes1.cla;
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','IDA','g','Units','zeta')
            cd (MainDirectory)
            
            [MaxU, MaxSA]=Plot_IDA_Curves (app, nGM, IDA,g, Units,zeta);
            
            app.slider1.Limits=[0.001 max(MaxU)-0.001];
            app.slider1.MajorTicks=0.0:ceil(MaxU*10/5)/10:ceil(MaxU);
            app.slider1.Value=(max(MaxU)/2);
            app.edit1.Value=round(max(MaxU)/2);
            
            if Units==1
                app.text23.Text='[m]';
            else
                app.text23.Text='[in]';
            end
            
            Plot_DispFragility (app)

        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','IDA','g')
            cd (MainDirectory)
            
            TargetDisp=app.edit1.Value;
            
            for i=1:nGM
                evalc(['Xaxes=IDA.U',num2str(i)]);
                MaxU(i,1)=max(Xaxes);
            end
            
            if TargetDisp>max(MaxU)-0.001
                app.edit1.FontColor='r';
                TargetDisp=max(MaxU)-0.001;
                app.edit1.Value=TargetDisp;
                return
            else
               app.edit1.FontColor='k';
            end
            
            if TargetDisp <0.001
                app.edit1.FontColor='r';
                TargetDisp=0.001;
                app.edit1.Value=TargetDisp;
                return
            else
               app.edit1.FontColor='k';
            end
            
            app.slider1.Value=TargetDisp;
            slider1_Callback(app)
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)
            
            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
            global MainDirectory
            [targetDisp,SA_at_targetDisp,EmpProbability,FitProbability,MedianSA,SigmaSA,unitname,SArange]=Plot_DispFragility (app);
            
            cd (FilePath)
            fileX = fopen(FileName,'wt');
            fprintf(fileX,'%s\t%f%s\t','Fragility Curve for Limit State Disp. =',targetDisp,unitname);
            fprintf(fileX,'******************************************************************');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s%f%s%s%f\t','Median SA=',exp(MedianSA),'g  and  ','Beta SA=',SigmaSA);
            fprintf(fileX,'\n');
            fprintf(fileX,'******************************************************************');
            fprintf(fileX,'\n');
            fprintf(fileX,'Empirical Probability');
            fprintf(fileX,'\n');
            a ='SA [g]';b='Probability';
            fprintf(fileX,'%s\t%s\t',a,b);
            fprintf(fileX,'\n');
            
            for i=1:size(SA_at_targetDisp,1)
              fprintf(fileX,'%f\t%f\t', SA_at_targetDisp(i,1), EmpProbability(i,1));
              fprintf(fileX,'\n');
            end
            
            fprintf(fileX,'\n');
            fprintf(fileX,'******************************************************************');
            fprintf(fileX,'\n');
            fprintf(fileX,'******************************************************************');
            fprintf(fileX,'\n');
            fprintf(fileX,'Fitted Probability');
            fprintf(fileX,'\n');
            a ='SA [g]';b='Probability';
            fprintf(fileX,'%s\t%s\t',a,b);
            fprintf(fileX,'\n');
            
            for i=1:size(SArange,2)
              fprintf(fileX,'%f\t%f\t', SArange(1,i), FitProbability(1,i));
              fprintf(fileX,'\n');
            end
            
            cd (MainDirectory)
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            Plot_DispFragility (app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            fclose all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Results: IDA - Displacement Limit State Fragility';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Displacement Limit State Fragility';
            app.axes1 = uiaxes(app.uipanel1);
            app.slider1 = uislider(app.uipanel1);
            app.slider1.Limits = [0 5];
            app.slider1.MajorTicks = [];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Value = 0.5;
            app.text23 = uilabel(app.uipanel1);
            app.text23.Text = '[m]';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Text = 'Target Disp:';
            app.saveButton = uibutton(app.uipanel1, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Text = 'save plot data';
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.Limits = [0 Inf];
            app.edit1.ValueDisplayFormat = '%.3f';
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_DispLimitStateFragility_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_DispLimitStateFragility_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end