classdef Module_Results_Dynamic_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        axes1           matlab.ui.control.UIAxes
        uipanel3        matlab.ui.container.Panel
        text19          matlab.ui.control.Label
        text18          matlab.ui.control.Label
        GMpopup         matlab.ui.control.DropDown
        text20          matlab.ui.control.Label
        NextButton      matlab.ui.control.Button
        PreviousButton  matlab.ui.control.Button
        saveButton      matlab.ui.control.Button
        axes2           matlab.ui.control.UIAxes
        axes3           matlab.ui.control.UIAxes
        axes4           matlab.ui.control.UIAxes
        text2           matlab.ui.control.Label
    end

    
    methods (Access = private)
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_Dynamic_OpeningFcn(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','GM','RESULTS','Wave_name','GM_Option')
            cd (MainDirectory)

            for GM_No=1:nGM
                evalc(['Xaxes=RESULTS.U',num2str(GM_No)]);
                evalc(['Yaxes=RESULTS.F',num2str(GM_No)]);
                evalc(['Zaxes=RESULTS.V',num2str(GM_No)]);
                evalc(['Caxes=RESULTS.AA',num2str(GM_No)]);
                MaxU(GM_No,1)=max(abs(Xaxes));
                MaxF(GM_No,1)=max(abs(Yaxes));
                MaxV(GM_No,1)=max(abs(Zaxes));
                MaxAA(GM_No,1)=max(abs(Caxes));
            end
                MaxValues(1,1)=max(MaxU);
                MaxValues(2,1)=max(MaxF);
                MaxValues(3,1)=max(MaxV);
                MaxValues(4,1)=max(MaxAA);
            app.NextButton.UserData=MaxValues;
            app.PreviousButton.UserData=nGM;
            
            if GM_Option==3
                X{1,1}=Wave_name;
            else
                if nGM==1
                    zz=GM.name{1};
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz={zz(1:indexz-1)}; end
                    X(1,1)=zz;
                else
                    zz=char(GM.name{1,1});
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz=zz(1:indexz-1); end
                    X{1,1}=zz;
                    clear z
                    for i=1:nGM
                        zz=char(GM.name{i,1});
                        indexz=strfind(zz,'.');
                        if isempty(zz); else; zz=zz(1:indexz-1); end
                        X{i,1}=zz;
                        clear zz
                    end
                end
            end
            app.GMpopup.Items=X;
            app.GMpopup.ItemsData=1:nGM;
            app.GMpopup.UserData=1;
            app.GMpopup.Value=1;
            GMpopup_Callback(app);

        end

        % Button pushed function: NextButton
        function NextButton_Callback(app, event)
            nGM=app.PreviousButton.UserData;
            Current_GM_No=app.GMpopup.Value;
            if Current_GM_No<nGM
                Next_GM_No=Current_GM_No+1;
                app.GMpopup.Value=Next_GM_No;
                GMpopup_Callback(app)
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButton_Callback(app, event)
            nGM=app.PreviousButton.UserData;
            Current_GM_No=app.GMpopup.Value;
            if Current_GM_No>1
                Previous_GM_No=Current_GM_No-1;
                app.GMpopup.Value=Previous_GM_No;
                GMpopup_Callback(app)
            end
        end

        % Value changed function: GMpopup
        function GMpopup_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','GM','RESULTS','Wave_name','g','Units')
            cd (MainDirectory)
            
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=15;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            app.axes2.cla;
            app.axes2.BackgroundColor=[1 1 1];
            app.axes2.FontName='Times';
            app.axes2.FontSize=15;
            app.axes2.XGrid='on';
            app.axes2.YGrid='on';
            app.axes2.Box='on';
            hold(app.axes2,"on");
            
            app.axes3.cla;
            app.axes3.BackgroundColor=[1 1 1];
            app.axes3.FontName='Times';
            app.axes3.FontSize=15;
            app.axes3.XGrid='on';
            app.axes3.YGrid='on';
            app.axes3.Box='on';
            hold(app.axes3,"on");
            
            app.axes4.cla;
            app.axes4.BackgroundColor=[1 1 1];
            app.axes4.FontName='Times';
            app.axes4.FontSize=15;
            app.axes4.XGrid='on';
            app.axes4.YGrid='on';
            app.axes4.Box='on';
            hold(app.axes4,"on");
            
            GM_No=app.GMpopup.Value;
            
            MaxValues=app.NextButton.UserData;
            
            evalc(['U=','RESULTS.U',num2str(GM_No)]);
            evalc(['V=','RESULTS.V',num2str(GM_No)]);
            evalc(['A=','RESULTS.A',num2str(GM_No)]);
            evalc(['F=','RESULTS.F',num2str(GM_No)]);
            %evalc(['K=','RESULTS.K',num2str(GM_No)]);
            evalc(['T=','RESULTS.T',num2str(GM_No)]);
            evalc(['AA=','RESULTS.AA',num2str(GM_No)]);
            
            plot(app.axes1,T,F,'-r','linewidth',2)
            [MaxF, IndexMaxF]=max(abs(F));
            plot(app.axes1,T(IndexMaxF,1),F(IndexMaxF,1),'-ok','linewidth',1,'Markerfacecolor','g')
            if Units==1
                text(app.axes1,T(IndexMaxF,1),F(IndexMaxF,1)*1.15,[' ',num2str(round(MaxF*100)/100),' kN'], 'fontname', 'Courier', 'fontsize',12)
                app.axes1.YLabel.String='Force [kN]';
            else
                text(app.axes1,T(IndexMaxF,1),F(IndexMaxF,1)*1.15,[' ',num2str(round(MaxF*100)/100),' kip'], 'fontname', 'Courier', 'fontsize',12)
                app.axes1.YLabel.String='Force [kip]';
            end
            app.axes1.YLim=[-MaxValues(2,1)*1.3 MaxValues(2,1)*1.3];
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
            
            plot(app.axes2,T,U,'-r','linewidth',2)
            [MaxU, IndexMaxU]=max(abs(U));
            plot(app.axes2,T(IndexMaxU,1),U(IndexMaxU,1),'-ok','linewidth',1,'Markerfacecolor','g')
            if Units==1
                text(app.axes2,T(IndexMaxU,1),U(IndexMaxU,1)*1.15,[' ',num2str(round(MaxU*1000)/1000),' m'], 'fontname', 'Courier', 'fontsize',12)
                app.axes2.YLabel.String='Rel. \delta [m]';
            else
                text(app.axes2,T(IndexMaxU,1),U(IndexMaxU,1)*1.15,[' ',num2str(round(MaxU*1000)/1000),' in'], 'fontname', 'Courier', 'fontsize',12)
                app.axes2.YLabel.String='Rel. \delta [in]';
            end
            app.axes2.YLim=[-MaxValues(1,1)*1.3 MaxValues(1,1)*1.3];
            app.axes2.XLabel.FontName='Times';
            app.axes2.YLabel.FontName='Times';
            
            plot(app.axes3,T,V,'-r','linewidth',2)
            [MaxV, IndexMaxV]=max(abs(V));
            plot(app.axes3,T(IndexMaxV,1),V(IndexMaxV,1)*1.05,'-ok','linewidth',1,'Markerfacecolor','g')
            if Units==1
                text(app.axes3,T(IndexMaxV,1),V(IndexMaxV,1)*1.15,[' ',num2str(round(MaxV*100)/100),' m/sec'], 'fontname', 'Courier', 'fontsize',12)
                app.axes3.YLabel.String='Rel. V [m/sec]';
            else
                text(app.axes3,T(IndexMaxV,1),V(IndexMaxV,1)*1.15,[' ',num2str(round(MaxV*100)/100),' in/sec'], 'fontname', 'Courier', 'fontsize',12)
                app.axes3.YLabel.String='Rel. V [in/sec]';
            end
            app.axes3.YLim=[-MaxValues(3,1)*1.3 MaxValues(3,1)*1.3];
            app.axes3.XLabel.FontName='Times';
            app.axes3.YLabel.FontName='Times';
            
            plot(app.axes4,T,AA/g,'-r','linewidth',2)
            [MaxA, IndexMaxA]=max(abs(AA));
            plot(app.axes4,T(IndexMaxA,1),AA(IndexMaxA,1)/g,'-ok','linewidth',1,'Markerfacecolor','g')
            text(app.axes4,T(IndexMaxA,1),AA(IndexMaxA,1)*1.15/g,[' ',num2str(round(MaxA*100/g)/100),' g'], 'fontname', 'Courier', 'fontsize',12)
            app.axes4.YLabel.String='Abs. A [g]';
            app.axes4.XLabel.String='Time [sec]';
            app.axes4.YLim=[-MaxValues(4,1)/g*1.3 MaxValues(4,1)/g*1.3];
            app.axes4.XLabel.FontName='Times';
            app.axes4.YLabel.FontName='Times';
            
            evalc(['SF=','RESULTS.SF',num2str(GM_No)]);
            xx=['SF = ',num2str(round(SF*100)/100)];
            app.text2.Text=xx;
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)
            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','GM','RESULTS','Wave_name','g','Units')
            cd (MainDirectory)
            
            GM_No=app.GMpopup.Value;
            
            evalc(['U=','RESULTS.U',num2str(GM_No)]);
            evalc(['V=','RESULTS.V',num2str(GM_No)]);
            evalc(['A=','RESULTS.A',num2str(GM_No)]);
            evalc(['F=','RESULTS.F',num2str(GM_No)]);
            %evalc(['K=','RESULTS.K',num2str(GM_No)]);
            evalc(['T=','RESULTS.T',num2str(GM_No)]);
            evalc(['AA=','RESULTS.AA',num2str(GM_No)]);
            
            cd (FilePath)
            fileX = fopen(FileName,'wt');
            
            if Units==1
                a='Time [sec]';  c ='Rel-Displacement [m]'; d='Rel-Velocity [m/sec]'; h='Abs-Acceleration [m/sec2]'; i='Force [kN]';
            else
                a='Time [sec]';  c ='Rel-Displacement [in]'; d='Rel-Velocity [in/sec]'; h='Abs-Acceleration [in/sec2]'; i='Force [kip]';
            end
            fprintf(fileX,'%s\t%s\t%s\t%s\t%s\t',a,c,d,h,i);
            fprintf(fileX,'\n');
            
            for i=1:size(T,1)
              fprintf(fileX,'%f\t%f\t%f\t%f\t%f\t', T(i,1), U(i,1), V(i,1), AA(i,1), F(i,1));
              fprintf(fileX,'\n');
            end
            
            cd (MainDirectory)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            fclose all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'RHA: Response History Plots';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.axes1 = uiaxes(app.figure1);
            app.uipanel3 = uipanel(app.figure1);
            app.text19 = uilabel(app.uipanel3);
            app.text19.Text = 'LEGEND:  Rel.: Relative   Abs.: Absolute  V: Velocity  A: Acceleration';
            app.text18 = uilabel(app.figure1);
            app.text18.Text = 'Ground Motion:';
            app.GMpopup = uidropdown(app.figure1);
            app.GMpopup.Items = {};
            app.GMpopup.ValueChangedFcn = createCallbackFcn(app, @GMpopup_Callback, true);
            app.GMpopup.Value = {};
            app.text20 = uilabel(app.figure1);
            app.text20.Text = 'Response History Plots';
            app.NextButton = uibutton(app.figure1, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButton_Callback, true);
            app.NextButton.Text = '>';
            app.PreviousButton = uibutton(app.figure1, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_Callback, true);
            app.PreviousButton.Text = '<';
            app.saveButton = uibutton(app.figure1, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Text = 'save plot data';
            app.axes2 = uiaxes(app.figure1);
            app.axes3 = uiaxes(app.figure1);
            app.axes4 = uiaxes(app.figure1);
            app.text2 = uilabel(app.figure1);
            app.text2.Text = 'SF=1.0';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_Dynamic_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_Dynamic_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end