classdef Module_Results_HazardCurve_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        uipanel1    matlab.ui.container.Panel
        axes1       matlab.ui.control.UIAxes
        saveButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        function Plot_HazardCurve(app)
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'HazardCurveData','Hazardstatus','COEFF','SaAVG_T1','Hazard_Option')
            cd (MainDirectory)
            
            SA=HazardCurveData(:,1);
            MAFE=HazardCurveData(:,2);
            scatter (app.axes1,SA,MAFE, 'ko','MarkerEdgeColor','r','MarkerFaceColor','r','SizeData',10);
            counter=1;
            for Sa=0.001:0.001:3.0
                SA(counter,1)=Sa;
                MAFE(counter,1)=COEFF(1)*log(Sa)^4+COEFF(2)*log(Sa)^3+COEFF(3)*log(Sa)^2+COEFF(4)*log(Sa)^1+COEFF(5);
                counter=counter+1;
            end
            plot(app.axes1,SA,exp(MAFE),'-k','linewidth',2)
            app.axes1.XScale='log';
            app.axes1.YScale='log';
            app.axes1.XLabel.String='Sa [g]';
            app.axes1.YLabel.String='MAFE, \it\lambda';
            app.axes1.XLim=[0.002 3.0];
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
            if Hazard_Option==1
                legend(app.axes1,'Extracted data','Fitted 4th-degree poly.');
                app.axes1.Legend.Location='SouthWest';
                app.axes1.Legend.FontSize=10;
            else
                legend(app.axes1,'Imported data','Fitted 4th-degree poly.');
                app.axes1.Legend.Location='SouthWest';
                app.axes1.Legend.FontSize=10;
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_HazardCurve_OpeningFcn(app)
            Plot_HazardCurve(app)
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)
            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'HazardCurveData','Hazardstatus','COEFF','SaAVG_T1','Hazard_Option')
            cd (MainDirectory)
            
            cd (FilePath)
            fileX = fopen(FileName,'wt');
            
            counter=1;
            for Sa=0.001:0.001:3.0
                SA(counter,1)=Sa;
                x=COEFF(1)*log(Sa)^4+COEFF(2)*log(Sa)^3+COEFF(3)*log(Sa)^2+COEFF(4)*log(Sa)^1+COEFF(5);
                MAFE(counter,1)=exp(x);
                counter=counter+1;
            end
            
            fprintf(fileX,'%s\t','Hazard data of the Fitted 4th-degree Polynomial');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','-------------------------------');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t%s','Sa [g]','MAFE');
            fprintf(fileX,'\n');
            fprintf(fileX,'%s\t','-------------------------------');
            fprintf(fileX,'\n');
            for i=1:size(SA,1)
              fprintf(fileX,'%f\t%f\t', SA(i,1), MAFE(i,1));
              fprintf(fileX,'\n');
            end
            
            cd (MainDirectory)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Seismic Hazard Curve';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Seismic Hazard Curve';
            app.axes1 = uiaxes(app.uipanel1);
            app.saveButton = uibutton(app.uipanel1, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Text = 'save plot data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_HazardCurve_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_HazardCurve_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end