classdef Module_Results_IDA_FD_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        uipanel1    matlab.ui.container.Panel
        axes1       matlab.ui.control.UIAxes
        text23      matlab.ui.control.Label
        GMpopup     matlab.ui.control.DropDown
        text24      matlab.ui.control.Label
        slider1     matlab.ui.control.Slider
        saveButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        function Plot_FD(app)
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'IDA','g','Units','SystemModel_Option','Backbone','SaAVG_status')
            cd (MainDirectory)
            
            GM_No=app.GMpopup.Value;
            Incr_No=app.slider1.Value;
            N_Increments=app.slider1.UserData;
            
            for i=1:N_Increments
                evalc(['Xaxes=IDA.U',num2str(GM_No),'_',num2str(i)]);
                evalc(['Yaxes=IDA.F',num2str(GM_No),'_',num2str(i)]);
                MaxU(i,1)=max(abs(Xaxes));
                MaxF(i,1)=max(abs(Yaxes));
            end
            
            
            evalc(['SaT1=IDA.Sa',num2str(GM_No),'(Incr_No,1)/g']);
            evalc(['SaSF=IDA.Sa_SF',num2str(GM_No),'(Incr_No,1)']);
            
            evalc(['Xaxes=IDA.U',num2str(GM_No),'_',num2str(Incr_No)]);
            evalc(['Yaxes=IDA.F',num2str(GM_No),'_',num2str(Incr_No)]);
            plot (app.axes1,Xaxes,Yaxes, '-r','linewidth',1.5);
            plot (app.axes1,[0],[0], '--k','linewidth',1); % dummy plot for legend
            text(app.axes1,-max(MaxU)*1.10,max(MaxF)*1.05,['Increment: ',num2str(Incr_No),'/',num2str(N_Increments)], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            if SaAVG_status==0
                text(app.axes1,-max(MaxU)*1.10,max(MaxF)*0.85,['Sa(T_1)= ',num2str(round(SaT1*100)/100),'g'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            else
                text(app.axes1,-max(MaxU)*1.10,max(MaxF)*0.85,['Sa_{avg}= ',num2str(round(SaT1*100)/100),'g'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')            
            end
            text(app.axes1,-max(MaxU)*1.10,max(MaxF)*0.70,['Scale factor= ',num2str(round(SaSF*10)/10)], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            
            app.axes1.XLim=[-max(MaxU)*1.15 max(MaxU)*1.15];
            app.axes1.YLim=[-max(MaxF)*1.15 max(MaxF)*1.15];
            
            if Units ==1
                app.axes1.YLabel.String='Force [kN]';
                app.axes1.XLabel.String='Displacement, \delta [m]';
            else
                app.axes1.YLabel.String='Force [kip]';
                app.axes1.XLabel.String='Displacement, \delta [in]';
            end
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';

            %% Plot the Backbone Curve based on System Model Type
            if SystemModel_Option == 1
               M1 = Backbone.Ke * max(MaxU*1.3);
               plot(app.axes1,[0.0  max(MaxU)*1.3],[0  M1],'--k','linewidth',1);
               plot(app.axes1,[0.0 -max(MaxU)*1.3],[0 -M1],'--k','linewidth',1);
            end
            
            if SystemModel_Option == 2
               M1 = Backbone.Ke * Backbone.dy;
               M2 = M1 + Backbone.Kp * max(abs(MaxU))*1.3;
               plot(app.axes1,[0.0  Backbone.dy  Backbone.dy+max(abs(MaxU))*1.3],[0  M1  M2],'--k','linewidth',1);
               plot(app.axes1,[0.0 -Backbone.dy -Backbone.dy-max(abs(MaxU))*1.3],[0 -M1 -M2],'--k','linewidth',1);
            end
            
            if SystemModel_Option == 3 || SystemModel_Option == 6
                UFail_pos0=min(Backbone.Uu_pos0,Backbone.Uy_pos0+Backbone.Up_pos0+Backbone.Upc_pos0);
                UFail_neg0=min(Backbone.Uu_neg0,Backbone.Uy_neg0+Backbone.Up_neg0+Backbone.Upc_neg0);
            
                % Plot backbone
                if Backbone.Uu_pos0 > Backbone.Uy_pos0+Backbone.Up_pos0+Backbone.Upc_pos0
                    RotationPos=[0 Backbone.Uy_pos0 Backbone.Umax_pos0 Backbone.Umax_pos0+(Backbone.Fmax_pos0-Backbone.Fres_pos0)/Backbone.Kpc_pos0];
                    MomentPos=[0 Backbone.Fy_pos0 Backbone.Fmax_pos0 Backbone.Fres_pos0];
                else
                    RotationPos=[0 Backbone.Uy_pos0 Backbone.Umax_pos0 Backbone.Uu_pos0];
                    MomentPos  =[0 Backbone.Fy_pos0 Backbone.Fmax_pos0 Backbone.Fmax_pos0-Backbone.Kpc_pos0*(Backbone.Uu_pos0-Backbone.Umax_pos0)];
                end
            
                if Backbone.Uu_neg0 > Backbone.Uy_neg0+Backbone.Up_neg0+Backbone.Upc_neg0
                    RotationNeg=[0 Backbone.Uy_neg0 Backbone.Umax_neg0 Backbone.Umax_neg0+(Backbone.Fmax_neg0-Backbone.Fres_neg0)/Backbone.Kpc_neg0];
                    MomentNeg=[0 Backbone.Fy_neg0 Backbone.Fmax_neg0 Backbone.Fres_neg0];
                else
                    RotationNeg=[0 Backbone.Uy_neg0 Backbone.Umax_neg0 Backbone.Uu_neg0];
                    MomentNeg  =[0 Backbone.Fy_neg0     Backbone.Fmax_neg0      Backbone.Fmax_neg0-Backbone.Kpc_neg0*(Backbone.Uu_neg0-Backbone.Umax_neg0)];
                end
            
                % Plot backbone
                plot(app.axes1,RotationPos,MomentPos,'--k','linewidth',2);
                plot(app.axes1,-RotationNeg,-MomentNeg,'--k','linewidth',2);
            end
            
            if SystemModel_Option == 4 || SystemModel_Option == 5
                UFail_pos=min(Backbone.Uu_pos,Backbone.Uy_pos+Backbone.Up_pos+Backbone.Upc_pos);
                UFail_neg=min(Backbone.Uu_neg,Backbone.Uy_neg+Backbone.Up_neg+Backbone.Upc_neg);
            
                % Plot backbone
                if Backbone.Uu_pos > Backbone.Uy_pos+Backbone.Up_pos+Backbone.Upc_pos
                    RotationPos=[0 Backbone.Uy_pos Backbone.Umax_pos Backbone.Umax_pos+(Backbone.Fmax_pos-Backbone.Fres_pos)/Backbone.Kpc_pos];
                    MomentPos=[0 Backbone.Fy_pos Backbone.Fmax_pos Backbone.Fres_pos];
                else
                    RotationPos=[0 Backbone.Uy_pos Backbone.Umax_pos Backbone.Uu_pos];
                    MomentPos  =[0 Backbone.Fy_pos Backbone.Fmax_pos Backbone.Fmax_pos-Backbone.Kpc_pos*(Backbone.Uu_pos-Backbone.Umax_pos)];
                end
            
                if Backbone.Uu_neg > Backbone.Uy_neg+Backbone.Up_neg+Backbone.Upc_neg
                    RotationNeg=[0 Backbone.Uy_neg Backbone.Umax_neg Backbone.Umax_neg+(Backbone.Fmax_neg-Backbone.Fres_neg)/Backbone.Kpc_neg];
                    MomentNeg=[0 Backbone.Fy_neg Backbone.Fmax_neg Backbone.Fres_neg];
                else
                    RotationNeg=[0 Backbone.Uy_neg Backbone.Umax_neg Backbone.Uu_neg];
                    MomentNeg  =[0 Backbone.Fy_neg Backbone.Fmax_neg Backbone.Fmax_neg-Backbone.Kpc_neg*(Backbone.Uu_neg-Backbone.Umax_neg)];
                end
            
                % Plot backbone
                plot(app.axes1,RotationPos,MomentPos,'--k','linewidth',2);
                plot(app.axes1,-RotationNeg,-MomentNeg,'--k','linewidth',2);
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_IDA_FD_OpeningFcn(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'GM_Option','Wave_name','nGM','GM')
            cd (MainDirectory)
            
            if GM_Option==3
                X{1,1}=Wave_name;
            else
                if nGM==1
                    zz=GM.name{1};
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz={zz(1:indexz-1)}; end
                    X(1,1)=zz;
                else
                    zz=char(GM.name{1,1});
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz=zz(1:indexz-1); end
                    X{1,1}=zz;
                    clear z
                    for i=1:nGM
                        zz=char(GM.name{i,1});
                        indexz=strfind(zz,'.');
                        if isempty(zz); else; zz=zz(1:indexz-1); end
                        X{i,1}=zz;
                        clear zz
                    end
                end
            end
            app.GMpopup.Items=X;
            app.GMpopup.ItemsData=1:nGM;
            app.GMpopup.Value=1;
            GMpopup_Callback(app)

        end

        % Value changed function: GMpopup
        function GMpopup_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'IDA')
            cd (MainDirectory)
            
            GM_No=app.GMpopup.Value;
            evalc(['Xaxes=IDA.Sa',num2str(GM_No)]);

            N_Increments= size(Xaxes,1)-1;
            app.slider1.Limits=[1 N_Increments];
            app.slider1.Value=round(N_Increments/2);
            app.slider1.UserData=N_Increments;
            app.slider1.MajorTicks=1:max(round(N_Increments/10),1):N_Increments;
            
            Plot_FD(app)
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)
            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'IDA','Units','g')
            cd (MainDirectory)
            GM_No=app.GMpopup.Value;
            Incr_No=app.slider1.Value;
            
            evalc(['SaT1=IDA.Sa',num2str(GM_No),'(Incr_No,1)/g']);
            evalc(['SaSF=IDA.Sa_SF',num2str(GM_No),'(Incr_No,1)']);
            
            evalc(['U=IDA.U',num2str(GM_No),'_',num2str(Incr_No)]);
            evalc(['F=IDA.F',num2str(GM_No),'_',num2str(Incr_No)]);
            
            cd (FilePath)
            fileX = fopen(FileName,'wt');
            fprintf(fileX,'%s\t%f%s\t%f\t','Sa(T1)=',SaT1,'g  SF=',SaSF);
            fprintf(fileX,'\n');
            if Units==1
                a ='Displacement [m]';b='Force [kN]';
            else
                a='Displacement [in]';b='Force [kip]';
            end
            fprintf(fileX,'%s\t%s\t',a,b);
            fprintf(fileX,'\n');
            
            for i=1:size(U,1)
              fprintf(fileX,'%f\t%f\t', U(i,1), F(i,1));
              fprintf(fileX,'\n');
            end
            
            cd (MainDirectory)
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            pause(0.5)
            X = round(app.slider1.Value);
            app.slider1.Value=X;
            app.axes1.cla;
            Plot_FD(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            fclose all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Results: IDA - Incremental Force-Disp';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Force-Displacement Response in IDA';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.XLim = [-0.110161604692016 0.110161604692016];
            app.axes1.YLim = [-3.17482251867287 3.17482251867287];
            app.text23 = uilabel(app.uipanel1);
            app.text23.Text = 'Ground Motion:';
            app.GMpopup = uidropdown(app.uipanel1);
            app.GMpopup.Items = {'1'};
            app.GMpopup.ValueChangedFcn = createCallbackFcn(app, @GMpopup_Callback, true);
            app.GMpopup.Value = '1';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Text = 'Increment No:';
            app.slider1 = uislider(app.uipanel1);
            app.slider1.Limits = [0 1];
            app.slider1.MajorTicks = [0 0.5 1];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.saveButton = uibutton(app.uipanel1, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Text = 'save plot data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_IDA_FD_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_IDA_FD_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end