classdef Module_Results_IDA_Options_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        SubmitButton  matlab.ui.control.Button
        checkbox1     matlab.ui.control.CheckBox
        checkbox2     matlab.ui.control.CheckBox
        checkbox3     matlab.ui.control.CheckBox
        checkbox4     matlab.ui.control.CheckBox
        checkbox5     matlab.ui.control.CheckBox
        checkbox6     matlab.ui.control.CheckBox
        checkbox7     matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_IDA_Options_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global MainDirectory ProjectPath ProjectName
            cd (ProjectPath)
            load(ProjectName,'Analysis_Option','nGM','Hazardstatus');
            cd (MainDirectory)
            clc
            
            if Analysis_Option==2 && nGM >3.0
                app.checkbox1.Enable='on';
                app.checkbox2.Enable='on';
                app.checkbox3.Enable='on';
                app.checkbox4.Enable='on';
                app.checkbox5.Enable='on';
                app.checkbox7.Enable='on';
                if Hazardstatus==1
                    app.checkbox6.Enable='on';
                else
                    app.checkbox6.Enable='off';
                end
            elseif Analysis_Option==2 && nGM <=3.0
                app.checkbox1.Enable='on';
                app.checkbox2.Enable='off';
                app.checkbox3.Enable='off';
                app.checkbox4.Enable='off';
                app.checkbox5.Enable='on';
                app.checkbox6.Enable='off';
                app.checkbox7.Enable='off';
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            
            if app.checkbox1.Value==1
                %Module_Results_IDAcurve_App
                Module_Results_IDAcurveX_App;
            end
            
            if app.checkbox2.Value==1
                Module_Results_DispFragility_App;
            end
            
            if app.checkbox3.Value==1
                Module_Results_CollapseFragilityCurve_App;
            end
            
            if app.checkbox4.Value==1
                Module_Results_IDAcomposite_App;
            end
            
            if app.checkbox5.Value==1
                Module_Results_IDA_FD_App;
            end
            
            if app.checkbox6.Value==1
                Module_Results_HazardCurve_App;
            end
            
            if app.checkbox7.Value==1
                Module_Results_DispLimitStateFragility_App;
            end
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Result: Plot Options';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Select Results to Plot';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'View';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.Text = ' IDA Curves';
            app.checkbox2 = uicheckbox(app.uipanel1);
            app.checkbox2.Text = ' Displacement Fragility at Target SA';
            app.checkbox3 = uicheckbox(app.uipanel1);
            app.checkbox3.Text = ' Collapse Fragility Curve';
            app.checkbox4 = uicheckbox(app.uipanel1);
            app.checkbox4.Text = ' Composite Plot (IDA+Disp Fragility)';
            app.checkbox5 = uicheckbox(app.uipanel1);
            app.checkbox5.Text = ' Incremental Force-Displacement';
            app.checkbox6 = uicheckbox(app.uipanel1);
            app.checkbox6.Text = ' Seismic Hazard Curve';
            app.checkbox7 = uicheckbox(app.uipanel1);
            app.checkbox7.Text = ' Disp Limit State Fragility Curve';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_IDA_Options_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_Results_IDA_Options_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end