classdef Module_Results_IDAcomposite_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1   matlab.ui.Figure
        uipanel1  matlab.ui.container.Panel
        axes2     matlab.ui.control.UIAxes
        axes3     matlab.ui.control.UIAxes
        slider1   matlab.ui.control.Slider
        text23    matlab.ui.control.Label
        text24    matlab.ui.control.Label
        text25    matlab.ui.control.Label
        edit1     matlab.ui.control.NumericEditField
    end

    
    methods (Access = private)
        function [MaxU, MaxSA] = Plot_IDA_Curves(app, nGM, IDA, g, Units, zeta,SaAVG_status,T,SaAVG_T1,SaAVG_T2)
            Umax=app.slider1.UserData;
            app.axes2.cla;
            app.axes2.BackgroundColor=[1 1 1];
            app.axes2.FontName='Times';
            app.axes2.FontSize=17;
            app.axes2.XGrid='on';
            app.axes2.YGrid='on';
            app.axes2.Box='on';
            hold(app.axes2,"on");
        
            for i=1:nGM
                evalc(['Xaxes=IDA.U',num2str(i)]);
                evalc(['Yaxes=IDA.Sa',num2str(i),'/g']);
                MaxU(i,1)=max(Xaxes);
                MaxSA(i,1)=max(Yaxes);
            
                plot (app.axes2,Xaxes,Yaxes, '-','Color',[0.5 0.5 0.5]);
                plot (app.axes2,Xaxes(end-1,1),Yaxes(end-1,1), '-ok','MarkerFaceColor','r','MarkerSize',4);
            end
            if Units ==1
                app.axes2.XLabel.String='Displacementt, \delta [m]';
            else
                app.axes2.XLabel.String='Displacementt, \delta [in]';
            end
            if SaAVG_status==0
                app.axes2.YLabel.String=['Sa (T_1, ',num2str(zeta*100), '%) [g]'];
            else
                app.axes2.YLabel.String= ['Sa_{avg} (T=',num2str(round(SaAVG_T1*T*10)/10),'s-',num2str(round(SaAVG_T2*T*10)/10),'s, ',num2str(zeta*100), '%) [g]'];
            end
            app.axes2.XLim=[0 max(MaxU)];
            app.axes2.YLim=[0 round((max(MaxSA)+0.1)*10)/10];
            app.axes2.XLabel.FontName='Times';
            app.axes2.YLabel.FontName='Times';
            TargetSa=app.slider1.Value;
            plot(app.axes2,[0 Umax*1.5],[TargetSa TargetSa],'--b','linewidth',1.5);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_IDAcomposite_OpeningFcn(app)
            
            app.axes3.cla
            app.axes3.BackgroundColor=[1 1 1];
            app.axes3.FontName='Times';
            app.axes3.FontSize=17;
            app.axes3.XGrid='on';
            app.axes3.YGrid='on';
            app.axes3.Box='on';
            hold(app.axes3,"on");
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'IDA','g','Units','zeta','nGM','Alimit','SaAVG_status','T,SaAVG_T1','SaAVG_T2','T')
            cd (MainDirectory)

            [MaxU, MaxSA]=Plot_IDA_Curves (app, nGM, IDA,g, Units,zeta,SaAVG_status,T,SaAVG_T1,SaAVG_T2);
            
            MAXMaxSA=max(MaxSA);
            app.slider1.Limits=[0.0 round(MAXMaxSA*10)/10];
            app.slider1.MajorTicks=0.0:ceil(MAXMaxSA*10/5)/10:ceil(MAXMaxSA);
            app.slider1.Value=round(MAXMaxSA*10/2)/10;
            app.edit1.Limits=[0.0 round(MAXMaxSA*10)/10];

            %% PLOT FRAGILITY CURVE AT TARGET SA
            % For each GM, Get the displacements at the target Sa
            TargetSa=app.edit1.Value;
            count=0;
            for i=1:nGM
                if  evalc(['max(IDA.Sa',num2str(i),'(1:end-1,1))<=TargetSa*g'])
                    count=count+1;
                    evalc(['U_at_targetSA(count,1)=interp1(IDA.Sa',num2str(i),'(1:end-1,1),','IDA.U',num2str(i),'(1:end-1,1)',',','TargetSa*g)']);
                else
                end
            end
                        
            if count>=3
                U_at_targetSA=sort(U_at_targetSA, 1);
                Parameters=lognfit(U_at_targetSA);
                MedianU=Parameters(1);
                SigmaU=Parameters(2);
                if max(U_at_targetSA)==min(U_at_targetSA)
                    MedianU=MedianU;
                    SigmaU=0.001;
                end
            end
            
            Probability=zeros(count,1);
            for i=1:count
                Probability(i,1)=i/count;
            end
            
            scatter (app.axes3,U_at_targetSA,Probability, 'ko','MarkerEdgeColor','k','MarkerFaceColor',[0.6 0.6 0.6],'SizeData',26);
            if count>=3
                Urange = (0.0:max(MaxU)/nGM/100:max(MaxU)*1.1);
                Probability = logncdf(Urange,MedianU,SigmaU);
                plot(app.axes3,Urange,Probability,'-r','linewidth',2)
            end
            

            if Units ==1; app.axes3.XLabel.String='Displacementt, \delta [m]';else; app.axes3.XLabel.String='Displacement, \delta [in]'; end
            app.axes3.YLabel.String=['P[\delta|Sa=',num2str(round(TargetSa*10)/10),'g]'];
            app.axes3.YLim=[0 1.0];
            app.axes3.XLim=[0 max(MaxU)];
            app.axes3.XLabel.FontName='Times';
            app.axes3.YLabel.FontName='Times';
            app.slider1.UserData=max(MaxU);
            slider1_Callback(app)

        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','IDA','g')
            cd (MainDirectory)
            
            TargetSa=app.edit1.Value;
            
            for i=1:nGM
                evalc(['Yaxes=IDA.Sa',num2str(i),'/g']);
                MaxSA(i,1)=max(Yaxes);
            end
            
            if TargetSa>max(MaxSA)-0.001
                app.edit1.FontColor='r';
                TargetSa=max(MaxSA)-0.001;
                app.edit1.Value=TargetSa;
                return
            else
               app.edit1.FontColor='k';
            end
            
            if TargetSa <0.001
                app.edit1.FontColor='r';
                TargetSa=0.001;
                app.edit1.Value=TargetSa;
                return
            else
               app.edit1.FontColor='k';
            end
            
            app.slider1.Value=TargetSa;
            slider1_Callback(app)
        end

        % Value changed function: slider1
        function slider1_Callback(app, event)
            app.axes3.cla;
            app.axes3.BackgroundColor=[1 1 1];
            app.axes3.FontName='Times';
            app.axes3.FontSize=17;
            app.axes3.XGrid='on';
            app.axes3.YGrid='on';
            app.axes3.Box='on';
            hold(app.axes3,"on");
            
            global MainDirectory ProjectPath ProjectName
            cd (ProjectPath)
            load (ProjectName,'nGM','IDA','g','Units','zeta','SaAVG_status','T','SaAVG_T1','SaAVG_T2')
            cd (MainDirectory)
            
            TargetSa=app.slider1.Value;
            if TargetSa==0; TargetSa=0.001; end
            app.edit1.Value=TargetSa;
            Umax=app.slider1.UserData;
            
            %% PLOT FRAGILITY CURVE AT TARGET SA
            % For each GM, Get the displacements at the target Sa
            count=0;
            for i=1:nGM
                 evalc(['maxsa=max(IDA.Sa',num2str(i),'(1:end-1,1))']);
                if  maxsa>TargetSa*g
                    count=count+1;
                    evalc(['U_at_targetSA(count,1)=interp1(IDA.Sa',num2str(i),'(1:end-1,1),','IDA.U',num2str(i),'(1:end-1,1)',',','TargetSa*g)']);
                else
                end
            end
            
            if count>=3
                U_at_targetSA=sort(U_at_targetSA, 1);
                Parameters=lognfit(U_at_targetSA);
                MedianU=Parameters(1);
                SigmaU=Parameters(2);
                if max(U_at_targetSA)==min(U_at_targetSA)
                    MedianU=MedianU;
                    SigmaU=0.001;
                end
            end
            
            Probability=zeros(count,1);
            for i=1:count
                Probability(i,1)=i/count;
            end
            
            for i=1:nGM
                evalc(['Xaxes=IDA.U',num2str(i)]);
                evalc(['Yaxes=IDA.Sa',num2str(i),'/g']);
                MaxU(i,1)=max(Xaxes);
                MaxSA(i,1)=max(Yaxes);
            end
            
            scatter (app.axes3,U_at_targetSA,Probability, 'ko','MarkerEdgeColor','k','MarkerFaceColor',[0.6 0.6 0.6],'SizeData',26);
            if count>=3
                MaxU_atSa=max(U_at_targetSA);
                Urange = (0.0:MaxU_atSa/nGM/100:MaxU_atSa*1.5);
                Probability = logncdf(Urange,MedianU,SigmaU);
                plot(app.axes3,Urange,Probability,'-r','linewidth',2)
            end
            
            if Units ==1; app.axes3.XLabel.String='Displacementt, \delta [m]';else; app.axes3.XLabel.String='Displacementt, \delta [in]'; end
            app.axes3.YLabel.String=['P[\delta|Sa=',num2str(round(TargetSa*10)/10),'g]'];
            app.axes3.YLim=[0 1.0];
            app.axes3.XLim=[0 Umax];
            text(app.axes3,0.01, 0.95,['#GM= ',num2str(count),'/',num2str(nGM)], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            if count>=3
                Probability = logncdf(Urange,MedianU,SigmaU);
                if Units==1;text(app.axes3,0.01,0.88,['Median: ',num2str(round(exp(MedianU)*1000)/1000),'m'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold'); end
                if Units==2;text(app.axes3,0.01,0.88,['Median: ',num2str(round(exp(MedianU)*1000)/1000),'in'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold'); end
                text(app.axes3,0.01,0.81,['Dispersion: ',num2str(round(SigmaU*1000)/1000)], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            else
                text(app.axes3,0.0,0.88,['Median: NaN'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
                text(app.axes3,0.0,0.81,['Dispersion: NaN'], 'fontname', 'Courier', 'fontsize',12,'fontweight','bold')
            end
            app.axes3.XLabel.FontName='Times';
            app.axes3.YLabel.FontName='Times';

            [MaxU, MaxSA]=Plot_IDA_Curves (app, nGM, IDA,g, Units,zeta,SaAVG_status,T,SaAVG_T1,SaAVG_T2);
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            fclose all
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Results: IDA - Multiple GMs';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.axes2 = uiaxes(app.uipanel1);
            app.axes3 = uiaxes(app.uipanel1);
            app.slider1 = uislider(app.uipanel1);
            app.slider1.Limits = [0 1];
            app.slider1.ValueChangedFcn = createCallbackFcn(app, @slider1_Callback, true);
            app.slider1.MinorTicks = [];
            app.slider1.Value = 0.5;
            app.text23 = uilabel(app.uipanel1);
            app.text23.Text = '[g]';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Text = 'Target Sa:';
            app.text25 = uilabel(app.uipanel1);
            app.text25.Text = 'IDA Curves';
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.Limits = [0.001 Inf];
            app.edit1.ValueDisplayFormat = '%5.2f';
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Value = 0.5;
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_IDAcomposite_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_IDAcomposite_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end