classdef Module_Results_IDAcurveX_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1     matlab.ui.Figure
        uipanel1    matlab.ui.container.Panel
        axes1       matlab.ui.control.UIAxes
        text23      matlab.ui.control.Label
        GMpopup     matlab.ui.control.DropDown
        checkbox1   matlab.ui.control.CheckBox
        text24      matlab.ui.control.Label
        popupmenu6  matlab.ui.control.DropDown
        edit1       matlab.ui.control.NumericEditField
        saveButton  matlab.ui.control.Button
    end

    
    methods (Access = private)
        function [Median16_Sa, Median50_Sa, Median84_Sa, D_Vector] = Get_Median_Collapse_Fragility(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','IDA','g')
            cd (MainDirectory)
            
            targetEDP=str2double(app.popupmenu6.Value);
            
            for i=1:nGM
                if targetEDP==1
                    evalc(['Xaxes=IDA.U',num2str(i)]);
                elseif targetEDP==2
                    evalc(['Xaxes=IDA.AA',num2str(i)]);
                end
                MaxEDP(i,1)=max(Xaxes);
            end
            
                counter = 1;
            for D = 0.0:max(MaxEDP)/100:max(MaxEDP)
            
                for GM_No=1:nGM     % Loop for GMs
                    if targetEDP==1
                        evalc(['Xaxes=IDA.U',num2str(GM_No)]);
                    elseif targetEDP==2
                        evalc(['Xaxes=IDA.AA',num2str(GM_No)]);
                    end
                    evalc(['Yaxes=IDA.Sa',num2str(GM_No),'/g']);
                    Sa_Interp (GM_No,1)=interp1(Xaxes,Yaxes,D);
                end
            
                Sa_Interp=sort(Sa_Interp);
                Median16_Sa(counter,1)=(Sa_Interp(ceil(0.16*nGM),1)+Sa_Interp(floor(0.16*nGM),1))/2;
                Median50_Sa(counter,1)=median(Sa_Interp);
                Median84_Sa(counter,1)=(Sa_Interp(ceil(0.84*nGM),1)+Sa_Interp(floor(0.84*nGM),1))/2;
                D_Vector (counter,1)=D;
            
                counter = counter + 1;
            end
            
                Median16_Sa(counter,1)=Median16_Sa(end,1);
                Median50_Sa(counter,1)=Median50_Sa(end,1);
                Median84_Sa(counter,1)=Median84_Sa(end,1);
                Median16_Sa(~isfinite(Median16_Sa))=max(Median16_Sa);
                Median50_Sa(~isfinite(Median50_Sa))=max(Median50_Sa);
                Median84_Sa(~isfinite(Median84_Sa))=max(Median84_Sa);
            
                D_Vector (counter,1)=max(MaxEDP);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_IDAcurveX_OpeningFcn(app)
            app.axes1.cla;
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'GM_Option','Wave_name','nGM','GM')
            cd (MainDirectory)
            
            if GM_Option==3
                X{1,1}=Wave_name;
            else
                if nGM==1
                    X(1,1)={'All'};
                    zz=GM.name{1};
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz={zz(1:indexz-1)}; end
                    X(2,1)=zz;
                else
                    X{1,1}='All';
                    for i=1:nGM
                        zz=char(GM.name{i,1});
                        indexz=strfind(zz,'.');
                        if isempty(zz); else; zz=zz(1:indexz-1); end
                        X{i+1,1}=zz;
                        clear zz
                    end
                end
            end
            app.GMpopup.Items=X;
            app.GMpopup.ItemsData=1:nGM+1;
            app.checkbox1.Value=0;
            checkbox1_Callback(app);
            if nGM==1
                app.GMpopup.Value=2;
            else
                app.GMpopup.Value=1;
            end
            GMpopup_Callback(app);
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Units','H')
            cd (MainDirectory)
            
            if app.checkbox1.Value==1
               app.edit1.Enable='on';
               if Units ==1
                    app.edit1.Value=H;
               else
                    app.edit1.Value=H;
               end
               GMpopup_Callback(app)
            else
               app.edit1.Enable='off';
               app.edit1.Value=0;
               GMpopup_Callback(app)
            end
        end

        % Callback function
        function edit1_Callback(app, event)
            GMpopup_Callback(app)
        end

        % Value changed function: GMpopup
        function GMpopup_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'IDA','g','nGM','zeta','Units','SaAVG_status','SaAVG_T1','SaAVG_T2','T')
            cd (MainDirectory)
            
            % clear axes
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=18;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            if app.checkbox1.Value==1
               NormVal=app.edit1.Value;
            else
               NormVal=1;
            end
            
            GM_No=(app.GMpopup.Value)-1;
            targetEDP=str2double(app.popupmenu6.Value);
            
            for i=1:nGM
                if targetEDP==1
                    rootname='IDA.U';
                    evalc(['Xaxes=IDA.U',num2str(i)]);
                elseif targetEDP==2
                    NormVal=g;
                    rootname='IDA.AA';
                    evalc(['Xaxes=IDA.AA',num2str(i)]);
                end
                evalc(['Yaxes=IDA.Sa',num2str(i),'/g']);
                MaxEDP(i,1)=max(Xaxes);
                MaxSA(i,1)=max(Yaxes);
            end
            
            if GM_No==0 && nGM>5
                    plot (app.axes1,[0],[0],'-ok','Color',[0.65 0.65 0.65],'MarkerSize',3);
                    plot (app.axes1,[0],[0],'-r','linewidth',2);
                    plot (app.axes1,[0],[0],'--r','linewidth',2);
            end
            
            if GM_No==0
                app.saveButton.Enable='off';
                for i=1:nGM
                    if targetEDP==1
                        evalc(['Xaxes=',rootname,num2str(i),'(1:end,1)/NormVal']);
                        evalc(['Yaxes=IDA.Sa',num2str(i),'(1:end,1)/g']);
                    else
                        evalc(['Xaxes=',rootname,num2str(i),'(1:end-2,1)/NormVal']);
                        evalc(['Yaxes=IDA.Sa',num2str(i),'(1:end-2,1)/g']);
                    end
                    MaxX(i,1)=max(Xaxes);
                    plot (app.axes1,Xaxes,Yaxes,'-ok','Color',[0.65 0.65 0.65],'MarkerSize',3);
                    plot (app.axes1,Xaxes(end,1),Yaxes(end,1), '-ok','MarkerFaceColor','r','MarkerSize',4);
                end
            
                app.axes1.XLim=[0 max(MaxX)];
                app.axes1.YLim=[0 round((max(MaxSA)+0.1)*10)/10];
            
                if nGM>5 && targetEDP==1
                    [Median16_Sa, Median50_Sa, Median84_Sa, D_Vector]=Get_Median_Collapse_Fragility (app);
                    plot (app.axes1,D_Vector/NormVal,Median16_Sa,'--r','linewidth',2);
                    plot (app.axes1,D_Vector/NormVal,Median50_Sa,'-r','linewidth',2);
                    plot (app.axes1,D_Vector/NormVal,Median84_Sa,'--r','linewidth',2);
                end
            else
                app.saveButton.Enable='on';
                if targetEDP==1
                    evalc(['Xaxes=',rootname,num2str(GM_No),'(1:end,1)/NormVal']);
                    evalc(['Yaxes=IDA.Sa',num2str(GM_No),'(1:end,1)/g']);
                else
                    evalc(['Xaxes=',rootname,num2str(GM_No),'(1:end-2,1)/NormVal']);
                    evalc(['Yaxes=IDA.Sa',num2str(GM_No),'(1:end-2,1)/g']);
                end
                MaxX(GM_No,1)=max(Xaxes);
                plot (app.axes1,Xaxes,Yaxes, '-ok','MarkerEdgeColor','k','MarkerSize',3,'Color',[0.5 0.5 0.5]);
                plot (app.axes1,Xaxes(end,1),Yaxes(end,1), '-ok','MarkerFaceColor','r','MarkerSize',4);
                app.axes1.XLim=[0 max(MaxX)*1.2];
                app.axes1.YLim=[0 round((max(Yaxes)+0.1)*10)/10];
            end
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
            
            if targetEDP==1
                if app.checkbox1.Value==1
                   app.axes1.XLabel.String= 'Drift ratio [rad]';
                else
                    if Units ==1
                        app.axes1.XLabel.String= 'Displacement, \delta [m]';
                    else
                        app.axes1.XLabel.String= 'Displacement, \delta [in]';
                    end
                end
            elseif targetEDP==2
                    app.axes1.XLabel.String= 'Abs. Acceleration [g]';
            end
            
            if SaAVG_status==0
                app.axes1.YLabel.String= ['Sa (T_1, ',num2str(zeta*100), '%) [g]'];
            else
                app.axes1.YLabel.String= ['Sa_{avg} (T=',num2str(round(SaAVG_T1*T*10)/10),'s-',num2str(round(SaAVG_T2*T*10)/10),'s, ',num2str(zeta*100), '%) [g]'];
            end
        end

        % Value changed function: popupmenu6
        function popupmenu6_Callback(app, event)
            if app.popupmenu6.Value=='1'
                app.checkbox1.Enable='on';
                app.edit1.Enable='off';
                checkbox1_Callback(app)
            else
                app.checkbox1.Enable='off';
                app.edit1.Enable='off';
                app.checkbox1.Value=0;
            end
            GMpopup_Callback(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            fclose all;
            delete(app)
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'IDA','Units','g')
            cd (MainDirectory)
            GM_No=app.GMpopup.Value-1;
            targetEDP=str2double(app.popupmenu6.Value);
            if app.checkbox1.Value==1
               NormVal=app.edit1.Value;
            else
               NormVal=1;
            end

            if targetEDP==1
                evalc(['Xaxes=IDA.U',num2str(GM_No),'(1:end,1)/NormVal']);
                evalc(['Yaxes=IDA.Sa',num2str(GM_No),'(1:end,1)/g']);
            else
                NormVal=g;
                evalc(['Xaxes=IDA.AA',num2str(GM_No),'(1:end-2,1)/NormVal']);
                evalc(['Yaxes=IDA.Sa',num2str(GM_No),'(1:end-2,1)/g']);
            end
            
            cd (FilePath)
            fileX = fopen(FileName,'wt');
            a ='Sa [g]';
            if targetEDP==1
                if app.checkbox1.Value==1
                    b='Drift [rad]';
                else
                    if Units==1
                        b='Displacement [m]';
                    else
                        b='Displacement [in]';
                    end                
                end
            else
                b='Abs. Acceleration [g]';                
            end
            fprintf(fileX,'%s\t%s\t',a,b);
            fprintf(fileX,'\n');
            
            for i=1:size(Xaxes,1)
              fprintf(fileX,'%f\t%f\t', Yaxes(i,1), Xaxes(i,1));
              fprintf(fileX,'\n');
            end
            
            cd (MainDirectory)            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Results: IDA Curves';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'IDA Curves';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.XLim = [-0.110161604692016 0.110161604692016];
            app.axes1.YLim = [-3.17482251867287 3.17482251867287];
            app.text23 = uilabel(app.uipanel1);
            app.text23.Text = 'Ground Motion:';
            app.GMpopup = uidropdown(app.uipanel1);
            app.GMpopup.Items = {'1'};
            app.GMpopup.ItemsData = {'1'};
            app.GMpopup.ValueChangedFcn = createCallbackFcn(app, @GMpopup_Callback, true);
            app.GMpopup.Value = '1';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'normalize x-axis by';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Text = 'Response Parameter:';
            app.popupmenu6 = uidropdown(app.uipanel1);
            app.popupmenu6.Items = {'Displacement', 'Max. Absolute Acceleration'};
            app.popupmenu6.ItemsData = {'1', '2'};
            app.popupmenu6.ValueChangedFcn = createCallbackFcn(app, @popupmenu6_Callback, true);
            app.popupmenu6.Value = '1';
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.ValueDisplayFormat = '%5.2f';
            app.saveButton = uibutton(app.uipanel1, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = 'save plot data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_IDAcurveX_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_IDAcurveX_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end