classdef Module_Results_RHA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1          matlab.ui.Figure
        uipanel4         matlab.ui.container.Panel
        axes1            matlab.ui.control.UIAxes
        text18           matlab.ui.control.Label
        NextButton       matlab.ui.control.Button
        PreviousButton   matlab.ui.control.Button
        saveButton       matlab.ui.control.Button
        text21           matlab.ui.control.Label
        Next2Button      matlab.ui.control.Button
        Previous2Button  matlab.ui.control.Button
        GMpopup          matlab.ui.control.DropDown
        PlotTypepopup    matlab.ui.control.DropDown
        text1            matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_RHA_OpeningFcn(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','GM','Wave_name','GM_Option')
            cd (MainDirectory)

            app.PreviousButton.UserData=nGM;
            
            if GM_Option==3
                X{1,1}=Wave_name;
            else
                if nGM==1
                    zz=GM.name{1};
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz={zz(1:indexz-1)}; end
                    X(1,1)=zz;
                else
                    zz=char(GM.name{1,1});
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz=zz(1:indexz-1); end
                    X{1,1}=zz;
                    clear z
                    for i=1:nGM
                        zz=char(GM.name{i,1});
                        indexz=strfind(zz,'.');
                        if isempty(zz); else; zz=zz(1:indexz-1); end
                        X{i,1}=zz;
                        clear zz
                    end
                end
            end
            app.GMpopup.Items=X;
            app.GMpopup.ItemsData=1:nGM;
            app.GMpopup.Value=1;
            GMpopup_Callback(app);

        end

        % Button pushed function: Next2Button
        function Next2Button_Callback(app, event)
            X=str2double(app.PlotTypepopup.Value);
            if X<7
                X=X+1;
                app.PlotTypepopup.Value=num2str(X);
                GMpopup_Callback(app)
            end
        end

        % Button pushed function: NextButton
        function NextButton_Callback(app, event)
            nGM=app.PreviousButton.UserData;
            Current_GM_No=app.GMpopup.Value;
            if Current_GM_No<nGM
                Next_GM_No=Current_GM_No+1;
                app.GMpopup.Value=Next_GM_No;
                GMpopup_Callback(app)
            end
        end

        % Button pushed function: Previous2Button
        function Previous2Button_Callback(app, event)
            X=str2double(app.PlotTypepopup.Value);
            if X>1
                X=X-1;
                app.PlotTypepopup.Value=num2str(X);
                GMpopup_Callback(app)
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButton_Callback(app, event)
            Current_GM_No=app.GMpopup.Value;
            if Current_GM_No>1
                Previous_GM_No=Current_GM_No-1;
                app.GMpopup.Value=Previous_GM_No;
                GMpopup_Callback(app)
            end
        end

        % Value changed function: GMpopup
        function GMpopup_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','GM','RESULTS','Wave_name','g','Units')
            cd (MainDirectory)
            
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=16;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            GM_No=app.GMpopup.Value;
            Plot_Type=str2double(app.PlotTypepopup.Value);
            
            evalc(['T=','RESULTS.T',num2str(GM_No)]);
            if Plot_Type==1
                evalc(['Yaxes=','RESULTS.U',num2str(GM_No)]);
                if Units==1; aaa='m'; else aaa='in'; end
                label='Rel-Disp.';
            elseif Plot_Type==2
                evalc(['Yaxes=','RESULTS.V',num2str(GM_No)]);
                if Units==1; aaa='m/sec'; else aaa='in/sec'; end
                label='Rel-Velocity';
            elseif Plot_Type==3
                evalc(['Yaxes=','RESULTS.A',num2str(GM_No),'/g']);
                if Units==1; aaa='g'; else aaa='g'; end
                label='Rel-Acceleration';
            elseif Plot_Type==4
                evalc(['Yaxes=','RESULTS.AA',num2str(GM_No),'/g']);
                if Units==1; aaa='g'; else aaa='g'; end
                label='Abs-Acc.';
            elseif Plot_Type==5
            	evalc(['Yaxes=','RESULTS.PV',num2str(GM_No)]);
                if Units==1; aaa='m/sec'; else aaa='in/sec'; end
                label='Pseudo-Velocity';
            elseif Plot_Type==6
            	evalc(['Yaxes=','RESULTS.PA',num2str(GM_No)]);
                if Units==1; aaa='g'; else aaa='g'; end
                label='Pseudo-Acc.';
            elseif Plot_Type==7
                evalc(['Yaxes=','RESULTS.F',num2str(GM_No)]);
                if Units==1; aaa='kN'; else aaa='kip'; end
                label='Force';
            end
            [MaxY, IndexMaxY]=max(abs(Yaxes));
            
            plot(app.axes1,T,Yaxes,'-r','linewidth',2)
            plot(app.axes1,T(IndexMaxY,1),Yaxes(IndexMaxY,1),'-ok','linewidth',1,'Markerfacecolor','g')
            app.axes1.XLabel.String='Time [sec]';
            app.axes1.YLim=[-MaxY*1.3 MaxY*1.3];
            text(app.axes1,T(IndexMaxY,1),Yaxes(IndexMaxY,1)*1.15,[' ',num2str(round(MaxY*100)/100),' ',aaa], 'fontname', 'Courier', 'fontsize',12)
            evalc(['SF=','RESULTS.SF',num2str(GM_No)]);
            xx=['SF=',num2str(round(SF*100)/100)];
            app.text1.Text=xx;
            app.axes1.YLabel.String=[label,' [',aaa,']'];
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
        end

        % Value changed function: PlotTypepopup
        function PlotTypepopup_Callback(app, event)
            GMpopup_Callback(app)
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)
            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'nGM','GM','RESULTS','Wave_name','g','Units')
            cd (MainDirectory)
            
            GM_No=app.GMpopup.Value;
            Plot_Type=app.PlotTypepopup.Value;
            
            evalc(['T=','RESULTS.T',num2str(GM_No)]);
            if Plot_Type==1
                evalc(['Yaxes=','RESULTS.U',num2str(GM_No)]);
                if Units==1; c='m'; else c='in'; end
                label='Rel-Displacement';
            elseif Plot_Type==2
                evalc(['Yaxes=','RESULTS.V',num2str(GM_No)]);
                if Units==1; c='m/sec'; else c='in/sec'; end
                label='Rel-Velocity';
            elseif Plot_Type==3
                evalc(['Yaxes=','RESULTS.A',num2str(GM_No),'/g']);
                if Units==1; c='g'; else c='g'; end
                label='Rel-Acceleration';
            elseif Plot_Type==4
                evalc(['Yaxes=','RESULTS.AA',num2str(GM_No),'/g']);
                if Units==1; c='g'; else c='g'; end
                label='Abs-Acceleration';
            elseif Plot_Type==5
            	evalc(['Yaxes=','RESULTS.PV',num2str(GM_No)]);
                if Units==1; c='m/sec'; else c='in/sec'; end
                label='Pseudo-Velocity';
            elseif Plot_Type==6
            	evalc(['Yaxes=','RESULTS.PA',num2str(GM_No),'/g']);
                if Units==1; c='g'; else c='g'; end
                label='Pseudo-Acceleration';
            elseif Plot_Type==7
                evalc(['Yaxes=','RESULTS.F',num2str(GM_No)]);
                if Units==1; c='kN'; else c='kip'; end
                label='Force';
            end
            
            
            cd (FilePath)
            fileX = fopen(FileName,'wt');
            
            a='Time [sec]';
            fprintf(fileX,'%s\t%s%s%s%s',a,label,' [',c,']');
            fprintf(fileX,'\n');
            
            for i=1:size(T,1)
              fprintf(fileX,'%f\t%f\t', T(i,1), Yaxes(i,1));
              fprintf(fileX,'\n');
            end
            
            cd (MainDirectory)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'RHA: Response History Plots';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel4 = uipanel(app.figure1);
            app.uipanel4.Title = 'Response History Plots';
            app.axes1 = uiaxes(app.uipanel4);
            app.text18 = uilabel(app.uipanel4);
            app.text18.Text = 'Ground Motion:';
            app.NextButton = uibutton(app.uipanel4, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButton_Callback, true);
            app.NextButton.Text = '>';
            app.PreviousButton = uibutton(app.uipanel4, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_Callback, true);
            app.PreviousButton.Text = '<';
            app.saveButton = uibutton(app.uipanel4, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Text = 'save plot data';
            app.text21 = uilabel(app.uipanel4);
            app.text21.Text = 'Plot Type:';
            app.Next2Button = uibutton(app.uipanel4, 'push');
            app.Next2Button.ButtonPushedFcn = createCallbackFcn(app, @Next2Button_Callback, true);
            app.Next2Button.Text = '>';
            app.Previous2Button = uibutton(app.uipanel4, 'push');
            app.Previous2Button.ButtonPushedFcn = createCallbackFcn(app, @Previous2Button_Callback, true);
            app.Previous2Button.Text = '<';
            app.GMpopup = uidropdown(app.uipanel4);
            app.GMpopup.Items = {'Pop-up Menu'};
            app.GMpopup.ValueChangedFcn = createCallbackFcn(app, @GMpopup_Callback, true);
            app.GMpopup.Value = 'Pop-up Menu';
            app.PlotTypepopup = uidropdown(app.uipanel4);
            app.PlotTypepopup.Items = {'Relative Displacement', 'Relative Velocity', 'Relative Acceleration', 'Absolute Acceleration', 'Pseudo Velocity', 'Pseudo Acceleration', 'Force'};
            app.PlotTypepopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.PlotTypepopup.ValueChangedFcn = createCallbackFcn(app, @PlotTypepopup_Callback, true);
            app.PlotTypepopup.Value = '1';
            app.text1 = uilabel(app.uipanel4);
            app.text1.Text = 'SF=1.50';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_RHA_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_RHA_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end