classdef Module_Results_RSA_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1            matlab.ui.Figure
        uipanel1           matlab.ui.container.Panel
        axes1              matlab.ui.control.UIAxes
        text7              matlab.ui.control.Label
        SpectrumTypepopup  matlab.ui.control.DropDown
        text18             matlab.ui.control.Label
        GMpopup            matlab.ui.control.DropDown
        saveButton         matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_RSA_OpeningFcn(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'GM_Option','Wave_name','nGM','GM','Quick_RSA')
            cd (MainDirectory)
            
            if GM_Option==3
                X{1,1}=Wave_name;
            else
                if nGM==1
                    X(1,1)={'All'};
                    zz=GM.name{1};
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz={zz(1:indexz-1)}; end
                    X(2,1)=zz;
                else
                    X{1,1}='All';
                    for i=1:nGM
                        zz=char(GM.name{i,1});
                        indexz=strfind(zz,'.');
                        if isempty(zz); else; zz=zz(1:indexz-1); end
                        X{i+1,1}=zz;
                        clear zz
                    end
                end
            end
            app.GMpopup.Items=X;
            app.GMpopup.ItemsData=1:nGM+1;
            app.GMpopup.Value=2;
            GMpopup_Callback(app)
            
            if Quick_RSA==1
                app.SpectrumTypepopup.Value='5';
                app.SpectrumTypepopup.Enable='off';
                SpectrumTypepopup_Callback(app)
            else
                app.SpectrumTypepopup.Value='1';
                SpectrumTypepopup_Callback(app)
            end

        end

        % Value changed function: GMpopup
        function GMpopup_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'RSA','Units','nGM','g','To')
            cd (MainDirectory)
            
            SpectrumType =str2double(app.SpectrumTypepopup.Value);
            
            GM_No=app.GMpopup.Value-1;
            if GM_No==0
                period=RSA.Period;
                if SpectrumType==1
                    Yaxis=RSA.MaxU(:,:);
                elseif SpectrumType==2
                    Yaxis=RSA.MaxV(:,:);
                elseif SpectrumType==3
                    Yaxis=RSA.MaxA(:,:)/g;
                elseif SpectrumType==4
                    Yaxis=RSA.MaxF(:,:);
                elseif SpectrumType==5
                    Yaxis=RSA.MaxAA(:,:)/g;
                elseif SpectrumType==6
                    Yaxis=RSA.MaxPV(:,:);
                elseif SpectrumType==7
                    Yaxis=RSA.MaxPA(:,:)/g;
                end
            
                % replace Inf values with zeros
                Yaxis(~isfinite(Yaxis))=0;
            
                app.axes1.cla;
                app.axes1.BackgroundColor=[1 1 1];
                app.axes1.FontName='Times';
                app.axes1.FontSize=18;
                app.axes1.XGrid='on';
                app.axes1.YGrid='on';
                app.axes1.Box='on';
                hold(app.axes1,"on");
                
                plot(app.axes1,[0],[0],'-b','linewidth',2)
                plot(app.axes1,[0],[0],'--b','linewidth',2)
                plot(app.axes1,period,Yaxis,'-r','linewidth',1)
            
                if Units==1
                    if SpectrumType==1
                        app.axes1.YLabel.String='Rel. Displacement [m]';
                    elseif SpectrumType==2
                        app.axes1.YLabel.String='Rel. Velocity [m/sec]';
                    elseif SpectrumType==3
                        app.axes1.YLabel.String='Rel. Acceleration [g]';
                    elseif SpectrumType==4
                        app.axes1.YLabel.String='Force [kN]';
                    elseif SpectrumType==5
                        app.axes1.YLabel.String='Abs. Acceleration [g]';
                    elseif SpectrumType==6
                        app.axes1.YLabel.String='P-Velocity [m/sec]';
                    elseif SpectrumType==7
                        app.axes1.YLabel.String='P-Acceleration [g]';
                    end
                else
                    if SpectrumType==1
                        app.axes1.YLabel.String='Rel. Displacement [in]';
                    elseif SpectrumType==2
                        app.axes1.YLabel.String='Rel. Velocity [in/sec]';
                    elseif SpectrumType==3
                        app.axes1.YLabel.String='Rel. Acceleration [g]';
                    elseif SpectrumType==4
                        app.axes1.YLabel.String='Force [kip]';
                    elseif SpectrumType==5
                        app.axes1.YLabel.String='Abs. Acceleration [g]';
                    elseif SpectrumType==6
                        app.axes1.YLabel.String='P-Velocity [in/sec]';
                    elseif SpectrumType==7
                        app.axes1.YLabel.String='P-Acceleration [g]';
                    end
                end
                app.axes1.XLabel.String='Period [sec]';
                app.axes1.XLim=[To max(period)];
                app.axes1.YLim=[0 max(max(abs(Yaxis)))*1.3];
            
                app.axes1.XLabel.FontName='Times';
                app.axes1.YLabel.FontName='Times';
                
                if nGM>=5
                    for i=1:size(period,1)
                           Sorted_Yaxis=sort(Yaxis(i,:));
                           MedianY(i,1)=Sorted_Yaxis(1,round(nGM*0.5));
                           Median16Y(i,1)=Sorted_Yaxis(1,round(nGM*0.16));
                           Median84Y(i,1)=Sorted_Yaxis(1,round(nGM*0.84));
                    end
                    plot(app.axes1,period,MedianY,'-b','linewidth',2)
                    plot(app.axes1,period,Median16Y,'--b','linewidth',2)
                    plot(app.axes1,period,Median84Y,'--b','linewidth',2)
            
                    legend(app.axes1,'Median Spectrum','16^t^h and 84^t^h percentile');
                    app.axes1.Legend.Location='southeast';
                    app.axes1.Legend.FontName='Courier';
                    app.axes1.Legend.FontSize=10;
                end
            
            else
            
                period=RSA.Period;
                if SpectrumType==1
                    Yaxis=RSA.MaxU(:,GM_No);
                elseif SpectrumType==2
                    Yaxis=RSA.MaxV(:,GM_No);
                elseif SpectrumType==3
                    Yaxis=RSA.MaxA(:,GM_No)/g;
                elseif SpectrumType==4
                    Yaxis=RSA.MaxF(:,GM_No);
                elseif SpectrumType==5
                    Yaxis=RSA.MaxAA(:,GM_No)/g;
                elseif SpectrumType==6
                    Yaxis=RSA.MaxPV(:,GM_No);
                elseif SpectrumType==7
                    Yaxis=RSA.MaxPA(:,GM_No)/g;
                end
            
                % replace Inf values with zeros
                Yaxis(~isfinite(Yaxis))=0;
            
                app.axes1.cla;
                app.axes1.BackgroundColor=[1 1 1];
                app.axes1.FontName='Times';
                app.axes1.FontSize=18;
                app.axes1.XGrid='on';
                app.axes1.YGrid='on';
                app.axes1.Box='on';
                hold(app.axes1,"on");
            
                plot(app.axes1,[0],[0],'-b','linewidth',2)
                plot(app.axes1,[0],[0],'--b','linewidth',2)
                plot(app.axes1,period,Yaxis,'-r','linewidth',2)
                if Units==1
                    if SpectrumType==1
                        app.axes1.YLabel.String='Rel. Displacement [m]';
                    elseif SpectrumType==2
                        app.axes1.YLabel.String='Rel. Velocity [m/sec]';
                    elseif SpectrumType==3
                        app.axes1.YLabel.String='Rel. Acceleration [g]';
                    elseif SpectrumType==4
                        app.axes1.YLabel.String='Force [kN]';
                    elseif SpectrumType==5
                        app.axes1.YLabel.String='Abs. Acceleration [g]';
                    elseif SpectrumType==6
                        app.axes1.YLabel.String='P-Velocity [m/sec]';
                    elseif SpectrumType==7
                        app.axes1.YLabel.String='P-Acceleration [g]';
                    end
                else
                    if SpectrumType==1
                        app.axes1.YLabel.String='Rel. Displacement [in]';
                    elseif SpectrumType==2
                        app.axes1.YLabel.String='Rel. Velocity [in/sec]';
                    elseif SpectrumType==3
                        app.axes1.YLabel.String='Rel. Acceleration [g]';
                    elseif SpectrumType==4
                        app.axes1.YLabel.String='Force [kip]';
                    elseif SpectrumType==5
                        app.axes1.YLabel.String='Abs. Acceleration [g]';
                    elseif SpectrumType==6
                        app.axes1.YLabel.String='P-Velocity [in/sec]';
                    elseif SpectrumType==7
                        app.axes1.YLabel.String='P-Acceleration [g]';
                    end
                end
                app.axes1.XLabel.String='Period [sec]';
                app.axes1.XLim=[To max(period)];
                app.axes1.YLim=[0 max(abs(Yaxis))*1.3];
                legend(app.axes1,'off');
                app.axes1.XLabel.FontName='Times';
                app.axes1.YLabel.FontName='Times';
            end
        end

        % Value changed function: SpectrumTypepopup
        function SpectrumTypepopup_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'RSA','Units','nGM','g','To')
            cd (MainDirectory)
            
            SpectrumType =str2double(app.SpectrumTypepopup.Value);
            GM_No=app.GMpopup.Value-1;
            if GM_No==0
                period=RSA.Period;
                if SpectrumType==1
                    Yaxis=RSA.MaxU(:,:);
                elseif SpectrumType==2
                    Yaxis=RSA.MaxV(:,:);
                elseif SpectrumType==3
                    Yaxis=RSA.MaxA(:,:)/g;
                elseif SpectrumType==4
                    Yaxis=RSA.MaxF(:,:);
                elseif SpectrumType==5
                    Yaxis=RSA.MaxAA(:,:)/g;
                elseif SpectrumType==6
                    Yaxis=RSA.MaxPV(:,:);
                elseif SpectrumType==7
                    Yaxis=RSA.MaxPA(:,:)/g;
                end
            
                % replace Inf values with zeros
                Yaxis(~isfinite(Yaxis))=0;
                app.axes1.cla;
                app.axes1.BackgroundColor=[1 1 1];
                app.axes1.FontName='Times';
                app.axes1.FontSize=18;
                app.axes1.XGrid='on';
                app.axes1.YGrid='on';
                app.axes1.Box='on';
                hold(app.axes1,"on");
                
                plot(app.axes1,[0],[0],'-b','linewidth',2)
                plot(app.axes1,[0],[0],'--b','linewidth',2)
                plot(app.axes1,period,Yaxis,'-r','linewidth',1)

                if Units==1
                    if SpectrumType==1
                        app.axes1.YLabel.String='Rel. Displacement [m]';
                    elseif SpectrumType==2
                        app.axes1.YLabel.String='Rel. Velocity [m/sec]';
                    elseif SpectrumType==3
                        app.axes1.YLabel.String='Rel. Acceleration [g]';
                    elseif SpectrumType==4
                        app.axes1.YLabel.String='Force [kN]';
                    elseif SpectrumType==5
                        app.axes1.YLabel.String='Abs. Acceleration [g]';
                    elseif SpectrumType==6
                        app.axes1.YLabel.String='P-Velocity [m/sec]';
                    elseif SpectrumType==7
                        app.axes1.YLabel.String='P-Acceleration [g]';
                    end
                else
                    if SpectrumType==1
                        app.axes1.YLabel.String='Rel. Displacement [in]';
                    elseif SpectrumType==2
                        app.axes1.YLabel.String='Rel. Velocity [in/sec]';
                    elseif SpectrumType==3
                        app.axes1.YLabel.String='Rel. Acceleration [g]';
                    elseif SpectrumType==4
                        app.axes1.YLabel.String='Force [kip]';
                    elseif SpectrumType==5
                        app.axes1.YLabel.String='Abs. Acceleration [g]';
                    elseif SpectrumType==6
                        app.axes1.YLabel.String='P-Velocity [in/sec]';
                    elseif SpectrumType==7
                        app.axes1.YLabel.String='P-Acceleration [g]';
                    end
                end
                app.axes1.XLabel.String='Period [sec]';
                app.axes1.XLim=[To max(period)];
                app.axes1.YLim=[0 max(max(abs(Yaxis)))*1.3];
                app.axes1.XLabel.FontName='Times';
                app.axes1.YLabel.FontName='Times';
                
                if nGM>=5
                    for i=1:size(period,1)
                           Sorted_Yaxis=sort(Yaxis(i,:));
                           MedianY(i,1)=Sorted_Yaxis(1,round(nGM*0.5));
                           Median16Y(i,1)=Sorted_Yaxis(1,round(nGM*0.16));
                           Median84Y(i,1)=Sorted_Yaxis(1,round(nGM*0.84));
                    end
                    plot(app.axes1,period,MedianY,'-b','linewidth',2)
                    plot(app.axes1,period,Median16Y,'--b','linewidth',2)
                    plot(app.axes1,period,Median84Y,'--b','linewidth',2)
            
                    legend(app.axes1,'Median Spectrum','16^t^h and 84^t^h percentile');
                    app.axes1.Legend.Location='southeast';
                    app.axes1.Legend.FontName='Courier';
                    app.axes1.Legend.FontSize=10;
                end
            
            else
            
                period=RSA.Period;
                if SpectrumType==1
                    Yaxis=RSA.MaxU(:,GM_No);
                elseif SpectrumType==2
                    Yaxis=RSA.MaxV(:,GM_No);
                elseif SpectrumType==3
                    Yaxis=RSA.MaxA(:,GM_No)/g;
                elseif SpectrumType==4
                    Yaxis=RSA.MaxF(:,GM_No);
                elseif SpectrumType==5
                    Yaxis=RSA.MaxAA(:,GM_No)/g;
                elseif SpectrumType==6
                    Yaxis=RSA.MaxPV(:,GM_No);
                elseif SpectrumType==7
                    Yaxis=RSA.MaxPA(:,GM_No)/g;
                end
            
                % replace Inf values with zeros
                Yaxis(~isfinite(Yaxis))=0;
            
                app.axes1.cla;
                app.axes1.BackgroundColor=[1 1 1];
                app.axes1.FontName='Times';
                app.axes1.FontSize=18;
                app.axes1.XGrid='on';
                app.axes1.YGrid='on';
                app.axes1.Box='on';
                hold(app.axes1,"on");
            
                plot(app.axes1,[0],[0],'-b','linewidth',2)
                plot(app.axes1,[0],[0],'--b','linewidth',2)
                plot(app.axes1,period,Yaxis,'-r','linewidth',2)
                
                if Units==1
                    if SpectrumType==1
                        app.axes1.YLabel.String='Rel. Displacement [m]';
                    elseif SpectrumType==2
                        app.axes1.YLabel.String='Rel. Velocity [m/sec]';
                    elseif SpectrumType==3
                        app.axes1.YLabel.String='Rel. Acceleration [g]';
                    elseif SpectrumType==4
                        app.axes1.YLabel.String='Force [kN]';
                    elseif SpectrumType==5
                        app.axes1.YLabel.String='Abs. Acceleration [g]';
                    elseif SpectrumType==6
                        app.axes1.YLabel.String='P-Velocity [m/sec]';
                    elseif SpectrumType==7
                        app.axes1.YLabel.String='P-Acceleration [g]';
                    end
                else
                    if SpectrumType==1
                        app.axes1.YLabel.String='Rel. Displacement [in]';
                    elseif SpectrumType==2
                        app.axes1.YLabel.String='Rel. Velocity [in/sec]';
                    elseif SpectrumType==3
                        app.axes1.YLabel.String='Rel. Acceleration [g]';
                    elseif SpectrumType==4
                        app.axes1.YLabel.String='Force [kip]';
                    elseif SpectrumType==5
                        app.axes1.YLabel.String='Abs. Acceleration [g]';
                    elseif SpectrumType==6
                        app.axes1.YLabel.String='P-Velocity [in/sec]';
                    elseif SpectrumType==7
                        app.axes1.YLabel.String='P-Acceleration [g]';
                    end
                end
                app.axes1.XLabel.String='Period [sec]';
                app.axes1.XLim=[To max(period)];
                app.axes1.YLim=[0 max(abs(Yaxis))*1.3];
                legend(app.axes1,'off');
                app.axes1.XLabel.FontName='Times';
                app.axes1.YLabel.FontName='Times';
            end
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)
            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'RSA','Units','g','nGM')
            cd (MainDirectory)
            SpectrumType =str2double(app.SpectrumTypepopup.Value);
            GM_No=app.GMpopup.Value-1;
            
            Period=RSA.Period;
            
            if SpectrumType==1
                Yaxis=RSA.MaxU(:,:);
                b='Rel-Displacement';
                if Units==1; c=' [m]';  else c=' [in]'; end
            elseif SpectrumType==2
                Yaxis=RSA.MaxV(:,:);
                b ='Rel-Velocity';
                if Units==1; c=' [m/sec]';  else c=' [in/sec]'; end
            elseif SpectrumType==3
                Yaxis=RSA.MaxA(:,:);
                b='Rel-Acceleration';
                if Units==1; c=' [m/sec2]';  else c=' [in/sec2]'; end
            elseif SpectrumType==4
                Yaxis=RSA.MaxF(:,:);
                b='Force';
                if Units==1; c=' [kN]';  else c=' [kip]'; end
            elseif SpectrumType==5
                Yaxis=RSA.MaxAA(:,:);
                b='Abs-Acceleration';
                if Units==1; c=' [m/sec2]';  else c=' [in/sec2]'; end
            elseif SpectrumType==6
                Yaxis=RSA.MaxPV(:,:);
                b ='Pseudo-Velocity';
                    if Units==1; c=' [m/sec]';  else c=' [in/sec]'; end
            elseif SpectrumType==7
                Yaxis=RSA.MaxPA(:,:);
                b='Pseudo-Acceleration';
                if Units==1; c=' [m/sec2]';  else c=' [in/sec2]'; end
            end
            Yaxis(~isfinite(Yaxis))=0;
            
            cd (FilePath)
            fileX = fopen(FileName,'wt');
            
            fprintf(fileX,'%s\t%s%s\t','Period [sec]',b,c);
            fprintf(fileX,'\n');
            
            for i=1:size(Period,1)
                fprintf(fileX,'%f\t', Period(i,1));
                if GM_No==0
                    for j=1:nGM
                        fprintf(fileX,'%f\t', Yaxis(i,j));
                    end
                else
                    fprintf(fileX,'%f\t', Yaxis(i,GM_No));
                end
                fprintf(fileX,'\n');
            end
            
            cd (MainDirectory)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            fclose all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Results: RSA';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Response Spectrum Plot';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.XLim = [0.01 5];
            app.axes1.YLim = [0 2.15439906378692];
            app.text7 = uilabel(app.uipanel1);
            app.text7.Text = 'Spectrum Type:';
            app.SpectrumTypepopup = uidropdown(app.uipanel1);
            app.SpectrumTypepopup.Items = {'Relative Displacement Spectrum', 'Relative Velocity Spectrum', 'Relative Acceleration Spectrum', 'Force Spectrum', 'Absolute Acceleration Spectrum', 'Pseudo Velocity Spectrum', 'Pseudo Acceleration Spectrum'};
            app.SpectrumTypepopup.ItemsData = {'1', '2', '3', '4', '5', '6', '7'};
            app.SpectrumTypepopup.ValueChangedFcn = createCallbackFcn(app, @SpectrumTypepopup_Callback, true);
            app.SpectrumTypepopup.Value = '5';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Text = 'Ground Motion:';
            app.GMpopup = uidropdown(app.uipanel1);
            app.GMpopup.Items = {'All', '1'};
            app.GMpopup.ValueChangedFcn = createCallbackFcn(app, @GMpopup_Callback, true);
            app.GMpopup.Value = '1';
            app.saveButton = uibutton(app.uipanel1, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Text = 'save plot data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_RSA_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_RSA_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end