classdef Module_Results_SystemForceDisp_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1         matlab.ui.Figure
        uipanel1        matlab.ui.container.Panel
        axes1           matlab.ui.control.UIAxes
        text23          matlab.ui.control.Label
        GMpopup         matlab.ui.control.DropDown
        NextButton      matlab.ui.control.Button
        PreviousButton  matlab.ui.control.Button
        saveButton      matlab.ui.control.Button
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_Results_SystemForceDisp_OpeningFcn(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'RESULTS','GM_Option','Wave_name','nGM','GM')
            cd (MainDirectory)
            
            for GM_No=1:nGM
                evalc(['Xaxes=RESULTS.U',num2str(GM_No)]);
                evalc(['Yaxes=RESULTS.F',num2str(GM_No)]);
                MaxU(GM_No,1)=max(abs(Xaxes));
                MaxF(GM_No,1)=max(abs(Yaxes));
            end
            app.NextButton.UserData=max(MaxU);
            app.PreviousButton.UserData=max(MaxF);
            app.GMpopup.UserData=nGM;
            
            if GM_Option==3
                X{1,1}=Wave_name;
            else
                if nGM==1
                    zz=GM.name{1};
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz={zz(1:indexz-1)}; end
                    X(1,1)=zz;
                else
                    zz=char(GM.name{1,1});
                    indexz=strfind(zz,'.');
                    if isempty(zz); else; zz=zz(1:indexz-1); end
                    X{1,1}=zz;
                    clear z
                    for i=1:nGM
                        zz=char(GM.name{i,1});
                        indexz=strfind(zz,'.');
                        if isempty(zz); else; zz=zz(1:indexz-1); end
                        X{i,1}=zz;
                        clear zz
                    end
                end
            end
            app.GMpopup.Items=X;
            app.GMpopup.ItemsData=1:nGM;
            app.GMpopup.Value=1;
            GMpopup_Callback(app)

        end

        % Button pushed function: NextButton
        function NextButton_Callback(app, event)
            nGM=app.GMpopup.UserData;
            Current_GM_No=app.GMpopup.Value;
            if Current_GM_No<nGM
                Next_GM_No=Current_GM_No+1;
                app.GMpopup.Value=Next_GM_No;
                GMpopup_Callback(app)
            end
        end

        % Button pushed function: PreviousButton
        function PreviousButton_Callback(app, event)
            nGM=app.GMpopup.UserData;
            Current_GM_No=app.GMpopup.Value;
            if Current_GM_No>1
                Previous_GM_No=Current_GM_No-1;
                app.GMpopup.Value=Previous_GM_No;
                GMpopup_Callback(app)
            end
        end

        % Value changed function: GMpopup
        function GMpopup_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'RESULTS','Units','SystemModel_Option','Backbone')
            cd (MainDirectory)
            
            GM_No=app.GMpopup.Value;
            
            evalc(['U=','RESULTS.U',num2str(GM_No)]);
            evalc(['F=','RESULTS.F',num2str(GM_No)]);
            
            % clear axes
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=17;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            %% Plot the F-delta response
            plot(app.axes1,U,F,'-r','linewidth',1.5)
            if Units==1
                app.axes1.YLabel.String='Force [kN]';
                app.axes1.XLabel.String='Displacement [m]';
            else
                app.axes1.YLabel.String='Force [kip]';
                app.axes1.XLabel.String='Displacement [in]';
            end
            app.axes1.YLabel.FontName='Times';
            app.axes1.XLabel.FontName='Times';
                
            %% Plot the Backbone Curve based on System Model Type
            if SystemModel_Option == 1
               M2 = Backbone.Ke * max(abs(U))*1.3;
               plot(app.axes1,[0.0   max(abs(U))*1.3],[0  M2],'--k','linewidth',1);
               plot(app.axes1,[0.0  -max(abs(U))*1.3],[0 -M2],'--k','linewidth',1);
            end
            
            if SystemModel_Option == 2
               M1 = Backbone.Ke * Backbone.dy;
               M2 = M1 + Backbone.Kp * max(abs(U))*1.3;
               plot(app.axes1,[0.0  Backbone.dy  Backbone.dy+max(abs(U))*1.3],[0  M1  M2],'--k','linewidth',1);
               plot(app.axes1,[0.0 -Backbone.dy -Backbone.dy-max(abs(U))*1.3],[0 -M1 -M2],'--k','linewidth',1);
            end
            
            if SystemModel_Option == 3 || SystemModel_Option == 6
                UFail_pos0=min(Backbone.Uu_pos0,Backbone.Uy_pos0+Backbone.Up_pos0+Backbone.Upc_pos0);
                UFail_neg0=min(Backbone.Uu_neg0,Backbone.Uy_neg0+Backbone.Up_neg0+Backbone.Upc_neg0);
            
                % Plot backbone
                if Backbone.Uu_pos0 > Backbone.Uy_pos0+Backbone.Up_pos0+Backbone.Upc_pos0
                    RotationPos=[0 Backbone.Uy_pos0 Backbone.Umax_pos0 Backbone.Umax_pos0+(Backbone.Fmax_pos0-Backbone.Fres_pos0)/Backbone.Kpc_pos0 Backbone.Uu_pos0 Backbone.Uu_pos0];
                    MomentPos=[0 Backbone.Fy_pos0 Backbone.Fmax_pos0 Backbone.Fres_pos0 Backbone.Fres_pos0 0];
                else
                    RotationPos=[0 Backbone.Uy_pos0 Backbone.Umax_pos0 Backbone.Uu_pos0 Backbone.Uu_pos0];
                    MomentPos  =[0 Backbone.Fy_pos0 Backbone.Fmax_pos0 Backbone.Fmax_pos0-Backbone.Kpc_pos0*(Backbone.Uu_pos0-Backbone.Umax_pos0)      0];
                end
            
                if Backbone.Uu_neg0 > Backbone.Uy_neg0+Backbone.Up_neg0+Backbone.Upc_neg0
                    RotationNeg=[0 Backbone.Uy_neg0 Backbone.Umax_neg0 Backbone.Umax_neg0+(Backbone.Fmax_neg0-Backbone.Fres_neg0)/Backbone.Kpc_neg0 Backbone.Uu_neg0 Backbone.Uu_neg0];
                    MomentNeg=[0 Backbone.Fy_neg0 Backbone.Fmax_neg0 Backbone.Fres_neg0 Backbone.Fres_neg0 0];
                else
                    RotationNeg=[0 Backbone.Uy_neg0 Backbone.Umax_neg0 Backbone.Uu_neg0 Backbone.Uu_neg0];
                    MomentNeg  =[0 Backbone.Fy_neg0     Backbone.Fmax_neg0      Backbone.Fmax_neg0-Backbone.Kpc_neg0*(Backbone.Uu_neg0-Backbone.Umax_neg0)      0];
                end
            
                % Plot backbone
                plot(app.axes1,RotationPos,MomentPos,'--k','linewidth',2);
                plot(app.axes1,-RotationNeg,-MomentNeg,'--k','linewidth',2);
            end
            
            if SystemModel_Option == 4 || SystemModel_Option == 5
                UFail_pos=min(Backbone.Uu_pos,Backbone.Uy_pos+Backbone.Up_pos+Backbone.Upc_pos);
                UFail_neg=min(Backbone.Uu_neg,Backbone.Uy_neg+Backbone.Up_neg+Backbone.Upc_neg);
            
                % Plot backbone
                if Backbone.Uu_pos > Backbone.Uy_pos+Backbone.Up_pos+Backbone.Upc_pos
                    RotationPos=[0 Backbone.Uy_pos Backbone.Umax_pos Backbone.Umax_pos+(Backbone.Fmax_pos-Backbone.Fres_pos)/Backbone.Kpc_pos Backbone.Uu_pos Backbone.Uu_pos];
                    MomentPos=[0 Backbone.Fy_pos Backbone.Fmax_pos Backbone.Fres_pos Backbone.Fres_pos 0];
                else
                    RotationPos=[0 Backbone.Uy_pos Backbone.Umax_pos Backbone.Uu_pos Backbone.Uu_pos];
                    MomentPos  =[0 Backbone.Fy_pos Backbone.Fmax_pos Backbone.Fmax_pos-Backbone.Kpc_pos*(Backbone.Uu_pos-Backbone.Umax_pos)      0];
                end
            
                if Backbone.Uu_neg > Backbone.Uy_neg+Backbone.Up_neg+Backbone.Upc_neg
                    RotationNeg=[0 Backbone.Uy_neg Backbone.Umax_neg Backbone.Umax_neg+(Backbone.Fmax_neg-Backbone.Fres_neg)/Backbone.Kpc_neg Backbone.Uu_neg Backbone.Uu_neg];
                    MomentNeg=[0 Backbone.Fy_neg Backbone.Fmax_neg Backbone.Fres_neg Backbone.Fres_neg 0];
                else
                    RotationNeg=[0 Backbone.Uy_neg Backbone.Umax_neg Backbone.Uu_neg Backbone.Uu_neg];
                    MomentNeg  =[0 Backbone.Fy_neg Backbone.Fmax_neg Backbone.Fmax_neg-Backbone.Kpc_neg*(Backbone.Uu_neg-Backbone.Umax_neg)      0];
                end
            
                % Plot backbone
                plot(app.axes1,RotationPos,MomentPos,'--k','linewidth',2);
                plot(app.axes1,-RotationNeg,-MomentNeg,'--k','linewidth',2);
            end
            
            %% Plot Origin Lines
            MaxU=app.NextButton.UserData;
            MaxF=app.PreviousButton.UserData;
            plot(app.axes1,[-MaxU*2 MaxU*2],[0 0],'-k')
            plot(app.axes1,[0 0],[-MaxF*2 MaxF*2],'-k')
            app.axes1.YLim=[-MaxF*1.3 MaxF*1.3];
            app.axes1.XLim=[-MaxU*1.3 MaxU*1.3];
        end

        % Button pushed function: saveButton
        function saveButton_Callback(app, event)
            [FileName, FilePath] = uiputfile('*.txt','Specify file name and saving location');
            if isempty(FileName) || ischar(FileName)==0
                return
            end
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'RESULTS','Units','SystemModel_Option','Backbone')
            cd (MainDirectory)
            
            GM_No=app.GMpopup.Value;
            
            evalc(['U=','RESULTS.U',num2str(GM_No)]);
            evalc(['F=','RESULTS.F',num2str(GM_No)]);
            
            cd (FilePath)
            fileX = fopen(FileName,'wt');
            
            if Units==1
                a ='Displacement [m]';b='Force [kN]';
            else
                a='Displacement [in]';b='Force [kip]';
            end
            fprintf(fileX,'%s\t%s\t',a,b);
            fprintf(fileX,'\n');
            
            for i=1:size(U,1)
              fprintf(fileX,'%f\t%f\t', U(i,1), F(i,1));
              fprintf(fileX,'\n');
            end
            
            cd (MainDirectory)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            fclose all;
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Results: Dynamic';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'System''s Force-Displacement Response';
            app.axes1 = uiaxes(app.uipanel1);
            app.axes1.XLim = [-0.110161604692016 0.110161604692016];
            app.axes1.YLim = [-3.17482251867287 3.17482251867287];
            app.text23 = uilabel(app.uipanel1);
            app.text23.Text = 'Ground Motion:';
            app.GMpopup = uidropdown(app.uipanel1);
            app.GMpopup.Items = {'1'};
            app.GMpopup.ValueChangedFcn = createCallbackFcn(app, @GMpopup_Callback, true);
            app.GMpopup.Value = '1';
            app.NextButton = uibutton(app.uipanel1, 'push');
            app.NextButton.ButtonPushedFcn = createCallbackFcn(app, @NextButton_Callback, true);
            app.NextButton.Text = '>';
            app.PreviousButton = uibutton(app.uipanel1, 'push');
            app.PreviousButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousButton_Callback, true);
            app.PreviousButton.Text = '<';
            app.saveButton = uibutton(app.uipanel1, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButton_Callback, true);
            app.saveButton.Text = 'save plot data';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_Results_SystemForceDisp_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @Module_Results_SystemForceDisp_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end