classdef Module_SDoF_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1           matlab.ui.Figure
        uipanel1          matlab.ui.container.Panel
        text2             matlab.ui.control.Label
        text3             matlab.ui.control.Label
        text4             matlab.ui.control.Label
        text7             matlab.ui.control.Label
        InputChoicepopup  matlab.ui.control.DropDown
        MassEdit          matlab.ui.control.NumericEditField
        StiffnessEdit     matlab.ui.control.NumericEditField
        PeriodEdit        matlab.ui.control.NumericEditField
        DampingEdit       matlab.ui.control.NumericEditField
        MLabel            matlab.ui.control.Label
        KLabel            matlab.ui.control.Label
        TLabel            matlab.ui.control.Label
        Label             matlab.ui.control.Label
        uipanel4          matlab.ui.container.Panel
        PDeltacheckbox    matlab.ui.control.CheckBox
        text15            matlab.ui.control.Label
        text18            matlab.ui.control.Label
        toggle1           matlab.ui.control.StateButton
        toggle2           matlab.ui.control.StateButton
        Pedit             matlab.ui.control.NumericEditField
        Hedit             matlab.ui.control.NumericEditField
        HLabel            matlab.ui.control.Label
        PLabel            matlab.ui.control.Label
        Label_2           matlab.ui.control.Label
        SCedit            matlab.ui.control.NumericEditField
        SubmitButton      matlab.ui.control.Button
        Image             matlab.ui.control.Image
    end

    properties (Access = private)
        MainApp
    end

    methods (Access = private)  
        function UpdatePDeltaValues(app)
            global MainDirectory ProjectPath ProjectName
            cd (ProjectPath)
            load(ProjectName,'g')
            cd (MainDirectory)
            if app.toggle1.Value==1
                P=app.Pedit.Value;
                H=app.Hedit.Value;
                Ko=app.StiffnessEdit.Value;
                Stability_Coeff=P/H/Ko;
                app.SCedit.Value=Stability_Coeff;
            else
                Stability_Coeff=app.SCedit.Value;
                M=app.MassEdit.Value;
                Ko=app.StiffnessEdit.Value;
                P=M*g;
                app.Pedit.Value=P;
                H=P/Stability_Coeff/Ko;
                app.Hedit.Value=H;
            end
        end
        function UpdateValues(app)
            Choice =str2double(app.InputChoicepopup.Value);
            if Choice==1
                T=app.PeriodEdit.Value;
                M=app.MassEdit.Value;
                Ko=M/(T/2/pi)^2;
                app.StiffnessEdit.Value=Ko;
%                 if strcmp(app.StiffnessEdit.Value,'NaN')==1; app.StiffnessEdit.Value=''; end
            end
            
            if Choice==2
                T=app.PeriodEdit.Value;
                Ko=app.StiffnessEdit.Value;
                M=Ko*(T/2/pi)^2;
                app.MassEdit.Value=M;
%                 if strcmp(app.MassEdit.Value,'NaN')==1; app.MassEdit.Value=''; end
            end
            
            if Choice==3
                M=app.MassEdit.Value;
                Ko=app.StiffnessEdit.Value;
                T=2*pi*sqrt(M/Ko);
                app.PeriodEdit.Value=T;
%                 if strcmp(app.PeriodEdit.Value,'NaN')==1; app.PeriodEdit.Value=''; end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_SDoF_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            Pos_Main=app.MainApp.figure0.Position;
            Pos_Secd=app.figure1.Position;
            app.figure1.Position=[Pos_Main(1) Pos_Main(2) Pos_Secd(3) Pos_Secd(4)];
            
            global MainDirectory ProjectName ProjectPath
            clc;
            cd (ProjectPath)
            load(ProjectName,'Ko','M','g','Units','zeta','T','Stability_Coeff','SDoFstatus','PDeltastatus','P','H')
            cd (MainDirectory)
            
            app.toggle1.Value=1;
            toggle1_Callback(app)
            app.InputChoicepopup.Value='1';
            InputChoicepopup_Callback(app)

            if Units==1
                app.text2.Text='Mass [ton]';
                app.text3.Text='Stiffness [kN/m]';
                app.text15.Text='kN';
                app.text18.Text='m';
                app.Hedit.Value=4.0;
            else
                app.text2.Text='Mass [kip-mass]';
                app.text3.Text='Stiffness [kip/in]';
                app.text15.Text='kip';
                app.text18.Text='in';
                app.Hedit.Value=160;
            end
            
            if SDoFstatus==1
                app.MassEdit.Value=M;
                app.StiffnessEdit.Value=Ko;
                app.PeriodEdit.Value=T;
                app.DampingEdit.Value=zeta*100;
            
                if PDeltastatus==0
                    app.PDeltacheckbox.Value=0;
                    app.Pedit.Value=M*g;
                    if Units==1; app.Hedit.Value=4.0; end
                    if Units==2; app.Hedit.Value=4.0*100*2.54; end
                    app.SCedit.Value=Stability_Coeff;
                    PDeltacheckbox_Callback(app)
                else
                    app.PDeltacheckbox.Value=1;
                    app.Pedit.Value=P;
                    app.Hedit.Value=H;
                    app.SCedit.Value=Stability_Coeff;
                    PDeltacheckbox_Callback(app)
                end
            else
                app.PDeltacheckbox.Value=0;
                PDeltacheckbox_Callback(app)
            end
            
            UpdateValues (app);
        end

        % Value changed function: DampingEdit
        function DampingEdit_Callback(app, event)
            zeta=app.DampingEdit.Value;
           if zeta<0.5
               app.SubmitButton.Enable='off';
               ed=warndlg('Note that the Damping Coeffiecient is expressed as a "percentage"','!! REMINDER !!');
               waitfor(ed)
               app.SubmitButton.Enable='on';
           end
            UpdateValues (app);
            UpdatePDeltaValues (app)
        end

        % Value changed function: Hedit
        function Hedit_Callback(app, event)
            UpdatePDeltaValues (app)
        end

        % Value changed function: InputChoicepopup
        function InputChoicepopup_Callback(app, event)
            Choice =str2double(app.InputChoicepopup.Value);
            switch Choice
                case 1
                  app.PeriodEdit.Enable='on';
                  app.MassEdit.Enable='on';
                  app.StiffnessEdit.Enable='off';
                case 2
                  app.PeriodEdit.Enable='on';
                  app.MassEdit.Enable='off';
                  app.StiffnessEdit.Enable='on';
                case 3
                  app.PeriodEdit.Enable='off';
                  app.MassEdit.Enable='on';
                  app.StiffnessEdit.Enable='on';
            end
        end

        % Value changed function: MassEdit
        function MassEdit_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'g')
            cd (MainDirectory)
            
            Mass=app.MassEdit.Value;
            app.Pedit.Value=round(Mass*g*100)/100;
            UpdateValues (app);
            UpdatePDeltaValues (app)
        end

        % Value changed function: PDeltacheckbox
        function PDeltacheckbox_Callback(app, event)
            if app.PDeltacheckbox.Value==1
                app.toggle1.Enable='on';
                app.toggle2.Enable='on';
                toggle1_Callback(app)
            else
                app.toggle1.Enable='off';
                app.toggle2.Enable='off';
                app.Pedit.Enable='off';
                app.Hedit.Enable='off';
                app.SCedit.Enable='off';
            end
        end

        % Value changed function: Pedit
        function Pedit_Callback(app, event)
            P=app.Pedit.Value;
            UpdatePDeltaValues (app)
        end

        % Value changed function: PeriodEdit
        function PeriodEdit_Callback(app, event)
            Period=app.PeriodEdit.Value;
            UpdateValues (app);
            UpdatePDeltaValues (app)
        end

        % Value changed function: SCedit
        function SCedit_Callback(app, event)
            UpdatePDeltaValues (app)
        end

        % Value changed function: StiffnessEdit
        function StiffnessEdit_Callback(app, event)
            Stiffness=app.StiffnessEdit.Value;
            UpdateValues (app);
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Runstatus','Analysis_Option')
            cd (MainDirectory)
            
            if Runstatus==1
                waitfor(DeleteResultsMessage_App);
            end
            
            cd (ProjectPath)
            load(ProjectName,'DeleteResults')
            cd (MainDirectory)
            
            if Runstatus==1 && DeleteResults==0
                return;
            elseif Runstatus==1 && DeleteResults==1
                if Analysis_Option==1
                    rmvar(ProjectPath,ProjectName, 'RESULTS')
                elseif Analysis_Option==2
                    rmvar(ProjectPath,ProjectName, 'RESULTS','IDA')
                elseif Analysis_Option==3
                    rmvar(ProjectPath,ProjectName, 'RESULTS','RSA')
                elseif Analysis_Option==4
            
                end
                Runstatus=0;
                DeleteResults=1;
            
                cd (ProjectPath)
                save(ProjectName,'Runstatus','DeleteResults','-append')
                cd (MainDirectory)
            
                app.MainApp.ResultsButton.Enable='off';
                app.MainApp.SaveButton.Enable='off';
            end
            
            
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option','Ko','M','g','Units','zeta','T','Stability_Coeff','SDoFstatus','PDeltastatus','P','H','Backbone','BackboneNoPD','BackbonePD')
            cd (MainDirectory)
            
            UpdateValues (app);
            UpdatePDeltaValues (app);
            
            T=app.PeriodEdit.Value;
            M=app.MassEdit.Value;
            Ko=app.StiffnessEdit.Value;
            zeta=app.DampingEdit.Value/100;
            omega=2*pi/T;
            C=zeta*2*omega*M;
            
            if app.PDeltacheckbox.Value==1
                PDeltastatus=1;
                if app.toggle1.Value==1
                    P=app.Pedit.Value;
                    H=app.Hedit.Value;
                    Stability_Coeff=P/H/Ko;
                    app.SCedit.Value=Stability_Coeff;
                else
                    Stability_Coeff=app.SCedit.Value;
                    M=app.MassEdit.Value;
                    Ko=app.StiffnessEdit.Value;
                    P=M*g;
                    app.Pedit.Value=P;
                    H=P/Stability_Coeff/Ko;
                    app.Hedit.Value=H;
                end
            else
                PDeltastatus=0;
                H=1;
                P=0;
                Stability_Coeff=P/H/Ko;
                app.SCedit.Value=Stability_Coeff;
            end
            
            BackboneNoPD.Ke=Ko;
            BackbonePD.Ke=BackboneNoPD.Ke-P/H;
            Backbone.Ke=BackbonePD.Ke;
            
            if SystemModelstatus==1 && SystemModel_Option==2
                % Modify the slopes paramters of the bilinear curve to account for P-Delta
                BackbonePD.dy=BackboneNoPD.dy;
                BackbonePD.Kp=BackboneNoPD.Kp-P/H;
                BackbonePD.Fy=BackbonePD.Ke*BackboneNoPD.dy;
            
                % Get Failure displacement
                if BackbonePD.Ke<0.0
                    delta_PDeltaFail=0.0;
                else
                    if BackbonePD.Kp<0.0
                        delta_PDeltaFail=BackbonePD.dy+BackbonePD.Fy/abs(BackbonePD.Kp);
                    else
                        delta_PDeltaFail=999;
                    end
                end
            end
            
            delta_PDeltaFail_pos=0;
            delta_PDeltaFail_neg=0;
            if SystemModelstatus==1 && SystemModel_Option==3
                % Deduce Backbone with P-Delta
                % Modify the strength paramters of the backbone curve to account for P-Delta
                BackbonePD.Fy_pos0     =BackboneNoPD.Fy_pos0-(P*BackboneNoPD.Uy_pos0/H);
                BackbonePD.Fmax_pos0=BackboneNoPD.Fmax_pos0-P*(BackboneNoPD.Uy_pos0+BackboneNoPD.Up_pos0)/H;
                BackbonePD.FmaxFy_pos0 =BackbonePD.Fmax_pos0/BackbonePD.Fy_pos0;
                BackbonePD.Fres_pos0=BackboneNoPD.Fres_pos0-(P*(BackboneNoPD.Uy_pos0+BackboneNoPD.Up_pos0+(BackboneNoPD.Fmax_pos0-BackboneNoPD.Fres_pos0)/BackboneNoPD.Kpc_pos0)/H);
                BackbonePD.FresFy_pos0 =BackbonePD.Fres_pos0/BackbonePD.Fy_pos0;
                if BackbonePD.FresFy_pos0<0.0; BackbonePD.FresFy_pos0=0; end
            
                BackbonePD.Fy_neg0     =BackboneNoPD.Fy_neg0-(P*BackboneNoPD.Uy_pos0/H);
                BackbonePD.Fmax_neg0    =BackboneNoPD.Fmax_neg0-P*(BackboneNoPD.Uy_neg0+BackboneNoPD.Up_neg0)/H;
                BackbonePD.FmaxFy_neg0 =BackbonePD.Fmax_neg0/BackbonePD.Fy_neg0;
                BackbonePD.Fres_neg0=BackboneNoPD.Fres_neg0-(P*(BackboneNoPD.Uy_neg0+BackboneNoPD.Up_neg0+(BackboneNoPD.Fmax_neg0-BackboneNoPD.Fres_neg0)/BackboneNoPD.Kpc_neg0)/H);
                BackbonePD.FresFy_neg0 =BackbonePD.Fres_neg0/BackbonePD.Fy_neg0;
                if BackbonePD.FresFy_neg0<0.0; BackbonePD.FresFy_neg0=0; end
            
                BackbonePD.Ke =BackbonePD.Fy_pos0/BackboneNoPD.Uy_pos0;
            
            %     delta_PDeltaFail_pos = BackbonePD.Uy_pos0 + BackbonePD.Up_pos0 + BackbonePD.Fmax_pos0/BackbonePD.Kpc_pos0;
            %     delta_PDeltaFail_neg = BackbonePD.Uy_neg0 + BackbonePD.Up_neg0 + BackbonePD.Fmax_neg0/BackbonePD.Kpc_neg0;
            
                if BackbonePD.Fres_pos0 < 0.0
                    delta_PDeltaFail_pos = BackbonePD.Uy_pos0 + BackbonePD.Up_pos0 + BackbonePD.Fmax_pos0/BackbonePD.Kpc_pos0;
                else
                    delta_PDeltaFail_pos = BackbonePD.Uu_pos0;
                end
            
                if BackbonePD.Fres_neg0 < 0.0
                    delta_PDeltaFail_neg = BackbonePD.Uy_neg0 + BackbonePD.Up_neg0 + BackbonePD.Fmax_neg0/BackbonePD.Kpc_neg0;
                else
                    delta_PDeltaFail_neg= BackbonePD.Uu_neg0;
                end
            end
            
            if SystemModelstatus==1 && SystemModel_Option==6
                % Deduce Backbone with P-Delta
                % Modify the strength paramters of the backbone curve to account for P-Delta
                BackbonePD.Fy_pos0     =BackboneNoPD.Fy_pos0-(P*BackboneNoPD.Uy_pos0/H);
                BackbonePD.Fmax_pos0=BackboneNoPD.Fmax_pos0-P*(BackboneNoPD.Uy_pos0+BackboneNoPD.Up_pos0)/H;
                BackbonePD.FmaxFy_pos0 =BackbonePD.Fmax_pos0/BackbonePD.Fy_pos0;
                BackbonePD.Fres_pos0=BackboneNoPD.Fres_pos0-(P*(BackboneNoPD.Uy_pos0+BackboneNoPD.Up_pos0+(BackboneNoPD.Fmax_pos0-BackboneNoPD.Fres_pos0)/BackboneNoPD.Kpc_pos0)/H);
                BackbonePD.FresFy_pos0 =BackbonePD.Fres_pos0/BackbonePD.Fy_pos0;
                if BackbonePD.FresFy_pos0<0.0; BackbonePD.FresFy_pos0=0; end
            
                BackbonePD.Fy_neg0     =BackboneNoPD.Fy_neg0-(P*BackboneNoPD.Uy_pos0/H);
                BackbonePD.Fmax_neg0    =BackboneNoPD.Fmax_neg0-P*(BackboneNoPD.Uy_neg0+BackboneNoPD.Up_neg0)/H;
                BackbonePD.FmaxFy_neg0 =BackbonePD.Fmax_neg0/BackbonePD.Fy_neg0;
                BackbonePD.Fres_neg0=BackboneNoPD.Fres_neg0-(P*(BackboneNoPD.Uy_neg0+BackboneNoPD.Up_neg0+(BackboneNoPD.Fmax_neg0-BackboneNoPD.Fres_neg0)/BackboneNoPD.Kpc_neg0)/H);
                BackbonePD.FresFy_neg0 =BackbonePD.Fres_neg0/BackbonePD.Fy_neg0;
                if BackbonePD.FresFy_neg0<0.0; BackbonePD.FresFy_neg0=0; end
            
                BackbonePD.Ke =BackbonePD.Fy_pos0/BackboneNoPD.Uy_pos0;
            
            %     delta_PDeltaFail_pos = BackbonePD.Uy_pos0 + BackbonePD.Up_pos0 + BackbonePD.Fmax_pos0/BackbonePD.Kpc_pos0;
            %     delta_PDeltaFail_neg = BackbonePD.Uy_neg0 + BackbonePD.Up_neg0 + BackbonePD.Fmax_neg0/BackbonePD.Kpc_neg0;
            
                if BackbonePD.Fres_pos0 < 0.0
                    delta_PDeltaFail_pos = BackbonePD.Uy_pos0 + BackbonePD.Up_pos0 + BackbonePD.Fmax_pos0/BackbonePD.Kpc_pos0;
                else
                    delta_PDeltaFail_pos = BackbonePD.Uu_pos0;
                end
            
                if BackbonePD.Fres_neg0 < 0.0
                    delta_PDeltaFail_neg = BackbonePD.Uy_neg0 + BackbonePD.Up_neg0 + BackbonePD.Fmax_neg0/BackbonePD.Kpc_neg0;
                else
                    delta_PDeltaFail_neg= BackbonePD.Uu_neg0;
                end
            end
            
            if SystemModelstatus==1
                if SystemModel_Option==4  || SystemModel_Option == 5
                % Deduce Backbone with P-Delta
                % Modify the strength paramters of the backbone curve to account for P-Delta
                BackbonePD.Fy_pos     =BackboneNoPD.Fy_pos-(P*BackboneNoPD.Uy_pos/H);
                BackbonePD.Fmax_pos=BackboneNoPD.Fmax_pos-P*(BackboneNoPD.Uy_pos+BackboneNoPD.Up_pos)/H;
                BackbonePD.FmaxFy_pos =BackbonePD.Fmax_pos/BackbonePD.Fy_pos;
                BackbonePD.Fres_pos=BackboneNoPD.Fres_pos-(P*(BackboneNoPD.Uy_pos+BackboneNoPD.Up_pos+(BackboneNoPD.Fmax_pos-BackboneNoPD.Fres_pos)/BackboneNoPD.Kpc_pos)/H);
                BackbonePD.FresFy_pos =BackbonePD.Fres_pos/BackbonePD.Fy_pos;
                if BackbonePD.FresFy_pos<0.0; BackbonePD.FresFy_pos=0; end
            
                BackbonePD.Fy_neg     =BackboneNoPD.Fy_neg-(P*BackboneNoPD.Uy_pos/H);
                BackbonePD.Fmax_neg    =BackboneNoPD.Fmax_neg-P*(BackboneNoPD.Uy_neg+BackboneNoPD.Up_neg)/H;
                BackbonePD.FmaxFy_neg =BackbonePD.Fmax_neg/BackbonePD.Fy_neg;
                BackbonePD.Fres_neg=BackboneNoPD.Fres_neg-(P*(BackboneNoPD.Uy_neg+BackboneNoPD.Up_neg+(BackboneNoPD.Fmax_neg-BackboneNoPD.Fres_neg)/BackboneNoPD.Kpc_neg)/H);
                BackbonePD.FresFy_neg =BackbonePD.Fres_neg/BackbonePD.Fy_neg;
                if BackbonePD.FresFy_neg<0.0; BackbonePD.FresFy_neg=0; end
            
                BackbonePD.Ke =BackbonePD.Fy_pos/BackboneNoPD.Uy_pos;
            
                if BackbonePD.Fres_pos < 0.0
                    delta_PDeltaFail_pos = BackbonePD.Uy_pos + BackbonePD.Up_pos + BackbonePD.Fmax_pos/BackbonePD.Kpc_pos;
                else
                    delta_PDeltaFail_pos = BackbonePD.Uu_pos;
                end
            
                if BackbonePD.Fres_neg < 0.0
                    delta_PDeltaFail_neg = BackbonePD.Uy_neg + BackbonePD.Up_neg + BackbonePD.Fmax_neg/BackbonePD.Kpc_neg;
                else
                    delta_PDeltaFail_neg= BackbonePD.Uu_neg;
                end
            end
            end
            
            
            % Get Failure displacement
            if BackbonePD.Ke<0.0
                delta_PDeltaFail=0.0;
            else
                delta_PDeltaFail=999;
            end
            
            %% INPUT VALUES CHECKS BEFORE SUBMISSION
            if M<0 || T<0 || Ko<0 || zeta<0
                ed = errordlg('Mass/Stiffness/Period cannot be less than 0 (-ve)','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            if isnan(M) || isnan(T) || isnan(Ko) || isnan(zeta) || isnan(P) || isnan(H) || isnan(Stability_Coeff)
                ed = errordlg('Some paramters are not defined. Please define all the required paramters.','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            if P<0 || H<0 || Stability_Coeff<0
                ed = errordlg('Gravity Load (P), Height (H), Stability Coeffecient cannot be less than 0 (-ve)','Error', 'modal');
                uiwait(ed);
                return;
            end
            %%
            
            SDoFstatus=1;
            
            cd (MainDirectory)
            cd (ProjectPath)
            pause(0.1)
            save(ProjectName,'M','Ko','T','zeta','omega','C','P','H','Stability_Coeff','PDeltastatus','SDoFstatus','BackboneNoPD','BackbonePD','Backbone','delta_PDeltaFail','delta_PDeltaFail_pos','delta_PDeltaFail_neg','-append')
            pause(0.5)
            cd (MainDirectory)
            
            if PDeltastatus~=1
                app.MainApp.status1.BackgroundColor='g';
                app.MainApp.status1.FontColor='k';
                app.MainApp.status1.Text=['SDoF: T=',num2str(round(T*100)/100),' sec (no PDelta)'];%text1 is the value tag for the Static Text in GUI1
            else
                app.MainApp.status1.BackgroundColor='g';
                app.MainApp.status1.FontColor='k';
                app.MainApp.status1.Text=['SDoF: T=',num2str(round(T*100)/100),' sec (with PDelta)'];%text1 is the value tag for the Static Text in GUI1
            end
            
            app.MainApp.status1b.BackgroundColor='g';%text1 is the value tag for the Static Text in GUI1
            if SystemModelstatus~=1
                app.MainApp.ModelButton.Enable='on';
                app.MainApp.status2.BackgroundColor='r';%text1 is the value tag for the Static Text in GUI1
                app.MainApp.status2b.BackgroundColor='r';%text1 is the value tag for the Static Text in GUI1
            end
            
            app.MainApp.Image5.Visible='off';
            closereq
        end

        % Value changed function: toggle1
        function toggle1_Callback(app, event)
            app.toggle1.Value=1;
            app.toggle2.Value=0;
            app.Pedit.Enable='on';
            app.Hedit.Enable='on';
            app.SCedit.Enable='off';
            app.Pedit.Enable='on';
            app.Hedit.Enable='on';
            app.SCedit.Enable='off';
            UpdatePDeltaValues (app)
        end

        % Value changed function: toggle2
        function toggle2_Callback(app, event)
            app.toggle1.Value=0;
            app.toggle2.Value=1;
            app.Pedit.Enable='off';
            app.Hedit.Enable='off';
            app.SCedit.Enable='on';
            app.Pedit.Enable='off';
            app.Hedit.Enable='off';
            app.SCedit.Enable='on';
            UpdatePDeltaValues (app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'SDoF Parameters';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'SDoF Parameters';
            app.text2 = uilabel(app.uipanel1);
            app.text2.Text = 'Mass [kN.sec/m]';
            app.text3 = uilabel(app.uipanel1);
            app.text3.Text = 'Stiffness [kN/m]';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Period [sec]';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Text = 'Damping coefficient [%]';
            app.InputChoicepopup = uidropdown(app.uipanel1);
            app.InputChoicepopup.Items = {'Period and Mass', 'Period and Stiffness', 'Mass and Stiffness'};
            app.InputChoicepopup.ItemsData = {'1', '2', '3'};
            app.InputChoicepopup.ValueChangedFcn = createCallbackFcn(app, @InputChoicepopup_Callback, true);
            app.InputChoicepopup.Value = '1';
            app.MassEdit = uieditfield(app.uipanel1, 'numeric');
            app.MassEdit.Limits = [0.001 Inf];
            app.MassEdit.ValueChangedFcn = createCallbackFcn(app, @MassEdit_Callback, true);
            app.MassEdit.Value = 1;
            app.StiffnessEdit = uieditfield(app.uipanel1, 'numeric');
            app.StiffnessEdit.Limits = [0.001 Inf];
            app.StiffnessEdit.ValueChangedFcn = createCallbackFcn(app, @StiffnessEdit_Callback, true);
            app.StiffnessEdit.Value = 1;
            app.PeriodEdit = uieditfield(app.uipanel1, 'numeric');
            app.PeriodEdit.Limits = [0.001 Inf];
            app.PeriodEdit.ValueChangedFcn = createCallbackFcn(app, @PeriodEdit_Callback, true);
            app.PeriodEdit.Value = 1;
            app.DampingEdit = uieditfield(app.uipanel1, 'numeric');
            app.DampingEdit.Limits = [0 Inf];
            app.DampingEdit.ValueChangedFcn = createCallbackFcn(app, @DampingEdit_Callback, true);
            app.MLabel = uilabel(app.uipanel1);
            app.MLabel.Text = 'M:';
            app.KLabel = uilabel(app.uipanel1);
            app.KLabel.Text = 'K:';
            app.TLabel = uilabel(app.uipanel1);
            app.TLabel.Text = 'T:';
            app.Label = uilabel(app.uipanel1);
            app.Label.Text = 'ξ:';
            app.uipanel4 = uipanel(app.figure1);
            app.PDeltacheckbox = uicheckbox(app.uipanel4);
            app.PDeltacheckbox.ValueChangedFcn = createCallbackFcn(app, @PDeltacheckbox_Callback, true);
            app.PDeltacheckbox.Text = 'Optional: Include P-Delta';
            app.text15 = uilabel(app.uipanel4);
            app.text15.Text = 'kN';
            app.text18 = uilabel(app.uipanel4);
            app.text18.Text = 'm';
            app.toggle1 = uibutton(app.uipanel4, 'state');
            app.toggle1.ValueChangedFcn = createCallbackFcn(app, @toggle1_Callback, true);
            app.toggle1.Text = 'Specify Weight and Height';
            app.toggle2 = uibutton(app.uipanel4, 'state');
            app.toggle2.ValueChangedFcn = createCallbackFcn(app, @toggle2_Callback, true);
            app.toggle2.Text = 'Specify Stability Coeff.';
            app.Pedit = uieditfield(app.uipanel4, 'numeric');
            app.Pedit.Limits = [0 Inf];
            app.Pedit.ValueDisplayFormat = '%.3f';
            app.Pedit.ValueChangedFcn = createCallbackFcn(app, @Pedit_Callback, true);
            app.Pedit.Value = 1;
            app.Hedit = uieditfield(app.uipanel4, 'numeric');
            app.Hedit.Limits = [0.001 Inf];
            app.Hedit.ValueDisplayFormat = '%.3f';
            app.Hedit.ValueChangedFcn = createCallbackFcn(app, @Hedit_Callback, true);
            app.Hedit.Value = 1;
            app.HLabel = uilabel(app.uipanel4);
            app.HLabel.Text = 'H:';
            app.PLabel = uilabel(app.uipanel4);
            app.PLabel.Text = 'P:';
            app.Label_2 = uilabel(app.uipanel4);
            app.Label_2.Text = 'θ:';
            app.SCedit = uieditfield(app.uipanel4, 'numeric');
            app.SCedit.Limits = [0 Inf];
            app.SCedit.ValueDisplayFormat = '%.4f';
            app.SCedit.ValueChangedFcn = createCallbackFcn(app, @SCedit_Callback, true);
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'SDoFaxes3figure.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_SDoF_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_SDoF_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end