classdef Module_SystemModel_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1                 matlab.ui.Figure
        uipanel1                matlab.ui.container.Panel
        text4                   matlab.ui.control.Label
        text13                  matlab.ui.control.Label
        text14                  matlab.ui.control.Label
        text15                  matlab.ui.control.Label
        SubmitButton            matlab.ui.control.Button
        text18                  matlab.ui.control.Label
        text19                  matlab.ui.control.Label
        text20                  matlab.ui.control.Label
        text21                  matlab.ui.control.Label
        text22                  matlab.ui.control.Label
        text23                  matlab.ui.control.Label
        text24                  matlab.ui.control.Label
        text25                  matlab.ui.control.Label
        text26                  matlab.ui.control.Label
        text27                  matlab.ui.control.Label
        text28                  matlab.ui.control.Label
        Elasticradiobutton      matlab.ui.control.Switch
        BiLinearradiobutton     matlab.ui.control.Switch
        IMKSmoothradiobutton    matlab.ui.control.Switch
        IMKPinchingradiobutton  matlab.ui.control.Switch
        IMKPOradiobutton        matlab.ui.control.Switch
        IMKSCradiobutton        matlab.ui.control.Switch
        Image                   matlab.ui.control.Image
        Image_2                 matlab.ui.control.Image
        Image_3                 matlab.ui.control.Image
        Image_4                 matlab.ui.control.Image
        Image_5                 matlab.ui.control.Image
        Image_6                 matlab.ui.control.Image
    end

    
    properties (Access = private)
        MainApp % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_SystemModel_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            Pos_Main=app.MainApp.figure0.Position;
            Pos_Secd=app.figure1.Position;
            app.figure1.Position=[Pos_Main(1) Pos_Main(2) Pos_Secd(3) Pos_Secd(4)];
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option')
            cd (MainDirectory)
            
            if SystemModelstatus==1
                if SystemModel_Option==1
                    app.Elasticradiobutton.Value='1';
                    ElasticradiobuttonValueChanged(app)
                elseif SystemModel_Option==2
                    app.BiLinearradiobutton.Value='1';
                    BiLinearradiobuttonValueChanged(app)
                elseif SystemModel_Option==3
                    app.IMKSmoothradiobutton.Value='1';
                    IMKSmoothradiobuttonValueChanged(app)
                elseif SystemModel_Option==4
                    app.IMKPinchingradiobutton.Value='1';
                    IMKPinchingradiobuttonValueChanged(app)
                elseif SystemModel_Option==5
                    app.IMKPOradiobutton.Value='1';
                    IMKPOradiobuttonValueChanged(app)
                elseif SystemModel_Option==6
                    app.IMKSCradiobutton.Value='1';
                    IMKSCradiobuttonValueChanged(app)
                end
            else
                 app.Elasticradiobutton.Value='1';
                 ElasticradiobuttonValueChanged(app)
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            clc;
            app.MainApp.Image5.Visible='on';

            if app.Elasticradiobutton.Value=='1'
                Module_SystemModel_Linear_App(app.MainApp)
            elseif app.BiLinearradiobutton.Value=='1'
                Module_SystemModel_BiLinear_App(app.MainApp)
            elseif app.IMKSmoothradiobutton.Value=='1'
                Module_SystemModel_IMKSmooth_App(app.MainApp)
            elseif app.IMKPinchingradiobutton.Value=='1'
                Module_SystemModel_IMKPinching_App(app.MainApp)
            elseif app.IMKPOradiobutton.Value=='1'
                Module_SystemModel_IMKPeakOriented_App(app.MainApp)
            elseif app.IMKSCradiobutton.Value=='1'
                Module_SystemModel_IMKSelfCentering_App(app.MainApp)
            end
            closereq            
        end

        % Value changed function: Elasticradiobutton
        function ElasticradiobuttonValueChanged(app, event)
                app.Elasticradiobutton.Value='1';
                app.BiLinearradiobutton.Value='0';
                app.IMKSmoothradiobutton.Value='0';
                app.IMKPinchingradiobutton.Value='0';
                app.IMKPOradiobutton.Value='0';
                app.IMKSCradiobutton.Value='0';
                app.Elasticradiobutton.FontColor=[1 0 0];
                app.BiLinearradiobutton.FontColor=[0 0 0];
                app.IMKSmoothradiobutton.FontColor=[0 0 0];
                app.IMKPinchingradiobutton.FontColor=[0 0 0];
                app.IMKPOradiobutton.FontColor=[0 0 0];
                app.IMKSCradiobutton.FontColor=[0 0 0];
                app.Elasticradiobutton.FontWeight='bold';
                app.BiLinearradiobutton.FontWeight='normal';
                app.IMKSmoothradiobutton.FontWeight='normal';
                app.IMKPinchingradiobutton.FontWeight='normal';
                app.IMKPOradiobutton.FontWeight='normal';
                app.IMKSCradiobutton.FontWeight='normal';                
        end

        % Value changed function: BiLinearradiobutton
        function BiLinearradiobuttonValueChanged(app, event)
                app.Elasticradiobutton.Value='0';
                app.BiLinearradiobutton.Value='1';
                app.IMKSmoothradiobutton.Value='0';
                app.IMKPinchingradiobutton.Value='0';
                app.IMKPOradiobutton.Value='0';
                app.IMKSCradiobutton.Value='0';   
                app.Elasticradiobutton.FontColor=[0 0 0];
                app.BiLinearradiobutton.FontColor=[1 0 0];
                app.IMKSmoothradiobutton.FontColor=[0 0 0];
                app.IMKPinchingradiobutton.FontColor=[0 0 0];
                app.IMKPOradiobutton.FontColor=[0 0 0];
                app.IMKSCradiobutton.FontColor=[0 0 0];
                app.Elasticradiobutton.FontWeight='normal';
                app.BiLinearradiobutton.FontWeight='bold';
                app.IMKSmoothradiobutton.FontWeight='normal';
                app.IMKPinchingradiobutton.FontWeight='normal';
                app.IMKPOradiobutton.FontWeight='normal';
                app.IMKSCradiobutton.FontWeight='normal';                
        end

        % Value changed function: IMKSmoothradiobutton
        function IMKSmoothradiobuttonValueChanged(app, event)
                app.Elasticradiobutton.Value='0';
                app.BiLinearradiobutton.Value='0';
                app.IMKSmoothradiobutton.Value='1';
                app.IMKPinchingradiobutton.Value='0';
                app.IMKPOradiobutton.Value='0';
                app.IMKSCradiobutton.Value='0';   
                app.Elasticradiobutton.FontColor=[0 0 0];
                app.BiLinearradiobutton.FontColor=[0 0 0];
                app.IMKSmoothradiobutton.FontColor=[1 0 0];
                app.IMKPinchingradiobutton.FontColor=[0 0 0];
                app.IMKPOradiobutton.FontColor=[0 0 0];
                app.IMKSCradiobutton.FontColor=[0 0 0];
                app.Elasticradiobutton.FontWeight='normal';
                app.BiLinearradiobutton.FontWeight='normal';
                app.IMKSmoothradiobutton.FontWeight='bold';
                app.IMKPinchingradiobutton.FontWeight='normal';
                app.IMKPOradiobutton.FontWeight='normal';
                app.IMKSCradiobutton.FontWeight='normal';                
        end

        % Value changed function: IMKPinchingradiobutton
        function IMKPinchingradiobuttonValueChanged(app, event)
                app.Elasticradiobutton.Value='0';
                app.BiLinearradiobutton.Value='0';
                app.IMKSmoothradiobutton.Value='0';
                app.IMKPinchingradiobutton.Value='1';
                app.IMKPOradiobutton.Value='0';
                app.IMKSCradiobutton.Value='0';     
                app.Elasticradiobutton.FontColor=[0 0 0];
                app.BiLinearradiobutton.FontColor=[0 0 0];
                app.IMKSmoothradiobutton.FontColor=[0 0 0];
                app.IMKPinchingradiobutton.FontColor=[1 0 0];
                app.IMKPOradiobutton.FontColor=[0 0 0];
                app.IMKSCradiobutton.FontColor=[0 0 0];
                app.Elasticradiobutton.FontWeight='normal';
                app.BiLinearradiobutton.FontWeight='normal';
                app.IMKSmoothradiobutton.FontWeight='normal';
                app.IMKPinchingradiobutton.FontWeight='bold';
                app.IMKPOradiobutton.FontWeight='normal';
                app.IMKSCradiobutton.FontWeight='normal';                
        end

        % Value changed function: IMKPOradiobutton
        function IMKPOradiobuttonValueChanged(app, event)
                app.Elasticradiobutton.Value='0';
                app.BiLinearradiobutton.Value='0';
                app.IMKSmoothradiobutton.Value='0';
                app.IMKPinchingradiobutton.Value='0';
                app.IMKPOradiobutton.Value='1';
                app.IMKSCradiobutton.Value='0';   
                app.Elasticradiobutton.FontColor=[0 0 0];
                app.BiLinearradiobutton.FontColor=[0 0 0];
                app.IMKSmoothradiobutton.FontColor=[0 0 0];
                app.IMKPinchingradiobutton.FontColor=[0 0 0];
                app.IMKPOradiobutton.FontColor=[1 0 0];
                app.IMKSCradiobutton.FontColor=[0 0 0];
                app.Elasticradiobutton.FontWeight='normal';
                app.BiLinearradiobutton.FontWeight='normal';
                app.IMKSmoothradiobutton.FontWeight='normal';
                app.IMKPinchingradiobutton.FontWeight='normal';
                app.IMKPOradiobutton.FontWeight='bold';
                app.IMKSCradiobutton.FontWeight='normal';
        end

        % Value changed function: IMKSCradiobutton
        function IMKSCradiobuttonValueChanged(app, event)
                app.Elasticradiobutton.Value='0';
                app.BiLinearradiobutton.Value='0';
                app.IMKSmoothradiobutton.Value='0';
                app.IMKPinchingradiobutton.Value='0';
                app.IMKPOradiobutton.Value='0';
                app.IMKSCradiobutton.Value='1';
                app.Elasticradiobutton.FontColor=[0 0 0];
                app.BiLinearradiobutton.FontColor=[0 0 0];
                app.IMKSmoothradiobutton.FontColor=[0 0 0];
                app.IMKPinchingradiobutton.FontColor=[0 0 0];
                app.IMKPOradiobutton.FontColor=[0 0 0];
                app.IMKSCradiobutton.FontColor=[1 0 0];
                app.Elasticradiobutton.FontWeight='normal';
                app.BiLinearradiobutton.FontWeight='normal';
                app.IMKSmoothradiobutton.FontWeight='normal';
                app.IMKPinchingradiobutton.FontWeight='normal';
                app.IMKPOradiobutton.FontWeight='normal';
                app.IMKSCradiobutton.FontWeight='bold';
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'System Model';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Choose System Model';
            app.text4 = uilabel(app.uipanel1);
            app.text4.Text = 'Non-deteriorating bilinear system with';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Text = 'Non-deteriorating linear elastic';
            app.text14 = uilabel(app.uipanel1);
            app.text14.Text = 'with smooth loading/hardening transition';
            app.text15 = uilabel(app.uipanel1);
            app.text15.Text = 'Deteriorating multi-linear model';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Next';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Text = 'system';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Text = 'based on the modified I-M-K model';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Text = 'Deteriorating multi-linear model';
            app.text21 = uilabel(app.uipanel1);
            app.text21.Text = 'with pinching response';
            app.text22 = uilabel(app.uipanel1);
            app.text22.Text = 'symmetric response';
            app.text23 = uilabel(app.uipanel1);
            app.text23.Text = 'based on the modified I-M-K model';
            app.text24 = uilabel(app.uipanel1);
            app.text24.Text = 'Deteriorating multi-linear model';
            app.text25 = uilabel(app.uipanel1);
            app.text25.Text = 'with peak-oriented response';
            app.text26 = uilabel(app.uipanel1);
            app.text26.Text = 'based on the modified I-M-K model';
            app.text27 = uilabel(app.uipanel1);
            app.text27.Text = 'Non-deteriorating multi-linear model';
            app.text28 = uilabel(app.uipanel1);
            app.text28.Text = 'with flag-shaped response';
            app.Elasticradiobutton = uiswitch(app.uipanel1, 'slider');
            app.Elasticradiobutton.Items = {'', 'Linear'};
            app.Elasticradiobutton.ItemsData = {'0', '1'};
            app.Elasticradiobutton.ValueChangedFcn = createCallbackFcn(app, @ElasticradiobuttonValueChanged, true);
            app.Elasticradiobutton.Value = '0';
            app.BiLinearradiobutton = uiswitch(app.uipanel1, 'slider');
            app.BiLinearradiobutton.Items = {'', 'BiLinear'};
            app.BiLinearradiobutton.ItemsData = {'0', '1'};
            app.BiLinearradiobutton.ValueChangedFcn = createCallbackFcn(app, @BiLinearradiobuttonValueChanged, true);
            app.BiLinearradiobutton.Value = '0';
            app.IMKSmoothradiobutton = uiswitch(app.uipanel1, 'slider');
            app.IMKSmoothradiobutton.Items = {'', 'IMK BiLinear'};
            app.IMKSmoothradiobutton.ItemsData = {'0', '1'};
            app.IMKSmoothradiobutton.ValueChangedFcn = createCallbackFcn(app, @IMKSmoothradiobuttonValueChanged, true);
            app.IMKSmoothradiobutton.Value = '0';
            app.IMKPinchingradiobutton = uiswitch(app.uipanel1, 'slider');
            app.IMKPinchingradiobutton.Items = {'', 'IMK Pinching'};
            app.IMKPinchingradiobutton.ItemsData = {'0', '1'};
            app.IMKPinchingradiobutton.ValueChangedFcn = createCallbackFcn(app, @IMKPinchingradiobuttonValueChanged, true);
            app.IMKPinchingradiobutton.Value = '0';
            app.IMKPOradiobutton = uiswitch(app.uipanel1, 'slider');
            app.IMKPOradiobutton.Items = {'', 'IMK Peak-Oriented'};
            app.IMKPOradiobutton.ItemsData = {'0', '1'};
            app.IMKPOradiobutton.ValueChangedFcn = createCallbackFcn(app, @IMKPOradiobuttonValueChanged, true);
            app.IMKPOradiobutton.Value = '0';
            app.IMKSCradiobutton = uiswitch(app.uipanel1, 'slider');
            app.IMKSCradiobutton.Items = {'', 'Flag-Shaped'};
            app.IMKSCradiobutton.ItemsData = {'0', '1'};
            app.IMKSCradiobutton.ValueChangedFcn = createCallbackFcn(app, @IMKSCradiobuttonValueChanged, true);
            app.IMKSCradiobutton.Value = '0';
            app.Image = uiimage(app.uipanel1);
            app.Image.ImageSource = 'Linear.png';
            app.Image_2 = uiimage(app.uipanel1);
            app.Image_2.ImageSource = 'BiLinear.png';
            app.Image_3 = uiimage(app.uipanel1);
            app.Image_3.ImageSource = 'IMKSmooth.png';
            app.Image_4 = uiimage(app.uipanel1);
            app.Image_4.ImageSource = 'IMKPinching.png';
            app.Image_5 = uiimage(app.uipanel1);
            app.Image_5.ImageSource = 'IMKPeakOriented.png';
            app.Image_6 = uiimage(app.uipanel1);
            app.Image_6.ImageSource = 'IMKSelfCentering.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_SystemModel_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_SystemModel_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end