classdef Module_SystemModel_BiLinear_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        text12        matlab.ui.control.Label
        text13        matlab.ui.control.Label
        checkbox1     matlab.ui.control.CheckBox
        text19        matlab.ui.control.Label
        SubmitButton  matlab.ui.control.Button
        edit1         matlab.ui.control.NumericEditField
        edit2         matlab.ui.control.NumericEditField
        edit3         matlab.ui.control.NumericEditField
        edit4         matlab.ui.control.NumericEditField
        edit5         matlab.ui.control.NumericEditField
        ButtonGroup   matlab.ui.container.ButtonGroup
        radiobutton1  matlab.ui.control.RadioButton
        radiobutton2  matlab.ui.control.RadioButton
        FWLabel       matlab.ui.control.Label
        KLabel        matlab.ui.control.Label
        Label         matlab.ui.control.Label
        KKLabel       matlab.ui.control.Label
        Label_2       matlab.ui.control.Label
        axes1         matlab.ui.control.UIAxes
        Image         matlab.ui.control.Image
    end

    
    properties (Access = private)
        MainApp % Description
    end
    
    methods (Access = private)
        function Plot(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option','Ko','BackboneNoPD','BackbonePD','Backbone','M','g','delta_SystemFail','delta_PDeltaFail','Units','P','H','PDeltastatus')
            cd (MainDirectory)
            
            BackboneNoPD.Ke=Ko;
            BackboneNoPD.Kp=app.edit2.Value*Ko/100;
            if app.radiobutton1.Value==1
                BL_Option=1;
                BackboneNoPD.Fy=app.edit4.Value*M*g;
                BackboneNoPD.dy=BackboneNoPD.Fy/BackboneNoPD.Ke;
            else
                BL_Option=2;
                BackboneNoPD.dy=app.edit1.Value;
                BackboneNoPD.Fy=BackboneNoPD.Ke*BackboneNoPD.dy;
            end
            
            if app.checkbox1.Value==1
                delta_SystemFail=app.edit3.Value*BackboneNoPD.dy;
            else
                delta_SystemFail=999;
            end
            
            % Modify the slopes paramters of the bilinear curve to account for P-Delta
            BackbonePD.dy=BackboneNoPD.dy;
            BackbonePD.Ke=BackboneNoPD.Ke-P/H;
            BackbonePD.Kp=BackboneNoPD.Kp-P/H;
            BackbonePD.Fy=BackboneNoPD.Fy-P*BackboneNoPD.dy/H;
            
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=15;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            % Plot backbone
            DispNoPD =[0 BackboneNoPD.dy BackboneNoPD.dy*5.0];
            ForceNoPD=[0 BackboneNoPD.Fy BackboneNoPD.Fy+BackboneNoPD.Kp*BackboneNoPD.dy*4];
            
            plot(app.axes1,DispNoPD,ForceNoPD,'-k','linewidth',2);
            app.axes1.FontName='Times';
            app.axes1.FontSize=16;
            if Units==1
                app.axes1.XLabel.String='Displacement, \delta [m]';
                app.axes1.YLabel.String='Force [kN]';
            else
                app.axes1.XLabel.String='Displacement, \delta [in]';
                app.axes1.YLabel.String='Force [kip]';
            end
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
                
            % Plot backbone with P-Delta if applicable
            if PDeltastatus==1
                DispPD =[0 BackbonePD.dy BackbonePD.dy*5.0];
                ForcePD=[0 BackbonePD.Fy BackbonePD.Fy+BackbonePD.Kp*BackbonePD.dy*4];
                plot(app.axes1,DispPD,ForcePD,'--r','linewidth',2);
                legend(app.axes1,'without P-Delta','with P-Delta');
                app.axes1.Legend.Location='southeast';
                app.axes1.Legend.FontName='Times';
                app.axes1.Legend.FontSize=10;
            end
            
            app.axes1.YLim=[0 (BackboneNoPD.Fy+BackboneNoPD.Kp*BackboneNoPD.dy*4)*1.2];
            app.axes1.XLim=[0 BackboneNoPD.dy*5.50];
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_SystemModel_BiLinear_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;    
        
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option','Ko','BackboneNoPD','M','g','delta_SystemFail','Units')
            cd (MainDirectory)
            
            app.edit5.Value=Ko;
            app.edit5.Enable='off';
            
            if Units==1
                app.text12.Text='m';
                app.text19.Text='kN/m';
            else
                app.text12.Text='in';
                app.text19.Text='kip/in';
            end
            
            if SystemModelstatus==1 && SystemModel_Option==2
               app.edit4.Value=BackboneNoPD.Fy/M/g;
               app.edit1.Value=BackboneNoPD.dy;
               app.edit2.Value=BackboneNoPD.Kp/Ko*100;
               app.edit3.Value=delta_SystemFail/BackboneNoPD.dy;
               app.radiobutton1.Value=1;
               ButtonGroupSelectionChanged(app)
               app.checkbox1.Value=1;
               checkbox1_Callback(app)
               Plot(app)
            else
               app.edit2.Value=3;
               app.checkbox1.Value=0;
               checkbox1_Callback(app)
               app.radiobutton1.Value=1;
               ButtonGroupSelectionChanged(app)
               app.edit4.Value=0.15;
               edit4_Callback(app)
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Runstatus','Analysis_Option')
            cd (MainDirectory)
            
            if Runstatus==1
                waitfor(DeleteResultsMessage_App);
            end
            
            cd (ProjectPath)
            load(ProjectName,'DeleteResults')
            cd (MainDirectory)
            
            if Runstatus==1 && DeleteResults==0
                return;
            elseif Runstatus==1 && DeleteResults==1
                if Analysis_Option==1
                    rmvar(ProjectPath,ProjectName, 'RESULTS')
                elseif Analysis_Option==2
                    rmvar(ProjectPath,ProjectName, 'RESULTS','IDA')
                elseif Analysis_Option==3
                    rmvar(ProjectPath,ProjectName, 'RESULTS','RSA')
                elseif Analysis_Option==4
            
                end
                Runstatus=0;
                DeleteResults=1;
            
                cd (ProjectPath)
                save(ProjectName,'Runstatus','DeleteResults','-append')
                cd (MainDirectory)
            
                app.MainApp.ResultsButton.Enable='off';
                app.MainApp.SaveButton.Enable='off';
            end
            
            
            cd (ProjectPath)
            load(ProjectName,'Ko','BackboneNoPD','BackbonePD','Backbone','M','g','delta_SystemFail','delta_PDeltaFail','Units','P','H','PDeltastatus','GMstatus')
            cd (MainDirectory)
            
            clear BackboneNoPD BackbonePD Backbone
            
            BackboneNoPD.Ke=Ko;
            BackboneNoPD.Kp=app.edit2.Value*Ko/100;
            
            if app.radiobutton1.Value==1
                BL_Option=1;
                BackboneNoPD.Fy=app.edit4.Value*M*g;
                BackboneNoPD.dy=BackboneNoPD.Fy/Ko;
            else
                BL_Option=2;
                BackboneNoPD.dy=app.edit1.Value;
                BackboneNoPD.Fy=Ko*BackboneNoPD.dy;
            end
            
            if app.checkbox1.Value==1
                delta_SystemFail=app.edit3.Value*BackboneNoPD.dy;
            else
                delta_SystemFail=999;
            end
            
            % Modify the slopes paramters of the bilinear curve to account for P-Delta
            BackbonePD.dy=BackboneNoPD.dy;
            BackbonePD.Ke=BackboneNoPD.Ke-P/H;
            BackbonePD.Kp=BackboneNoPD.Kp-P/H;
            BackbonePD.Fy=BackboneNoPD.Fy-P*BackboneNoPD.dy/H;
            
            % Get Failure displacement
            if BackbonePD.Ke<0.0
                delta_PDeltaFail=0.0;
            else
                if BackbonePD.Kp<0.0
                    delta_PDeltaFail=BackbonePD.dy+BackbonePD.Fy/abs(BackbonePD.Kp);
                else
                    delta_PDeltaFail=999;
                end
            end
            
            
            %% INPUT VALUES CHECKS BEFORE SUBMISSION
            if BackboneNoPD.dy<0 || delta_SystemFail<0
                ed = errordlg('Yield and Ultimate Displacements cannot be less than 0 (-ve)','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            if BackboneNoPD.Kp> BackboneNoPD.Ke
                ed = errordlg('Post-yielding stiffness cannot be LARGER THAN the elastic stiffness','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            if isnan(BackboneNoPD.dy) || isnan(BackboneNoPD.Fy) || isnan(BackboneNoPD.Kp) || isnan(delta_SystemFail)
                ed = errordlg('Some paramters are not defined. Please define all the required paramters.','Error', 'modal');
                waitfor(ed);
                return;
            end

            SystemModelstatus=1;
            SystemModel_Option=2;
            
            cd (MainDirectory)
            cd (ProjectPath)
            pause(0.2)
            save(ProjectName,'BackboneNoPD','BackbonePD','SystemModelstatus','SystemModel_Option','BL_Option','delta_PDeltaFail','delta_SystemFail','-append')
            pause(0.7)
            cd (MainDirectory)
            
            %a=findall(groot, 'Name', 'IIDAP'); %myguide1 is the file name for guide1
            app.MainApp.status2.BackgroundColor='g';
            app.MainApp.status2.FontColor='k';
            app.MainApp.status2.Text='System Model: Bi-Linear';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.status2b.BackgroundColor='g';%text1 is the value tag for the Static Text in GUI1
            if GMstatus~=1
                app.MainApp.GMButton.Enable='on';%text1 is the value tag for the Static Text in GUI1
                app.MainApp.status3.BackgroundColor='r';%text1 is the value tag for the Static Text in GUI1
                app.MainApp.status3b.BackgroundColor='r';%text1 is the value tag for the Static Text in GUI1
            end
            
            app.MainApp.Image5.Visible='off';
            closereq
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            if app.checkbox1.Value==1
                app.edit3.Enable='on';
            else
                app.edit3.Enable='off';
                app.edit3.Value=999;
            end
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc
            cd (ProjectPath)
            load(ProjectName,'Ko','M','g')
            cd (MainDirectory)
            X=app.edit1.Value*Ko/M/g;
            app.edit4.Value=X;
            Plot(app)
        end

        % Value changed function: edit4
        function edit4_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc
            cd (ProjectPath)
            load(ProjectName,'M','g','Ko')
            cd (MainDirectory)
            X=app.edit4.Value*M*g/Ko;
            app.edit1.Value=X;
            Plot(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)            
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.radiobutton1.Value==1
                app.edit4.Enable='on';
                app.edit1.Enable='off';
                edit4_Callback(app);
            else
                app.edit4.Enable='off';
                app.edit1.Enable='on';                
                edit1_Callback(app);
            end
        end

        % Value changed function: edit2
        function edit2ValueChanged(app, event)
           if app.edit2.Value<1 && app.edit2.Value>0
               ed=warndlg('Note that this Kp/Ke ratio is expressed as a "percentage"','!! REMINDER !!');
               waitfor(ed)
           end
           Plot(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Bi-Linear Model';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Backbone Parameters';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Tooltip = 'Define the yield point by either Fy OR dy';
            app.text12.Text = 'm';
            app.text13 = uilabel(app.uipanel1);
            app.text13.Tooltip = 'Define the post-yield slope as a percentage of Ke';
            app.text13.Text = '[%]';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Tooltip = 'Optional: Define an ultimate (i.e., failure) displacement';
            app.checkbox1.Text = '';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Text = 'kN/m';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.Limits = [0.001 Inf];
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Value = 1;
            app.edit2 = uieditfield(app.uipanel1, 'numeric');
            app.edit2.Limits = [0 100];
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2ValueChanged, true);
            app.edit3 = uieditfield(app.uipanel1, 'numeric');
            app.edit3.Limits = [0.001 Inf];
            app.edit3.Value = 5;
            app.edit4 = uieditfield(app.uipanel1, 'numeric');
            app.edit4.Limits = [0.001 Inf];
            app.edit4.ValueChangedFcn = createCallbackFcn(app, @edit4_Callback, true);
            app.edit4.Value = 1;
            app.edit5 = uieditfield(app.uipanel1, 'numeric');
            app.edit5.Limits = [0.001 Inf];
            app.edit5.Value = 1;
            app.ButtonGroup = uibuttongroup(app.uipanel1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.radiobutton1 = uiradiobutton(app.ButtonGroup);
            app.radiobutton1.Text = '';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.ButtonGroup);
            app.radiobutton2.Text = '';
            app.FWLabel = uilabel(app.uipanel1);
            app.FWLabel.Text = 'Fᵧ / W';
            app.KLabel = uilabel(app.uipanel1);
            app.KLabel.Text = 'Kₑ';
            app.Label = uilabel(app.uipanel1);
            app.Label.Text = 'δᵧ';
            app.KKLabel = uilabel(app.uipanel1);
            app.KKLabel.Text = 'Kₚ / Kₑ';
            app.Label_2 = uilabel(app.uipanel1);
            app.Label_2.Text = 'δᵤ / δᵧ';
            app.axes1 = uiaxes(app.figure1);
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'BLaxes1figure.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_SystemModel_BiLinear_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_SystemModel_BiLinear_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end