classdef Module_SystemModel_IMKPeakOriented_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel6      matlab.ui.container.Panel
        text17        matlab.ui.control.Label
        text18        matlab.ui.control.Label
        text19        matlab.ui.control.Label
        text20        matlab.ui.control.Label
        text21        matlab.ui.control.Label
        text33        matlab.ui.control.Label
        text36        matlab.ui.control.Label
        cLabel        matlab.ui.control.Label
        Label_6       matlab.ui.control.Label
        edit13        matlab.ui.control.NumericEditField
        edit14        matlab.ui.control.NumericEditField
        edit15        matlab.ui.control.NumericEditField
        edit60        matlab.ui.control.NumericEditField
        edit17        matlab.ui.control.NumericEditField
        edit18        matlab.ui.control.NumericEditField
        edit19        matlab.ui.control.NumericEditField
        edit61        matlab.ui.control.NumericEditField
        edit16        matlab.ui.control.NumericEditField
        edit20        matlab.ui.control.NumericEditField
        uipanel7      matlab.ui.container.Panel
        uipanel4      matlab.ui.container.Panel
        edit7         matlab.ui.control.NumericEditField
        edit8         matlab.ui.control.NumericEditField
        edit9         matlab.ui.control.NumericEditField
        edit10        matlab.ui.control.NumericEditField
        edit11        matlab.ui.control.NumericEditField
        edit12        matlab.ui.control.NumericEditField
        uipanel2      matlab.ui.container.Panel
        edit1         matlab.ui.control.NumericEditField
        edit2         matlab.ui.control.NumericEditField
        edit3         matlab.ui.control.NumericEditField
        edit4         matlab.ui.control.NumericEditField
        edit5         matlab.ui.control.NumericEditField
        edit6         matlab.ui.control.NumericEditField
        Label_2       matlab.ui.control.Label
        Label_3       matlab.ui.control.Label
        Label_4       matlab.ui.control.Label
        FWLabel       matlab.ui.control.Label
        FFLabel       matlab.ui.control.Label
        FFLabel_2     matlab.ui.control.Label
        KLabel        matlab.ui.control.Label
        edit59        matlab.ui.control.NumericEditField
        text35        matlab.ui.control.Label
        Label_7       matlab.ui.control.Label
        edit64        matlab.ui.control.NumericEditField
        text29        matlab.ui.control.Label
        checkbox1     matlab.ui.control.CheckBox
        FLabel        matlab.ui.control.Label
        edit62        matlab.ui.control.NumericEditField
        Label_8       matlab.ui.control.Label
        edit63        matlab.ui.control.NumericEditField
        SubmitButton  matlab.ui.control.Button
        axes1         matlab.ui.control.UIAxes
        Image         matlab.ui.control.Image
    end

    
    properties (Access = private)
        MainApp
    end
    
    methods (Access = private)
        function [BackboneNoPD, BackbonePD] = Get_Backbone(app)
                global MainDirectory ProjectPath ProjectName
                clc;
                cd (ProjectPath)
                load(ProjectName,'Ko','BackboneNoPD','BackbonePD','Backbone','M','g','delta_SystemFail','delta_PDeltaFail','P','H','PDeltastatus')
                cd (MainDirectory)
            
                BackboneNoPD.Ke =Ko;
                BackboneNoPD.Fy_pos  = app.edit4.Value*M*g;
                BackboneNoPD.Fy_neg  = app.edit10.Value*M*g;
                BackboneNoPD.Uy_pos  = BackboneNoPD.Fy_pos / BackboneNoPD.Ke;
                BackboneNoPD.Uy_neg  = BackboneNoPD.Fy_neg / BackboneNoPD.Ke;
            
                BackboneNoPD.Up_pos     = app.edit1.Value*BackboneNoPD.Uy_pos;
                BackboneNoPD.Upc_pos    = app.edit2.Value*BackboneNoPD.Uy_pos;
                BackboneNoPD.Uu_pos     = app.edit3.Value*BackboneNoPD.Uy_pos;
                BackboneNoPD.FmaxFy_pos = app.edit5.Value;
                BackboneNoPD.FresFy_pos = app.edit6.Value;
            
                BackboneNoPD.Up_neg     = app.edit7.Value*BackboneNoPD.Uy_neg;
                BackboneNoPD.Upc_neg    = app.edit8.Value*BackboneNoPD.Uy_neg;
                BackboneNoPD.Uu_neg     = app.edit9.Value*BackboneNoPD.Uy_neg;
                BackboneNoPD.FmaxFy_neg = app.edit11.Value;
                BackboneNoPD.FresFy_neg = app.edit12.Value;
            
                BackboneNoPD.Lambda_S   = app.edit13.Value;
                BackboneNoPD.Lambda_C   = app.edit14.Value;
                BackboneNoPD.Lambda_K   = app.edit15.Value;
                BackboneNoPD.Lambda_A   = app.edit60.Value;
            
                BackboneNoPD.c_S        = app.edit17.Value;
                BackboneNoPD.c_C        = app.edit18.Value;
                BackboneNoPD.c_K        = app.edit19.Value;
                BackboneNoPD.c_A        = app.edit61.Value;
            
                BackboneNoPD.D_pos      = app.edit16.Value;
                BackboneNoPD.D_neg      = app.edit20.Value;
            
                BackboneNoPD.kappaF     = app.edit62.Value;
                BackboneNoPD.kappaD     = app.edit63.Value;
            
                if isnan(BackboneNoPD.Up_pos) || isnan(BackboneNoPD.Upc_pos) || isnan(BackboneNoPD.Uu_pos) || isnan(BackboneNoPD.Fy_pos) || isnan(BackboneNoPD.FmaxFy_pos) || isnan(BackboneNoPD.FresFy_pos) || ...
                   isnan(BackboneNoPD.Up_neg) || isnan(BackboneNoPD.Upc_neg) || isnan(BackboneNoPD.Uu_neg) || isnan(BackboneNoPD.Fy_neg) || isnan(BackboneNoPD.FmaxFy_neg) || isnan(BackboneNoPD.FresFy_neg) || ...
                   isnan(BackboneNoPD.Lambda_S) || isnan(BackboneNoPD.Lambda_C) || isnan(BackboneNoPD.Lambda_K) || isnan(BackboneNoPD.Lambda_A) || isnan(BackboneNoPD.c_S) || isnan(BackboneNoPD.c_C) || isnan(BackboneNoPD.c_K) || isnan(BackboneNoPD.c_A) || ...
                   isnan(BackboneNoPD.D_pos) || isnan(BackboneNoPD.D_neg) || isnan(BackboneNoPD.kappaF) || isnan(BackboneNoPD.kappaD)
                    ed = errordlg('Some paramters are not defined. Please define all the required paramters.','Error', 'modal');
                    waitfor(ed);
                    return;
                end
            
                BackboneNoPD.Uy_pos     = BackboneNoPD.Fy_pos/BackboneNoPD.Ke;
                BackboneNoPD.Umax_pos   = BackboneNoPD.Uy_pos+BackboneNoPD.Up_pos;
                BackboneNoPD.Fmax_pos   = BackboneNoPD.FmaxFy_pos*BackboneNoPD.Fy_pos;
                BackboneNoPD.Fres_pos   = BackboneNoPD.FresFy_pos*BackboneNoPD.Fy_pos;
                BackboneNoPD.Kpc_pos    = BackboneNoPD.Fmax_pos/(BackboneNoPD.Upc_pos);
                BackboneNoPD.Kp_pos     = (BackboneNoPD.Fmax_pos-BackboneNoPD.Fy_pos)/(BackboneNoPD.Umax_pos-BackboneNoPD.Uy_pos);
                BackboneNoPD.Ures_pos   = BackboneNoPD.Uy_pos+BackboneNoPD.Up_pos+(BackboneNoPD.Fmax_pos-BackboneNoPD.Fres_pos)/BackboneNoPD.Kpc_pos;
            
                BackboneNoPD.Uy_neg     = BackboneNoPD.Fy_neg/BackboneNoPD.Ke;
                BackboneNoPD.Umax_neg   = BackboneNoPD.Uy_neg+BackboneNoPD.Up_neg;
                BackboneNoPD.Fmax_neg   = BackboneNoPD.FmaxFy_neg*BackboneNoPD.Fy_neg;
                BackboneNoPD.Fres_neg   = BackboneNoPD.FresFy_neg*BackboneNoPD.Fy_neg;
                BackboneNoPD.Kpc_neg    = BackboneNoPD.Fmax_neg/(BackboneNoPD.Upc_neg);
                BackboneNoPD.Kp_neg     = (BackboneNoPD.Fmax_neg-BackboneNoPD.Fy_neg)/(BackboneNoPD.Umax_neg-BackboneNoPD.Uy_neg);
                BackboneNoPD.Ures_neg   = BackboneNoPD.Uy_neg+BackboneNoPD.Up_neg+(BackboneNoPD.Fmax_neg-BackboneNoPD.Fres_neg)/BackboneNoPD.Kpc_neg;
            
                BackboneNoPD.EtS        = BackboneNoPD.Lambda_S*BackboneNoPD.Fy_pos*(BackboneNoPD.Up_pos);
                BackboneNoPD.EtC        = BackboneNoPD.Lambda_C*BackboneNoPD.Fy_pos*(BackboneNoPD.Up_pos);
                BackboneNoPD.EtA        = BackboneNoPD.Lambda_A*BackboneNoPD.Fy_pos*(BackboneNoPD.Up_pos);
                BackboneNoPD.EtK        = BackboneNoPD.Lambda_K*BackboneNoPD.Fy_pos*(BackboneNoPD.Up_pos);
            
                % Deduce Backbone with P-Delta
                % Modify the strength paramters of the backbone curve to account for P-Delta
                BackbonePD.Fy_pos       = BackboneNoPD.Fy_pos-(P*BackboneNoPD.Uy_pos/H);
                BackbonePD.Fmax_pos     = BackboneNoPD.Fmax_pos-P*(BackboneNoPD.Uy_pos+BackboneNoPD.Up_pos)/H;
                BackbonePD.FmaxFy_pos   = BackbonePD.Fmax_pos/BackbonePD.Fy_pos;
                BackbonePD.Fres_pos     = BackboneNoPD.Fres_pos-(P*(BackboneNoPD.Uy_pos+BackboneNoPD.Up_pos+(BackboneNoPD.Fmax_pos-BackboneNoPD.Fres_pos)/BackboneNoPD.Kpc_pos)/H);
                BackbonePD.FresFy_pos   = BackbonePD.Fres_pos/BackbonePD.Fy_pos;
                if BackbonePD.FresFy_pos<0.0; BackbonePD.FresFy_pos=0; end
            
                BackbonePD.Fy_neg       = BackboneNoPD.Fy_neg-(P*BackboneNoPD.Uy_pos/H);
                BackbonePD.Fmax_neg     = BackboneNoPD.Fmax_neg-P*(BackboneNoPD.Uy_neg+BackboneNoPD.Up_neg)/H;
                BackbonePD.FmaxFy_neg   = BackbonePD.Fmax_neg/BackbonePD.Fy_neg;
                BackbonePD.Fres_neg     = BackboneNoPD.Fres_neg-(P*(BackboneNoPD.Uy_neg+BackboneNoPD.Up_neg+(BackboneNoPD.Fmax_neg-BackboneNoPD.Fres_neg)/BackboneNoPD.Kpc_neg)/H);
                BackbonePD.FresFy_neg   = BackbonePD.Fres_neg/BackbonePD.Fy_neg;
                if BackbonePD.FresFy_neg<0.0; BackbonePD.FresFy_neg=0; end
            
                BackbonePD.Ke =BackbonePD.Fy_pos/BackboneNoPD.Uy_pos;
            
                % The rest of the parameters remain the same
                BackbonePD.Up_pos   = BackboneNoPD.Up_pos;
                BackbonePD.Upc_pos  = BackboneNoPD.Upc_pos;
                BackbonePD.Uu_pos   = BackboneNoPD.Uu_pos;
                BackbonePD.Uy_pos   = BackboneNoPD.Uy_pos;
                BackbonePD.Umax_pos = BackboneNoPD.Umax_pos;
            
                BackbonePD.Up_neg   = BackboneNoPD.Up_neg;
                BackbonePD.Upc_neg  = BackboneNoPD.Upc_neg;
                BackbonePD.Uu_neg   = BackboneNoPD.Uu_neg;
                BackbonePD.Uy_neg   = BackboneNoPD.Uy_neg;
                BackbonePD.Umax_neg = BackboneNoPD.Umax_neg;
            
                BackbonePD.Lambda_S = BackboneNoPD.Lambda_S;
                BackbonePD.Lambda_C = BackboneNoPD.Lambda_C;
                BackbonePD.Lambda_K = BackboneNoPD.Lambda_K;
                BackbonePD.Lambda_A = BackboneNoPD.Lambda_A;
                BackbonePD.c_S      = BackboneNoPD.c_S;
                BackbonePD.c_C      = BackboneNoPD.c_C;
                BackbonePD.c_K      = BackboneNoPD.c_K;
                BackbonePD.c_A      = BackboneNoPD.c_A;
            
                BackbonePD.D_pos    = BackboneNoPD.D_pos;
                BackbonePD.D_neg    = BackboneNoPD.D_neg;
            
                BackbonePD.kappaF   = BackboneNoPD.kappaF;
                BackbonePD.kappaD   = BackboneNoPD.kappaD;
            
                BackbonePD.EtS      = BackboneNoPD.EtS;
                BackbonePD.EtC      = BackboneNoPD.EtC;
                BackbonePD.EtK      = BackboneNoPD.EtK;
                BackbonePD.EtA      = BackboneNoPD.EtA;
            
                BackbonePD.Kp_pos   = (BackbonePD.FmaxFy_pos-1)*BackbonePD.Fy_pos/(BackbonePD.Up_pos);
                BackbonePD.Kp_neg   = (BackbonePD.FmaxFy_neg-1)*BackbonePD.Fy_neg/(BackbonePD.Up_neg);
            
                BackbonePD.Ures_pos = BackboneNoPD.Ures_pos-BackboneNoPD.Umax_pos;
                BackbonePD.Ures_neg = BackboneNoPD.Ures_neg-BackboneNoPD.Umax_neg;
            
                BackbonePD.Kpc_pos  = (BackbonePD.Fmax_pos-BackbonePD.Fres_pos)/(BackbonePD.Ures_pos);
                BackbonePD.Kpc_neg  = (BackbonePD.Fmax_neg-BackbonePD.Fres_neg)/(BackbonePD.Ures_neg);
        end
        
        function PlotButton_Callback(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option','Ko','BackboneNoPD','BackbonePD','Backbone','M','g','delta_SystemFail','delta_PDeltaFail','Units','P','H','PDeltastatus','GMstatus')
            cd (MainDirectory)
            
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=16;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            [BackboneNoPD,BackbonePD]=Get_Backbone (app);
            
            UFail_pos=min(BackboneNoPD.Uu_pos,BackboneNoPD.Uy_pos+BackboneNoPD.Up_pos+BackboneNoPD.Upc_pos);
            UFail_neg=min(BackboneNoPD.Uu_neg,BackboneNoPD.Uy_neg+BackboneNoPD.Up_neg+BackboneNoPD.Upc_neg);
            
            % Plot backbone
            if BackboneNoPD.Uu_pos > BackboneNoPD.Uy_pos+BackboneNoPD.Up_pos+BackboneNoPD.Upc_pos
                RotationPos=[0 BackboneNoPD.Uy_pos BackboneNoPD.Umax_pos BackboneNoPD.Umax_pos+(BackboneNoPD.Fmax_pos-BackboneNoPD.Fres_pos)/BackboneNoPD.Kpc_pos BackboneNoPD.Uu_pos BackboneNoPD.Uu_pos];
                MomentPos=[0 BackboneNoPD.Fy_pos BackboneNoPD.Fmax_pos BackboneNoPD.Fres_pos BackboneNoPD.Fres_pos 0];
            else
                RotationPos=[0 BackboneNoPD.Uy_pos BackboneNoPD.Umax_pos BackboneNoPD.Uu_pos BackboneNoPD.Uu_pos];
                MomentPos  =[0 BackboneNoPD.Fy_pos     BackboneNoPD.Fmax_pos      BackboneNoPD.Fmax_pos-BackboneNoPD.Kpc_pos*(BackboneNoPD.Uu_pos-BackboneNoPD.Umax_pos)      0];
            end
            
            if BackboneNoPD.Uu_neg > BackboneNoPD.Uy_neg+BackboneNoPD.Up_neg+BackboneNoPD.Upc_neg
                RotationNeg=[0 BackboneNoPD.Uy_neg BackboneNoPD.Umax_neg BackboneNoPD.Umax_neg+(BackboneNoPD.Fmax_neg-BackboneNoPD.Fres_neg)/BackboneNoPD.Kpc_neg BackboneNoPD.Uu_neg BackboneNoPD.Uu_neg];
                MomentNeg=[0 BackboneNoPD.Fy_neg BackboneNoPD.Fmax_neg BackboneNoPD.Fres_neg BackboneNoPD.Fres_neg 0];
            else
                RotationNeg=[0 BackboneNoPD.Uy_neg BackboneNoPD.Umax_neg BackboneNoPD.Uu_neg BackboneNoPD.Uu_neg];
                MomentNeg  =[0 BackboneNoPD.Fy_neg     BackboneNoPD.Fmax_neg      BackboneNoPD.Fmax_neg-BackboneNoPD.Kpc_neg*(BackboneNoPD.Uu_neg-BackboneNoPD.Umax_neg)      0];
            end
            
            if PDeltastatus==1
                plot(app.axes1,[0],[0],'-k','linewidth',2);
                plot(app.axes1,[0],[0],'--b','linewidth',2);
                plot(app.axes1,[0],[0],'-r','linewidth',2);
            else
                plot(app.axes1,[0],[0],'-k','linewidth',2);
                plot(app.axes1,[0],[0],'-r','linewidth',2);
            end
            
            plot(app.axes1,RotationPos,MomentPos,'-k','linewidth',2);
            plot(app.axes1,-RotationNeg,-MomentNeg,'-k','linewidth',2);
            if Units==1
                app.axes1.XLabel.String= 'Displacement, \delta [m]';
                app.axes1.YLabel.String= 'Force [kN]';
            else
                app.axes1.XLabel.String= 'Displacement, \delta [in]';
                app.axes1.YLabel.String= 'Force [kip]';
            end
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
            
            % Plot origin lines
            plot(app.axes1,[0 0],[-BackboneNoPD.Fmax_neg*1.5 BackboneNoPD.Fmax_pos*1.5],'-k','linewidth',1);
            plot(app.axes1,[-UFail_neg*1.5 UFail_pos*1.5],[0 0],'-k','linewidth',1);
            app.axes1.XLim=[-UFail_neg*1.15 UFail_pos*1.15];
            app.axes1.YLim=[-BackboneNoPD.Fmax_neg*1.15 BackboneNoPD.Fmax_pos*1.15];
            
            % Plot backbone with P-Delta if applicable
            if PDeltastatus==1
                if BackboneNoPD.Uu_pos > BackboneNoPD.Uy_pos+BackboneNoPD.Up_pos+BackboneNoPD.Upc_pos
                    RotationPos=[0 BackbonePD.Uy_pos BackbonePD.Umax_pos BackbonePD.Umax_pos+(BackboneNoPD.Fmax_pos-BackboneNoPD.Fres_pos)/BackboneNoPD.Kpc_pos];
                    MomentPos  =[0 BackbonePD.Fy_pos BackbonePD.Fmax_pos BackbonePD.Fres_pos                                                                   ];
                else
                    RotationPos=[0 BackbonePD.Uy_pos BackbonePD.Umax_pos BackbonePD.Uu_pos];
                    MomentPos  =[0 BackbonePD.Fy_pos     BackbonePD.Fmax_pos      (BackboneNoPD.Fmax_pos-BackboneNoPD.Kpc_pos*(BackbonePD.Uu_pos-BackbonePD.Umax_pos))-P*BackboneNoPD.Uu_pos/H];
                end
                plot(app.axes1,RotationPos,MomentPos,'--b','linewidth',2);
            
                if BackboneNoPD.Uu_neg > BackboneNoPD.Uy_neg+BackboneNoPD.Up_neg+BackboneNoPD.Upc_neg
                    RotationNeg=[0 BackbonePD.Uy_neg BackbonePD.Umax_neg BackbonePD.Umax_neg+(BackboneNoPD.Fmax_neg-BackboneNoPD.Fres_neg)/BackboneNoPD.Kpc_neg ];
                    MomentNeg  =[0 BackbonePD.Fy_neg     BackbonePD.Fmax_neg      BackbonePD.Fres_neg                                                                           ];
                else
                    RotationNeg=[0 BackbonePD.Uy_neg BackbonePD.Umax_neg BackbonePD.Uu_neg];
                    MomentNeg  =[0 BackbonePD.Fy_neg     BackbonePD.Fmax_neg      (BackboneNoPD.Fmax_neg-BackboneNoPD.Kpc_neg*(BackboneNoPD.Uu_neg-BackboneNoPD.Umax_neg))-P*BackboneNoPD.Uu_neg/H];
                end
                plot(app.axes1,-RotationNeg,-MomentNeg,'--b','linewidth',2);
            end
            
            %% Plot Sample Hysteretic Behavior
            Backbone=BackbonePD;
            load('TestDispHistory.mat')
            
            Scale=1.5*Backbone.Umax_pos;
            A=A*Scale;
            
            fi_1=0;
            ui_1=0;
            
            force = [];
            disp =[];
            
            for i=2: size(A,1)
                ui=A(i,1);
            
                if i==2
                    [Energy,Backbone_j_1,Flag, u0, du_i_1] = IMKPeakOriented_IntializeFun(Backbone);
                end
                % main function
                [fi,ki, Energy,Backbone_j_1,Flag,u0, du_i_1] = IMKPeakOriented_MainFun(Backbone,Energy,Backbone_j_1,Flag,u0,ui, ui_1, du_i_1,fi_1);
        
                force=[force;fi];
                disp=[disp;ui];
            
                fi_1=fi;
                ui_1=ui;
            end
            
            plot(app.axes1,disp,force,'-r');
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_SystemModel_IMKPeakOriented_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option','Ko','BackboneNoPD','BackbonePD','Backbone','M','g','Units')
            cd (MainDirectory)
                        
            app.edit59.Value=Ko;
            app.edit59.Enable='off';
            
            dy= app.edit4.Value*M*g / Ko;
            app.edit64.Value=dy;
            app.edit64.Enable='off';
            
            app.checkbox1.Value=1;
            checkbox1_Callback(app);
            
            if Units==1
                app.text29.Text='m';
                app.text35.Text='kN/m';
            else
                app.text29.Text='in';
                app.text35.Text='kip/in';
            end

            if SystemModelstatus==1 && SystemModel_Option==5
               app.edit1.Value=BackboneNoPD.Up_pos/BackboneNoPD.Uy_pos;
               app.edit2.Value=BackboneNoPD.Upc_pos/BackboneNoPD.Uy_pos;
               app.edit3.Value=BackboneNoPD.Uu_pos/BackboneNoPD.Uy_pos;
               app.edit4.Value=BackboneNoPD.Fy_pos/M/g;
               app.edit5.Value=BackboneNoPD.FmaxFy_pos;
               app.edit6.Value=BackboneNoPD.FresFy_pos;
            
               app.edit7.Value=BackboneNoPD.Up_neg/BackboneNoPD.Uy_neg;
               app.edit8.Value=BackboneNoPD.Upc_neg/BackboneNoPD.Uy_neg;
               app.edit9.Value=BackboneNoPD.Uu_neg/BackboneNoPD.Uy_neg;
               app.edit10.Value=BackboneNoPD.Fy_neg/M/g;
               app.edit11.Value=BackboneNoPD.FmaxFy_neg;
               app.edit12.Value=BackboneNoPD.FresFy_neg;
            
               app.edit13.Value=BackboneNoPD.Lambda_S;
               app.edit14.Value=BackboneNoPD.Lambda_C;
               app.edit15.Value=BackboneNoPD.Lambda_K;
               app.edit60.Value=BackboneNoPD.Lambda_A;
               app.edit19.Value=BackboneNoPD.c_S;
               app.edit17.Value=BackboneNoPD.c_C;
               app.edit18.Value=BackboneNoPD.c_K;
               app.edit61.Value=BackboneNoPD.c_A;
            
               app.edit16.Value=BackboneNoPD.D_pos;
               app.edit20.Value=BackboneNoPD.D_neg;
            
            else
                app.edit4.Value=0.15;
                app.edit10.Value=0.15;
            end
            
            app.edit62.Value=1.0;
            app.edit63.Value=1.0;
            app.edit62.Enable='off';
            app.edit63.Enable='off';
            
            PlotButton_Callback(app)

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Runstatus','Analysis_Option')
            cd (MainDirectory)
            
            if Runstatus==1
                waitfor(DeleteResultsMessage_App);
            end
            
            cd (ProjectPath)
            load(ProjectName,'DeleteResults')
            cd (MainDirectory)
            
            if Runstatus==1 && DeleteResults==0
                return;
            elseif Runstatus==1 && DeleteResults==1
                if Analysis_Option==1
                    rmvar(ProjectPath,ProjectName, 'RESULTS')
                elseif Analysis_Option==2
                    rmvar(ProjectPath,ProjectName, 'RESULTS','IDA')
                elseif Analysis_Option==3
                    rmvar(ProjectPath,ProjectName, 'RESULTS','RSA')
                elseif Analysis_Option==4
            
                end
                Runstatus=0;
                DeleteResults=1;
            
                cd (ProjectPath)
                save(ProjectName,'Runstatus','DeleteResults','-append')
                cd (MainDirectory)
            
                app.MainApp.ResultsButton.Enable='off';
                app.MainApp.SaveButton.Enable='off';
            end
            
            
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option','Ko','BackboneNoPD','BackbonePD','Backbone','M','g','delta_SystemFail','delta_PDeltaFail','Units','P','H','PDeltastatus','GMstatus')
            cd (MainDirectory)
            
            clear BackboneNoPD BackbonePD Backbone
            
            [BackboneNoPD,BackbonePD]=Get_Backbone (app);
            
            delta_SystemFail_pos=BackbonePD.Uu_pos;
            delta_SystemFail_neg=BackbonePD.Uu_neg;
            
            if BackbonePD.Fres_pos <= 0.0
                delta_PDeltaFail_pos = BackbonePD.Uy_pos + BackbonePD.Up_pos + BackbonePD.Fmax_pos/BackbonePD.Kpc_pos;
            else
                delta_PDeltaFail_pos = BackbonePD.Uu_pos;
            end
            
            if BackbonePD.Fres_neg <= 0.0
                delta_PDeltaFail_neg = BackbonePD.Uy_neg + BackbonePD.Up_neg + BackbonePD.Fmax_neg/BackbonePD.Kpc_neg;
            else
                delta_PDeltaFail_neg= BackbonePD.Uu_neg;
            end
            
            %% INPUT VALUES CHECKS BEFORE SUBMISSION
            if BackboneNoPD.Up_pos<0 || BackboneNoPD.Upc_pos<0 || BackboneNoPD.Uu_pos<0 || BackboneNoPD.Fy_pos<0 || BackboneNoPD.FmaxFy_pos<0 || BackboneNoPD.FresFy_pos<0 || ...
               BackboneNoPD.Up_neg<0 || BackboneNoPD.Upc_neg<0 || BackboneNoPD.Uu_neg<0 || BackboneNoPD.Fy_neg<0 || BackboneNoPD.FmaxFy_neg<0 || BackboneNoPD.FresFy_neg<0
                ed = errordlg('All Backbone Parameters Must Be Positive!','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            if BackboneNoPD.Lambda_S<0 || BackboneNoPD.Lambda_C<0 || BackboneNoPD.Lambda_K<0 || BackboneNoPD.Lambda_A<0 || BackboneNoPD.c_S<0 || BackboneNoPD.c_C<0 || BackboneNoPD.c_K<0 || BackboneNoPD.c_A<0 || BackboneNoPD.D_pos<0 || BackboneNoPD.D_neg<0 || BackboneNoPD.kappaF<0 || BackboneNoPD.kappaD<0
                ed = errordlg('All Deterioration Parameters Must Be Positive!','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            if BackboneNoPD.Fy_pos*(BackboneNoPD.FmaxFy_pos-1)/BackboneNoPD.Up_pos> BackboneNoPD.Ke || BackboneNoPD.Fy_neg*(BackboneNoPD.FmaxFy_neg-1)/BackboneNoPD.Up_neg> BackboneNoPD.Ke
                ed = errordlg('Post-yielding stiffness cannot be LARGER THAN the elastic stiffness','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            if BackboneNoPD.D_pos > 1 || BackboneNoPD.D_neg > 1
                ed = errordlg('The assymetric rates of cyclic deterioratiom, D+ and D-, cannot be LARGER THAN 1.0','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            if BackboneNoPD.kappaF > 1 || BackboneNoPD.kappaD > 1
                ed = errordlg('The pinching point force and displacement ratios, kF and kd, cannot be LARGER THAN 1.0','Error', 'modal');
                waitfor(ed);
                return;
            end
            
            %%
            
            SystemModelstatus=1;
            SystemModel_Option=5;
            
            if app.checkbox1.Value==1
                SymmetricBackbone=1;
            else
                SymmetricBackbone=0;
            end
            
            cd (ProjectPath)
            save(ProjectName,'SystemModelstatus','SystemModel_Option','SymmetricBackbone','BackboneNoPD','BackbonePD', 'delta_SystemFail_pos','delta_SystemFail_neg','delta_PDeltaFail_pos','delta_PDeltaFail_neg','-append')
            cd (MainDirectory)
            
            app.MainApp.status2.BackgroundColor='g';
            app.MainApp.status2.FontColor='k';
            app.MainApp.status2.Text='System Model: IMK P-Oriented';
            app.MainApp.status2b.BackgroundColor='g';
            if GMstatus~=1
                app.MainApp.GMButton.Enable='on';
                app.MainApp.status3.BackgroundColor='r';
                app.MainApp.status3b.BackgroundColor='r';
            end
           
            app.MainApp.Image5.Visible='off';
            closereq
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            if app.checkbox1.Value==1
                app.edit7.Enable='off';
                app.edit8.Enable='off';
                app.edit9.Enable='off';
                app.edit10.Enable='off';
                app.edit11.Enable='off';
                app.edit12.Enable='off';
                edit1_Callback(app)
                edit2_Callback(app)
                edit3_Callback(app)
                edit4_Callback(app)
                edit5_Callback(app)
                edit6_Callback(app)
            else
                app.edit7.Enable='on';
                app.edit8.Enable='on';
                app.edit9.Enable='on';
                app.edit10.Enable='on';
                app.edit11.Enable='on';
                app.edit12.Enable='on';
            end
        end

        % Value changed function: edit10
        function edit10_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit11
        function edit11_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit12
        function edit12_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit13
        function edit13_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit14
        function edit14_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit15
        function edit15_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit16
        function edit16_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit17
        function edit17_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit18
        function edit18_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit19
        function edit19_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            Up_pos =app.edit1.Value;
            if app.checkbox1.Value==1
                app.edit7.Value=Up_pos;
            end
            PlotButton_Callback(app)
        end

        % Value changed function: edit20
        function edit20_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            Upc_pos =app.edit2.Value;
            if app.checkbox1.Value==1
                app.edit8.Value=Upc_pos;
            end
            PlotButton_Callback(app)
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            Uu_pos =app.edit3.Value;
            if app.checkbox1.Value==1
                app.edit9.Value=Uu_pos;
            end
            PlotButton_Callback(app)
        end

        % Value changed function: edit4
        function edit4_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'M','g','Ko')
            pause (0.5)
            cd (MainDirectory)
            Fy_pos =app.edit4.Value;
            if app.checkbox1.Value==1
                app.edit10.Value=Fy_pos;
            end
            Fy_pos  = app.edit4.Value*M*g;
            app.edit64.Value=Fy_pos/Ko;
            PlotButton_Callback(app)
        end

        % Value changed function: edit59
        function edit59_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit5
        function edit5_Callback(app, event)
            FmaxFy_pos =app.edit5.Value;
            if app.checkbox1.Value==1
                app.edit11.Value=FmaxFy_pos;
            end
            PlotButton_Callback(app)
        end

        % Value changed function: edit60
        function edit60_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit61
        function edit61_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit6
        function edit6_Callback(app, event)
            Fres_pos =app.edit6.Value;
            if app.checkbox1.Value==1
                app.edit12.Value=Fres_pos;
            end
            PlotButton_Callback(app)
        end

        % Value changed function: edit7
        function edit7_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit8
        function edit8_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit9
        function edit9_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'IMK-Peak Oriented Model';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Deterioration Parameters';
            app.text17 = uilabel(app.uipanel6);
            app.text17.Tooltip = 'Controls the assymetric rates of cyclci deterioration in +/- directions. D < 1.0';
            app.text17.Text = 'D+';
            app.text18 = uilabel(app.uipanel6);
            app.text18.Tooltip = 'Controls the assymetric rates of cyclci deterioration in +/- directions. D < 1.0';
            app.text18.Text = 'D-';
            app.text19 = uilabel(app.uipanel6);
            app.text19.Text = 'Basic Strength';
            app.text20 = uilabel(app.uipanel6);
            app.text20.Text = 'PostCap Strength';
            app.text21 = uilabel(app.uipanel6);
            app.text21.Text = 'Unload Stiffness';
            app.text33 = uilabel(app.uipanel6);
            app.text33.Tooltip = 'Controls the assymetric rates of cyclci deterioration in +/- directions. D < 1.0';
            app.text33.Text = 'Assymetric Rate';
            app.text36 = uilabel(app.uipanel6);
            app.text36.Text = 'Acc. Stiffness';
            app.cLabel = uilabel(app.uipanel6);
            app.cLabel.Text = 'c';
            app.Label_6 = uilabel(app.uipanel6);
            app.Label_6.Text = 'λ';
            app.edit13 = uieditfield(app.uipanel6, 'numeric');
            app.edit13.Limits = [0 Inf];
            app.edit13.ValueChangedFcn = createCallbackFcn(app, @edit13_Callback, true);
            app.edit13.Value = 50;
            app.edit14 = uieditfield(app.uipanel6, 'numeric');
            app.edit14.Limits = [0 Inf];
            app.edit14.ValueChangedFcn = createCallbackFcn(app, @edit14_Callback, true);
            app.edit14.Value = 50;
            app.edit15 = uieditfield(app.uipanel6, 'numeric');
            app.edit15.Limits = [0 Inf];
            app.edit15.ValueChangedFcn = createCallbackFcn(app, @edit15_Callback, true);
            app.edit15.Value = 50;
            app.edit60 = uieditfield(app.uipanel6, 'numeric');
            app.edit60.Limits = [0 Inf];
            app.edit60.ValueChangedFcn = createCallbackFcn(app, @edit60_Callback, true);
            app.edit60.Value = 50;
            app.edit17 = uieditfield(app.uipanel6, 'numeric');
            app.edit17.Limits = [0 Inf];
            app.edit17.ValueDisplayFormat = '%.1f';
            app.edit17.ValueChangedFcn = createCallbackFcn(app, @edit17_Callback, true);
            app.edit17.Value = 1;
            app.edit18 = uieditfield(app.uipanel6, 'numeric');
            app.edit18.Limits = [0 Inf];
            app.edit18.ValueDisplayFormat = '%.1f';
            app.edit18.ValueChangedFcn = createCallbackFcn(app, @edit18_Callback, true);
            app.edit18.Value = 1;
            app.edit19 = uieditfield(app.uipanel6, 'numeric');
            app.edit19.Limits = [0 Inf];
            app.edit19.ValueDisplayFormat = '%.1f';
            app.edit19.ValueChangedFcn = createCallbackFcn(app, @edit19_Callback, true);
            app.edit19.Value = 1;
            app.edit61 = uieditfield(app.uipanel6, 'numeric');
            app.edit61.Limits = [0 Inf];
            app.edit61.ValueDisplayFormat = '%.1f';
            app.edit61.ValueChangedFcn = createCallbackFcn(app, @edit61_Callback, true);
            app.edit61.Value = 1;
            app.edit16 = uieditfield(app.uipanel6, 'numeric');
            app.edit16.Limits = [0.001 1];
            app.edit16.ValueDisplayFormat = '%.1f';
            app.edit16.ValueChangedFcn = createCallbackFcn(app, @edit16_Callback, true);
            app.edit16.Value = 1;
            app.edit20 = uieditfield(app.uipanel6, 'numeric');
            app.edit20.Limits = [0.001 1];
            app.edit20.ValueDisplayFormat = '%.1f';
            app.edit20.ValueChangedFcn = createCallbackFcn(app, @edit20_Callback, true);
            app.edit20.Value = 1;
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Backbone Parameters';
            app.uipanel4 = uipanel(app.uipanel7);
            app.uipanel4.Title = 'Negative';
            app.edit7 = uieditfield(app.uipanel4, 'numeric');
            app.edit7.Limits = [0 Inf];
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit7.Value = 3;
            app.edit8 = uieditfield(app.uipanel4, 'numeric');
            app.edit8.Limits = [0 Inf];
            app.edit8.ValueChangedFcn = createCallbackFcn(app, @edit8_Callback, true);
            app.edit8.Value = 10;
            app.edit9 = uieditfield(app.uipanel4, 'numeric');
            app.edit9.Limits = [0 Inf];
            app.edit9.ValueChangedFcn = createCallbackFcn(app, @edit9_Callback, true);
            app.edit9.Value = 20;
            app.edit10 = uieditfield(app.uipanel4, 'numeric');
            app.edit10.Limits = [0 Inf];
            app.edit10.ValueChangedFcn = createCallbackFcn(app, @edit10_Callback, true);
            app.edit10.Value = 0.15;
            app.edit11 = uieditfield(app.uipanel4, 'numeric');
            app.edit11.Limits = [0 Inf];
            app.edit11.ValueChangedFcn = createCallbackFcn(app, @edit11_Callback, true);
            app.edit11.Value = 1.05;
            app.edit12 = uieditfield(app.uipanel4, 'numeric');
            app.edit12.Limits = [0 Inf];
            app.edit12.ValueChangedFcn = createCallbackFcn(app, @edit12_Callback, true);
            app.edit12.Value = 0.2;
            app.uipanel2 = uipanel(app.uipanel7);
            app.uipanel2.Title = 'Positive';
            app.edit1 = uieditfield(app.uipanel2, 'numeric');
            app.edit1.Limits = [0 Inf];
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Value = 3;
            app.edit2 = uieditfield(app.uipanel2, 'numeric');
            app.edit2.Limits = [0 Inf];
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Value = 10;
            app.edit3 = uieditfield(app.uipanel2, 'numeric');
            app.edit3.Limits = [0 Inf];
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Value = 20;
            app.edit4 = uieditfield(app.uipanel2, 'numeric');
            app.edit4.Limits = [0 Inf];
            app.edit4.ValueChangedFcn = createCallbackFcn(app, @edit4_Callback, true);
            app.edit4.Value = 0.15;
            app.edit5 = uieditfield(app.uipanel2, 'numeric');
            app.edit5.Limits = [0 Inf];
            app.edit5.ValueChangedFcn = createCallbackFcn(app, @edit5_Callback, true);
            app.edit5.Value = 1.05;
            app.edit6 = uieditfield(app.uipanel2, 'numeric');
            app.edit6.Limits = [0 Inf];
            app.edit6.ValueChangedFcn = createCallbackFcn(app, @edit6_Callback, true);
            app.edit6.Value = 0.2;
            app.Label_2 = uilabel(app.uipanel7);
            app.Label_2.Text = 'δₚ / δᵧ';
            app.Label_3 = uilabel(app.uipanel7);
            app.Label_3.Text = 'δᴘᴄ / δᵧ';
            app.Label_4 = uilabel(app.uipanel7);
            app.Label_4.Text = 'δᵤ / δᵧ';
            app.FWLabel = uilabel(app.uipanel7);
            app.FWLabel.Text = 'Fᵧ / W';
            app.FFLabel = uilabel(app.uipanel7);
            app.FFLabel.Text = 'Fₘₐₓ / Fᵧ';
            app.FFLabel_2 = uilabel(app.uipanel7);
            app.FFLabel_2.Text = 'Fᵣₑₛ / Fᵧ';
            app.KLabel = uilabel(app.uipanel7);
            app.KLabel.Text = 'Kₑ';
            app.edit59 = uieditfield(app.uipanel7, 'numeric');
            app.edit59.Limits = [0 Inf];
            app.edit59.ValueDisplayFormat = '%.3f';
            app.edit59.ValueChangedFcn = createCallbackFcn(app, @edit59_Callback, true);
            app.edit59.Enable = 'off';
            app.edit59.Value = 0.02;
            app.text35 = uilabel(app.uipanel7);
            app.text35.Text = 'kN/m';
            app.Label_7 = uilabel(app.uipanel7);
            app.Label_7.Text = 'δᵧ';
            app.edit64 = uieditfield(app.uipanel7, 'numeric');
            app.edit64.Limits = [0 Inf];
            app.edit64.ValueDisplayFormat = '%.3f';
            app.edit64.Enable = 'off';
            app.edit64.Value = 0.02;
            app.text29 = uilabel(app.uipanel7);
            app.text29.Text = 'm';
            app.checkbox1 = uicheckbox(app.uipanel7);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'Symmetric Backbone';
            app.FLabel = uilabel(app.uipanel7);
            app.FLabel.Text = 'κF';
            app.edit62 = uieditfield(app.uipanel7, 'numeric');
            app.edit62.Limits = [0 Inf];
            app.edit62.ValueDisplayFormat = '%5.2f';
            app.edit62.Value = 0.2;
            app.Label_8 = uilabel(app.uipanel7);
            app.Label_8.Text = 'κδ';
            app.edit63 = uieditfield(app.uipanel7, 'numeric');
            app.edit63.Limits = [0 Inf];
            app.edit63.ValueDisplayFormat = '%5.2f';
            app.edit63.Value = 0.4;
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.axes1 = uiaxes(app.figure1);
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'axes1figure.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_SystemModel_IMKPeakOriented_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_SystemModel_IMKPeakOriented_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end