classdef Module_SystemModel_IMKSmooth_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel7      matlab.ui.container.Panel
        uipanel4      matlab.ui.container.Panel
        edit12        matlab.ui.control.NumericEditField
        edit11        matlab.ui.control.NumericEditField
        edit10        matlab.ui.control.NumericEditField
        edit9         matlab.ui.control.NumericEditField
        edit8         matlab.ui.control.NumericEditField
        edit7         matlab.ui.control.NumericEditField
        uipanel2      matlab.ui.container.Panel
        edit6         matlab.ui.control.NumericEditField
        edit5         matlab.ui.control.NumericEditField
        edit4         matlab.ui.control.NumericEditField
        edit3         matlab.ui.control.NumericEditField
        edit2         matlab.ui.control.NumericEditField
        edit1         matlab.ui.control.NumericEditField
        checkbox1     matlab.ui.control.CheckBox
        Label_2       matlab.ui.control.Label
        Label_3       matlab.ui.control.Label
        Label_4       matlab.ui.control.Label
        FWLabel_2     matlab.ui.control.Label
        FFLabel_2     matlab.ui.control.Label
        FFLabel       matlab.ui.control.Label
        text29        matlab.ui.control.Label
        text35        matlab.ui.control.Label
        edit60        matlab.ui.control.NumericEditField
        edit59        matlab.ui.control.NumericEditField
        KLabel        matlab.ui.control.Label
        Label_9       matlab.ui.control.Label
        uipanel6      matlab.ui.container.Panel
        text17        matlab.ui.control.Label
        text18        matlab.ui.control.Label
        text19        matlab.ui.control.Label
        text20        matlab.ui.control.Label
        text21        matlab.ui.control.Label
        text33        matlab.ui.control.Label
        cLabel_2      matlab.ui.control.Label
        Label_8       matlab.ui.control.Label
        edit13        matlab.ui.control.NumericEditField
        edit14        matlab.ui.control.NumericEditField
        edit15        matlab.ui.control.NumericEditField
        edit17        matlab.ui.control.NumericEditField
        edit18        matlab.ui.control.NumericEditField
        edit24        matlab.ui.control.NumericEditField
        edit23        matlab.ui.control.NumericEditField
        edit22        matlab.ui.control.NumericEditField
        edit21        matlab.ui.control.NumericEditField
        checkbox2     matlab.ui.control.CheckBox
        edit19        matlab.ui.control.NumericEditField
        edit16        matlab.ui.control.NumericEditField
        edit20        matlab.ui.control.NumericEditField
        uipanel5      matlab.ui.container.Panel
        nLabel        matlab.ui.control.Label
        cLabel        matlab.ui.control.Label
        sLabel        matlab.ui.control.Label
        Label_7       matlab.ui.control.Label
        axes1         matlab.ui.control.UIAxes
        SubmitButton  matlab.ui.control.Button
        Image         matlab.ui.control.Image
    end

    
    properties (Access = private)
        MainApp % Description
    end
    
    methods (Access = private)
        function [BackboneNoPD, BackbonePD] = Get_Backbone(app)
                global MainDirectory ProjectPath ProjectName
                clc;
                cd (ProjectPath)
                load(ProjectName,'Ko','BackboneNoPD','BackbonePD','Backbone','M','g','delta_SystemFail','delta_PDeltaFail','P','H','PDeltastatus')
                cd (MainDirectory)
            
                BackboneNoPD.Ke =Ko;
                BackboneNoPD.Fy_pos0     = app.edit4.Value*M*g;
                BackboneNoPD.Fy_neg0     = app.edit10.Value*M*g;
                BackboneNoPD.Uy_pos0     = BackboneNoPD.Fy_pos0 / BackboneNoPD.Ke;
                BackboneNoPD.Uy_neg0     = BackboneNoPD.Fy_neg0 / BackboneNoPD.Ke;
            
                BackboneNoPD.Up_pos0 =app.edit1.Value*BackboneNoPD.Uy_pos0;
                BackboneNoPD.Upc_pos0=app.edit2.Value*BackboneNoPD.Uy_pos0;
                BackboneNoPD.Uu_pos0 =app.edit3.Value*BackboneNoPD.Uy_pos0;
                BackboneNoPD.FmaxFy_pos0 =app.edit5.Value;
                BackboneNoPD.FresFy_pos0 =app.edit6.Value;
            
                BackboneNoPD.Up_neg0 =app.edit7.Value*BackboneNoPD.Uy_neg0;
                BackboneNoPD.Upc_neg0=app.edit8.Value*BackboneNoPD.Uy_neg0;
                BackboneNoPD.Uu_neg0 =app.edit9.Value*BackboneNoPD.Uy_neg0;
                BackboneNoPD.FmaxFy_neg0 =app.edit11.Value;
                BackboneNoPD.FresFy_neg0 =app.edit12.Value;
            
                BackboneNoPD.LAMBDA_S =app.edit13.Value*BackboneNoPD.Up_pos0;
                BackboneNoPD.LAMBDA_C =app.edit14.Value*BackboneNoPD.Up_pos0;
                BackboneNoPD.LAMBDA_K =app.edit15.Value*BackboneNoPD.Up_pos0;
                BackboneNoPD.c_S      =app.edit17.Value;
                BackboneNoPD.c_C      =app.edit18.Value;
                BackboneNoPD.c_K      =app.edit19.Value;
            
                BackboneNoPD.D_pos =app.edit16.Value;
                BackboneNoPD.D_neg =app.edit20.Value;
            
                if app.checkbox2.Value==1
                    BackboneNoPD.Smooth   =1;
                    BackboneNoPD.n        =app.edit21.Value;
                    BackboneNoPD.Roffset  =app.edit22.Value;
                    BackboneNoPD.LAMBDA_F =app.edit23.Value*BackboneNoPD.Up_pos0;
                    BackboneNoPD.c_F      =app.edit24.Value;
                else
                    BackboneNoPD.Smooth   =0;
                    BackboneNoPD.n        =0.0;
                    BackboneNoPD.Roffset  =0.0;
                    BackboneNoPD.LAMBDA_F =10*BackboneNoPD.Up_pos0;
                    BackboneNoPD.c_F      =1;
                end
            
                if isnan(BackboneNoPD.Up_pos0) || isnan(BackboneNoPD.Upc_pos0) || isnan(BackboneNoPD.Uu_pos0) || isnan(BackboneNoPD.Fy_pos0) || isnan(BackboneNoPD.FmaxFy_pos0) || isnan(BackboneNoPD.FresFy_pos0) || ...
                   isnan(BackboneNoPD.Up_neg0) || isnan(BackboneNoPD.Upc_neg0) || isnan(BackboneNoPD.Uu_neg0) || isnan(BackboneNoPD.Fy_neg0) || isnan(BackboneNoPD.FmaxFy_neg0) || isnan(BackboneNoPD.FresFy_neg0) || ...
                   isnan(BackboneNoPD.LAMBDA_S) || isnan(BackboneNoPD.LAMBDA_C) || isnan(BackboneNoPD.LAMBDA_K) || isnan(BackboneNoPD.c_S) || isnan(BackboneNoPD.c_C) || isnan(BackboneNoPD.c_K) || ...
                   isnan(BackboneNoPD.D_pos) || isnan(BackboneNoPD.D_neg) || isnan(BackboneNoPD.n) || isnan(BackboneNoPD.Roffset) || isnan(BackboneNoPD.LAMBDA_F) || isnan(BackboneNoPD.c_F)
                    ed = errordlg('Some paramters are not defined. Please define all the required paramters.','Error', 'modal');
                    uiwait(ed);
                    return;
                end
            
                %BackboneNoPD.Uy_pos0=BackboneNoPD.Fy_pos0/BackboneNoPD.Ke;
                BackboneNoPD.Umax_pos0 = BackboneNoPD.Uy_pos0+BackboneNoPD.Up_pos0;
                BackboneNoPD.Fmax_pos0=BackboneNoPD.FmaxFy_pos0*BackboneNoPD.Fy_pos0;
                BackboneNoPD.Fres_pos0=BackboneNoPD.FresFy_pos0*BackboneNoPD.Fy_pos0;
                BackboneNoPD.Kpc_pos0 = BackboneNoPD.Fmax_pos0/(BackboneNoPD.Upc_pos0);
                BackboneNoPD.Ures_pos0=BackboneNoPD.Uy_pos0+BackboneNoPD.Up_pos0+(BackboneNoPD.Fmax_pos0-BackboneNoPD.Fres_pos0)/BackboneNoPD.Kpc_pos0;
            
                %BackboneNoPD.Uy_neg0=BackboneNoPD.Fy_neg0/BackboneNoPD.Ke;
                BackboneNoPD.Umax_neg0 = BackboneNoPD.Uy_neg0+BackboneNoPD.Up_neg0;
                BackboneNoPD.Fmax_neg0=BackboneNoPD.FmaxFy_neg0*BackboneNoPD.Fy_neg0;
                BackboneNoPD.Fres_neg0=BackboneNoPD.FresFy_neg0*BackboneNoPD.Fy_neg0;
                BackboneNoPD.Kpc_neg0 = BackboneNoPD.Fmax_neg0/(BackboneNoPD.Upc_neg0);
                BackboneNoPD.Ures_neg0=BackboneNoPD.Uy_neg0+BackboneNoPD.Up_neg0+(BackboneNoPD.Fmax_neg0-BackboneNoPD.Fres_neg0)/BackboneNoPD.Kpc_neg0;
            
                % Deduce Backbone with P-Delta
                % Modify the strength paramters of the backbone curve to account for P-Delta
                BackbonePD.Fy_pos0     =BackboneNoPD.Fy_pos0-(P*BackboneNoPD.Uy_pos0/H);
                BackbonePD.Fmax_pos0   =BackboneNoPD.Fmax_pos0-P*(BackboneNoPD.Uy_pos0+BackboneNoPD.Up_pos0)/H;
                BackbonePD.FmaxFy_pos0 =BackbonePD.Fmax_pos0/BackbonePD.Fy_pos0;
                BackbonePD.Fres_pos0   =BackboneNoPD.Fres_pos0-(P*(BackboneNoPD.Uy_pos0+BackboneNoPD.Up_pos0+(BackboneNoPD.Fmax_pos0-BackboneNoPD.Fres_pos0)/BackboneNoPD.Kpc_pos0)/H);
                BackbonePD.FresFy_pos0 =BackbonePD.Fres_pos0/BackbonePD.Fy_pos0;
                if BackbonePD.FresFy_pos0<0.0; BackbonePD.FresFy_pos0=0; end
            
                BackbonePD.Fy_neg0     =BackboneNoPD.Fy_neg0-(P*BackboneNoPD.Uy_pos0/H);
                BackbonePD.Fmax_neg0    =BackboneNoPD.Fmax_neg0-P*(BackboneNoPD.Uy_neg0+BackboneNoPD.Up_neg0)/H;
                BackbonePD.FmaxFy_neg0 =BackbonePD.Fmax_neg0/BackbonePD.Fy_neg0;
                BackbonePD.Fres_neg0=BackboneNoPD.Fres_neg0-(P*(BackboneNoPD.Uy_neg0+BackboneNoPD.Up_neg0+(BackboneNoPD.Fmax_neg0-BackboneNoPD.Fres_neg0)/BackboneNoPD.Kpc_neg0)/H);
                BackbonePD.FresFy_neg0 =BackbonePD.Fres_neg0/BackbonePD.Fy_neg0;
                if BackbonePD.FresFy_neg0<0.0; BackbonePD.FresFy_neg0=0; end
            
                BackbonePD.Ke =BackbonePD.Fy_pos0/BackboneNoPD.Uy_pos0;
            
                % The rest of the parameters remain the same
                BackbonePD.Uy_pos0 =BackboneNoPD.Uy_pos0;
                BackbonePD.Up_pos0 =BackboneNoPD.Up_pos0;
                BackbonePD.Upc_pos0=BackboneNoPD.Upc_pos0;
                BackbonePD.Uu_pos0 =BackboneNoPD.Uu_pos0;
                BackbonePD.Uy_pos0=BackboneNoPD.Uy_pos0;
                BackbonePD.Umax_pos0 = BackboneNoPD.Umax_pos0;
            
                BackbonePD.Uy_neg0 =BackboneNoPD.Uy_neg0;
                BackbonePD.Up_neg0 =BackboneNoPD.Up_neg0;
                BackbonePD.Upc_neg0=BackboneNoPD.Upc_neg0;
                BackbonePD.Uu_neg0 =BackboneNoPD.Uu_neg0;
                BackbonePD.Uy_neg0=BackboneNoPD.Uy_neg0;
                BackbonePD.Umax_neg0 = BackboneNoPD.Umax_neg0;
            
                BackbonePD.LAMBDA_S =BackboneNoPD.LAMBDA_S;
                BackbonePD.LAMBDA_C =BackboneNoPD.LAMBDA_C;
                BackbonePD.LAMBDA_K =BackboneNoPD.LAMBDA_K;
                BackbonePD.c_S      =BackboneNoPD.c_S;
                BackbonePD.c_C      =BackboneNoPD.c_C;
                BackbonePD.c_K      =BackboneNoPD.c_K;
            
                BackbonePD.D_pos =BackboneNoPD.D_pos;
                BackbonePD.D_neg =BackboneNoPD.D_neg;
            
                BackbonePD.Smooth=BackboneNoPD.Smooth;
                BackbonePD.n        =BackboneNoPD.n;
                BackbonePD.Roffset  =BackboneNoPD.Roffset;
                BackbonePD.LAMBDA_F =BackboneNoPD.LAMBDA_F;
                BackbonePD.c_F      =BackboneNoPD.c_F;
            
                BackbonePD.Ures_pos0=BackboneNoPD.Ures_pos0-BackboneNoPD.Umax_pos0;
                BackbonePD.Ures_neg0=BackboneNoPD.Ures_neg0-BackboneNoPD.Umax_neg0;
            
                BackbonePD.Kpc_pos0 = (BackbonePD.Fmax_pos0-BackbonePD.Fres_pos0)/(BackbonePD.Ures_pos0);
                BackbonePD.Kpc_neg0 = (BackbonePD.Fmax_neg0-BackbonePD.Fres_neg0)/(BackbonePD.Ures_neg0);
        end
        
        function PlotButton_Callback(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option','Ko','BackboneNoPD','BackbonePD','Backbone','M','g','delta_SystemFail','delta_PDeltaFail','Units','P','H','PDeltastatus','GMstatus')
            cd (MainDirectory)
            
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=16;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            [BackboneNoPD,BackbonePD]=Get_Backbone (app);
            
            UFail_pos0=min(BackboneNoPD.Uu_pos0,BackboneNoPD.Uy_pos0+BackboneNoPD.Up_pos0+BackboneNoPD.Upc_pos0);
            UFail_neg0=min(BackboneNoPD.Uu_neg0,BackboneNoPD.Uy_neg0+BackboneNoPD.Up_neg0+BackboneNoPD.Upc_neg0);
            
            % Plot backbone
            if BackboneNoPD.Uu_pos0 > BackboneNoPD.Uy_pos0+BackboneNoPD.Up_pos0+BackboneNoPD.Upc_pos0
                RotationPos=[0 BackboneNoPD.Uy_pos0 BackboneNoPD.Umax_pos0 BackboneNoPD.Umax_pos0+(BackboneNoPD.Fmax_pos0-BackboneNoPD.Fres_pos0)/BackboneNoPD.Kpc_pos0 BackboneNoPD.Uu_pos0    BackboneNoPD.Uu_pos0];
                MomentPos  =[0 BackboneNoPD.Fy_pos0 BackboneNoPD.Fmax_pos0 BackboneNoPD.Fres_pos0                                                                       BackboneNoPD.Fres_pos0  0];
            else
                RotationPos=[0 BackboneNoPD.Uy_pos0 BackboneNoPD.Umax_pos0 BackboneNoPD.Uu_pos0 BackboneNoPD.Uu_pos0];
                MomentPos  =[0 BackboneNoPD.Fy_pos0     BackboneNoPD.Fmax_pos0      BackboneNoPD.Fmax_pos0-BackboneNoPD.Kpc_pos0*(BackboneNoPD.Uu_pos0-BackboneNoPD.Umax_pos0)      0];
            end
            
            if BackboneNoPD.Uu_neg0 > BackboneNoPD.Uy_neg0+BackboneNoPD.Up_neg0+BackboneNoPD.Upc_neg0
                RotationNeg=[0 BackboneNoPD.Uy_neg0 BackboneNoPD.Umax_neg0 BackboneNoPD.Umax_neg0+(BackboneNoPD.Fmax_neg0-BackboneNoPD.Fres_neg0)/BackboneNoPD.Kpc_neg0 BackboneNoPD.Uu_neg0 BackboneNoPD.Uu_neg0];
                MomentNeg=[0 BackboneNoPD.Fy_neg0 BackboneNoPD.Fmax_neg0 BackboneNoPD.Fres_neg0 BackboneNoPD.Fres_neg0 0];
            else
                RotationNeg=[0 BackboneNoPD.Uy_neg0 BackboneNoPD.Umax_neg0 BackboneNoPD.Uu_neg0 BackboneNoPD.Uu_neg0];
                MomentNeg  =[0 BackboneNoPD.Fy_neg0     BackboneNoPD.Fmax_neg0      BackboneNoPD.Fmax_neg0-BackboneNoPD.Kpc_neg0*(BackboneNoPD.Uu_neg0-BackboneNoPD.Umax_neg0)      0];
            end
            
            if PDeltastatus==1
                plot(app.axes1,[0],[0],'-k','linewidth',2);
                plot(app.axes1,[0],[0],'--b','linewidth',2);
                plot(app.axes1,[0],[0],'-r','linewidth',2);
            else
                plot(app.axes1,[0],[0],'-k','linewidth',2);
                plot(app.axes1,[0],[0],'-r','linewidth',2);
            end
            
            plot(app.axes1,RotationPos,MomentPos,'-k','linewidth',2);
            plot(app.axes1,-RotationNeg,-MomentNeg,'-k','linewidth',2);

            if Units==1
                app.axes1.XLabel.String= 'Displacement, \delta [m]';
                app.axes1.YLabel.String= 'Force [kN]';
            else
                app.axes1.XLabel.String= 'Displacement, \delta [in]';
                app.axes1.YLabel.String= 'Force [kip]';
            end
            
            % Plot origin lines
            plot(app.axes1,[0 0],[-BackboneNoPD.Fmax_neg0*1.5 BackboneNoPD.Fmax_pos0*1.5],'-k','linewidth',1);
            plot(app.axes1,[-UFail_neg0*1.5 UFail_pos0*1.5],[0 0],'-k','linewidth',1);
            app.axes1.XLim=[-UFail_neg0*1.15 UFail_pos0*1.15];
            app.axes1.YLim=[-BackboneNoPD.Fmax_neg0*1.15 BackboneNoPD.Fmax_pos0*1.15];
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
                
            % Plot backbone with P-Delta if applicable
            if PDeltastatus==1
                if BackboneNoPD.Uu_pos0 > BackboneNoPD.Uy_pos0+BackboneNoPD.Up_pos0+BackboneNoPD.Upc_pos0
                    RotationPos=[0 BackbonePD.Uy_pos0       BackbonePD.Umax_pos0        BackbonePD.Umax_pos0+(BackboneNoPD.Fmax_pos0-BackboneNoPD.Fres_pos0)/BackboneNoPD.Kpc_pos0];
                    MomentPos  =[0 BackbonePD.Fy_pos0       BackbonePD.Fmax_pos0        BackbonePD.Fres_pos0                                                                      ];
                else
                    RotationPos=[0 BackbonePD.Uy_pos0       BackbonePD.Umax_pos0        BackbonePD.Uu_pos0];
                    MomentPos  =[0 BackbonePD.Fy_pos0       BackbonePD.Fmax_pos0       (BackboneNoPD.Fmax_pos0-BackboneNoPD.Kpc_pos0*(BackboneNoPD.Uu_pos0-BackboneNoPD.Umax_pos0))-P*BackboneNoPD.Uu_pos0/H];
                end
                plot(app.axes1,RotationPos,MomentPos,'--b','linewidth',2);
            
                if BackboneNoPD.Uu_neg0 > BackboneNoPD.Uy_neg0+BackboneNoPD.Up_neg0+BackboneNoPD.Upc_neg0
                    RotationNeg=[0 BackbonePD.Uy_neg0       BackbonePD.Umax_neg0        BackbonePD.Umax_neg0+(BackboneNoPD.Fmax_neg0-BackboneNoPD.Fres_neg0)/BackboneNoPD.Kpc_neg0];
                    MomentNeg  =[0 BackbonePD.Fy_neg0       BackbonePD.Fmax_neg0        BackbonePD.Fres_neg0                                                                      ];
                else
                    RotationNeg=[0 BackbonePD.Uy_neg0       BackbonePD.Umax_neg0        BackbonePD.Uu_neg0];
                    MomentNeg  =[0 BackbonePD.Fy_neg0       BackbonePD.Fmax_neg0       (BackboneNoPD.Fmax_neg0-BackboneNoPD.Kpc_neg0*(BackboneNoPD.Uu_neg0-BackboneNoPD.Umax_neg0))-P*BackboneNoPD.Uu_neg0/H];
                end
                plot(app.axes1,-RotationNeg,-MomentNeg,'--b','linewidth',2);
            end


            %% Plot Hysteretic Behavior
            Backbone=BackbonePD;
            load('TestDispHistory.mat')
            
            Scale=1.5*Backbone.Umax_pos0;
            A=A*Scale;
            
            force = [];
            disp =[];
            
            for i=2: size(A,1)
            
                uii=A(i,1);
            
            if i==2
                [BackbonePos_Original, BackboneNeg_Original, BackbonePos_j_1, BackboneNeg_j_1, Beta_j_1, Constants, Flags, InitialValues, Smoothparamters, ui, fi, Di, Energy_Excrsni_1,Energy_Excrsn, Energy_Rev, Energy_total]=IMKSmooth_IntializeFun (Backbone);
            end
            % main function
                [uii, fii, Dii, Di , BackbonePos_j_1, BackboneNeg_j_1, Beta_j_1,  Energy_Excrsni_1,Energy_Excrsn, Energy_Rev, Energy_total, Flags, InitialValues, Smoothparamters, kii]=IMKSmooth_MainFun (uii, ui, fi, Di, BackbonePos_Original, BackboneNeg_Original, BackbonePos_j_1, BackboneNeg_j_1 , Beta_j_1, Energy_Excrsni_1,Energy_Excrsn, Energy_Rev, Energy_total, Flags, Constants, InitialValues, Smoothparamters);
            
            force=[force;fii];
            disp=[disp;uii];
            
              fi=fii;
              ui=uii;
            end
            
            plot(app.axes1,disp,force,'-r');
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_SystemModel_IMKSmooth_OpeningFcn(app, mainapp)
        
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option','SymmetricBackbone','SmoothResponse','Ko','BackboneNoPD','BackbonePD','Backbone','M','g','Units')
            cd (MainDirectory)
            
            %app.uitoggletool1.UserData=1;
            
            app.edit59.Value=Ko;
            app.edit59.Enable='off';
            
            dy = app.edit4.Value*M*g / Ko;
            app.edit60.Value=dy;
            app.edit60.Enable='off';
            
            app.checkbox1.Value=1;
            checkbox1_Callback(app);
            
            if Units==1
                app.text29.Text='m';
                app.text35.Text='kN/m';
            else
                app.text29.Text='in';
                app.text35.Text='kip/in';
            end
            
            
            %Plot Origin lines for axes3
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=16;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            plot(app.axes1,[0 0],[-1.0 1.0],'-k','linewidth',1);
            app.axes1.XLabel.String= 'Displacement, \delta [m]';
            app.axes1.YLabel.String='Force [kN]';
            % Plot origin lines
            plot(app.axes1,[-1.0 1.0],[0 0],'-k','linewidth',1);
            
            if SystemModelstatus==1 && SystemModel_Option==3
                app.edit1.Value=BackboneNoPD.Up_pos0/BackboneNoPD.Uy_pos0;
                app.edit2.Value=BackboneNoPD.Upc_pos0/BackboneNoPD.Uy_pos0;
                app.edit3.Value=BackboneNoPD.Uu_pos0/BackboneNoPD.Uy_pos0;
                app.edit4.Value=BackboneNoPD.Fy_pos0/M/g;
                app.edit5.Value=BackboneNoPD.FmaxFy_pos0;
                app.edit6.Value=BackboneNoPD.FresFy_pos0;
            
                if SymmetricBackbone==1
                    app.checkbox1.Value=1;
                    checkbox1_Callback(app)
                else
                    app.edit7.Value=BackboneNoPD.Up_neg0/BackboneNoPD.Uy_pos0;
                    app.edit8.Value=BackboneNoPD.Upc_neg0/BackboneNoPD.Uy_pos0;
                    app.edit9.Value=BackboneNoPD.Uu_neg0/BackboneNoPD.Uy_pos0;
                    app.edit10.Value=BackboneNoPD.Fy_neg0/M/g;
                    app.edit11.Value=BackboneNoPD.FmaxFy_neg0;
                    app.edit12.Value=BackboneNoPD.FresFy_neg0;
                    app.checkbox1.Value=0;
                    checkbox1_Callback(app)
                end
            
                app.edit13.Value=BackboneNoPD.LAMBDA_S/BackboneNoPD.Up_pos0;
                app.edit14.Value=BackboneNoPD.LAMBDA_C/BackboneNoPD.Up_pos0;
                app.edit15.Value=BackboneNoPD.LAMBDA_K/BackboneNoPD.Up_pos0;
                app.edit17.Value=BackboneNoPD.c_S;
                app.edit18.Value=BackboneNoPD.c_C;
                app.edit19.Value=BackboneNoPD.c_K;
            
                app.edit16.Value=BackboneNoPD.D_pos;
                app.edit20.Value=BackboneNoPD.D_neg;
                if SmoothResponse==1
                    app.checkbox2.Value=1;
                    checkbox2_Callback(app)
                    app.edit21.Value=BackboneNoPD.n;
                    app.edit22.Value=BackboneNoPD.Roffset;
                    app.edit23.Value=BackboneNoPD.LAMBDA_F/BackboneNoPD.Up_pos0;
                    app.edit24.Value=BackboneNoPD.c_F;
                else
                    app.edit21.Value=BackboneNoPD.Uy_pos0/BackboneNoPD.Up_pos0;
                    app.edit22.Value=BackboneNoPD.Up_pos0*0.5;
                    app.edit23.Value=BackboneNoPD.LAMBDA_S/BackboneNoPD.Up_pos0;
                    app.edit24.Value=BackboneNoPD.c_S;
                    app.checkbox2.Value=0;
                    checkbox2_Callback(app)
                end
            
            else
                app.edit4.Value=0.15;
                app.edit10.Value=0.15;
                app.checkbox2.Value=0;
                [BackboneNoPD,BackbonePD]=Get_Backbone (app);
                app.edit21.Value=BackboneNoPD.Uy_pos0/BackboneNoPD.Up_pos0;
                app.edit22.Value=BackboneNoPD.Up_pos0*0.5;
                app.edit23.Value=BackboneNoPD.LAMBDA_S/BackboneNoPD.Up_pos0;
                app.edit24.Value=BackboneNoPD.c_S;
                checkbox2_Callback(app)
            end
            
            PlotButton_Callback(app)

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Runstatus','Analysis_Option')
            cd (MainDirectory)
            
            if Runstatus==1
                waitfor(DeleteResultsMessage_App);
            end
            
            cd (ProjectPath)
            load(ProjectName,'DeleteResults')
            cd (MainDirectory)
            
            if Runstatus==1 && DeleteResults==0
                return;
            elseif Runstatus==1 && DeleteResults==1
                if Analysis_Option==1
                    rmvar(ProjectPath,ProjectName, 'RESULTS')
                elseif Analysis_Option==2
                    rmvar(ProjectPath,ProjectName, 'RESULTS','IDA')
                elseif Analysis_Option==3
                    rmvar(ProjectPath,ProjectName, 'RESULTS','RSA')
                elseif Analysis_Option==4
            
                end
                Runstatus=0;
                DeleteResults=1;
            
                cd (ProjectPath)
                save(ProjectName,'Runstatus','DeleteResults','-append')
                cd (MainDirectory)
            
                app.MainApp.ResultsButton.Enable='off';
                app.MainApp.SaveButton.Enable='off';
            end
            
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option','Ko','BackboneNoPD','BackbonePD','Backbone','M','g','delta_SystemFail','delta_PDeltaFail','Units','P','H','PDeltastatus','GMstatus')
            pause (0.5)
            cd (MainDirectory)
            
            clear BackboneNoPD BackbonePD Backbone
            
            [BackboneNoPD,BackbonePD]=Get_Backbone (app);
            
            delta_SystemFail_pos=BackbonePD.Uu_pos0;
            delta_SystemFail_neg=BackbonePD.Uu_neg0;
            
            if BackbonePD.Fres_pos0 <= 0.0
                delta_PDeltaFail_pos = BackbonePD.Uy_pos0 + BackbonePD.Up_pos0 + BackbonePD.Fmax_pos0/BackbonePD.Kpc_pos0;
            else
                delta_PDeltaFail_pos = BackbonePD.Uu_pos0;
            end
            
            if BackbonePD.Fres_neg0 <= 0.0
                delta_PDeltaFail_neg = BackbonePD.Uy_neg0 + BackbonePD.Up_neg0 + BackbonePD.Fmax_neg0/BackbonePD.Kpc_neg0;
            else
                delta_PDeltaFail_neg= BackbonePD.Uu_neg0;
            end
            
            %% INPUT VALUES CHECKS BEFORE SUBMISSION
            if BackboneNoPD.Up_pos0<0 || BackboneNoPD.Upc_pos0<0 || BackboneNoPD.Uu_pos0<0 || BackboneNoPD.Fy_pos0<0 || BackboneNoPD.FmaxFy_pos0<0 || BackboneNoPD.FresFy_pos0<0 || ...
               BackboneNoPD.Up_neg0<0 || BackboneNoPD.Upc_neg0<0 || BackboneNoPD.Uu_neg0<0 || BackboneNoPD.Fy_neg0<0 || BackboneNoPD.FmaxFy_neg0<0 || BackboneNoPD.FresFy_neg0<0
                ed = errordlg('All Backbone Parameters Must Be Positive!','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            if BackboneNoPD.LAMBDA_S<0 || BackboneNoPD.LAMBDA_C<0 || BackboneNoPD.LAMBDA_K<0 || BackboneNoPD.LAMBDA_F<0 || BackboneNoPD.c_S<0 || BackboneNoPD.c_C<0 || BackboneNoPD.c_K<0  || BackboneNoPD.c_F<0|| BackboneNoPD.D_pos<0 || BackboneNoPD.D_neg<0
                ed = errordlg('All Deterioration Parameters Must Be Positive!','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            if BackboneNoPD.Fy_pos0*(BackboneNoPD.FmaxFy_pos0-1)/BackboneNoPD.Up_pos0> BackboneNoPD.Ke || BackboneNoPD.Fy_neg0*(BackboneNoPD.FmaxFy_neg0-1)/BackboneNoPD.Up_neg0> BackboneNoPD.Ke
                ed = errordlg('Post-yielding stiffness cannot be LARGER THAN the elastic stiffness','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            if BackboneNoPD.D_pos < 1 || BackboneNoPD.D_neg < 1
                ed = errordlg('The assymetric rates of cyclic deterioratiom, D+ and D-, cannot be LARGER THAN 1.0','Error', 'modal');
                uiwait(ed);
                return;
            end
            
            if app.checkbox2.Value==1
                if BackboneNoPD.Roffset >  min([BackboneNoPD.Up_pos0; BackboneNoPD.Up_neg0])
                    app.edit22.Value=min([BackboneNoPD.Up_pos0; BackboneNoPD.Up_neg0]);
                    ed = errordlg('The smoothing offset displacement, doffset, cannot be LARGER THAN the pre-capping plastic displacement, dp','Error', 'modal');
                    uiwait(ed);
                    return;
                end
            
                if BackboneNoPD.n * BackboneNoPD.Roffset > min([BackboneNoPD.Uy_pos0; BackboneNoPD.Uy_neg0])
                    app.edit21.Value=min([BackboneNoPD.Uy_pos0; BackboneNoPD.Uy_neg0])/BackboneNoPD.Roffset;
                    ed = errordlg('The product of the smoothing offset factor, n, and the smoothing offset displacement, doffset, cannot be LARGER THAN the yield displacement, dy','Error', 'modal');
                    uiwait(ed);
                    return;
                end
            end
            %%
            
            SystemModelstatus=1;
            SystemModel_Option=3;
            
            if app.checkbox1.Value==1
                SymmetricBackbone=1;
            else
                SymmetricBackbone=0;
            end
            
            if app.checkbox2.Value==1
                SmoothResponse=1;
            else
                SmoothResponse=0;
            end
            
            cd (ProjectPath)
            pause (0.1)
            save(ProjectName,'SystemModelstatus','SystemModel_Option','SymmetricBackbone','SmoothResponse','BackboneNoPD','BackbonePD', 'delta_SystemFail_pos','delta_SystemFail_neg','delta_PDeltaFail_pos','delta_PDeltaFail_neg','-append')
            pause (1.0)
            cd (MainDirectory)
            
            app.MainApp.status2.BackgroundColor='g';
            app.MainApp.status2.FontColor='k';
            app.MainApp.status2.Text='System Model: IMK Bilinear';
            app.MainApp.status2b.BackgroundColor='g';
            if GMstatus~=1
                app.MainApp.GMButton.Enable='on';
                app.MainApp.status3.BackgroundColor='r';
                app.MainApp.status3b.BackgroundColor='r';
            end
            
            app.MainApp.Image5.Visible='off';
            closereq
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            if app.checkbox1.Value==1
                app.edit7.Enable='off';
                app.edit8.Enable='off';
                app.edit9.Enable='off';
                app.edit10.Enable='off';
                app.edit11.Enable='off';
                app.edit12.Enable='off';
                edit1_Callback(app)
                edit2_Callback(app)
                edit3_Callback(app)
                edit4_Callback(app)
                edit5_Callback(app)
                edit6_Callback(app)
            else
                app.edit7.Enable='on';
                app.edit8.Enable='on';
                app.edit9.Enable='on';
                app.edit10.Enable='on';
                app.edit11.Enable='on';
                app.edit12.Enable='on';
            end
        end

        % Value changed function: checkbox2
        function checkbox2_Callback(app, event)
            if app.checkbox2.Value==1
                app.edit21.Enable='on';
                app.edit22.Enable='on';
                app.edit23.Enable='on';
                app.edit24.Enable='on';
                PlotButton_Callback(app);
            else
                app.edit21.Enable='off';
                app.edit22.Enable='off';
                app.edit23.Enable='off';
                app.edit24.Enable='off';
                PlotButton_Callback(app)
            end
        end

        % Value changed function: edit10
        function edit10_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit11
        function edit11_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit12
        function edit12_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit13
        function edit13_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit14
        function edit14_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit15
        function edit15_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit16
        function edit16_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit17
        function edit17_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit18
        function edit18_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit19
        function edit19_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            Up_pos0 =app.edit1.Value;
            if app.checkbox1.Value==1
                app.edit7.Value=Up_pos0;
            end
            PlotButton_Callback(app)
        end

        % Value changed function: edit20
        function edit20_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit21
        function edit21_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit22
        function edit22_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit23
        function edit23_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit24
        function edit24_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            Upc_pos0 =app.edit2.Value;
            if app.checkbox1.Value==1
                app.edit8.Value=Upc_pos0;
            end
            PlotButton_Callback(app)
        end

        % Value changed function: edit3
        function edit3_Callback(app, event)
            Uu_pos0 =app.edit3.Value;
            if app.checkbox1.Value==1
                app.edit9.Value=Uu_pos0;
            end
            PlotButton_Callback(app)
        end

        % Value changed function: edit4
        function edit4_Callback(app, event)
            Fy_pos0 =app.edit4.Value;
            if app.checkbox1.Value==1
                app.edit10.Value=Fy_pos0;
            end
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'M','g','Ko')
            cd (MainDirectory)
            dy = app.edit4.Value*M*g / Ko;
            app.edit60.Value=dy;
            PlotButton_Callback(app)
        end

        % Value changed function: edit59
        function edit59_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit5
        function edit5_Callback(app, event)
            FmaxFy_pos0 =app.edit5.Value;
            if app.checkbox1.Value==1
                app.edit11.Value=FmaxFy_pos0;
            end
            PlotButton_Callback(app)
        end

        % Value changed function: edit6
        function edit6_Callback(app, event)
            Mres_pos0 =app.edit6.Value;
            if app.checkbox1.Value==1
                app.edit12.Value=Mres_pos0;
            end
            PlotButton_Callback(app)
        end

        % Value changed function: edit7
        function edit7_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit8
        function edit8_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Value changed function: edit9
        function edit9_Callback(app, event)
            PlotButton_Callback(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'IMK-Bilinear Model';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel7 = uipanel(app.figure1);
            app.uipanel7.Title = 'Backbone Parameters';
            app.uipanel4 = uipanel(app.uipanel7);
            app.uipanel4.Title = 'Negative';
            app.edit7 = uieditfield(app.uipanel4, 'numeric');
            app.edit7.Limits = [0 Inf];
            app.edit7.ValueChangedFcn = createCallbackFcn(app, @edit7_Callback, true);
            app.edit7.Value = 3;
            app.edit8 = uieditfield(app.uipanel4, 'numeric');
            app.edit8.Limits = [0 Inf];
            app.edit8.ValueChangedFcn = createCallbackFcn(app, @edit8_Callback, true);
            app.edit8.Value = 10;
            app.edit9 = uieditfield(app.uipanel4, 'numeric');
            app.edit9.Limits = [0 Inf];
            app.edit9.ValueChangedFcn = createCallbackFcn(app, @edit9_Callback, true);
            app.edit9.Value = 20;
            app.edit10 = uieditfield(app.uipanel4, 'numeric');
            app.edit10.Limits = [0 Inf];
            app.edit10.ValueChangedFcn = createCallbackFcn(app, @edit10_Callback, true);
            app.edit10.Value = 0.15;
            app.edit11 = uieditfield(app.uipanel4, 'numeric');
            app.edit11.Limits = [0 Inf];
            app.edit11.ValueChangedFcn = createCallbackFcn(app, @edit11_Callback, true);
            app.edit11.Value = 1.05;
            app.edit12 = uieditfield(app.uipanel4, 'numeric');
            app.edit12.Limits = [0 Inf];
            app.edit12.ValueChangedFcn = createCallbackFcn(app, @edit12_Callback, true);
            app.edit12.Value = 0.2;
            app.uipanel2 = uipanel(app.uipanel7);
            app.uipanel2.Title = 'Positive';
            app.edit1 = uieditfield(app.uipanel2, 'numeric');
            app.edit1.Limits = [0 Inf];
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Value = 3;
            app.edit2 = uieditfield(app.uipanel2, 'numeric');
            app.edit2.Limits = [0 Inf];
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Value = 10;
            app.edit3 = uieditfield(app.uipanel2, 'numeric');
            app.edit3.Limits = [0 Inf];
            app.edit3.ValueChangedFcn = createCallbackFcn(app, @edit3_Callback, true);
            app.edit3.Value = 20;
            app.edit4 = uieditfield(app.uipanel2, 'numeric');
            app.edit4.Limits = [0 Inf];
            app.edit4.ValueChangedFcn = createCallbackFcn(app, @edit4_Callback, true);
            app.edit4.Value = 0.15;
            app.edit5 = uieditfield(app.uipanel2, 'numeric');
            app.edit5.Limits = [0 Inf];
            app.edit5.ValueChangedFcn = createCallbackFcn(app, @edit5_Callback, true);
            app.edit5.Value = 1.05;
            app.edit6 = uieditfield(app.uipanel2, 'numeric');
            app.edit6.Limits = [0 Inf];
            app.edit6.ValueChangedFcn = createCallbackFcn(app, @edit6_Callback, true);
            app.edit6.Value = 0.2;
            app.checkbox1 = uicheckbox(app.uipanel7);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = 'Symmetric Backbone';
            app.Label_2 = uilabel(app.uipanel7);
            app.Label_2.Text = 'δₚ / δᵧ';
            app.Label_3 = uilabel(app.uipanel7);
            app.Label_3.Text = 'δᴘᴄ / δᵧ';
            app.Label_4 = uilabel(app.uipanel7);
            app.Label_4.Text = 'δᵤ / δᵧ';
            app.FWLabel_2 = uilabel(app.uipanel7);
            app.FWLabel_2.Text = 'Fᵧ / W';
            app.FFLabel_2 = uilabel(app.uipanel7);
            app.FFLabel_2.Text = 'Fₘₐₓ / Fᵧ';
            app.FFLabel = uilabel(app.uipanel7);
            app.FFLabel.Text = 'Fᵣₑₛ / Fᵧ';
            app.text29 = uilabel(app.uipanel7);
            app.text29.Text = 'm';
            app.text35 = uilabel(app.uipanel7);
            app.text35.Text = 'kN/m';
            app.edit60 = uieditfield(app.uipanel7, 'numeric');
            app.edit60.Limits = [0 Inf];
            app.edit60.ValueDisplayFormat = '%.3f';
            app.edit60.Enable = 'off';
            app.edit60.Value = 0.02;
            app.edit59 = uieditfield(app.uipanel7, 'numeric');
            app.edit59.Limits = [0 Inf];
            app.edit59.ValueDisplayFormat = '%.3f';
            app.edit59.ValueChangedFcn = createCallbackFcn(app, @edit59_Callback, true);
            app.edit59.Enable = 'off';
            app.edit59.Value = 0.02;
            app.KLabel = uilabel(app.uipanel7);
            app.KLabel.Text = 'Kₑ';
            app.Label_9 = uilabel(app.uipanel7);
            app.Label_9.Text = 'δᵧ';
            app.uipanel6 = uipanel(app.figure1);
            app.uipanel6.Title = 'Deterioration Parameters';
            app.text17 = uilabel(app.uipanel6);
            app.text17.Tooltip = 'Controls the assymetric rates of cyclci deterioration in +/- directions. D < 1.0';
            app.text17.Text = 'D+';
            app.text18 = uilabel(app.uipanel6);
            app.text18.Tooltip = 'Controls the assymetric rates of cyclci deterioration in +/- directions. D < 1.0';
            app.text18.Text = 'D-';
            app.text19 = uilabel(app.uipanel6);
            app.text19.Text = 'Basic Strength';
            app.text20 = uilabel(app.uipanel6);
            app.text20.Text = 'PostCap Strength';
            app.text21 = uilabel(app.uipanel6);
            app.text21.Text = 'Lateral Stiffness';
            app.text33 = uilabel(app.uipanel6);
            app.text33.Text = 'Assymetric Rate';
            app.cLabel_2 = uilabel(app.uipanel6);
            app.cLabel_2.Text = 'c';
            app.Label_8 = uilabel(app.uipanel6);
            app.Label_8.Text = 'λ';
            app.edit13 = uieditfield(app.uipanel6, 'numeric');
            app.edit13.Limits = [0 Inf];
            app.edit13.ValueChangedFcn = createCallbackFcn(app, @edit13_Callback, true);
            app.edit13.Value = 50;
            app.edit14 = uieditfield(app.uipanel6, 'numeric');
            app.edit14.Limits = [0 Inf];
            app.edit14.ValueChangedFcn = createCallbackFcn(app, @edit14_Callback, true);
            app.edit14.Value = 50;
            app.edit15 = uieditfield(app.uipanel6, 'numeric');
            app.edit15.Limits = [0 Inf];
            app.edit15.ValueChangedFcn = createCallbackFcn(app, @edit15_Callback, true);
            app.edit15.Value = 50;
            app.edit17 = uieditfield(app.uipanel6, 'numeric');
            app.edit17.Limits = [0 Inf];
            app.edit17.ValueDisplayFormat = '%.1f';
            app.edit17.ValueChangedFcn = createCallbackFcn(app, @edit17_Callback, true);
            app.edit17.Value = 1;
            app.edit18 = uieditfield(app.uipanel6, 'numeric');
            app.edit18.Limits = [0 Inf];
            app.edit18.ValueDisplayFormat = '%.1f';
            app.edit18.ValueChangedFcn = createCallbackFcn(app, @edit18_Callback, true);
            app.edit18.Value = 1;
            app.edit19 = uieditfield(app.uipanel6, 'numeric');
            app.edit19.Limits = [0 Inf];
            app.edit19.ValueDisplayFormat = '%.1f';
            app.edit19.ValueChangedFcn = createCallbackFcn(app, @edit19_Callback, true);
            app.edit19.Value = 1;
            app.edit16 = uieditfield(app.uipanel6, 'numeric');
            app.edit16.Limits = [0 1];
            app.edit16.ValueDisplayFormat = '%.1f';
            app.edit16.ValueChangedFcn = createCallbackFcn(app, @edit16_Callback, true);
            app.edit16.Value = 1;
            app.edit20 = uieditfield(app.uipanel6, 'numeric');
            app.edit20.Limits = [0 1];
            app.edit20.ValueDisplayFormat = '%.1f';
            app.edit20.ValueChangedFcn = createCallbackFcn(app, @edit20_Callback, true);
            app.edit20.Value = 1;
            app.uipanel5 = uipanel(app.figure1);
            app.nLabel = uilabel(app.uipanel5);
            app.nLabel.Text = 'n';
            app.cLabel = uilabel(app.uipanel5);
            app.cLabel.Text = 'c';
            app.sLabel = uilabel(app.uipanel5);
            app.sLabel.Text = 'δᴏғғsᴇᴛ';
            app.Label_7 = uilabel(app.uipanel5);
            app.Label_7.Text = 'λ';
            app.checkbox2 = uicheckbox(app.uipanel5);
            app.checkbox2.ValueChangedFcn = createCallbackFcn(app, @checkbox2_Callback, true);
            app.checkbox2.Text = 'Smooth Transition Parameters';
            app.edit21 = uieditfield(app.uipanel5, 'numeric');
            app.edit21.ValueChangedFcn = createCallbackFcn(app, @edit21_Callback, true);
            app.edit21.Value = 0.001;
            app.edit22 = uieditfield(app.uipanel5, 'numeric');
            app.edit22.ValueChangedFcn = createCallbackFcn(app, @edit22_Callback, true);
            app.edit22.Value = 0.1;
            app.edit23 = uieditfield(app.uipanel5, 'numeric');
            app.edit23.ValueChangedFcn = createCallbackFcn(app, @edit23_Callback, true);
            app.edit23.Value = 50;
            app.edit24 = uieditfield(app.uipanel5, 'numeric');
            app.edit24.ValueChangedFcn = createCallbackFcn(app, @edit24_Callback, true);
            app.edit24.Value = 1;
            app.axes1 = uiaxes(app.figure1);
            app.SubmitButton = uibutton(app.figure1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'axes1figure.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_SystemModel_IMKSmooth_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_SystemModel_IMKSmooth_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end