classdef Module_SystemModel_Linear_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        text12        matlab.ui.control.Label
        text7         matlab.ui.control.Label
        text16        matlab.ui.control.Label
        text18        matlab.ui.control.Label
        text19        matlab.ui.control.Label
        SubmitButton  matlab.ui.control.Button
        text20        matlab.ui.control.Label
        checkbox1     matlab.ui.control.CheckBox
        ButtonGroup   matlab.ui.container.ButtonGroup
        radiobutton1  matlab.ui.control.RadioButton
        radiobutton2  matlab.ui.control.RadioButton
        Label         matlab.ui.control.Label
        FLabel        matlab.ui.control.Label
        edit3         matlab.ui.control.NumericEditField
        edit1         matlab.ui.control.NumericEditField
        edit2         matlab.ui.control.NumericEditField
        axes1         matlab.ui.control.UIAxes
        Image         matlab.ui.control.Image
    end

    
    properties (Access = private)
        MainApp % Description
    end
    
    methods (Access = private)
        function Plot(app)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option','Ko','BackboneNoPD','BackbonePD','Backbone','M','g','delta_SystemFail','delta_PDeltaFail','Units','P','H','PDeltastatus')
            cd (MainDirectory)
            
            BackboneNoPD.Ke=Ko;
            BackbonePD.Ke=BackboneNoPD.Ke-P/H;
            
            app.axes1.cla;
            app.axes1.BackgroundColor=[1 1 1];
            app.axes1.FontName='Times';
            app.axes1.FontSize=16;
            app.axes1.XGrid='on';
            app.axes1.YGrid='on';
            app.axes1.Box='on';
            hold(app.axes1,"on");
            
            % Plot backbone
            if Units==1
                Disp =[0 1.0];
                ForceNoPD=[0 BackboneNoPD.Ke*1.0];
                ForcePD=[0 BackbonePD.Ke*1.0];
            else
                Disp =[0 100];
                ForceNoPD=[0 BackboneNoPD.Ke*100];
                ForcePD=[0 BackbonePD.Ke*100];
            end
            
            plot(app.axes1,Disp,ForceNoPD,'-k','linewidth',2);
            if Units==1
                app.axes1.XLabel.String='Displacement, \delta [m]';
                app.axes1.YLabel.String='Force [kN]';
            else
                app.axes1.XLabel.String='Displacement, \delta [in]';
                app.axes1.YLabel.String='Force [kip]';
            end
            app.axes1.XLabel.FontName='Times';
            app.axes1.YLabel.FontName='Times';
                
            % Plot backbone with P-Delta if applicable
            if PDeltastatus==1
                plot(app.axes1,Disp,ForcePD,'--r','linewidth',2);
                legend(app.axes1,'without P-Delta','with P-Delta');
                app.axes1.Legend.Location='southeast';
                app.axes1.Legend.FontName='Courier';
                app.axes1.Legend.FontSize=10;
            end
            
            if Units==1
                app.axes1.XLim=[0 1.0*1.2];
                app.axes1.YLim=[0 BackboneNoPD.Ke*1.0*1.2];
            else
                app.axes1.XLim=[0 100*1.2];
                app.axes1.YLim=[0 BackboneNoPD.Ke*100*1.2];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function Module_SystemModel_Linear_OpeningFcn(app, mainapp)
            app.MainApp=mainapp;
            app.MainApp.Image5.Visible='on';
            
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'SystemModelstatus','SystemModel_Option','Ko','BackboneNoPD','M','g','delta_SystemFail','Units')
            cd (MainDirectory)
            
            app.edit3.Value=Ko;
            app.edit3.Enable='off';
            app.edit3.Editable='off';
            
            if Units==1
                app.text19.Text='kN/m';
                app.text16.Text='kN';
                app.text12.Text='m';
            else
                app.text19.Text='kip/in';
                app.text16.Text='kip';
                app.text12.Text='in';
            end
            
            if SystemModelstatus==1 && SystemModel_Option==1
               app.edit2.Value=delta_SystemFail;
               edit1_Callback(app)
               app.radiobutton1.Value=1;
               app.checkbox1.Value=1;
               checkbox1_Callback(app)
               Plot(app)
            else
               app.checkbox1.Value=0;
               checkbox1_Callback(app)
               app.edit2.Value=999;
               edit1_Callback(app)
            end

        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global MainDirectory ProjectPath ProjectName
            clc;
            cd (ProjectPath)
            load(ProjectName,'Runstatus','Analysis_Option')
            cd (MainDirectory)
            
            if Runstatus==1
                waitfor(DeleteResultsMessage_App);
            end
            
            cd (ProjectPath)
            load(ProjectName,'DeleteResults')
            cd (MainDirectory)
            
            if Runstatus==1 && DeleteResults==0
                return;
            elseif Runstatus==1 && DeleteResults==1
                if Analysis_Option==1
                    rmvar(ProjectPath,ProjectName, 'RESULTS')
                elseif Analysis_Option==2
                    rmvar(ProjectPath,ProjectName, 'RESULTS','IDA')
                elseif Analysis_Option==3
                    rmvar(ProjectPath,ProjectName, 'RESULTS','RSA')
                elseif Analysis_Option==4
            
                end
                Runstatus=0;
                DeleteResults=1;
            
                cd (ProjectPath)
                save(ProjectName,'Runstatus','DeleteResults','-append')
                cd (MainDirectory)
            
                app.MainApp.ResultsButton.Enable='off';
                app.MainApp.SaveButton.Enable='off';
            end
            
            cd (ProjectPath)
            load(ProjectName,'Ko','BackboneNoPD','BackbonePD','Backbone','M','g','delta_SystemFail','delta_PDeltaFail','Units','P','H','PDeltastatus','GMstatus')
            cd (MainDirectory)
            
            clear BackboneNoPD BackbonePD Backbone
            
            BackboneNoPD.Ke=Ko;
            BackbonePD.Ke=BackboneNoPD.Ke-P/H;
            
            % Get System Failure Displacement
            if app.radiobutton1.Value==1
                Force_SystemFail=app.edit1.Value;
                delta_SystemFail=Force_SystemFail/BackbonePD.Ke;
            else
                delta_SystemFail=app.edit2.Value;
            end
            
            % Get P-Delta Failure Displacement
            if BackbonePD.Ke<0.0
                delta_PDeltaFail=0.0;
            else
                delta_PDeltaFail=999;
            end
            
            %% INPUT VALUES CHECKS BEFORE SUBMISSION
%             if delta_SystemFail<0
%                 ed = errordlg('Ultimate Displacements cannot be less than 0 (-ve)','Error', 'modal');
%                 waitfor(ed);
%                 return;
%             end
            
%             if isnan(delta_SystemFail)
%                 ed = errordlg('Ultimate force/displacement is not defined. Please define and re-submit','Error', 'modal');
%                 waitfor(ed);
%                 return;
%             end
            
            BackboneNoPD.du=delta_SystemFail;
            BackbonePD.du=delta_SystemFail;
            
            SystemModelstatus=1;
            SystemModel_Option=1;
            
            cd (MainDirectory)
            cd (ProjectPath)
            pause(0.2)
            save(ProjectName,'BackboneNoPD','BackbonePD','SystemModelstatus','SystemModel_Option','delta_PDeltaFail','delta_SystemFail','-append')
            pause(0.7)
            cd (MainDirectory)
            
            app.MainApp.status2.BackgroundColor='g';
            app.MainApp.status2.FontColor='k';
            app.MainApp.status2.Text='System Model: Linear';%text1 is the value tag for the Static Text in GUI1
            app.MainApp.status2b.BackgroundColor='g';%text1 is the value tag for the Static Text in GUI1
            if GMstatus~=1
                app.MainApp.GMButton.Enable='on';%text1 is the value tag for the Static Text in GUI1
                app.MainApp.status3.BackgroundColor='r';%text1 is the value tag for the Static Text in GUI1
                app.MainApp.status3b.BackgroundColor='r';%text1 is the value tag for the Static Text in GUI1
            end
            
            app.MainApp.Image5.Visible='off';
            closereq
        end

        % Value changed function: checkbox1
        function checkbox1_Callback(app, event)
            if  app.checkbox1.Value==1
                app.radiobutton1.Enable='on';
                app.radiobutton2.Enable='on';
                app.radiobutton1.Value=1;
                app.edit1.Enable='on';
                app.edit2.Enable='on';
                edit1_Callback(app)
            else
                app.edit1.Enable='off';
                app.edit2.Enable='off';
                app.radiobutton1.Enable='off';
                app.radiobutton2.Enable='off';
                app.edit2.Value=999;
                edit1_Callback(app)
            end
            ButtonGroupSelectionChanged(app)
        end

        % Value changed function: edit1
        function edit1_Callback(app, event)
            Ke=app.edit3.Value;
            X=app.edit1.Value/Ke;
            app.edit2.Value=X;
            Plot(app)
        end

        % Value changed function: edit2
        function edit2_Callback(app, event)
            Ke=app.edit3.Value;
            X=app.edit2.Value*Ke;
            app.edit1.Value=X;
            Plot(app)
        end

        % Close request function: figure1
        function figure1CloseRequest(app, event)
            app.MainApp.Image5.Visible='off';
            delete(app)
        end

        % Selection changed function: ButtonGroup
        function ButtonGroupSelectionChanged(app, event)
            if app.radiobutton1.Value==1
                app.edit1.Enable='on';
                app.edit2.Enable='off';
                edit1_Callback(app);
            else
                app.edit1.Enable='off';
                app.edit2.Enable='on';                
                edit2_Callback(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Linear Model';
            app.figure1.CloseRequestFcn = createCallbackFcn(app, @figure1CloseRequest, true);
            app.uipanel1 = uipanel(app.figure1);
            app.uipanel1.Title = 'Backbone Parameters';
            app.text12 = uilabel(app.uipanel1);
            app.text12.Text = 'm';
            app.text7 = uilabel(app.uipanel1);
            app.text7.Text = 'Ultimate Displacement';
            app.text16 = uilabel(app.uipanel1);
            app.text16.Text = 'kN';
            app.text18 = uilabel(app.uipanel1);
            app.text18.Text = 'Kₑ';
            app.text19 = uilabel(app.uipanel1);
            app.text19.Text = 'kN/m';
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.text20 = uilabel(app.uipanel1);
            app.text20.Text = 'Ultimate Force';
            app.checkbox1 = uicheckbox(app.uipanel1);
            app.checkbox1.ValueChangedFcn = createCallbackFcn(app, @checkbox1_Callback, true);
            app.checkbox1.Text = ' Limit Force/Displacement';
            app.ButtonGroup = uibuttongroup(app.uipanel1);
            app.ButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ButtonGroupSelectionChanged, true);
            app.radiobutton1 = uiradiobutton(app.ButtonGroup);
            app.radiobutton1.Text = '';
            app.radiobutton1.Value = true;
            app.radiobutton2 = uiradiobutton(app.ButtonGroup);
            app.radiobutton2.Text = '';
            app.Label = uilabel(app.uipanel1);
            app.Label.Text = 'δᵤ:';
            app.FLabel = uilabel(app.uipanel1);
            app.FLabel.Text = 'Fᵤ:';
            app.edit3 = uieditfield(app.uipanel1, 'numeric');
            app.edit3.Limits = [0.001 Inf];
            app.edit3.Value = 0.001;
            app.edit1 = uieditfield(app.uipanel1, 'numeric');
            app.edit1.Limits = [0.001 Inf];
            app.edit1.ValueChangedFcn = createCallbackFcn(app, @edit1_Callback, true);
            app.edit1.Value = 1;
            app.edit2 = uieditfield(app.uipanel1, 'numeric');
            app.edit2.Limits = [0.001 Inf];
            app.edit2.ValueChangedFcn = createCallbackFcn(app, @edit2_Callback, true);
            app.edit2.Value = 1;
            app.axes1 = uiaxes(app.figure1);
            app.Image = uiimage(app.figure1);
            app.Image.ImageSource = 'Linearaxes1.png';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Module_SystemModel_Linear_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @(app)Module_SystemModel_Linear_OpeningFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end