classdef UnitMessage_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        figure1       matlab.ui.Figure
        uipanel1      matlab.ui.container.Panel
        SubmitButton  matlab.ui.control.Button
        text6         matlab.ui.control.Label
        popupmenu1    matlab.ui.control.DropDown
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function UnitMessage_OpeningFcn(app)
            
        end

        % Button pushed function: SubmitButton
        function SubmitButton_Callback(app, event)
            global  MainDirectory ProjectPath ProjectName
            clc;
            
            Units=str2double(app.popupmenu1.Value);
            
            % Set "g" value based on selected units
            if Units==1
                g=9.81;  % m/sec2
            else
                g=386.2; % in/sec2
            end
            
            cd (ProjectPath)
            save(ProjectName,'MainDirectory','ProjectName','ProjectPath','Units','g')
            pause(0.5)
            cd (MainDirectory)
            closereq
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.figure1 = uifigure('Visible', 'off');
            app.figure1.Name = 'Select Project Units';
            app.uipanel1 = uipanel(app.figure1);
            app.SubmitButton = uibutton(app.uipanel1, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_Callback, true);
            app.SubmitButton.Text = 'Submit';
            app.text6 = uilabel(app.uipanel1);
            app.text6.Text = 'Project Units:';
            app.popupmenu1 = uidropdown(app.uipanel1);
            app.popupmenu1.Items = {'kN & m', 'Kip & in'};
            app.popupmenu1.ItemsData = {'1', '2'};
            app.popupmenu1.Value = '1';
            % Show the figure after all components are created
            app.figure1.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = UnitMessage_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.figure1)

            % Execute the startup function
            runStartupFcn(app, @UnitMessage_OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.figure1)
        end
    end
end